classdef EEG_Processor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        HelpMenu                        matlab.ui.container.Menu
        ReadTheDocsMenu                 matlab.ui.container.Menu
        BIDSSpecificationMenu           matlab.ui.container.Menu
        DevelopMenu                     matlab.ui.container.Menu
        DebugModeMenu                   matlab.ui.container.Menu
        UIFigureGridLayout              matlab.ui.container.GridLayout
        TreeNodesPool                   matlab.ui.container.CheckBoxTree
        MainPanel                       matlab.ui.container.Panel
        MainGridLayout                  matlab.ui.container.GridLayout
        ProcessesPanel                  matlab.ui.container.Panel
        ProcessesGridLayout             matlab.ui.container.GridLayout
        ProcessesRunButton              matlab.ui.control.Button
        ProcessesDeleteButton           matlab.ui.control.Button
        ProcessesMoveDownButton         matlab.ui.control.Button
        ProcessesMoveUpButton           matlab.ui.control.Button
        ProcessesListBox                matlab.ui.control.ListBox
        PropertiesTabGroup              matlab.ui.container.TabGroup
        DatasetPropertiesTab            matlab.ui.container.Tab
        DatasetPropertiesGridLayout     matlab.ui.container.GridLayout
        DatasetAcknowledgementsPanel    matlab.ui.container.Panel
        DatasetAcknowledgementsGridLayout  matlab.ui.container.GridLayout
        DatasetAcknowledgementsTextArea  matlab.ui.control.TextArea
        DatasetAcknowledgePanel         matlab.ui.container.Panel
        DatasetAcknowledgeGridLayout    matlab.ui.container.GridLayout
        DatasetAcknowledgeTextArea      matlab.ui.control.TextArea
        DatasetEthicsPanel              matlab.ui.container.Panel
        DatasetEthicsGridLayout         matlab.ui.container.GridLayout
        DatasetEthicsTextArea           matlab.ui.control.TextArea
        DatasetFundingPanel             matlab.ui.container.Panel
        DatasetFundingGridLayout        matlab.ui.container.GridLayout
        DatasetFundingTextArea          matlab.ui.control.TextArea
        DatasetAuthorsPanel             matlab.ui.container.Panel
        DatasetAuthorsGridLayout        matlab.ui.container.GridLayout
        DatasetAuthorsTextArea          matlab.ui.control.TextArea
        DatasetGeneratedByPanel         matlab.ui.container.Panel
        DatasetGeneratedByGridLayout    matlab.ui.container.GridLayout
        DatasetGeneratedByDescriptionTextArea  matlab.ui.control.TextArea
        DescriptionTextAreaLabel        matlab.ui.control.Label
        DatasetGeneratedByNameEditField  matlab.ui.control.EditField
        NameEditFieldLabel_7            matlab.ui.control.Label
        DatasetTasksPanel               matlab.ui.container.Panel
        DatasetTasksGridLayout          matlab.ui.container.GridLayout
        DatasetTasksListBox             matlab.ui.control.ListBox
        DatasetTasksEditButton          matlab.ui.control.Button
        DatasetTasksAddButton           matlab.ui.control.Button
        DatasetSessionsPanel            matlab.ui.container.Panel
        DatasetSessionsGridLayout       matlab.ui.container.GridLayout
        DatasetSessionsListBox          matlab.ui.control.ListBox
        DatasetSessionsEditButton       matlab.ui.control.Button
        DatasetSessionsAddButton        matlab.ui.control.Button
        DatasetNamePanel                matlab.ui.container.Panel
        DatasetNameGridLayout           matlab.ui.container.GridLayout
        DatasetLicenseDropDown          matlab.ui.control.DropDown
        LicenseDropDownLabel            matlab.ui.control.Label
        DatasetDOIEditField             matlab.ui.control.EditField
        DOIEditFieldLabel               matlab.ui.control.Label
        DatasetNameEditField            matlab.ui.control.EditField
        NameEditFieldLabel_6            matlab.ui.control.Label
        NoDatasetLoadedLabel            matlab.ui.control.Label
        SubjectPropertiesTab            matlab.ui.container.Tab
        SubjectPropertiesGridLayout     matlab.ui.container.GridLayout
        SubjectPropertiesDeletePanel    matlab.ui.container.Panel
        SubjectPropertiesDeleteGridLayout  matlab.ui.container.GridLayout
        SubjectPropertiesDeleteButton   matlab.ui.control.Button
        SubjectPropertiesDeleteLabel    matlab.ui.control.Label
        SubjectPropertiesPanel          matlab.ui.container.Panel
        SubjectPropertiesPanelGridLayout  matlab.ui.container.GridLayout
        SubjectPropertiesIdEditField    matlab.ui.control.EditField
        SubjectIDLabel                  matlab.ui.control.Label
        SubjectPropertiesSaveButton     matlab.ui.control.Button
        SubjectPropertiesUndoButton     matlab.ui.control.Button
        NoSubjectSelectedLabel          matlab.ui.control.Label
        FilePropertiesTab               matlab.ui.container.Tab
        FilePropertiesGridLayout        matlab.ui.container.GridLayout
        NoFileSelectedLabel             matlab.ui.control.Label
        FilePropertiesErrorPanel        matlab.ui.container.Panel
        FilePropertiesErrorGridLayout   matlab.ui.container.GridLayout
        FilePropertiesErrorLabel        matlab.ui.control.Label
        FilePropertiesPanel             matlab.ui.container.Panel
        FilePropertiesPanelGridLayout   matlab.ui.container.GridLayout
        taskLabel                       matlab.ui.control.Label
        FilePropertiesTaskDropdown      matlab.ui.control.DropDown
        FilePropertiesSessionDropdown   matlab.ui.control.DropDown
        sessionLabel                    matlab.ui.control.Label
        FilePropertiesQualitiyPanel     matlab.ui.container.Panel
        FilePropertiesQualitiyGridLayout  matlab.ui.container.GridLayout
        FilePropertiesQualitiyValue     matlab.ui.control.Label
        FilePropertiesQualitiyLabel     matlab.ui.control.Label
        FilePropertiesPNSChansValue     matlab.ui.control.Label
        FilePropertiesEEGChansValue     matlab.ui.control.Label
        FilePropertiesChannelsLabel     matlab.ui.control.Label
        FilePropertiesDataValue         matlab.ui.control.Label
        FilePropertiesDataLabel         matlab.ui.control.Label
        FilePropertiesSaveButton        matlab.ui.control.Button
        FilePropertiesUndoButton        matlab.ui.control.Button
        FilePropertiesInspectButton     matlab.ui.control.Button
        FilePropertiesHistoryPanel      matlab.ui.container.Panel
        FilePropertiesHistoryGridLayout  matlab.ui.container.GridLayout
        FilePropertiesHistoryTree       matlab.ui.container.Tree
        FilePropertiesEventsPanel       matlab.ui.container.Panel
        FilePropertiesEventsGridLayout  matlab.ui.container.GridLayout
        FilePropertiesEventImportButton  matlab.ui.control.Button
        FilePropertiesEventInstanceListBox  matlab.ui.control.ListBox
        FilePropertiesEventGroupListBox  matlab.ui.control.ListBox
        FilePropertiesDeletePanel       matlab.ui.container.Panel
        FilePropertiesDeleteGridLayout  matlab.ui.container.GridLayout
        FilePropertiesDeleteButton      matlab.ui.control.Button
        FilePropertiesDeleteLabel       matlab.ui.control.Label
        FilesPanel                      matlab.ui.container.Panel
        FilesGridLayout                 matlab.ui.container.GridLayout
        FileAddProcessPanel             matlab.ui.container.Panel
        FileAddProcessGridLayout        matlab.ui.container.GridLayout
        FileAddProcessButton            matlab.ui.control.Button
        FileAddProcessInput             matlab.ui.control.DropDown
        FilesTabGroup                   matlab.ui.container.TabGroup
        FilesRawTab                     matlab.ui.container.Tab
        FilesRawGridLayout              matlab.ui.container.GridLayout
        FilesRawTree                    matlab.ui.container.CheckBoxTree
        FilesDerivativesTab             matlab.ui.container.Tab
        FilesDerivativesGridLayout      matlab.ui.container.GridLayout
        FilesDerivativesTree            matlab.ui.container.CheckBoxTree
        FilesFirstlvlOutputTab          matlab.ui.container.Tab
        FilesFirstlvlOutputGridLayout   matlab.ui.container.GridLayout
        FilesFirstlvlOutputTree         matlab.ui.container.CheckBoxTree
        FilesFilterEditField            matlab.ui.control.EditField
        FilesSelectButton               matlab.ui.control.Button
        FilesExpandButton               matlab.ui.control.Button
        FilesAddSubjectButton           matlab.ui.control.Button
        DatasetPanel                    matlab.ui.container.Panel
        DatasetGridLayout               matlab.ui.container.GridLayout
        AuthEditField                   matlab.ui.control.EditField
        AuthLabel                       matlab.ui.control.Label
        AuthButton                      matlab.ui.control.Button
        DatasetPathEditField            matlab.ui.control.EditField
        DatasetBrowseButton             matlab.ui.control.Button
        LoadingImage                    matlab.ui.control.Image
        FilesRawSubjectContextMenu      matlab.ui.container.ContextMenu
        FilesRawSubjectContextHeader    matlab.ui.container.Menu
        FilesRawSubjectContextImportEEGMenu  matlab.ui.container.Menu
        FilesContextMenu                matlab.ui.container.ContextMenu
        CopyPathnameMenu                matlab.ui.container.Menu
        LoadEEGdataMenu                 matlab.ui.container.Menu
        LoadEEGheaderMenu               matlab.ui.container.Menu
    end

    % #########################################################
    % App properties
    properties (Access = public)
        % App properties
        Waitbar;
        ErrorMessage;
        Import;
        State;
        DefaultProcesses = {...
            'Select...', ...
            '=== Preprocess ===', ...
            'Select and Preprocess Data', ...
            'Concatenate Data', ...
            '=== Analysis ===', ...
            'Power Spectral Analysis', ...
            'Time-Frequency Spectrogram (under development)', ...
            '=== I/O ===', ...
            'Save As 10-20 Dataset', ...
            '=== DANGER-ZONE ===', ...
            'Delete all checked files'};
        FstLvlProcesses = {...
            'Select...', ...
            '=== Postprocess ===', ...
            'Interpolate Power-Spectral Outliers (Single Subject)', ...
            '=== Group Analysis ===', ...
            'Correlation', ...
            'Two-sample unpaired t-test', ...
            'Two-sample paired t-test', ...
            'One-way ANOVA', ...
            'Two-way ANOVA', ...
            'General Linear Model', ...
            '=== DANGER-ZONE ===', ...
            'Delete all checked files'};
        GUIs;
        Pools;
    end
    % #########################################################
    % APP METHODS AND FUNCTIONS
    methods (Access = public)
        % =========================================================
        % APP METHODS: SET PROPERTIES OF OBJECTS
        % ---------------------------------------------------------
        % METHOD: Sets the waitbar
        function RenderWaitbar(app, Message, Value)
            if isempty(Message)
                app.Waitbar = [];
                drawnow;
                return
            end
            if isempty(app.Waitbar)
                app.Waitbar = uiprogressdlg(app.UIFigure);
                app.Waitbar.Title = 'Cogwheels are turning, please wait...';
            end
            app.Waitbar.Message = Message;
            if Value == -1
                app.Waitbar.Indeterminate ='on';
            else
                app.Waitbar.Indeterminate = 'off';
                app.Waitbar.Value = Value;
            end
            drawnow;
        end
        % ---------------------------------------------------------
        % METHOD: Sets an error message
        function RenderErrorMessage(app, Message)
            if app.State.Verbose
                t = now; %#ok<*TNOW1>
            end
            sel = uiconfirm(app.UIFigure, Message, 'Error', ...
                'Options',{'Ok'},...
                'DefaultOption', 'Ok', ...
                'Icon', 'error'); 
            drawnow;
            if app.State.Verbose
                fprintf('>> BIDS: RenderErrorMessage took %s.\n', duration2str(now-t));
            end
        end
        % ---------------------------------------------------------
        % METHOD: Set the value of an edit input field
        function RenderInputField(app, Obj, Value)
            if app.State.Verbose
                t = now;
            end
            if isempty(Value)
                UpdateProperty(app, Obj, 'Value', '');
            else
                UpdateProperty(app, Obj, 'Value', Value);
            end
            if app.State.Verbose
                fprintf('>> BIDS: RenderInputField took %s.\n', duration2str(now-t));
            end
        end
        % ---------------------------------------------------------
        % METHOD: Update DatasetPathEditField
        function RenderDatasetPathEditField(app)
            if app.State.Verbose
                t = now;
            end
            UpdateProperty(app, app.DatasetPathEditField, 'Value', app.State.Protocol.Path);
            if app.State.Verbose
                fprintf('>> BIDS: RenderDatasetPathEditField took %s.\n', duration2str(now-t));
            end
        end
        % ---------------------------------------------------------
        % METHOD: Update DatasetPathEditField
        function RenderAuthLabel(app)
            if app.State.Verbose
                t = now;
            end
            if app.State.Auth.IsAuthorized
                UpdateProperty(app, app.AuthLabel, 'Text', sprintf('Hi %s!', app.State.Auth.Name));
                UpdateProperty(app, app.AuthLabel, 'Visible', 'on');
                UpdateProperty(app, app.AuthEditField, 'Visible', 'off');
            else
                UpdateProperty(app, app.AuthLabel, 'Text', '');
                UpdateProperty(app, app.AuthLabel, 'Visible', 'off');
                UpdateProperty(app, app.AuthEditField, 'Visible', 'on');
            end
            if app.State.Auth.IsAuthorized
                UpdateProperty(app, app.AuthButton, 'Text', 'Sign out');
            else
                UpdateProperty(app, app.AuthButton, 'Text', 'Sign in');
            end
            if app.State.Verbose
                fprintf('>> BIDS: RenderAuthLabel took %s.\n', duration2str(now-t));
            end
        end
        % ---------------------------------------------------------
        % METHOD: Update DatasetPathEditField
        function RenderFilesAddSubjectButton(app)
            if app.State.Verbose
                t = now;
            end
            if isfield(app.State, 'Protocol') && ~isfield(app.State, 'Error')
                UpdateProperty(app, app.FilesAddSubjectButton, 'Enable', 'on');
            else
                UpdateProperty(app, app.FilesAddSubjectButton, 'Enable', 'off');
            end
            if app.State.Verbose
                fprintf('>> BIDS: RenderFilesAddSubjectButton took %s.\n', duration2str(now-t));
            end
        end
        % ---------------------------------------------------------
        % METHOD: Update FilesExpandButton
        function RenderFilesExpandButton(app)
            if app.State.Verbose
                t = now;
            end
            if isempty(app.FilesRawTree.Children) && isempty(app.FilesDerivativesTree.Children) && isempty(app.FilesFirstlvlOutputTree.Children)
                UpdateProperty(app, app.FilesExpandButton, 'Enable', 'off');
            else
                UpdateProperty(app, app.FilesExpandButton, 'Enable', 'on');
                if ~isfield(app.FilesRawTree.UserData, 'isCollapsed')
                    UpdateProperty(app, app.FilesExpandButton, 'Icon', 'icon_expand.png');
                    UpdateProperty(app, app.FilesExpandButton, 'Tooltip', 'expand all');
                elseif app.FilesRawTree.UserData.isCollapsed
                    UpdateProperty(app, app.FilesExpandButton, 'Icon', 'icon_expand.png');
                    UpdateProperty(app, app.FilesExpandButton, 'Tooltip', 'expand all');
                else
                    UpdateProperty(app, app.FilesExpandButton, 'Icon', 'icon_collapse.png');
                    UpdateProperty(app, app.FilesExpandButton, 'Tooltip', 'collapse all');
                end
            end
            if app.State.Verbose
                fprintf('>> BIDS: RenderFilesExpandButton took %s.\n', duration2str(now-t));
            end
        end
        % ---------------------------------------------------------
        % METHOD: Update FilesSelectButton
        function RenderFilesSelectButton(app)
            if app.State.Verbose
                t = now;
            end
            switch app.FilesTabGroup.SelectedTab.Title
                case 'Raw'
                    tree = app.FilesRawTree;
                case 'Derivatives'
                    tree = app.FilesDerivativesTree;
                case 'First level'
                    tree = app.FilesFirstlvlOutputTree;
                otherwise
                    % do nothing
                    return
            end
            if isempty(tree.Children)
                UpdateProperty(app, app.FilesSelectButton, 'Enable', 'off');
            else
                UpdateProperty(app, app.FilesSelectButton, 'Enable', 'on');
                if length(tree.CheckedNodes) == sum(cellfun(@(n) length(n), [{tree.Children}, {tree.Children.Children}]))
                    UpdateProperty(app, app.FilesSelectButton, 'Icon', 'icon_deselect-all.png');
                    UpdateProperty(app, app.FilesSelectButton, 'Tooltip', 'deselect all');
                else
                    UpdateProperty(app, app.FilesSelectButton, 'Icon', 'icon_select-all.png');
                    UpdateProperty(app, app.FilesSelectButton, 'Tooltip', 'select all');
                end
            end
            if app.State.Verbose
                fprintf('>> BIDS: RenderFilesSelectButton took %s.\n', duration2str(now-t));
            end
        end
        % ---------------------------------------------------------
        % METHOD: Initializes FilesFilterEditField to be empty
        function InitFilesFilterEditField(app)
            UpdateProperty(app, app.FilesFilterEditField, 'Value', '');
        end
        % ---------------------------------------------------------
        % METHOD: Update FilesFilterEditField
        function RenderFilesFilterEditField(app)
            if app.State.Verbose
                t = now;
            end
            if ~isfield(app.State, 'Files')
                UpdateProperty(app, app.FilesFilterEditField, 'Enable', 'off');
            elseif ~isempty(app.State.Files.Id)
                UpdateProperty(app, app.FilesFilterEditField, 'Enable', 'on');
            else
                UpdateProperty(app, app.FilesFilterEditField, 'Enable', 'off');
            end
            if app.State.Verbose
                fprintf('>> BIDS: RenderFilesFilterEditField took %s.\n', duration2str(now-t));
            end
        end
        % ---------------------------------------------------------
        % METHOD: Update FilesRawTree
        function RenderFilesTree(app, type)
            if app.State.Verbose
                t = now;
            end
            % Extract the correct tree
            switch type
                case 'raw'
                    tree = app.FilesRawTree;
                case 'derivative'
                    tree = app.FilesDerivativesTree;
                case 'fstlvl'
                    tree = app.FilesFirstlvlOutputTree;
                otherwise
                    return
            end
            % ---------------------------------------------------------
            % Initialize the userdata structure if it does not exist yet
            if ~isfield(tree.UserData, 'Nodes')
                tree.UserData.Nodes = struct();
            end
            % ---------------------------------------------------------
            % Filter the files
            % Keep only files that are of this type ('raw', 'derivative', or 'fstlvl') 
            try
            Files = app.State.Files(matches(app.State.Files.Type, type), :);
            catch ME
                keyboard
            end
            % Apply filter
            Filter = app.FilesFilterEditField.Value;
            if ~isempty(Filter)
                Files = Files(regexpIdx(Files.Path, Filter), :);
            end
            % Get all subject ids
            SubIds = app.State.Subjects.Id;
            % Remove any nodes that are not subject IDs or in the filtered file id list
            ExistingNodeIds = fieldnames(tree.UserData.Nodes);
            if ~isempty(ExistingNodeIds)
                RmIds = ExistingNodeIds(~ismember(ExistingNodeIds, [SubIds; Files.Id]));
                cellfun(@(id) delete(tree.UserData.Nodes.(id)), RmIds);
            end
            % ---------------------------------------------------------
            % First add all the subject nodes and sort them
            for i = 1:length(SubIds)
                % Extract ID and Name of subject
                SubId = SubIds{i};
                Name = app.State.Subjects.Name{matches(app.State.Subjects.Id, SubId)};
                % Check if the node exists already
                isnode = isfield(tree.UserData.Nodes, SubId);
                if isnode % Check it is not a handle to a deleted node
                    isnode = isvalid(tree.UserData.Nodes.(SubId));
                end
                if isnode % Just update the node
                    UpdateProperty(app, tree.UserData.Nodes.(SubId), 'Text', Name);
                else % Create subject node
                    tree.UserData.Nodes.(SubId) = uitreenode(tree, 'Text', Name);
                    tree.UserData.Nodes.(SubId).UserData.Slice = 'Subjects';
                    tree.UserData.Nodes.(SubId).UserData.id = SubId;
                    if strcmpi(type, 'raw')
                        tree.UserData.Nodes.(SubId).ContextMenu = app.FilesRawSubjectContextMenu;
                    end
                end
            end
            % Reorder subject nodes
            if ~isempty(tree.Children)
                [~, Reorder] = sort({tree.Children.Text});
                tree.Children = tree.Children(Reorder);
            end
            % ---------------------------------------------------------
            % Add the file nodes that passed the filter
            for i = 1:length(SubIds)
                % Extract ID and Name of subject
                SubId = SubIds{i};
                ThisFiles = Files(matches(Files.SubId, SubId), :);
                % For each file...
                for j = 1:size(ThisFiles, 1)
                    % Extract the file id, type and name
                    FileId = ThisFiles.Id{j};
                    Filename = struct2filename(ThisFiles.KeyVals{j});
                    % Check if the node exists already
                    isnode = isfield(tree.UserData.Nodes, FileId);
                    if isnode % Check it is not a handle to a deleted node
                        isnode = isvalid(tree.UserData.Nodes.(FileId));
                    end
                    % Render or update the node
                    if isnode % Just update the node
                        UpdateProperty(app, tree.UserData.Nodes.(FileId), 'Text', Filename);
                        if matches(ThisFiles.Status{j}, 'error')
                            UpdateProperty(app, tree.UserData.Nodes.(SubId), 'Icon', 'icon_error.png');
                            UpdateProperty(app, tree.UserData.Nodes.(FileId), 'Icon', 'icon_error.png');
                        else
                            UpdateProperty(app, tree.UserData.Nodes.(SubId), 'Icon', '');
                            UpdateProperty(app, tree.UserData.Nodes.(FileId), 'Icon', '');
                        end
                    else % Create file node
                        tree.UserData.Nodes.(FileId) = uitreenode(tree.UserData.Nodes.(SubId), 'Text', Filename);
                        tree.UserData.Nodes.(FileId).UserData.Slice = 'Files';
                        tree.UserData.Nodes.(FileId).UserData.id = FileId;
                        tree.UserData.Nodes.(FileId).ContextMenu = app.FilesContextMenu;
                        if matches(ThisFiles.Status{j}, 'error')
                            tree.UserData.Nodes.(SubId).Icon = 'icon_error.png';
                            tree.UserData.Nodes.(FileId).Icon = 'icon_error.png';
                        end
                    end
                end
                % Reorder children
                if ~isempty(tree.UserData.Nodes.(SubId).Children)
                    [~, Reorder] = sort({tree.UserData.Nodes.(SubId).Children.Text});
                    tree.UserData.Nodes.(SubId).Children = tree.UserData.Nodes.(SubId).Children(Reorder);
                end
            end
            if app.State.Verbose
                fprintf('>> BIDS: RenderFilesTree took %s.\n', duration2str(now-t));
            end
        end
        % ---------------------------------------------------------
        % METHOD: Toggle collapse and expand. Note the raw file tree keeps
        % the 'isCollapsed' state for all trees.
        function ToggleFilesTree(app, varargin)
            if app.State.Verbose
                t = now;
            end
            if nargin >= 2
                if varargin{1}
                    expand(app.FilesRawTree);
                    expand(app.FilesDerivativesTree);
                    expand(app.FilesFirstlvlOutputTree);
                    app.FilesRawTree.UserData.isCollapsed = false;
                else
                    collapse(app.FilesRawTree);
                    collapse(app.FilesDerivativesTree);
                    collapse(app.FilesFirstlvlOutputTree);
                    app.FilesRawTree.UserData.isCollapsed = true;
                end
                if length(varargin) == 2
                    if varargin{2} % Check or uncheck the checkboxes
                        app.FilesRawTree.CheckedNodes = app.FilesRawTree.Children;
                        app.FilesDerivativesTree.CheckedNodes = app.FilesDerivativesTree.Children;
                        app.FilesFirstlvlOutputTree.CheckedNodes = app.FilesFirstlvlOutputTree.Children;
                    end
                end
            elseif ~isfield(app.FilesRawTree.UserData, 'isCollapsed')
                expand(app.FilesRawTree);
                expand(app.FilesDerivativesTree);
                expand(app.FilesFirstlvlOutputTree);
                app.FilesRawTree.UserData.isCollapsed = false;
            elseif app.FilesRawTree.UserData.isCollapsed
                expand(app.FilesRawTree);
                expand(app.FilesDerivativesTree);
                expand(app.FilesFirstlvlOutputTree);
                app.FilesRawTree.UserData.isCollapsed = false;
            else
                collapse(app.FilesRawTree);
                collapse(app.FilesDerivativesTree);
                collapse(app.FilesFirstlvlOutputTree);
                app.FilesRawTree.UserData.isCollapsed = true;
            end
            if app.State.Verbose
                fprintf('>> BIDS: ToggleFilesTree took %s.\n', duration2str(now-t));
            end
        end
        % ---------------------------------------------------------
        % METHOD: Toggle select and deselect all
        function ToggleFilesSelect(app)
            if app.State.Verbose
                t = now;
            end
            switch app.FilesTabGroup.SelectedTab.Title
                case 'Raw'
                    tree = app.FilesRawTree;
                case 'Derivatives'
                    tree = app.FilesDerivativesTree;
                case 'First level'
                    tree = app.FilesFirstlvlOutputTree;
                otherwise
                    return
            end
            if length(tree.CheckedNodes) == sum(cellfun(@(n) length(n), [{tree.Children}, {tree.Children.Children}]))
                tree.CheckedNodes = [];
            else
                tree.CheckedNodes = tree.Children;
            end
            if app.State.Verbose
                fprintf('>> BIDS: ToggleFilesSelect took %s.\n', duration2str(now-t));
            end
        end
        % ---------------------------------------------------------
        % METHOD: Sets the selected node to one with a specific text
        function RenderFilesTreeSelectedNode(app, Text)
            if app.State.Verbose
                t = now;
            end
            trees = {'FilesRawTree', 'FilesDerivativesTree', 'FilesFirstlvlOutputTree'};
            for i = 1:length(trees)
                selNode = findobj(app.(trees{i}), 'Text', Text);
                if ~isempty(selNode)
                    UpdateProperty(app, app.(trees{i}), 'SelectedNodes', selNode);
                    scroll(app.(trees{i}), selNode);
                end
            end
            if app.State.Verbose
                fprintf('>> BIDS: RenderFilesTreeSelectedNode took %s.\n', duration2str(now-t));
            end
        end
        % ---------------------------------------------------------
        % METHOD: Sets the subject node based on the state
        function RenderFilesTreeSubjectNodeById(app, id)
            if app.State.Verbose
                t = now;
            end
            trees = {'FilesRawTree', 'FilesDerivativesTree', 'FilesFirstlvlOutputTree'};
            for i = 1:length(trees)
                node = app.(trees{i}).UserData.Nodes.(id);
                if ~isempty(node) && isvalid(node)
                    % Check if the subject still exists or not
                    idxSub = matches(app.State.Subjects.Id, id);
                    if any(idxSub)
                        UpdateProperty(app, node, 'Text', ifelse(matches(app.State.Subjects.Status{idxSub}, 'changed'), [app.State.Subjects.Name{idxSub}, ' (unsaved)'], app.State.Subjects.Name{idxSub}));
                        UpdateProperty(app, node, 'Icon', ifelse(matches(app.State.Subjects.Status{idxSub}, 'changed'), 'icon_unsaved.png', ''));
                    else
                        delete(node);
                    end
                end
            end
            if app.State.Verbose
                fprintf('>> BIDS: RenderFilesTreeSubjectNodeById took %s.\n', duration2str(now-t));
            end
        end
        % ---------------------------------------------------------
        % METHOD: Sets the file node based on the state
        function RenderFilesTreeFileNodeById(app, id)
            if app.State.Verbose
                t = now;
            end
            trees = {'FilesRawTree', 'FilesDerivativesTree', 'FilesFirstlvlOutputTree'};
            for i = 1:length(trees)
                if isfield(app.(trees{i}).UserData.Nodes, id)
                    node = app.(trees{i}).UserData.Nodes.(id);
                    if ~isempty(node)
                        % Check if the file still exists or not
                        idx = matches(app.State.Files.Id, id);
                        if any(idx)
                            Filename = struct2filename(app.State.Files.KeyVals{idx});
                            UpdateProperty(app, node, 'Text', ifelse(matches(app.State.Files.Status{idx}, 'changed'), [Filename, ' (unsaved)'], Filename));
                            UpdateProperty(app, node, 'Icon', ifelse(matches(app.State.Files.Status{idx}, 'changed'), 'icon_unsaved.png', ''));
                        else
                            delete(node);
                        end
                    end
                end
            end
            if app.State.Verbose
                fprintf('>> BIDS: RenderFilesTreeFileNodeById took %s.\n', duration2str(now-t));
            end
        end
        % ---------------------------------------------------------
        % METHOD: Initialize DatasetProperties Components
        function InitDatasetProperties(app)
            if app.State.Verbose
                t = now;
            end
            % Select all files so we can extract the task and session list
            % First remove the 'not loaded' message
            app.DatasetPropertiesGridLayout.RowHeight{1} = 0;
            UpdateProperty(app, app.NoDatasetLoadedLabel, 'Visible', 'off');
            % Set the session and tasks
            app.RenderDatasetSessionsListBox();
            app.RenderDatasetTasksListBox();
            % Check that the licence is part of the items list
            Items = unique([{app.State.Protocol.JSON.License}, app.DatasetLicenseDropDown.Items]);
            UpdateProperty(app, app.DatasetLicenseDropDown, 'Items', Items);
            UpdateProperty(app, app.DatasetLicenseDropDown, 'Value', app.State.Protocol.JSON.License);
            % Set the input values
            app.RenderInputField(app.DatasetNameEditField, app.State.Protocol.JSON.Name);
            app.RenderInputField(app.DatasetDOIEditField, app.State.Protocol.JSON.DatasetDOI);
            app.RenderInputField(app.DatasetGeneratedByNameEditField, app.State.Protocol.JSON.GeneratedBy.Name);
            app.RenderInputField(app.DatasetGeneratedByDescriptionTextArea, app.State.Protocol.JSON.GeneratedBy.Description);
            app.RenderInputField(app.DatasetAuthorsTextArea, app.State.Protocol.JSON.Authors);
            app.RenderInputField(app.DatasetFundingTextArea, app.State.Protocol.JSON.Funding);
            app.RenderInputField(app.DatasetEthicsTextArea, app.State.Protocol.JSON.EthicsApprovals);
            app.RenderInputField(app.DatasetAcknowledgeTextArea, app.State.Protocol.JSON.HowToAcknowledge);
            app.RenderInputField(app.DatasetAcknowledgementsTextArea, app.State.Protocol.JSON.Acknowledgements);
            % Show all panels
            UpdateProperty(app, app.DatasetNamePanel, 'Visible', 'on');
            UpdateProperty(app, app.DatasetSessionsPanel, 'Visible', 'on');
            UpdateProperty(app, app.DatasetTasksPanel, 'Visible', 'on');
            UpdateProperty(app, app.DatasetGeneratedByPanel, 'Visible', 'on');
            UpdateProperty(app, app.DatasetAuthorsPanel, 'Visible', 'on');
            UpdateProperty(app, app.DatasetFundingPanel, 'Visible', 'on');
            UpdateProperty(app, app.DatasetEthicsPanel, 'Visible', 'on');
            UpdateProperty(app, app.DatasetAcknowledgePanel, 'Visible', 'on');
            UpdateProperty(app, app.DatasetAcknowledgementsPanel, 'Visible', 'on');
            if app.State.Verbose
                fprintf('>> BIDS: InitDatasetProperties took %s.\n', duration2str(now-t));
            end
        end
        % ---------------------------------------------------------
        function RenderHistoryNodes(app, Parent, S)
            fnames = fieldnames(S);
            for i = 1:length(fnames)
                if isstruct(S.(fnames{i}))
                    for j = 1:length(S.(fnames{i}))
                        Node = uitreenode(Parent, 'Text', fnames{i});
                        app.RenderHistoryNodes(Node, S.(fnames{i})(j));
                    end
                else
                    Val = S.(fnames{i});
                    if iscell(Val) && length(Val) > 1
                        Text = 'cell-array';
                    elseif isnumeric(Val) && length(Val) > 1
                        Text = 'numeric-array';
                    elseif isempty(Val)
                        Text = 'empty';
                    elseif ischar(Val)
                        Text = sprintf('%s', Val);
                    elseif isnumeric(Val)
                        if mod(Val, 1) < 10-6
                            Text = sprintf('%i', Val);
                        else
                            Text = sprintf('%.6f', Val);
                        end
                    elseif islogical(Val)
                        if Val
                            Text = 'true';
                        else
                            Text = 'false';
                        end
                    end
                    uitreenode(Parent, 'Text', sprintf('%s: %s', fnames{i}, Text));
                end
            end
        end
        % ---------------------------------------------------------
        % METHOD: Sets the Properties Tab-group
        function RenderPropertiesTabGroup(app, Slice, id)
            if app.State.Verbose
                t = now;
            end
            % Init
            UserData = struct();
            UserData.Slice = Slice;
            UserData.id = id;
            % Delete any dynamic genrated fields
            if isfield(app.FilePropertiesTab.UserData, 'Label')
                Keys = fieldnames(app.FilePropertiesTab.UserData.Label);
                cellfun(@(k) delete(app.FilePropertiesTab.UserData.Label.(k)), Keys);
                cellfun(@(k) delete(app.FilePropertiesTab.UserData.Input.(k)), Keys);
                app.FilePropertiesPanelGridLayout.RowHeight = {24, 24, 24, 24, 24, 24, 24, 24};
            end
            % Set the components
            switch Slice
                case 'Subjects'
                    % ---------------------------------------------------------
                    % Get the index of this subject
                    idx = matches(app.State.Subjects.Id, id);
                    % ---------------------------------------------------------
                    % Select the appropriate tab
                    UpdateProperty(app, app.PropertiesTabGroup, 'SelectedTab', app.SubjectPropertiesTab);
                    % ---------------------------------------------------------
                    % Set subjects tab components
                    UpdateProperty(app, app.SubjectPropertiesTab, 'UserData', UserData);
                    % Hide the 'select subject' label and show the subject properties
                    app.SubjectPropertiesGridLayout.RowHeight{1} = 0;
                    UpdateProperty(app, app.NoSubjectSelectedLabel, 'Visible', 'off');
                    UpdateProperty(app, app.SubjectPropertiesPanel, 'Visible', 'on');
                    UpdateProperty(app, app.SubjectPropertiesDeletePanel, 'Visible', 'on');
                    % Set the value of the edit field
                    UpdateProperty(app, app.SubjectPropertiesIdEditField, 'Value', strrep(app.State.Subjects.Name{idx}, 'sub-', ''));
                    % ---------------------------------------------------------
                    % Set Files tab components
                    UpdateProperty(app, app.FilePropertiesTab, 'UserData', struct());
                    % Show the 'select file' label and hide the file properties
                    app.FilePropertiesGridLayout.RowHeight{1} = 24;
                    UpdateProperty(app, app.NoFileSelectedLabel, 'Visible', 'on');
                    UpdateProperty(app, app.FilePropertiesPanel, 'Visible', 'off');
                    UpdateProperty(app, app.FilePropertiesEventsPanel, 'Visible', 'off');
                    UpdateProperty(app, app.FilePropertiesHistoryPanel, 'Visible', 'off');
                    UpdateProperty(app, app.FilePropertiesDeletePanel, 'Visible', 'off');
                case 'Files'
                    % ---------------------------------------------------------
                    % Get the index of this file
                    idx = matches(app.State.Files.Id, id);
                    % ---------------------------------------------------------
                    % Select the appropriate tab
                    UpdateProperty(app, app.PropertiesTabGroup, 'SelectedTab', app.FilePropertiesTab);
                    % ---------------------------------------------------------
                    % Set subjects tab components
                    UpdateProperty(app, app.SubjectPropertiesTab, 'UserData', struct());
                    % Show the 'select subject' label and hide the subject properties
                    app.SubjectPropertiesGridLayout.RowHeight{1} = 24;
                    UpdateProperty(app, app.NoSubjectSelectedLabel, 'Visible', 'on');
                    UpdateProperty(app, app.SubjectPropertiesPanel, 'Visible', 'off');
                    UpdateProperty(app, app.SubjectPropertiesDeletePanel, 'Visible', 'off');
                    % ---------------------------------------------------------
                    % Set Files tab components
                    UpdateProperty(app, app.FilePropertiesTab, 'UserData', UserData);
                    % Hide the 'select file' label and show the file properties
                    app.FilePropertiesGridLayout.RowHeight{1} = 0;
                    UpdateProperty(app, app.NoFileSelectedLabel, 'Visible', 'off');
                    if strcmpi(app.State.Files.Status{idx}, 'error')
                        app.FilePropertiesGridLayout.RowHeight{2} = 115;
                        UpdateProperty(app, app.FilePropertiesErrorPanel, 'Visible', 'on');
                        UpdateProperty(app, app.FilePropertiesErrorLabel, 'Text', app.State.Files.ErrorMessage{idx});
                    else
                        app.FilePropertiesGridLayout.RowHeight{2} = 0;
                        UpdateProperty(app, app.FilePropertiesErrorPanel, 'Visible', 'off');
                        UpdateProperty(app, app.FilePropertiesErrorLabel, 'Text', '');
                    end
                    UpdateProperty(app, app.FilePropertiesPanel, 'Visible', 'on');
                    UpdateProperty(app, app.FilePropertiesEventsPanel, 'Visible', 'on');
                    UpdateProperty(app, app.FilePropertiesHistoryPanel, 'Visible', 'on');
                    UpdateProperty(app, app.FilePropertiesDeletePanel, 'Visible', 'on');
                    % Some older version may not have included 'TrialCount'
                    % in the JSON file, repair here
                    if ~isfield(app.State.Files.JSON{idx}, 'TrialCount')
                        app.State.Files.JSON{idx}.TrialCount = 1;
                        [fpath, fname] = fileparts(app.State.Files.Path{idx});
                        JSONFilename = [fpath, '/', fname, '.json'];
                        struct2json(app.State.Files.JSON{idx}, JSONFilename);
                    end
                    % Set the data epochs and duration
                    switch app.State.Files.KeyVals{idx}.filetype
                        case {'eeg', 'epoch', 'continuous'}
                            UpdateProperty(app, app.FilePropertiesDataValue, 'Text', sprintf('%i x %s @ %i Hz', ...
                            app.State.Files.JSON{idx}.TrialCount, ...
                            duration2str(app.State.Files.JSON{idx}.RecordingDuration/(60*60*24)), ...
                            app.State.Files.JSON{idx}.SamplingFrequency));
                        case 'powerspect'
                            UpdateProperty(app, app.FilePropertiesDataValue, 'Text', sprintf('%i x 0 - %.1f Hz @ %.1f Hz', ...
                            app.State.Files.JSON{idx}.TrialCount, ...
                            app.State.Files.JSON{idx}.SpectralAnalysis.MaximumFrequency, ...
                            app.State.Files.JSON{idx}.SpectralAnalysis.FrequencyStep));
                    end
                    % Set the channels and duration values
                    UpdateProperty(app, app.FilePropertiesEEGChansValue, 'Text',  sprintf('%i EEG Channels', app.State.Files.JSON{idx}.EEGChannelCount));
                    % BACKWARD COMPATABILITY
                    % The BIDS specficiation does not allow for custom
                    % fields in the JSON file. So now the JSON file
                    % contains separate fields for ECG, EMG, EOG and Misc
                    % channels. However, in the earlier verion, I summed
                    % these together as the PNSChannelCount. 
                    if isfield(app.State.Files.JSON{idx}, 'PNSChannelCount')
                        UpdateProperty(app, app.FilePropertiesPNSChansValue, 'Text', sprintf('%i PNS Channels', app.State.Files.JSON{idx}.PNSChannelCount));
                    else
                        ChanCount = app.State.Files.JSON{idx}.ECGChannelCount + app.State.Files.JSON{idx}.EMGChannelCount + app.State.Files.JSON{idx}.EOGChannelCount + app.State.Files.JSON{idx}.MiscChannelCount;
                        UpdateProperty(app, app.FilePropertiesPNSChansValue, 'Text', sprintf('%i PNS Channels', ChanCount));
                    end
                    % Quality
                    try
                    badChans = sum(strcmpi(app.State.Files.channels{idx}.status, 'bad'));
                    catch ME
                        keyboard
                    end
                    pBadChans = badChans / app.State.Files.JSON{idx}.EEGChannelCount * 100;
                    if any(strcmpi(app.State.Files.channels{idx}.Properties.VariableNames, 'description'))
                        intpChans = sum(strcmpi(app.State.Files.channels{idx}.description, 'interpolated'));
                    else
                        intpChans = 0;
                    end
                    pIntpChans = intpChans / app.State.Files.JSON{idx}.EEGChannelCount * 100;
                    if any(strcmpi(app.State.Files.events{idx}.Properties.VariableNames, 'trial_type'))
                        rejTime = sum(app.State.Files.events{idx}.duration(strcmpi(app.State.Files.events{idx}.trial_type, 'reject')));
                    else
                        rejTime = 0;
                        pRejTime = 0;
                    end
                    if isfield(app.State.Files.JSON{idx}, 'RecordingDuration')
                        pRejTime = rejTime / (app.State.Files.JSON{idx}.RecordingDuration * app.State.Files.JSON{idx}.TrialCount) * 100;
                        UpdateProperty(app, app.FilePropertiesQualitiyValue, 'Text', sprintf('%i bad channels (%.1f%%)\n%i interp. channels (%.1f%%)\n%s bad epochs (%.1f%%)', ...
                            badChans, pBadChans, ...
                            intpChans, pIntpChans, ...
                            duration2str(rejTime/(60*60*24)), pRejTime ...
                            ));
                    else
                        UpdateProperty(app, app.FilePropertiesQualitiyValue, 'Text', sprintf('%i bad channels (%.1f%%)\n%i interp. channels (%.1f%%)', ...
                            badChans, badChans / app.State.Files.JSON{idx}.EEGChannelCount * 100, ...
                            intpChans, intpChans / app.State.Files.JSON{idx}.EEGChannelCount * 100 ...
                            ));
                    end
                    if matches(app.State.Files.Type{idx}, 'raw')
                        UpdateProperty(app, app.FilePropertiesQualitiyPanel, 'Enable', 'off');
                        UpdateProperty(app, app.FilePropertiesQualitiyPanel, 'BackgroundColor', [0.94, 0.94, 0.94]);
                        UpdateProperty(app, app.FilePropertiesQualitiyGridLayout, 'BackgroundColor', [0.94, 0.94, 0.94]);
                        UpdateProperty(app, app.FilePropertiesQualitiyValue, 'FontColor', [0.24, 0.24, 0.24]);
                    elseif pBadChans == 0 && pRejTime == 0 && pIntpChans < 10
                        UpdateProperty(app, app.FilePropertiesQualitiyPanel, 'Enable', 'on');
                        UpdateProperty(app, app.FilePropertiesQualitiyPanel, 'BackgroundColor', [0.82, 0.91, 0.87]);
                        UpdateProperty(app, app.FilePropertiesQualitiyGridLayout, 'BackgroundColor', [0.82, 0.91, 0.87]);
                        UpdateProperty(app, app.FilePropertiesQualitiyValue, 'FontColor', [0.06, 0.32, 0.20]);
                    elseif pBadChans < 10 && pRejTime < 10 && pIntpChans < 10
                        UpdateProperty(app, app.FilePropertiesQualitiyPanel, 'Enable', 'on');
                        UpdateProperty(app, app.FilePropertiesQualitiyPanel, 'BackgroundColor', [1.00, 0.95, 0.80]);
                        UpdateProperty(app, app.FilePropertiesQualitiyGridLayout, 'BackgroundColor', [1.00, 0.95, 0.80]);
                        UpdateProperty(app, app.FilePropertiesQualitiyValue, 'FontColor', [0.40, 0.30, 0.01]);
                    else
                        UpdateProperty(app, app.FilePropertiesQualitiyPanel, 'Enable', 'on');
                        UpdateProperty(app, app.FilePropertiesQualitiyPanel, 'BackgroundColor', [0.95, 0.93, 0.94]);
                        UpdateProperty(app, app.FilePropertiesQualitiyGridLayout, 'BackgroundColor', [0.95, 0.93, 0.94]);
                        UpdateProperty(app, app.FilePropertiesQualitiyValue, 'FontColor', [0.64, 0.08, 0.18]);
                    end
                    % Set the sessions and tasks items
                    UpdateProperty(app, app.FilePropertiesSessionDropdown, 'Items', app.DatasetSessionsListBox.Items);
                    if isfield(app.State.Files.KeyVals{idx}, 'ses')
                        UpdateProperty(app, app.FilePropertiesSessionDropdown, 'Value', app.State.Files.KeyVals{idx}.ses);
                        UpdateProperty(app, app.FilePropertiesSessionDropdown, 'Enable', 'on');
                    else
                        UpdateProperty(app, app.FilePropertiesSessionDropdown, 'Enable', 'off');
                    end
                    UpdateProperty(app, app.FilePropertiesTaskDropdown, 'Items', app.DatasetTasksListBox.Items);
                    UpdateProperty(app, app.FilePropertiesTaskDropdown, 'Value', app.State.Files.KeyVals{idx}.task);
                    % Set the values of the edit fields
                    Keys = fieldnames(app.State.Files.KeyVals{idx});
                    for i = 1:length(Keys)
                        if strcmpi(Keys{i}, 'sub') || strcmpi(Keys{i}, 'ses') || strcmpi(Keys{i}, 'task') || strcmpi(Keys{i}, 'filetype')
                            continue
                        end
                        % Create an edit field for any key-value pairs in the filename
                        app.FilePropertiesPanelGridLayout.RowHeight = [app.FilePropertiesPanelGridLayout.RowHeight, {24}];
                        app.FilePropertiesTab.UserData.Label.(Keys{i}) = uilabel(app.FilePropertiesPanelGridLayout, 'Text', Keys{i}, 'HorizontalAlignment', 'right', 'FontSize', 10, 'FontWeight', 'bold');
                        app.FilePropertiesTab.UserData.Label.(Keys{i}).Layout.Column = [1, 2];
                        app.FilePropertiesTab.UserData.Label.(Keys{i}).Layout.Row = length(app.FilePropertiesPanelGridLayout.RowHeight);
                        app.FilePropertiesTab.UserData.Input.(Keys{i}) = uieditfield(app.FilePropertiesPanelGridLayout, 'Tag', Keys{i}, 'Value', app.State.Files.KeyVals{idx}.(Keys{i}), 'FontSize', 10);
                        app.FilePropertiesTab.UserData.Input.(Keys{i}).Layout.Column = [3, 5];
                        app.FilePropertiesTab.UserData.Input.(Keys{i}).Layout.Row = length(app.FilePropertiesPanelGridLayout.RowHeight);
                        app.FilePropertiesTab.UserData.Input.(Keys{i}).ValueChangingFcn = createCallbackFcn(app, @FilePropertiesValueChanged, true);
                    end
                    app.FilePropertiesGridLayout.RowHeight{3} = 8 + sum(cellfun(@(h) h + 6, app.FilePropertiesPanelGridLayout.RowHeight));
                    % Set the history
                    History = app.State.Files.history{idx};
                    delete(app.FilePropertiesHistoryTree.Children);
                    for i = 1:length(History)
                        if isstruct(History(i).Auth)
                            History(i).Auth=History(i).Auth.name;
                        end
                        node = uitreenode(app.FilePropertiesHistoryTree, 'Text', [History(i).Fcn, ' (', History(i).Auth, ', ', History(i).Date, ')']);
                        app.RenderHistoryNodes(node, History(i).Settings);
                    end
                    % Set the event lists
                    if ~isempty(app.State.Files.events{idx})
                        EventGroups = unique(app.State.Files.events{idx}.trial_type);
                        UpdateProperty(app, app.FilePropertiesEventGroupListBox, 'Items', EventGroups);
                        app.FilePropertiesEventGroupListBox.UserData.id = id;
                        SelectedEvent = app.FilePropertiesEventGroupListBox.Value;
                        if ~isempty(SelectedEvent)
                            EventInstances = table2cell(app.State.Files.events{idx}(matches(app.State.Files.events{idx}.trial_type, SelectedEvent), :));
                            EventInstances = cellfun(@(onset, duration) [datestr(onset/(24*60*60), 'HH:MM:SS'), ' - ', datestr((onset+duration)/(24*60*60), 'HH:MM:SS')], EventInstances(:, 1), EventInstances(:, 2), 'UniformOutput', false); %#ok<*DATST>
                            UpdateProperty(app, app.FilePropertiesEventInstanceListBox, 'Items', EventInstances);
                        end
                    else
                        UpdateProperty(app, app.FilePropertiesEventGroupListBox, 'Items', {});
                        UpdateProperty(app, app.FilePropertiesEventInstanceListBox, 'Items', {});
                    end
                otherwise
                    % Select the appropriate tab
                    UpdateProperty(app, app.PropertiesTabGroup, 'SelectedTab', app.DatasetPropertiesTab);
                    % Show the 'select subject' label and hide the subject properties
                    app.SubjectPropertiesGridLayout.RowHeight{1} = 24;
                    UpdateProperty(app, app.SubjectPropertiesTab, 'UserData', struct());
                    UpdateProperty(app, app.NoSubjectSelectedLabel, 'Visible', 'on');
                    UpdateProperty(app, app.SubjectPropertiesPanel, 'Visible', 'off');
                    UpdateProperty(app, app.SubjectPropertiesDeletePanel, 'Visible', 'off');
                    % Show the 'select file' label and hide the file properties
                    app.FilePropertiesGridLayout.RowHeight{1} = 24;
                    UpdateProperty(app, app.FilePropertiesTab, 'UserData', struct());
                    UpdateProperty(app, app.NoFileSelectedLabel, 'Visible', 'on');
                    UpdateProperty(app, app.FilePropertiesPanel, 'Visible', 'off');
                    UpdateProperty(app, app.FilePropertiesEventsPanel, 'Visible', 'off');
                    UpdateProperty(app, app.FilePropertiesHistoryPanel, 'Visible', 'off');
                    UpdateProperty(app, app.FilePropertiesDeletePanel, 'Visible', 'off');
            end
            if app.State.Verbose
                fprintf('>> BIDS: RenderPropertiesTabGroup took %s.\n', duration2str(now-t));
            end
        end
        % ---------------------------------------------------------
        function RenderDatasetSessionsListBox(app)
            if app.State.Verbose
                t = now;
            end
            % Select all files so we can extract the session list
            UpdateProperty(app, app.DatasetSessionsEditButton, 'Enable', 'off');
            UpdateProperty(app, app.DatasetSessionsListBox, 'Value', {});
            Sessions = {};
            for i = 1:length(app.State.Files.KeyVals)
                if isfield(app.State.Files.KeyVals{i}, 'ses')
                    Sessions = [Sessions; {app.State.Files.KeyVals{i}.ses}]; %#ok<AGROW>
                end
            end
            if isempty(Sessions)
                Sessions = {'1'};
            else
                Sessions = unique(Sessions);
            end
            UpdateProperty(app, app.DatasetSessionsListBox, 'Items', Sessions);
            if app.State.Verbose
                fprintf('>> BIDS: RenderDatasetSessionsListBox took %s.\n', duration2str(now-t));
            end
        end
        % ---------------------------------------------------------
        function RenderDatasetTasksListBox(app)
            if app.State.Verbose
                t = now;
            end
            % Select all files so we can extract the task list
            UpdateProperty(app, app.DatasetTasksEditButton, 'Enable', 'off');
            UpdateProperty(app, app.DatasetTasksListBox, 'Value', {});
            Tasks = {};
            for i = 1:length(app.State.Files.KeyVals)
                if isfield(app.State.Files.KeyVals{i}, 'task')
                    Tasks = [Tasks; {app.State.Files.KeyVals{i}.task}]; %#ok<AGROW>
                end
            end
            if isempty(Tasks)
                Tasks = {'default'};
            else
                Tasks = unique(Tasks);
            end
            UpdateProperty(app, app.DatasetTasksListBox, 'Items', Tasks);
            if app.State.Verbose
                fprintf('>> BIDS: RenderDatasetTasksListBox took %s.\n', duration2str(now-t));
            end
        end
        % ---------------------------------------------------------
        function RenderSubjectPropertiesIdEditField(app)
            if app.State.Verbose
                t = now;
            end
            % Check that there are no files currently in error with that subject name
            SubId = app.SubjectPropertiesIdEditField.Value;
            if any(contains(app.State.Files.Path, SubId) & matches(app.State.Files.Status, 'error'))
                Enable = 'off';
            else
                Enable = 'on';
            end
            UpdateProperty(app, app.SubjectPropertiesIdEditField, 'Enable', Enable);
            if app.State.Verbose
                fprintf('>> BIDS: RenderSubjectPropertiesIdEditField took %s.\n', duration2str(now-t));
            end
        end
        % ---------------------------------------------------------
        function RenderSubjectPropertiesSaveButton(app)
            if app.State.Verbose
                t = now;
            end
            if ~isfield(app.SubjectPropertiesTab.UserData, 'id')
                Enable = 'off';
            else
                id = app.SubjectPropertiesTab.UserData.id;
                idxSub = matches(app.State.Subjects.Id, id);
                if ~any(idxSub)
                    Enable = 'off';
                else
                    Status = app.State.Subjects.Status{idxSub};
                    % Check if any of its associated files have an empty KeyVal
                    FileIds = app.State.Subjects.FileIds{idxSub};
                    FilesHaveEmptyVals = cellfun(@(fid) any(cellfun(@(v) isempty(v), struct2cell(app.State.Files.KeyVals{matches(app.State.Files.Id, fid)}))), FileIds);
                    if strcmpi(app.State.Subjects.Name{idxSub}, 'sub-') || any(FilesHaveEmptyVals)
                        Enable = 'off';
                    elseif strcmpi(Status, 'changed')
                        Enable = 'on';
                    else
                        Enable = 'off';
                    end
                end
            end
            UpdateProperty(app, app.SubjectPropertiesSaveButton, 'Enable', Enable);
            if app.State.Verbose
                fprintf('>> BIDS: RenderSubjectPropertiesSaveButton took %s.\n', duration2str(now-t));
            end
        end
        % ---------------------------------------------------------
        function RenderSubjectPropertiesUndoButton(app)
            if app.State.Verbose
                t = now;
            end
            if ~isfield(app.SubjectPropertiesTab.UserData, 'id')
                Enable = 'off';
            else
                id = app.SubjectPropertiesTab.UserData.id;
                idxSub = matches(app.State.Subjects.Id, id);
                if ~any(idxSub)
                    Enable = 'off';
                else
                    Status = app.State.Subjects.Status{idxSub};
                    Enable = ifelse(strcmpi(Status, 'changed'), 'on', 'off');
                end
            end
            UpdateProperty(app, app.SubjectPropertiesUndoButton, 'Enable', Enable);
            if app.State.Verbose
                fprintf('>> BIDS: RenderSubjectPropertiesUndoButton took %s.\n', duration2str(now-t));
            end
        end
        % ---------------------------------------------------------
        function RenderFilePropertiesSaveButton(app)
            if app.State.Verbose
                t = now;
            end
            if ~isfield(app.FilePropertiesTab.UserData, 'id')
                Enable = 'off';
            else
                id = app.FilePropertiesTab.UserData.id;
                idx = matches(app.State.Files.Id, id);
                if ~any(idx)
                    Enable = 'off';
                else
                    Status = app.State.Files.Status{idx};
                    if any(cellfun(@(v) isempty(v), struct2cell(app.State.Files.KeyVals{idx})))
                        Enable = 'off';
                    elseif strcmpi(Status, 'changed')
                        Enable = 'on';
                    else
                        Enable = 'off';
                    end
                end
            end
            UpdateProperty(app, app.FilePropertiesSaveButton, 'Enable', Enable);
            if app.State.Verbose
                fprintf('>> BIDS: RenderFilePropertiesSaveButton took %s.\n', duration2str(now-t));
            end
        end
        % ---------------------------------------------------------
        function RenderFilePropertiesUndoButton(app)
            if app.State.Verbose
                t = now;
            end
            if ~isfield(app.FilePropertiesTab.UserData, 'id')
                Enable = 'off';
            else
                id = app.FilePropertiesTab.UserData.id;
                idx = matches(app.State.Files.Id, id);
                if ~any(idx)
                    Enable = 'off';
                else
                    Status = app.State.Files.Status{idx};
                    Enable = ifelse(strcmpi(Status, 'changed'), 'on', 'off');
                end
            end
            UpdateProperty(app, app.FilePropertiesUndoButton, 'Enable', Enable);
            if app.State.Verbose
                fprintf('>> BIDS: RenderFilePropertiesUndoButton took %s.\n', duration2str(now-t));
            end
        end
        % ---------------------------------------------------------
        function RenderFilePropertiesInspectButton(app)
            if app.State.Verbose
                t = now;
            end
            if ~isfield(app.FilePropertiesTab.UserData, 'id')
                Enable = 'off';
            else
                id = app.FilePropertiesTab.UserData.id;
                idx = matches(app.State.Files.Id, id);
                if ~any(idx)
                    Enable = 'off';
                else
                    Status = app.State.Files.Status{idx};
                    Enable = ifelse(strcmpi(Status, 'idle'), 'on', 'off');
                end
            end
            UpdateProperty(app, app.FilePropertiesInspectButton, 'Enable', Enable);
            if app.State.Verbose
                fprintf('>> BIDS: RenderFilePropertiesInspectButton took %s.\n', duration2str(now-t));
            end
        end
        % ---------------------------------------------------------
        function RenderFilePropertiesImportEventsButton(app)
            if app.State.Verbose
                t = now;
            end
            if ~isfield(app.FilePropertiesTab.UserData, 'id')
                Enable = 'off';
            else
                id = app.FilePropertiesTab.UserData.id;
                idx = matches(app.State.Files.Id, id);
                if ~any(idx)
                    Enable = 'off';
                else
                    Status = app.State.Files.Status{idx};
                    Enable = ifelse(strcmpi(Status, 'idle'), 'on', 'off');
                end
                if strcmpi(app.State.Files.Type{idx}, 'fstlvl')
                    Enable = 'off';
                end
            end
            UpdateProperty(app, app.FilePropertiesEventImportButton, 'Enable', Enable);
            if app.State.Verbose
                fprintf('>> BIDS: RenderFilePropertiesImportEventsButton took %s.\n', duration2str(now-t));
            end
        end
        % ---------------------------------------------------------
        function RenderFilePropertiesEventGroupListBox(app)
            if app.State.Verbose
                t = now;
            end
            % Get ID and index
            id = app.FilePropertiesEventGroupListBox.UserData.id;
            idx = matches(app.State.Files.Id, id);
            % Update the items in the list box
            EventGroups = unique(app.State.Files.events{idx}.trial_type);
            UpdateProperty(app, app.FilePropertiesEventGroupListBox, 'Items', EventGroups);
            % Update the event-instances of the selected event group
            SelectedEvent = app.FilePropertiesEventGroupListBox.Value;
            if ~isempty(SelectedEvent)
                EventInstances = table2cell(app.State.Files.events{idx}(strcmpi(app.State.Files.events{idx}.trial_type, SelectedEvent), :));
                EventInstances = cellfun(@(onset, duration) [datestr(onset/(24*60*60), 'HH:MM:SS'), ' - ', datestr((onset+duration)/(24*60*60), 'HH:MM:SS')], EventInstances(:, 1), EventInstances(:, 2), 'UniformOutput', false);
                UpdateProperty(app, app.FilePropertiesEventInstanceListBox, 'Items', EventInstances);
            end
            if app.State.Verbose
                fprintf('>> BIDS: RenderFilePropertiesEventGroupListBox took %s.\n', duration2str(now-t));
            end
        end
        % ---------------------------------------------------------
        function RenderFileAddProcessPanel(app)
            if app.State.Verbose
                t = now;
            end
            switch app.FilesTabGroup.SelectedTab.Title
                case 'Raw'
                    CheckedNodes = app.FilesRawTree.CheckedNodes;
                case 'Derivatives'
                    CheckedNodes = app.FilesDerivativesTree.CheckedNodes;
                case 'First level'
                    CheckedNodes = app.FilesFirstlvlOutputTree.CheckedNodes;
                otherwise
                    return
            end
            if isempty(CheckedNodes)
                UpdateProperty(app, app.FileAddProcessPanel, 'Title', 'Select files...');
                UpdateProperty(app, app.FileAddProcessPanel, 'Enable', 'off');
            else
                % Check if any file's status is not idle
                FileList = SelectFileById(app, cellfun(@(d) d.id, {CheckedNodes.UserData}, 'UniformOutput', false));
                anyFileIsNotIdle = any(~matches(FileList.Status, 'idle')) | isempty(FileList);
                UpdateProperty(app, app.FileAddProcessPanel, 'Title', sprintf('Apply process to %i files', size(FileList, 1)));
                UpdateProperty(app, app.FileAddProcessPanel, 'Enable', ifelse(anyFileIsNotIdle, 'off', 'on'));
            end
            if app.State.Verbose
                fprintf('>> BIDS: RenderFileAddProcessPanel took %s.\n', duration2str(now-t));
            end
        end
        % ---------------------------------------------------------
        function RenderAddProcessInput(app)
            if app.State.Verbose
                t = now;
            end
            switch app.FilesTabGroup.SelectedTab.Title
                case 'Raw'
                    Items = app.DefaultProcesses;
                case 'Derivatives'
                    Items = app.DefaultProcesses;
                case 'First level'
                    Items = app.FstLvlProcesses;
                otherwise
                    return
            end
            UpdateProperty(app, app.FileAddProcessInput, 'Items', Items);
            if app.State.Verbose
                fprintf('>> BIDS: RenderAddProcessInput took %s.\n', duration2str(now-t));
            end
        end
        % ---------------------------------------------------------
        function RenderAddProcessButton(app)
            if app.State.Verbose
                t = now;
            end
            if strcmpi(app.FileAddProcessInput.Value, 'Select...')
                Enable = 'off';
            elseif strcmpi(app.FileAddProcessInput.Value(1), '=')
                Enable = 'off';
            else
                Enable = 'on';
            end
            UpdateProperty(app, app.FileAddProcessButton, 'Enable', Enable);
            if app.State.Verbose
                fprintf('>> BIDS: RenderAddProcessButton took %s.\n', duration2str(now-t));
            end
        end
        % ---------------------------------------------------------
        function RenderFilesRawSubjectContextImportEEGMenu(app, SubName)
            if app.State.Verbose
                t = now;
            end
            % Check if any file's status is not idle
            Subject = SelectSubjectByName(app, SubName);
            if isempty(Subject) % The name has "(unsaved)" in it, so it won't be found
                Enable = 'off';
            else
                Enable = 'on';
            end
            UpdateProperty(app, app.FilesRawSubjectContextImportEEGMenu, 'Enable', Enable);
            if app.State.Verbose
                fprintf('>> BIDS: RenderFilesRawSubjectContextImportEEGMenu took %s.\n', duration2str(now-t));
            end
        end
        % ---------------------------------------------------------
        function RenderProcessesPanel(app)
            if app.State.Verbose
                t = now;
            end
            % Extract some vars for shorthand
            Value = ifelse(isempty(app.State.Processes), '', app.ProcessesListBox.Value);
            if ~isempty(Value)
                isFirstItem = find(strcmpi({app.State.Processes.Name}, Value)) == 1 | length({app.State.Processes.Name}) == 1;
                isLastItem = find(strcmpi({app.State.Processes.Name}, Value)) == length({app.State.Processes.Name}) | length({app.State.Processes.Name}) == 1;
            else
                isFirstItem = true;
                isLastItem = true;
            end
            UpdateProperty(app, app.ProcessesListBox, 'Items', {app.State.Processes.Name});
            UpdateProperty(app, app.ProcessesRunButton, 'Enable', ifelse(isempty(app.State.Processes), 'off', 'on'));
            UpdateProperty(app, app.ProcessesDeleteButton, 'Enable', ifelse(isempty(Value), 'off', 'on'));
            UpdateProperty(app, app.ProcessesMoveDownButton, 'Enable', ifelse(isLastItem, 'off', 'on'));
            UpdateProperty(app, app.ProcessesMoveUpButton, 'Enable', ifelse(isFirstItem, 'off', 'on'));
            if app.State.Verbose
                fprintf('>> BIDS: RenderProcessesPanel took %s.\n', duration2str(now-t));
            end
        end
        % ---------------------------------------------------------
        % END OF APP METHODS
        % =========================================================
        % APP FUNCTIONS: SETS THE APP STATE
        % ---------------------------------------------------------
        % FUNCTION: Sets loading state
        function SetLoading(app, isLoading, Source)
            app.State.isLoading = isLoading;
            if isLoading
                app.LoadingImage.Visible = 'on';
                app.UIFigure.Pointer = 'watch';
                app.MainPanel.Enable = 'off';
                if ~isempty(Source)
                    Source.Enable = 'off';
                end
                drawnow();
            else
                drawnow();
                app.LoadingImage.Visible = 'off';
                app.UIFigure.Pointer = 'arrow';
                app.MainPanel.Enable = 'on';
                if ~isempty(Source)
                    Source.Enable = 'on';
                end
                fprintf('>> BIDS: done.\n')
            end
        end
        % ---------------------------------------------------------
        % FUNCTION: Initializes a new Empty State
        function InitializeState(app, Path)
            if isfield(app.State, 'Auth')
                app.State = DefaultState(Path, app.State.Auth);
            else
                app.State = DefaultState(Path);
            end
            % ---------------------------------------------------------
            % Navigate to the path
            cd(app.State.Protocol.Path);
            % ---------------------------------------------------------
            % Check permissions
            [app, Permissions] = checkWritePermissions(app, app.State.Protocol.Path);
            if ~Permissions
                return
            end
            % ---------------------------------------------------------
            % Make BIDS directories of they do not exist already
            hasErrors = CreateBIDSDirectories(app);
            if hasErrors
                return
            end
            % ---------------------------------------------------------
            % Save the JSON Files
            struct2json(app.State.Protocol.JSON, [app.State.Protocol.Path, '/rawdata/dataset_description.json']);
        end
        % ---------------------------------------------------------
        % FUNCTION: Loads a Dataset
        function DatasetLoaded = LoadDataset(app)
            % ---------------------------------------------------------
            % Ask the user to select a folder
            [app, DatasetLoaded] = SelectAndLoadDataset(app);
            if ~DatasetLoaded
                return
            end
            % ---------------------------------------------------------
            % Navigate to the path
            cd(app.State.Protocol.Path);
            % ---------------------------------------------------------
            % Check permissions
            [app, Permissions] = checkWritePermissions(app, app.State.Protocol.Path);
            if ~Permissions
                return
            end
            % ---------------------------------------------------------
            % Make BIDS directories of they do not exist already
            hasErrors = CreateBIDSDirectories(app);
            if hasErrors
                return
            end
            % ---------------------------------------------------------
            % Initialize empty state
            NewState = DefaultState(app.State.Protocol.Path, app.State.Auth);
            app.State.Subjects = NewState.Subjects;
            app.State.Files = NewState.Files;
            app.State.Processes = NewState.Processes;
            app.State.Results = NewState.Results;
            % ---------------------------------------------------------
            % Load subjects
            app.State.Subjects = GetSubjects(app);
            % ---------------------------------------------------------
            % Load files
            app.State.Files = GetFiles(app);
        end
        % ---------------------------------------------------------
        % FUNCTION: Sets the Dataset description
        function SetDatasetDescription(app, Key, Value)
            switch Key
                case 'GeneratedByName'
                    app.State.Protocol.JSON.GeneratedBy.Name = Value;
                case 'GeneratedByDesc'
                    app.State.Protocol.JSON.GeneratedBy.Description = strjoin(Value, ' ');
                case 'Acknowledgements'
                    app.State.Protocol.JSON.Acknowledgements = strjoin(Value, ' ');
                case 'HowToAcknowledge'
                    app.State.Protocol.JSON.HowToAcknowledge = strjoin(Value, ' ');
                otherwise
                    app.State.Protocol.JSON.(Key) = Value;
            end
        end
        % ---------------------------------------------------------
        % FUNCTION: Edit a current session or task name and rename files on disk
        function SetSessionOrTaskName(app, Key, currentValue, newValue)
            % Get all files that are assigned this session name
            [FileList, ids, idx] = SelectFilesByKeyValue(app, Key, currentValue);
            if size(FileList, 1) == 0
                return
            end
            % Ask the user to confirm
            sel = uiconfirm(app.UIFigure, sprintf('On the brink of changing %i files.\nAre you sure to continue?', length(ids)), 'Question', ...
                'Options',{'Yes, go on', 'No, cancel'},...
                'DefaultOption', 'Yes, go on', ...
                'Icon', 'question');
            if strcmpi(sel, 'No, cancel')
                return
            end
            % Rename these files
            for i = 1:size(FileList, 1)
                % Render waitbar to indicate how far we are
                app.RenderWaitbar(sprintf('Renaming files %i of %i.', i, size(FileList, 1)), i/size(FileList, 1));
                % Set the file's local state: the new key value pair
                NewKeyVals = FileList.KeyVals{i};
                NewKeyVals.(Key) = newValue;
                % This function will rename the file and update the state
                app.State.Files(idx(i), :) = RenameFile(app, FileList(i, :), NewKeyVals);
                if matches(app.State.Files.Status{idx(i)}, 'error')
                    % If there is any error, stop what you're doing
                    return
                end
                % If the task-name was changed, then also update the JSON
                if strcmpi(Key, 'task')
                    fprintf('>> BIDS: Updating task\n')
                    app.State.Files.JSON{idx(i)}.TaskName = newValue;
                    [jsonPath, jsonFilename] = fileparts(app.State.Files.Path{idx(i)});
                    fprintf('>> BIDS: saving JSON to %s\n', [jsonPath, '/', jsonFilename, '.json'])
                    struct2json(app.State.Files.JSON{idx(i)}, [jsonPath, '/', jsonFilename, '.json']);
                end
            end
        end
        % ---------------------------------------------------------
        % FUNCTION: Add subject
        function [id, alreadyExists] = AddSubject(app, newValue)
            % Init
            alreadyExists = false;
            % Make sure the value is an alphanumeric string
            newValue = ['sub-', lower(regexprep(newValue, '[^a-zA-Z0-9]', ''))];
            id = ['X', datestr(now, 'HHMMSSFFF'), num2str(randperm(1, 1))];
            % Check there is not a subject with that name already
            SubjectList = SelectSubjectByName(app, newValue);
            if ~isempty(SubjectList)
                sel = uiconfirm(app.UIFigure, sprintf('Did not add new subject. A subject with the id ''%s'' already exists.', newValue), 'Warning', ...
                    'Options',{'OK'},...
                    'DefaultOption', 'OK', ...
                    'Icon', 'warning'); 
                alreadyExists = true;
                return
            end
            % Add subject to the slice
            app.State.Subjects.Id{end+1} = id;
            app.State.Subjects.Name{end} = newValue;
            app.State.Subjects.FileIds{end} = {};
            app.State.Subjects.Status{end} = 'idle';
            app.State.Subjects.PrevState{end} = [];
        end
        % ---------------------------------------------------------
        % FUNCTION: Save new subject properties on disk
        function SaveSubject(app, id)
            % Extract vars for shorthand
            idxSub = matches(app.State.Subjects.Id, id);
            Name = app.State.Subjects.Name{idxSub};
            % Select all subjects with the same name
            [~, ids] = SelectSubjectByName(app, Name);
            % We could expect someone clicks save without actually changing the
            % subject id, so remove this subject's id from the list
            ids(strcmpi(ids, id)) = [];
            % Check if there are any other subjects with the same name
            if ~isempty(ids)
                sel = uiconfirm(app.UIFigure, sprintf('Did not save the subject. A subject with the id ''%s'' already exists.', Name), 'Warning', ...
                    'Options',{'OK'},...
                    'DefaultOption', 'OK', ...
                    'Icon', 'warning'); 
                % TODO: Check whether we need to replace the state with PrevState
                return
            end
            [FileList, ~, idxFiles] = SelectFilesByKeyValue(app, 'sub', strrep(Name, 'sub-', ''));
            % These are all the paths that need to change
            CurrentPaths = unique(fileparts(FileList.Path));
            NewPaths = CurrentPaths;
            % Change the subject directories in these paths
            for i = 1:length(CurrentPaths)
                [NewPaths{i}, hasError] = RenameDir(app, CurrentPaths{i}, Name);
                if hasError
                    return
                end
            end
            % Ok, all paths have changed, now change all files
            for i = 1:size(FileList, 1)
                % Render waitbar to indicate how far we are
                app.RenderWaitbar(sprintf('Renaming files %i of %i.', i, size(FileList, 1)), i/size(FileList, 1));
                % Update the path of the file
                [Path, Filename, Extension] = fileparts(FileList.Path{i});
                FileList.Path{i} = [NewPaths{strcmpi(CurrentPaths, Path)}, '/', Filename, Extension];
                % Update the filename
                FileList(i, :) = RenameFile(app, FileList(i, :), FileList.KeyVals{i});
                if strcmpi(FileList.Status{i}, 'error')
                    % If there is any error, stop what you're doing
                    return
                end
            end
            % Transfer the FileList back to the main state
            for i = 1:length(idxFiles)
                app.State.Files(idxFiles(i), :) = FileList(i, :);
                app.State.Files.Status{idxFiles(i)} = 'idle';
                app.State.Files.PrevState(idxFiles(i)) = {[]};
            end
            % Finally set the subject's status
            app.State.Subjects.Status{idxSub} = 'idle';
            app.State.Subjects.PrevState(idxSub) = {[]};
        end
        % ---------------------------------------------------------
        % FUNCTION: Deletes a subject and all associated files
        function hasError = DeleteSubject(app, id)
            % Init
            hasError = false;
            idx = matches(app.State.Subjects.Id, id);
            if ~any(idx)
                error('Could not remove subject with id ''%s'' from state.', id)
            end
            % Extract vars for shorthand
            Name = app.State.Subjects.Name{idx};
            FileIds = app.State.Subjects.FileIds{idx};
            % Find any 'raw' directory with the subject id in it
            RawPaths = dir(fullfile(app.State.Protocol.Path, 'rawdata', Name));
            RawPaths = unique({RawPaths.folder});
            % Find any 'derivatives' directory with the subject name in it
            DerPaths = dir(fullfile(app.State.Protocol.Path, 'derivatives', 'EEG-*', Name));
            DerPaths = unique({DerPaths.folder});
            % Delete all these paths
            res = cellfun(@(p) DeleteDir(app, p), [RawPaths, DerPaths]);
            if any(res)
                hasError = true;
                return
            end
            % Remove all these files from the state
            if ~isempty(FileIds)
                cellfun(@(fid) app.RemoveFileById(id, fid), FileIds);
            end
            % Remove the subject id from the Subjects slice
            app.State.Subjects(idx, :) = [];
        end
        % ---------------------------------------------------------
        % FUNCTION: Sets the subject name
        function SetSubjectNameById(app, id, newName)
            idx = matches(app.State.Subjects.Id, id);
            newName = lower(regexprep(newName, '[^a-zA-Z0-9]', ''));
            if isempty(app.State.Subjects.PrevState{idx})
                app.State.Subjects.PrevState{idx} = app.State.Subjects(idx, :);
            end
            app.State.Subjects.Name{idx} = ['sub-', newName];
            app.State.Subjects.Status{idx} = ifelse(strcmpi(app.State.Subjects.PrevState{idx}.Name{1}, app.State.Subjects.Name{idx}), 'idle', 'changed');
        end
        % ---------------------------------------------------------
        % FUNCTION: Reverts the subject's state to the previous state
        function ResetSubjectById(app, id)
            idx = matches(app.State.Subjects.Id, id);
            if ~isempty(app.State.Subjects.PrevState{idx})
                app.State.Subjects(idx, :) = app.State.Subjects.PrevState{idx};
            end
        end
        % ---------------------------------------------------------
        % FUNCTION: Saves the changes to a file to disk and updates the state
        function SaveFile(app, FileId)
            % Update the filename
            idx = matches(app.State.Files.Id, FileId);
            app.State.Files(idx, :) = RenameFile(app, app.State.Files(idx, :), app.State.Files.KeyVals{idx});
            if strcmpi(app.State.Files.Status{idx}, 'error')
                % If there is any error, stop what you're doing
                return
            end
            app.State.Files.Status{idx} = 'idle';
            app.State.Files.PrevState(idx) = {[]};
        end
        % ---------------------------------------------------------
        % FUNCTION: Sets the file status
        function SetFileKeyValueById(app, id, Key, newValue)
            idx = matches(app.State.Files.Id, id);
            newValue = lower(regexprep(newValue, '[^a-zA-Z0-9]', ''));
            if isempty(app.State.Files.PrevState{idx})
                app.State.Files.PrevState{idx} = app.State.Files(idx, :);
            end
            app.State.Files.KeyVals{idx}.(Key) = newValue;
            if strcmpi(Key, 'task')
                app.State.Files.JSON{idx}.TaskName = newValue;
            end
            isEqual = cellfun(@(a, b) strcmpi(a, b), struct2cell(app.State.Files.PrevState{idx}.KeyVals{1}), struct2cell(app.State.Files.KeyVals{idx}));
            app.State.Files.Status{idx} = ifelse(isEqual, 'idle', 'changed');
        end
        % ---------------------------------------------------------
        % FUNCTION: Reverts the subject's state to the previous state
        function ResetFileById(app, id)
            idx = matches(app.State.Files.Id, id);
            if ~isempty(app.State.Files.PrevState{idx})
                app.State.Files(idx, :) = app.State.Files.PrevState{idx};
            end
        end
        % ---------------------------------------------------------
        % FUNCTION: Removes a file and its references from the state
        function RemoveFileById(app, SubId, FileId)
            % ---------------------------------------------------------
            % Remove the file id reference from the subject
            idxSub = matches(app.State.Subjects.Id, SubId);
            idxFile = strcmpi(app.State.Subjects.FileIds{idxSub}, FileId);
            if ~any(idxFile)
                error('Could not remove file with id ''%s'' from subject with id ''%s''.', FileId, SubId)
            end
            app.State.Subjects.FileIds{idxSub}(idxFile) = [];
            % Remove the file id from the Files slice
            idxFile = matches(app.State.Files.Id, FileId);
            if ~any(idxFile)
                error('Could not remove file with id ''%s'' from state.', FileId)
            end
            app.State.Files(idxFile, :) = [];
        end
        % ---------------------------------------------------------
        % FUNCTION: Generates a process object to select and process a file
        function Process = AddProcessPreprocData(app, File, Settings)
            % ---------------------------------------------------------
            % Check that only one file was provided
            if size(File, 1) ~= 1
                error('Input must be one file only')
            end
            % Generate a new filename, first get the current key-vals
            CurrentKeyVals = File.KeyVals{1};
            % ... and get the new key vals
            NewKeyVals = filename2struct(Settings.RecordingName.Setname);
            % ... overwrite any part of key-vals indicated by '<KEY>'
            fnames = fieldnames(NewKeyVals);
            for j = 1:length(fnames)
                if ~isempty(regexp(NewKeyVals.(fnames{j}), '<', 'once'))
                    % What key should we insert?
                    startIdx = regexp(NewKeyVals.(fnames{j}), '<');
                    endIdx = regexp(NewKeyVals.(fnames{j}), '>');
                    Key = NewKeyVals.(fnames{j})(startIdx+1:endIdx-1);
                    if isfield(CurrentKeyVals, Key)
                        Value = CurrentKeyVals.(Key);
                        NewKeyVals.(fnames{j}) = strrep(NewKeyVals.(fnames{j}), NewKeyVals.(fnames{j})(startIdx:endIdx), Value);
                    else
                        switch Key
                            case {'evtlbl'}
                                % ok
                            otherwise
                                NewKeyVals.(fnames{j}) = strrep(NewKeyVals.(fnames{j}), NewKeyVals.(fnames{j})(startIdx:endIdx), '');
                        end
                    end
                end
            end
            % Remove session or run if not in original filename
            if ~isfield(CurrentKeyVals, 'ses') && isfield(NewKeyVals, 'ses')
                NewKeyVals = rmfield(NewKeyVals, 'ses');
            end
            if ~isfield(CurrentKeyVals, 'run') && isfield(NewKeyVals, 'run')
                NewKeyVals = rmfield(NewKeyVals, 'run');
            end
            % Generate the new set-name for this file
            Settings.Setname = struct2filename(NewKeyVals);
            % Store the protocol path in the settings
            Settings.ProtocolPath = app.State.Protocol.Path;
            Settings.SourceFilePath = File.Path{1};
            % ---------------------------------------------------------
            % Clean up settings
            if isfield(Settings, 'RecordingName')
                Settings = rmfield(Settings, 'RecordingName');
            end
            if isfield(Settings, 'CommonChannels')
                Settings = rmfield(Settings, 'CommonChannels');
            end
            if isfield(Settings, 'IsHDEEG')
                Settings = rmfield(Settings, 'IsHDEEG');
            end
            if isfield(Settings, 'IsCropped')
                Settings = rmfield(Settings, 'IsCropped');
            end
            % ---------------------------------------------------------
            % Generate process
            % For legacy support, check if the 'EEG-select' directory exists, otherwise use 'EEG-preproc'
            if exist([app.State.Protocol.Path, '/derivatives/EEG-select'], 'dir') == 0
                if isfield(NewKeyVals, 'ses')
                    Process.OutputPath = [app.State.Protocol.Path, '/derivatives/EEG-preproc/sub-', NewKeyVals.sub, '/ses-', NewKeyVals.ses, '/', Settings.Setname, '.set'];
                else
                    Process.OutputPath = [app.State.Protocol.Path, '/derivatives/EEG-preproc/sub-', NewKeyVals.sub, '/', Settings.Setname, '.set'];
                end
            else
                if isfield(NewKeyVals, 'ses')
                    Process.OutputPath = [app.State.Protocol.Path, '/derivatives/EEG-select/sub-', NewKeyVals.sub, '/ses-', NewKeyVals.ses, '/', Settings.Setname, '.set'];
                else
                    Process.OutputPath = [app.State.Protocol.Path, '/derivatives/EEG-select/sub-', NewKeyVals.sub, '/', Settings.Setname, '.set'];
                end
            end
            Process.Name = sprintf('Select and preprocess: %s', Settings.Setname);
            Process.Function = @ProcPreprocData;
            Process.ArgIn = {Settings};
            Process.StartDateTime = '';
        end
        % ---------------------------------------------------------
        % FUNCTION: Concatenates data
        function Process = AddProcessConcatenateData(app, Files, Settings)
            % ---------------------------------------------------------
            % Unpack 'Files'
            Files = Files{:};
            % ---------------------------------------------------------
            % Settings
            Settings.ProtocolPath = app.State.Protocol.Path;
            Settings.SourceFilePath = Files.Path;
            % ---------------------------------------------------------
            % Generate the new filename, first get the current key-vals
            CurrentKeyVals = Files.KeyVals{1};
            Settings.KeyVals.sub = CurrentKeyVals.sub;
            Settings.KeyVals.filetype = CurrentKeyVals.filetype;
            % ... overwrite any part of key-vals indicated by '<KEY>'
            fnames = fieldnames(Settings.KeyVals);
            for j = 1:length(fnames)
                if ~isempty(regexp(Settings.KeyVals.(fnames{j}), '<', 'once'))
                    % What key should we insert?
                    startIdx = regexp(Settings.KeyVals.(fnames{j}), '<');
                    endIdx = regexp(Settings.KeyVals.(fnames{j}), '>');
                    Key = Settings.KeyVals.(fnames{j})(startIdx+1:endIdx-1);
                    if isfield(CurrentKeyVals, Key)
                        Value = CurrentKeyVals.(Key);
                    else
                        Value = '';
                    end
                    Settings.KeyVals.(fnames{j}) = strrep(Settings.KeyVals.(fnames{j}), Settings.KeyVals.(fnames{j})(startIdx:endIdx), Value);
                end
            end
            FilePath = fileparts(Files.Path{1});
            if Settings.ExcludeSes
                if isfield(Settings.KeyVals, 'ses')
                    Settings.KeyVals = rmfield(Settings.KeyVals, 'ses');
                end
                % Remove the 'ses-<i>' directory from the path
                FilePath = strsplit(FilePath, '/');
                if strcmpi(FilePath{end}(1:3), 'ses')
                    FilePath(end) = [];
                end
                FilePath = strjoin(FilePath, '/');
            end
            if Settings.ExcludeRun
                if isfield(Settings.KeyVals, 'run')
                    Settings.KeyVals = rmfield(Settings.KeyVals, 'run');
                end
            end
            Settings.TargetFilePath = fullfile(FilePath, struct2filename(Settings.KeyVals));
            % ---------------------------------------------------------
            % Clean up settings
            Settings = rmfield(Settings, 'ExcludeSes');
            Settings = rmfield(Settings, 'ExcludeRun');
            Settings = rmfield(Settings, 'KeyVals');
            % ---------------------------------------------------------
            % Generate process
            Process.OutputPath = Settings.TargetFilePath;
            Process.Name = sprintf('Concatenate %i files: %s', length(Files.Path), reverse_fileparts(Settings.TargetFilePath));
            Process.Function = @Proc_Concatenate;
            Process.ArgIn = {Settings};
            Process.StartDateTime = '';
        end
        % ---------------------------------------------------------
        % FUNCTION: Generates a process object to perform power spectral analysis
        function Process = AddProcessPowerSpectralAnalysis(app, File, Settings)
            % ---------------------------------------------------------
            % Check that only one file was provided
            if size(File, 1) ~= 1
                error('Input must be one file only')
            end
            % ---------------------------------------------------------
            % Generate a new filename, and overwrite the desc value
            NewKeyVals = File.KeyVals{1};
            NewKeyVals.filetype = 'powerspect';
            % ... overwrite any part of key-vals indicated by '<KEY>'
            if ~isempty(regexp(Settings.KeyVals.desc, '<', 'once'))
                % What key should we insert?
                startIdx = regexp(Settings.KeyVals.desc, '<');
                endIdx = regexp(Settings.KeyVals.desc, '>');
                Key = Settings.KeyVals.desc(startIdx+1:endIdx-1);
                if isfield(NewKeyVals, Key)
                    Value = NewKeyVals.(Key);
                else
                    Value = '';
                end
                Settings.KeyVals.desc = strrep(Settings.KeyVals.desc, Settings.KeyVals.desc(startIdx:endIdx), Value);
            end
            NewKeyVals.desc = Settings.KeyVals.desc;
            % Set the new filename
            Settings.ProtocolPath = app.State.Protocol.Path;
            Settings.SourceFilePath = File.Path{1};
            Settings.Filename = struct2filename(NewKeyVals);
            % ---------------------------------------------------------
            % Generate process
            if isfield(NewKeyVals, 'ses')
                Process.OutputPath = [app.State.Protocol.Path, '/derivatives/EEG-output-fstlvl/sub-', NewKeyVals.sub, '/ses-', NewKeyVals.ses, '/', Settings.Filename, '.mat'];
            else
                Process.OutputPath = [app.State.Protocol.Path, '/derivatives/EEG-output-fstlvl/sub-', NewKeyVals.sub, '/', Settings.Filename, '.mat'];
            end
            Process.Name = sprintf('Power spectral analysis: %s', Settings.Filename);
            Process.Function = @Analysis_PowerSpectralAnalysis;
            Process.ArgIn = {Settings};
            Process.StartDateTime = '';
        end
        % ---------------------------------------------------------
        % FUNCTION: Generates a process object to perform time-frequency spectral analysis
        function Process = AddProcessSpectrogramSettings(~, File, Settings) %#ok<INUSD,STOUT> 
            % Check that only one file was provided
            if size(File, 1) ~= 1
                error('Input must be one file only')
            end
            % If it is a 'raw' file, then we'll create a new file, otherwise we'll just overwrite the existing one
            % ********
            % YOu're here
            % *********
        end

        % ---------------------------------------------------------
        % FUNCTION: Generates a process object save a dataset as a routine EEG (10-20 system)
        function Process = AddProcessSaveAsTenTwentyDataset(app, File, Settings)
            % ---------------------------------------------------------
            % Check that only one file was provided
            if size(File, 1) ~= 1
                error('Input must be one file only')
            end
            % Generate a new filename, first get the current key-vals
            NewKeyVals = File.KeyVals{1};
            NewKeyVals.desc = 'convert1020';
            fnames = fieldnames(NewKeyVals);
            fnames = [fnames(~ismember(fnames, {'desc', 'filetype'})); {'desc'; 'filetype'}];
            NewKeyVals = orderfields(NewKeyVals, fnames);
            % Set the new filename
            Settings.Filename = struct2filename(NewKeyVals);
            Settings.ProtocolPath = app.State.Protocol.Path;
            if isfield(NewKeyVals, 'ses')
                Settings.Path = [app.State.Protocol.Path, '/derivatives/EEG-convert1020/sub-', NewKeyVals.sub, '/ses-', NewKeyVals.ses, '/', Settings.Filename];
            else
                Settings.Path = [app.State.Protocol.Path, '/derivatives/EEG-convert1020/sub-', NewKeyVals.sub, '/', Settings.Filename];
            end
            % ---------------------------------------------------------
            % Generate process
            if isfield(NewKeyVals, 'ses')
                Process.OutputPath = [app.State.Protocol.Path, '/derivatives/EEG-convert1020/sub-', NewKeyVals.sub, '/ses-', NewKeyVals.ses, '/', Settings.Filename, '.edf'];
            else
                Process.OutputPath = [app.State.Protocol.Path, '/derivatives/EEG-convert1020/sub-', NewKeyVals.sub, '/', Settings.Filename, '.edf'];
            end
            Process.Name = sprintf('Convert to 10-20: %s', Settings.Filename);
            Process.Function = @SaveTenTwentyDataset;
            Process.ArgIn = {File.Path{1}, Settings};
            Process.StartDateTime = '';
        end
        % ---------------------------------------------------------
        % FUNCTION: Runs the interpolation of outlier channels for the Power-spectral analysis
        function Process = AddProcessInterpolatePowerSpectralOutliers(app, File, Settings)
            % ---------------------------------------------------------
            % Check that only one file was provided
            if size(File, 1) ~= 1
                error('Input must be one file only')
            end
            % Generate a new filename, first get the current key-vals
            NewKeyVals = File.KeyVals{1};
            NewKeyVals.desc = [NewKeyVals.desc, 'interp'];
            % Set the new filename
            Settings.Filename = struct2filename(NewKeyVals);
            % ---------------------------------------------------------
            % Extract settings
            Settings.Window.Length = File.JSON{1}.SpectralAnalysis.WindowLength;
            Settings.Window.Overlap = File.JSON{1}.SpectralAnalysis.WindowOverlap;
            Settings.Norm.AcrossFreqBands = File.JSON{1}.SpectralAnalysis.Norm.AcrossFreqBands == 1;
            Settings.Norm.AcrossChannels = File.JSON{1}.SpectralAnalysis.Norm.AcrossChannels == 1;
            Settings.Norm.AcrossTrials = File.JSON{1}.SpectralAnalysis.Norm.AcrossTrials == 1;
            Settings.EpochAverage = File.JSON{1}.SpectralAnalysis.EpochAverage;
            % Extract frequency definitions
            fnames = cellfun(@(e) e.filename, {Settings.EEG.eeg}, 'UniformOutput', false);
            idxFile = cellfun(@(fname) contains(File.Path{1}, fname), fnames);
            cnt = 0;

            % Sapir added the feaures field part 24/02/2026
            if isfield(Settings.EEG(idxFile).eeg, 'features')
                fprintf('Running Power Spectral Outliers Interpolation')
            
            elseif isfield(Settings.EEG(idxFile).eeg, 'bands')
                Settings.EEG(idxFile).eeg.features = Settings.EEG(idxFile).eeg.bands;
                fprintf('Running Power Spectral Outliers Interpolation')
            
            else
                app.RenderErrorMessage('Unrecognized field name "bands"/"features"');
            end

            for i = 1:length(Settings.EEG(idxFile).eeg.features)
                if strcmpi(Settings.EEG(idxFile).eeg.features(i).type, 'absolute')
                    cnt = cnt+1;
                    Settings.FreqDef(cnt).label = Settings.EEG(idxFile).eeg.features(i).label;
                    Settings.FreqDef(cnt).band = Settings.EEG(idxFile).eeg.features(i).freqrange;
                end
            end
            % ---------------------------------------------------------
            % Set protocol path and input file
            Settings.ProtocolPath = app.State.Protocol.Path;
            Settings.SourceFilePath = bidsuri2fullpath(app.State.Protocol.Path, File.JSON{1}.Sources);
            % ---------------------------------------------------------
            % Generate process
            if isfield(NewKeyVals, 'ses')
                Process.OutputPath = [app.State.Protocol.Path, '/derivatives/EEG-output-fstlvl/sub-', NewKeyVals.sub, '/ses-', NewKeyVals.ses, '/', Settings.Filename, '.mat'];
            else
                Process.OutputPath = [app.State.Protocol.Path, '/derivatives/EEG-output-fstlvl/sub-', NewKeyVals.sub, ' /', Settings.Filename, '.mat'];
            end
            Process.Name = sprintf('Interpolate power spectral outliers: %s', Settings.Filename);
            Process.Function = @Analysis_PowerSpectralAnalysis;
            Process.ArgIn = {Settings};
            Process.StartDateTime = '';
        end
        % ---------------------------------------------------------
        % FUNCTION: Runs the group-level general linear model
        function Process = AddProcessGeneralLinearModel(app, ~, Settings)
            % ---------------------------------------------------------
            % Generate process
            Process.OutputPath = fullfile(Settings.OutputDir, 'somefile.txt');
            Process.Name = sprintf('Group analysis: %s (%s)', Settings.Model.ModelSpec, strrep(Settings.OutputDir, app.State.Protocol.Path, '.'));
            Process.Function = @GroupAnalysis_run;
            Process.ArgIn = {app, Settings};
            Process.StartDateTime = '';
        end
        % ---------------------------------------------------------
        % FUNCTION: Deletes many files
        function Process = AddProcessDeleteAllCheckedFiles(app, File, ~)
            % ---------------------------------------------------------
            % Generate process
            Process.OutputPath = 'none';
            Process.Name = sprintf('Delete: %s', reverse_fileparts(File.Path));
            Process.Function = @DeleteFile;
            Process.ArgIn = {app, File};
            Process.StartDateTime = '';
        end
        % ---------------------------------------------------------
        % END OF APP FUNCTIONS
        % =========================================================
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % =========================================================
            % STARTUP FUNCTION
            % ---------------------------------------------------------
            % Hide all Matlab warning messages
            warning('off', 'all');
            % ---------------------------------------------------------
            % Center the GUI on screen
            screensize = get(0, 'ScreenSize');
            app.UIFigure.Position(1) = 6;
            app.UIFigure.Position(2) = 80;
            app.UIFigure.Position(3) = max([screensize(3)/4, 640]);
            app.UIFigure.Position(4) = screensize(4) - 120;
            drawnow;
            % ---------------------------------------------------------
            % Show waitbar to indicate loading
            app.RenderWaitbar('Loading application and dependencies', -1);
            % ---------------------------------------------------------
            % If this EEG processor app is not on the path, add it
            if exist('eeg_processor_version') == 0 %#ok<EXIST>
                appDir = fileparts(which('EEG_processor'));
                % If we know where the app is, we can add the package dir to the path
                if ~isempty(appDir)
                    addpath(appDir);
                    addpath(genpath([appDir, filesep, 'package']));
                else
                    sel = uiconfirm(app.UIFigure, 'The EEG Processor app is not added to the Matlab path. Please use the browse button to specify the path where ''EEG_Processor.mlapp'' is located', 'Question', ...
                        'Options',{'Browse'},...
                        'DefaultOption', 'Browse', ...
                        'Icon', 'question'); 
                    appDir = uigetdir('.', 'Specify the path where ''EEG_Processor.mlapp'' is located');
                    if appDir == 0 % User pressed cancel, so quit
                        sel = uiconfirm(app.UIFigure, 'You pressed ''Cancel'', so I quit.', 'Info', ...
                            'Options',{'Quit'},...
                            'DefaultOption', 'Quit', ...
                            'Icon', 'Info'); 
                        close(app.UIFigure);
                        return
                    end
                    if exist([appDir, '/EEG_Processor.mlapp'], 'file') == 0
                        sel = uiconfirm(app.UIFigure, ['''EEG_Processor.mlapp'' is not located in ''', appDir,''', so I quit.'], 'Info', ...
                            'Options',{'Quit'},...
                            'DefaultOption', 'Quit', ...
                            'Icon', 'Info'); 
                        close(app.UIFigure);
                        delete(app);
                        return
                    else
                        addpath(appDir);
                        addpath(genpath([appDir, filesep, 'package']));
                    end
                end
            end
            % ---------------------------------------------------------
            % If EEGLAB is not on the path, add it
            AddEEGLABPath(app);
            % ---------------------------------------------------------
            % If Fieldtrip is not on the path, add it
            AddFieldtripPath(app);
            % ---------------------------------------------------------
            app.State = DefaultState(pwd);
            % ---------------------------------------------------------
            % Add icons to components
            app.UIFigure.Icon = 'bids_logo.png';
            app.LoadingImage.ImageSource = 'loading.gif';
            app.DatasetBrowseButton.Icon = 'icon_browse.png';
            app.FilesAddSubjectButton.Icon = 'icon_user-add.png';
            app.FilesExpandButton.Icon = 'icon_collapse.png';
            app.FilesSelectButton.Icon = 'icon_select-all.png';
            app.DatasetSessionsAddButton.Icon = 'icon_plus.png';
            app.DatasetSessionsEditButton.Icon = 'icon_edit.png';
            app.DatasetTasksAddButton.Icon = 'icon_plus.png';
            app.DatasetTasksEditButton.Icon = 'icon_edit.png';
            app.SubjectPropertiesSaveButton.Icon = 'icon_save.png';
            app.SubjectPropertiesUndoButton.Icon = 'icon_undo.png';
            app.SubjectPropertiesDeleteButton.Icon = 'icon_trash.png';
            app.FilePropertiesSaveButton.Icon = 'icon_save.png';
            app.FilePropertiesUndoButton.Icon = 'icon_undo.png';
            app.FilePropertiesDeleteButton.Icon = 'icon_trash.png';
            app.FilePropertiesInspectButton.Icon = 'icon_eye.png';
            app.FilePropertiesEventImportButton.Icon = 'icon_import.png';
            app.FileAddProcessButton.Icon = 'icon_plus.png';
            app.ProcessesRunButton.Icon = 'icon_run.png';
            app.ProcessesDeleteButton.Icon = 'icon_trash.png';
            app.ProcessesMoveDownButton.Icon = 'icon_move-down.png';
            app.ProcessesMoveUpButton.Icon = 'icon_move-up.png';
            % ---------------------------------------------------------
            % Object Pools
            app.Pools.TreeNodes = ObjectPool(app.TreeNodesPool);
            app.RenderAddProcessInput();
            % ---------------------------------------------------------
            % Close the waitbar
            app.RenderWaitbar([]);
            % ---------------------------------------------------------
            % END STARTUP FUNCTION
            % =========================================================
        end

        % Button pushed function: AuthButton
        function AuthButtonPushed(app, event)
            try
                % ---------------------------------------------------------
                % Disable button to prevent double clicking
                event.Source.Enable = 'off'; drawnow;
                % ---------------------------------------------------------
                % Call the GUI
                switch lower(event.Source.Text)
                    case 'sign in'
                        % Make sure the value is an alphanumeric string
                        newValue = lower(regexprep(app.AuthEditField.Value, '[^a-zA-Z0-9]', ''));
                        if ~isempty(newValue)
                            newValue(1) = upper(newValue(1));
                        end
                    case 'sign out'
                        newValue = '';
                end
                % ---------------------------------------------------------
                % SET STATE
                if isempty(newValue)
                    app.State.Auth.IsAuthorized = false;
                    app.State.Auth.Name = '';
                else
                    app.State.Auth.IsAuthorized = true;
                    app.State.Auth.Name = newValue;
                end
                % ---------------------------------------------------------
                % RENDER
                app.RenderAuthLabel();
                % ---------------------------------------------------------
                % Reset is loading
                event.Source.Enable = 'on';
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
                app.SetLoading(false, []);
                app.RenderWaitbar([]);
                event.Source.Enable = 'on';
            end
        end

        % Button pushed function: DatasetBrowseButton
        function DatasetBrowseButtonPushed(app, event)
            try
                % ---------------------------------------------------------
                % Check Auth status
                if ~app.State.Auth.IsAuthorized
                    app.RenderErrorMessage('Please sign in first.')
                    return
                end
                % ---------------------------------------------------------
                % Disable button to prevent double clicking
                event.Source.Enable = 'off'; drawnow;
                % ---------------------------------------------------------
                % Command Window
                fprintf('>> BIDS: Browse...\n');
                % ---------------------------------------------------------
                % Open waitbar
                app.RenderWaitbar('Select and load dataset.', -1);
                % ---------------------------------------------------------
                % Function
                DatasetLoaded = app.LoadDataset();
                % Update the object properties, but only if a Dataset is loaded
                if DatasetLoaded
                    % ---------------------------------------------------------
                    % Command Window
                    fprintf('>> BIDS: Dataset loaded\n');
                    fprintf('>> BIDS: %s\n', app.State.Protocol.Path);
                    % ---------------------------------------------------------
                    % SET STATE
                    app.SetLoading(true, []);
                    % ---------------------------------------------------------
                    % RENDER
                    % Set properties in the Dataset path panel
                    app.RenderDatasetPathEditField();
                    % Set properties in the raw and derivatives tabs
                    app.InitFilesFilterEditField();
                    % Delete all existing nodes
                    % ID #0007
                    delete(app.FilesRawTree.Children);
                    delete(app.FilesDerivativesTree.Children);
                    delete(app.FilesFirstlvlOutputTree.Children);
                    app.RenderFilesTree('raw');
                    app.RenderFilesTree('derivative');
                    app.RenderFilesTree('fstlvl');
                    app.ToggleFilesTree(true);
                    app.RenderFilesAddSubjectButton();
                    app.RenderFilesExpandButton();
                    app.RenderFilesSelectButton();
                    app.RenderFilesFilterEditField();
                    app.RenderDatasetSessionsListBox();
                    app.RenderDatasetTasksListBox();
                    % Set the properties of the add process panel
                    app.RenderFileAddProcessPanel();
                    % Set properties in the Dataset tab
                    app.RenderPropertiesTabGroup('', '');
                    app.RenderProcessesPanel(); % Fixed ID #0010
                    app.InitDatasetProperties();
                end
                % ---------------------------------------------------------
                % Close waitbar
                app.RenderWaitbar([]);
                app.SetLoading(false, []);
                event.Source.Enable = 'on';
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
                app.SetLoading(false, []);
                app.RenderWaitbar([]);
                event.Source.Enable = 'on';
            end
        end

        % Button pushed function: FilesAddSubjectButton
        function FilesAddSubjectButtonPushed(app, event)
            try
                % ---------------------------------------------------------
                % Check Auth status
                if ~app.State.Auth.IsAuthorized
                    app.RenderErrorMessage('Please sign in first.')
                    return
                end
                % ---------------------------------------------------------
                % Disable button to prevent double clicking
                event.Source.Enable = 'off'; drawnow;
                % ---------------------------------------------------------
                % Call the GUI
                app.GUIs.SimpleQuestion = SimpleQuestion(app, 'Subject id (without ''sub-'')', '');
                uiwait(app.GUIs.SimpleQuestion.UIFigure);
                % ---------------------------------------------------------
                % Check if user pressed cancel
                if ~app.Import.Return
                    event.Source.Enable = 'on';
                    return
                end
                % Make sure the value is an alphanumeric string
                newValue = lower(regexprep(app.Import.Data.Answer, '[^a-zA-Z0-9]', ''));
                % ---------------------------------------------------------
                % SET STATE
                [SubId, alreadyExists] = app.AddSubject(newValue);
                if alreadyExists
                    event.Source.Enable = 'on';
                    return
                end
                % ---------------------------------------------------------
                % RENDER
                app.InitFilesFilterEditField();
                app.RenderFilesTree('raw');
                app.RenderFilesTree('derivative');
                app.RenderFilesTree('fstlvl');
                app.RenderFilesExpandButton();
                app.RenderFilesSelectButton();
                app.RenderFilesTreeSelectedNode(['sub-', newValue]);
                app.RenderPropertiesTabGroup('Subjects', SubId);
                app.RenderSubjectPropertiesIdEditField();
                app.RenderSubjectPropertiesSaveButton();
                app.RenderSubjectPropertiesUndoButton();
                app.RenderFileAddProcessPanel();
                % ---------------------------------------------------------
                % Reset is loading
                event.Source.Enable = 'on';
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
                app.SetLoading(false, event.Source);
                event.Source.Enable = 'on';
            end
        end

        % Button pushed function: FilesExpandButton
        function FilesExpandButtonPushed(app, event)
            try
                % ---------------------------------------------------------
                % Disable button to prevent double clicking
                event.Source.Enable = 'off'; drawnow;
                % ---------------------------------------------------------
                % Set is loading
                app.SetLoading(true, event.Source);
                % ---------------------------------------------------------
                % RENDER
                app.ToggleFilesTree();
                app.RenderFilesExpandButton();
                % ---------------------------------------------------------
                % Reset is loading
                app.SetLoading(false, event.Source);
                event.Source.Enable = 'on';
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
                app.SetLoading(false, []);
                event.Source.Enable = 'on';
            end
        end

        % Button pushed function: FilesSelectButton
        function FilesSelectButtonPushed(app, event)
            try
                % ---------------------------------------------------------
                % Disable button to prevent double clicking
                event.Source.Enable = 'off'; drawnow;
                % ---------------------------------------------------------
                % Set is loading
                app.SetLoading(true, event.Source);
                % ---------------------------------------------------------
                % RENDER
                app.ToggleFilesSelect();
                app.RenderFilesSelectButton();
                app.RenderFileAddProcessPanel();
                % ---------------------------------------------------------
                % Reset is loading
                app.SetLoading(false, event.Source);
                event.Source.Enable = 'on';
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
                app.SetLoading(false, []);
                event.Source.Enable = 'on';
            end
        end

        % Value changed function: FilesFilterEditField
        function FilesFilterEditFieldValueChanged(app, event)
            try
                % ---------------------------------------------------------
                % Prevent double clicking
                event.Source.Enable = 'off'; drawnow;
                % ---------------------------------------------------------
                % Set is loading
                app.SetLoading(true, event.Source);
                % ---------------------------------------------------------
                % RENDER
                app.RenderFilesTree('raw');
                app.RenderFilesTree('derivative');
                app.RenderFilesTree('fstlvl');
                app.ToggleFilesTree(true, true);
                app.RenderFilesExpandButton();
                app.RenderFilesSelectButton();
                app.RenderFileAddProcessPanel();
                % ---------------------------------------------------------
                % Reset is loading
                app.SetLoading(false, event.Source);
                event.Source.Enable = 'on';
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
                app.SetLoading(false, []);
                event.Source.Enable = 'on';
            end
        end

        % Selection change function: FilesTabGroup
        function FilesTabGroupSelectionChanged(app, event)
            try
                % ---------------------------------------------------------
                % RENDER
                app.RenderFilesSelectButton();
                app.RenderFileAddProcessPanel();
                app.RenderAddProcessInput();
                switch event.NewValue.Title
                    case 'Raw'
                        if ~isempty(app.FilesRawTree.SelectedNodes)
                            app.RenderPropertiesTabGroup(app.FilesRawTree.SelectedNodes.UserData.Slice, app.FilesRawTree.SelectedNodes.UserData.id);
                        else
                            app.RenderPropertiesTabGroup('', '');
                        end
                    case 'Derivatives'
                        if ~isempty(app.FilesDerivativesTree.SelectedNodes)
                            app.RenderPropertiesTabGroup(app.FilesDerivativesTree.SelectedNodes.UserData.Slice, app.FilesDerivativesTree.SelectedNodes.UserData.id);
                        else
                            app.RenderPropertiesTabGroup('', '');
                        end
                    case 'First level'
                        if ~isempty(app.FilesFirstlvlOutputTree.SelectedNodes)
                            app.RenderPropertiesTabGroup(app.FilesFirstlvlOutputTree.SelectedNodes.UserData.Slice, app.FilesFirstlvlOutputTree.SelectedNodes.UserData.id);
                        else
                            app.RenderPropertiesTabGroup('', '');
                        end
                end
                app.RenderSubjectPropertiesIdEditField();
                app.RenderSubjectPropertiesSaveButton();
                app.RenderSubjectPropertiesUndoButton();
                app.RenderFilePropertiesSaveButton();
                app.RenderFilePropertiesUndoButton();
                app.RenderFilePropertiesInspectButton();
                app.RenderFilePropertiesImportEventsButton();
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
            end
        end

        % Callback function: FilesDerivativesTree, FilesFirstlvlOutputTree, 
        % FilesRawTree
        function FilesTreeCheckedNodesChanged(app, event)
            try
                % ---------------------------------------------------------
                % RENDER
                app.RenderFilesSelectButton();
                app.RenderFileAddProcessPanel();
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
            end
        end

        % Selection changed function: FilesDerivativesTree, 
        % FilesFirstlvlOutputTree, FilesRawTree
        function FilesTreeSelectionChanged(app, event)
            try
                % ---------------------------------------------------------
                % RENDER
                app.RenderPropertiesTabGroup(event.SelectedNodes.UserData.Slice, event.SelectedNodes.UserData.id);
                app.RenderSubjectPropertiesIdEditField();
                app.RenderSubjectPropertiesSaveButton();
                app.RenderSubjectPropertiesUndoButton();
                app.RenderFilePropertiesSaveButton();
                app.RenderFilePropertiesUndoButton();
                app.RenderFilePropertiesInspectButton();
                app.RenderFilePropertiesImportEventsButton();
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
            end
        end

        % Value changed function: DatasetAcknowledgeTextArea, 
        % DatasetAcknowledgementsTextArea, DatasetAuthorsTextArea, 
        % DatasetDOIEditField, DatasetEthicsTextArea, 
        % DatasetFundingTextArea, DatasetGeneratedByDescriptionTextArea, 
        % DatasetGeneratedByNameEditField, DatasetLicenseDropDown, 
        % DatasetNameEditField
        function DatasetPropertiesChanged(app, event)
            try
                % ---------------------------------------------------------
                % Check Auth status
                if ~app.State.Auth.IsAuthorized
                    event.Source.Value = event.PreviousValue;
                    app.RenderErrorMessage('Please sign in first.')
                    return
                end
                % ---------------------------------------------------------
                % Set is loading
                app.SetLoading(true, event.Source);
                % ---------------------------------------------------------
                % Set state
                app.SetDatasetDescription(event.Source.Tag, event.Source.Value);
                % ---------------------------------------------------------
                % Command Window
                fprintf('>> BIDS: Dataset description has updated, saving to JSON file.\n');
                fprintf('>> BIDS: %s\n', [app.State.Protocol.Path, '/rawdata/dataset_description.json'])
                % ---------------------------------------------------------
                % Save the JSON File
                struct2json(app.State.Protocol.JSON, [app.State.Protocol.Path, '/rawdata/dataset_description.json']);
                % ---------------------------------------------------------
                % Reset is loading
                app.SetLoading(false, event.Source)
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
                app.SetLoading(false, event.Source);
            end
        end

        % Callback function
        function DatasetSessionsListBoxValueChanged(app, event)
            try
                % ---------------------------------------------------------
                % Function (update property directly for simplicity)
                app.DatasetSessionsEditButton.Enable = ifelse(isempty(app.DatasetSessionsListBox.Value), 'off', 'on');
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
            end
        end

        % Button pushed function: DatasetSessionsAddButton
        function DatasetSessionsAddButtonPushed(app, event)
            try
                % ---------------------------------------------------------
                % Check Auth status
                if ~app.State.Auth.IsAuthorized
                    app.RenderErrorMessage('Please sign in first.')
                    return
                end
                % ---------------------------------------------------------
                % Disable button to prevent double clicking
                event.Source.Enable = 'off'; drawnow;
                % ---------------------------------------------------------
                % Call the GUI
                app.GUIs.SimpleQuestion = SimpleQuestion(app, 'Session name', '');
                uiwait(app.GUIs.SimpleQuestion.UIFigure);
                % ---------------------------------------------------------
                % Check if user pressed cancel
                if ~app.Import.Return
                    event.Source.Enable = 'on';
                    return
                end
                % Make sure the value is an alphanumeric string
                Value = lower(regexprep(app.Import.Data.Answer, '[^a-zA-Z0-9]', ''));
                % ---------------------------------------------------------
                % Function (update property directly for simplicity)
                UpdateProperty(app, app.DatasetSessionsListBox, 'Items', unique([app.DatasetSessionsListBox.Items, {Value}]));
                event.Source.Enable = 'on';
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
                event.Source.Enable = 'on';
            end
        end

        % Button pushed function: DatasetSessionsEditButton
        function DatasetSessionsEditButtonPushed(app, event)
            try
                % ---------------------------------------------------------
                % Check Auth status
                if ~app.State.Auth.IsAuthorized
                    app.RenderErrorMessage('Please sign in first.')
                    return
                end
                % ---------------------------------------------------------
                % Disable button to prevent double clicking
                event.Source.Enable = 'off'; drawnow;
                % ---------------------------------------------------------
                % For memory
                currentValue = app.DatasetSessionsListBox.Value;
                % ---------------------------------------------------------
                % Check that there are no files currently in error with that session name
                FileList = SelectFilesByKeyValue(app, 'ses', currentValue);
                if any(matches(FileList.Status, 'error'))
                    sel = uiconfirm(app.UIFigure, 'Some files with this session name have errors. Cannot change the session name until errors are resolved.', 'Warning', ...
                        'Options',{'OK'},...
                        'DefaultOption', 'OK', ...
                        'Icon', 'warning'); 
                    event.Source.Enable = 'on';
                    return
                end
                % ---------------------------------------------------------
                % Call the GUI
                app.GUIs.SimpleQuestion = SimpleQuestion(app, 'Session name', currentValue);
                uiwait(app.GUIs.SimpleQuestion.UIFigure);
                % ---------------------------------------------------------
                % Check if user pressed cancel
                if ~app.Import.Return
                    event.Source.Enable = 'on';
                    return
                end
                % ---------------------------------------------------------
                % Parse new value
                app.Import.Data.Answer = lower(regexprep(app.Import.Data.Answer, '[^a-zA-Z0-9]', ''));
                % ---------------------------------------------------------
                % SET STATE
                app.SetSessionOrTaskName('ses', currentValue, app.Import.Data.Answer);
                % ---------------------------------------------------------
                % RENDER
                app.DatasetSessionsListBox.Items{matches(app.DatasetSessionsListBox.Items, currentValue)} = app.Import.Data.Answer;
                app.DatasetSessionsListBox.Value = app.Import.Data.Answer;
                app.InitFilesFilterEditField();
                app.RenderFilesTree('raw');
                app.RenderFilesTree('derivative');
                app.RenderFilesTree('fstlvl');
                app.RenderFilesExpandButton();
                app.RenderFilesSelectButton();
                app.RenderFileAddProcessPanel();
                % ---------------------------------------------------------
                % Close waitbar
                app.SetLoading(false, []);
                app.RenderWaitbar([]);
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
                app.SetLoading(false, event.Source);
                app.RenderWaitbar([]);
            end
        end

        % Callback function
        function DatasetTasksListBoxValueChanged(app, event)
            try
                % ---------------------------------------------------------
                % Function (update property directly for simplicity)
                app.DatasetTasksEditButton.Enable = ifelse(isempty(app.DatasetTasksListBox.Value), 'off', 'on');
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
            end
        end

        % Button pushed function: DatasetTasksAddButton
        function DatasetTasksAddButtonPushed(app, event)
            try
                % ---------------------------------------------------------
                % Check Auth status
                if ~app.State.Auth.IsAuthorized
                    app.RenderErrorMessage('Please sign in first.')
                    return
                end
                % ---------------------------------------------------------
                % Disable button to prevent double clicking
                event.Source.Enable = 'off'; drawnow;
                % ---------------------------------------------------------
                % Call the GUI
                app.GUIs.SimpleQuestion = SimpleQuestion(app, 'Task name', '');
                uiwait(app.GUIs.SimpleQuestion.UIFigure);
                % ---------------------------------------------------------
                % Check if user pressed cancel
                if ~app.Import.Return
                    event.Source.Enable = 'on';
                    return
                end
                % Make sure the value is an alphanumeric string
                Value = lower(regexprep(app.Import.Data.Answer, '[^a-zA-Z0-9]', ''));
                % ---------------------------------------------------------
                % Function (update property directly for simplicity)
                UpdateProperty(app, app.DatasetTasksListBox, 'Items', unique([app.DatasetTasksListBox.Items, {Value}]));
                event.Source.Enable = 'on';
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
                event.Source.Enable = 'on';
            end
        end

        % Button pushed function: DatasetTasksEditButton
        function DatasetTasksEditButtonPushed(app, event)
            try
                % ---------------------------------------------------------
                % Check Auth status
                if ~app.State.Auth.IsAuthorized
                    app.RenderErrorMessage('Please sign in first.')
                    return
                end
                % ---------------------------------------------------------
                % Disable button to prevent double clicking
                event.Source.Enable = 'off'; drawnow;
                % ---------------------------------------------------------
                % For memory
                currentValue = app.DatasetTasksListBox.Value;
                if isempty(currentValue)
                    return
                end
                % ---------------------------------------------------------
                % Check that there are no files currently in error with that task name
                FileList = SelectFilesByKeyValue(app, 'task', currentValue);
                if any(matches(FileList.Status, 'error'))
                    sel = uiconfirm(app.UIFigure, 'Some files with this task name have errors. Cannot change the task name until errors are resolved.', 'Warning', ...
                        'Options',{'OK'},...
                        'DefaultOption', 'OK', ...
                        'Icon', 'warning'); 
                    event.Source.Enable = 'on';
                    return
                end
                % ---------------------------------------------------------
                % Call the GUI
                app.GUIs.SimpleQuestion = SimpleQuestion(app, 'Task name', currentValue);
                uiwait(app.GUIs.SimpleQuestion.UIFigure);
                % ---------------------------------------------------------
                % Check if user pressed cancel
                if ~app.Import.Return
                    event.Source.Enable = 'on';
                    return
                end
                % ---------------------------------------------------------
                % Parse new value
                app.Import.Data.Answer = lower(regexprep(app.Import.Data.Answer, '[^a-zA-Z0-9]', ''));
                % ---------------------------------------------------------
                % SET STATE
                app.SetSessionOrTaskName('task', currentValue, app.Import.Data.Answer);
                % ---------------------------------------------------------
                % RENDER
                app.DatasetTasksListBox.Items{matches(app.DatasetTasksListBox.Items, currentValue)} = app.Import.Data.Answer;
                app.DatasetTasksListBox.Value = app.Import.Data.Answer;
                app.InitFilesFilterEditField();
                app.RenderFilesTree('raw');
                app.RenderFilesTree('derivative');
                app.RenderFilesTree('fstlvl');
                app.RenderFilesExpandButton();
                app.RenderFilesSelectButton();
                app.RenderFileAddProcessPanel();
                % ---------------------------------------------------------
                % Close waitbar
                app.SetLoading(false, []);
                app.RenderWaitbar([]);
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
                app.SetLoading(false, event.Source);
                app.RenderWaitbar([]);
            end
        end

        % Value changed function: SubjectPropertiesIdEditField
        function SubjectPropertiesIdEditFieldValueChanged(app, event)
            try
                % ---------------------------------------------------------
                % Check Auth status
                if ~app.State.Auth.IsAuthorized
                    event.Source.Value = event.PreviousValue;
                    app.RenderErrorMessage('Please sign in first.')
                    return
                end
                % ---------------------------------------------------------
                % Open waitbar
                app.SetLoading(true, []);
                % ---------------------------------------------------------
                % Some vars for shorthand
                SubId = app.SubjectPropertiesTab.UserData.id;
                idxSub = matches(app.State.Subjects.Id, SubId);
                FileIds = app.State.Subjects.FileIds{idxSub};
                % ---------------------------------------------------------
                % SET STATE
                app.SetSubjectNameById(SubId, event.Value);
                cellfun(@(id) app.SetFileKeyValueById(id, 'sub', event.Value), FileIds);
                % ---------------------------------------------------------
                % RENDER
                app.RenderFilesTreeSubjectNodeById(SubId);
                cellfun(@(id) app.RenderFilesTreeFileNodeById(id), FileIds);
                app.RenderSubjectPropertiesSaveButton();
                app.RenderSubjectPropertiesUndoButton();
                app.RenderFileAddProcessPanel();
                % ---------------------------------------------------------
                % Close waitbar
                app.SetLoading(false, []);
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
                % ---------------------------------------------------------
                % Close waitbar
                app.SetLoading(false, []);
            end
        end

        % Button pushed function: SubjectPropertiesUndoButton
        function SubjectPropertiesUndoButtonPushed(app, event)
            try
                % ---------------------------------------------------------
                % Check Auth status
                if ~app.State.Auth.IsAuthorized
                    app.RenderErrorMessage('Please sign in first.')
                    return
                end
                % ---------------------------------------------------------
                % Some vars for shorthand
                SubId = app.SubjectPropertiesTab.UserData.id;
                idxSub = matches(app.State.Subjects.Id, SubId);
                FileIds = app.State.Subjects.FileIds{idxSub};
                % ---------------------------------------------------------
                % SET STATE
                app.ResetSubjectById(SubId);
                cellfun(@(id) app.ResetFileById(id), FileIds);
                % ---------------------------------------------------------
                % RENDER
                app.RenderFilesTreeSubjectNodeById(SubId);
                cellfun(@(id) app.RenderFilesTreeFileNodeById(id), FileIds);
                app.RenderPropertiesTabGroup('Subjects', SubId);
                app.RenderSubjectPropertiesSaveButton();
                app.RenderSubjectPropertiesUndoButton();
                app.RenderFileAddProcessPanel();
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
            end
        end

        % Button pushed function: SubjectPropertiesSaveButton
        function SubjectPropertiesSaveButtonPushed(app, event)
            try
                % ---------------------------------------------------------
                % Check Auth status
                if ~app.State.Auth.IsAuthorized
                    app.RenderErrorMessage('Please sign in first.')
                    return
                end
                % ---------------------------------------------------------
                % Disable button to prevent double clicking
                event.Source.Enable = 'off'; drawnow;
                % ---------------------------------------------------------
                % Open waitbar
                app.SetLoading(true, []);
                app.RenderWaitbar('Renaming files.', -1);
                % Some vars for shorthand
                SubId = app.SubjectPropertiesTab.UserData.id;
                idxSub = matches(app.State.Subjects.Id, SubId);
                FileIds = app.State.Subjects.FileIds{idxSub};
                % ---------------------------------------------------------
                % SET STATE
                app.SaveSubject(SubId);
                % ---------------------------------------------------------
                % RENDER
                app.RenderFilesTreeSubjectNodeById(SubId);
                cellfun(@(id) app.RenderFilesTreeFileNodeById(id), FileIds);
                app.RenderPropertiesTabGroup('Subjects', SubId);
                app.RenderSubjectPropertiesSaveButton();
                app.RenderSubjectPropertiesUndoButton();
                app.RenderFileAddProcessPanel();
                % ---------------------------------------------------------
                % Close waitbar
                app.SetLoading(false, []);
                app.RenderWaitbar([]);
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
                app.SetLoading(false, event.Source);
                app.RenderWaitbar([]);
            end
        end

        % Button pushed function: SubjectPropertiesDeleteButton
        function SubjectPropertiesDeleteButtonPushed(app, event)
            try
                % ---------------------------------------------------------
                % Check Auth status
                if ~app.State.Auth.IsAuthorized
                    app.RenderErrorMessage('Please sign in first.')
                    return
                end
                % ---------------------------------------------------------
                % Disable button to prevent double clicking
                event.Source.Enable = 'off'; drawnow;
                % ---------------------------------------------------------
                % Some vars for shorthand
                SubId = app.SubjectPropertiesTab.UserData.id;
                idxSub = matches(app.State.Subjects.Id, SubId);
                Name = app.State.Subjects.Name{idxSub};
                % ---------------------------------------------------------
                % Ask the user to confirm
                sel = uiconfirm(app.UIFigure, ['Are you sure to permanently delete subject ''', app.State.Subjects.Name{idxSub}, ''' and all its associated data in ''rawdata'' and ''derivatives''?'], 'Delete data?', ...
                    'Options', {'No, cancel', 'Yes, delete'},...
                    'DefaultOption', 'No, cancel', ...
                    'CancelOption', 'No, cancel', ...
                    'Icon', 'question');
                % ---------------------------------------------------------
                % If the user pressed 'Cancel' or 'Esc', return to stop the current process
                if strcmpi(sel, 'No, cancel')
                    event.Source.Enable = 'on';
                    return
                end
                % ---------------------------------------------------------
                % Open waitbar
                app.SetLoading(true, event.Source);
                app.RenderWaitbar('Deleting files.', -1);
                % ---------------------------------------------------------
                % SET STATE
                hasError = app.DeleteSubject(SubId);
                if hasError
                    app.SetLoading(false, event.Source);
                    app.RenderWaitbar([]);
                    return
                end
                % ---------------------------------------------------------
                % RENDER
                app.RenderFilesTreeSubjectNodeById(SubId);
                app.RenderPropertiesTabGroup('', ''); % Show the Dataset tab
                app.RenderFileAddProcessPanel();
                % ---------------------------------------------------------
                % Close waitbar
                app.SetLoading(false, event.Source);
                app.RenderWaitbar([]);
                % Tell the user the subject is deleted
                sel = uiconfirm(app.UIFigure, sprintf('Subject ''%s'' has been deleted from the dataset.', Name), 'Success', ...
                    'Options',{'Ok'},...
                    'DefaultOption', 'Ok', ...
                    'Icon', 'success'); 
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
                app.SetLoading(false, event.Source);
                app.RenderWaitbar([]);
            end
        end

        % Value changed function: FilePropertiesSessionDropdown, 
        % FilePropertiesTaskDropdown
        function FilePropertiesValueChanged(app, event)
            try
                % ---------------------------------------------------------
                % Check Auth status
                if ~app.State.Auth.IsAuthorized
                    event.Source.Value = event.PreviousValue;
                    app.RenderErrorMessage('Please sign in first.')
                    return
                end
                % ---------------------------------------------------------
                % Some vars for shorthand
                FileId = app.FilePropertiesTab.UserData.id;
                % ---------------------------------------------------------
                % SET STATE
                app.SetFileKeyValueById(FileId, event.Source.Tag, event.Value)
                % ---------------------------------------------------------
                % RENDER
                app.RenderFilesTreeFileNodeById(FileId);
                app.RenderFilePropertiesSaveButton();
                app.RenderFilePropertiesUndoButton();
                app.RenderFilePropertiesInspectButton();
                app.RenderFilePropertiesImportEventsButton();
                app.RenderFileAddProcessPanel();
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
            end
        end

        % Button pushed function: FilePropertiesInspectButton
        function FilePropertiesInspectButtonPushed(app, event)
            try
                % ---------------------------------------------------------
                % Check Auth status
                if ~app.State.Auth.IsAuthorized
                    app.RenderErrorMessage('Please sign in first.')
                    return
                end
                % ---------------------------------------------------------
                % Open waiting 
                app.RenderWaitbar('Opening visual inspection app...', 0);
                % ---------------------------------------------------------
                % Some vars for shorthand
                FileId = app.FilePropertiesTab.UserData.id;
                idxFile = matches(app.State.Files.Id, FileId);
                % ---------------------------------------------------------
                % Call the appropriate visual inspection app
                switch app.State.Files.KeyVals{idxFile}.filetype
                    case {'eeg', 'continuous', 'discontinuous', 'epoched'}
                        app.GUIs.VisualInspection = VisualInspection(app, app.State.Files.Path{idxFile});
                        uiwait(app.GUIs.VisualInspection.UIFigure);
                    case 'powerspect'
                        app.GUIs.VisualInspection = PowerSpectralTopoplots(app, app.State.Files.Path{idxFile});
                        uiwait(app.GUIs.VisualInspection.UIFigure);
                end
                % Check the return object
                if app.Import.Return
                    % .. When 'Return' is true, we need to add or update a file to the state
                    % ---------------------------------------------------------
                    % Extract subject and its id
                    SubName = app.Import.Data.EEG.subject;
                    [Subject, SubId, idxSub] = SelectSubjectByName(app, ['sub-', SubName]);
                    % ---------------------------------------------------------
                    % If subject was not found, throw error
                    if isempty(SubId{1})
                        error('A new file was added to an unknown subject with the name ''%s''.', SubName)
                    end
                    % ---------------------------------------------------------
                    % Create or update the structure containing info about the file
                    switch app.Import.Data.Next
                        case 'AddFile'
                            newFile = dir([app.Import.Data.EEG.filepath, '/', app.Import.Data.EEG.filename]);
                            % Add the new file to the state
                            [app.State.Files, thisSubjectFileIds] = AddFilesToState(app.State.Files, SubId{1}, newFile, 'derivative', Subject.FileIds{1});
                            % Add the new file's id to the subject's state
                            app.State.Subjects.FileIds{idxSub} = thisSubjectFileIds;
                        case 'UpdateFile'
                            % We must update the events, first get the event filename
                            Keys = fieldnames(app.State.Files.KeyVals{idxFile}); Keys(end) = [];
                            Values = struct2cell(app.State.Files.KeyVals{idxFile}); Values(end) = [];
                            % -----
                            % Generate filenames for all the sidecar files
                            BaseFilename = cellfun(@(k, v) [k, '-', v, '_'], Keys, Values, 'UniformOutput', false);
                            EventsFilename = [app.Import.Data.EEG.filepath, '/', strjoin([BaseFilename; {'events.tsv'}], '')];
                            app.State.Files.events{idxFile} = readSidecarTSV(EventsFilename, 'events');
                    end
                    % ---------------------------------------------------------
                    % RENDER
                    % Set properties in the raw and derivatives tabs
                    app.InitFilesFilterEditField();
                    app.RenderFilesTree('raw');
                    app.RenderFilesTree('derivative');
                    app.RenderFilesTree('fstlvl');
                    app.RenderFilesAddSubjectButton();
                    app.RenderFilesExpandButton();
                    app.RenderFilesSelectButton();
                    app.RenderFilesFilterEditField();
                    app.RenderFilePropertiesEventGroupListBox();
                    % Set the properties of the add process panel
                    app.RenderFileAddProcessPanel();
                end
                % ---------------------------------------------------------
                % Close the waitbar
                app.RenderWaitbar([]);
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
                app.RenderWaitbar([]);
            end
        end

        % Button pushed function: FilePropertiesEventImportButton
        function FilePropertiesEventImportButtonPushed(app, event)
            try
                % ---------------------------------------------------------
                % Check Auth status
                if ~app.State.Auth.IsAuthorized
                    app.RenderErrorMessage('Please sign in first.')
                    return
                end
                % ---------------------------------------------------------
                % Call the GUI
                app.GUIs.ImportExternalEvents = ImportExternalEventsSettings(app);
                if isvalid(app.GUIs.ImportExternalEvents)
                    uiwait(app.GUIs.ImportExternalEvents.UIFigure);
                end
                % ---------------------------------------------------------
                % Change working directory back to protocol
                cd(app.State.Protocol.Path)
                % ---------------------------------------------------------
                % Check if user pressed cancel
                if ~app.Import.Return
                    event.Source.Enable = 'on';
                    return
                end
                % ---------------------------------------------------------
                % Open waiting 
                app.RenderWaitbar('Importing external events...', 0);
                % -------------------------------------------------------------------------
                % Load the EEG header file
                id = app.FilePropertiesTab.UserData.id;
                idx = matches(app.State.Files.Id, id);
                EEG = LoadDataset(app.State.Files.Path{idx}, 'header'); %#ok<ADMTHDINV>
                Warnings = {};
                DidImport = false;
                % -------------------------------------------------------------------------
                % Import the events
                if ~isempty(app.Import.Data.HypnoPath)
                    DidImport = true;
                    [EEG, warnmsg] = compumed_import_sleep_scores(EEG, app.Import.Data.HypnoPath);
                    if ~isempty(warnmsg)
                        warnmsg = sprintf('%s: %s', EEG.filename, warnmsg);
                        Warnings = [Warnings, {warnmsg; '-----'}];
                    end
                end
                if ~isempty(app.Import.Data.EventsPath)
                    DidImport = true;
                    [EEG, warnmsg] = compumed_import_sleep_events(EEG, app.Import.Data.EventsPath);
                    if ~isempty(warnmsg)
                        warnmsg = sprintf('%s: %s', EEG.filename, warnmsg);
                        Warnings = [Warnings, {warnmsg; '-----'}];
                    end
                end
                if ~isempty(app.Import.Data.WonambiXMLPath)
                    DidImport = true;
                    [EEG, warnmsg] = wonambi_import_xml(EEG, app.Import.Data.WonambiXMLPath);
                    if ~isempty(warnmsg)
                        warnmsg = sprintf('%s: %s', EEG.filename, warnmsg);
                        Warnings = [Warnings, {warnmsg; '-----'}];
                    end
                end
                % Store the original events in case we modify any of the events
                EEG.urevent = EEG.event;
                % ---------------------------------------------------------
                % If nothing was done, then return
                if ~DidImport
                    return
                end
                % ---------------------------------------------------------
                % Save the dataset
                EEG = SaveDataset(EEG, 'header');
                % ---------------------------------------------------------
                % Update the state
                KeyVals = app.State.Files.KeyVals{idx};
                Keys = fieldnames(KeyVals); Keys(end) = [];
                Values = struct2cell(KeyVals); Values(end) = [];
                BaseFilename = cellfun(@(k, v) [k, '-', v, '_'], Keys, Values, 'UniformOutput', false);
                EventsFilename = [fileparts(app.State.Files.Path{idx}), '/', strjoin([BaseFilename; {'events.tsv'}], '')];
                app.State.Files.events{idx} = readSidecarTSV(EventsFilename, 'events');
                % ---------------------------------------------------------
                % RENDER
                app.RenderPropertiesTabGroup(app.FilePropertiesTab.UserData.Slice, id);
                % ---------------------------------------------------------
                % Close the waitbar
                app.RenderWaitbar([]);
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
                app.RenderWaitbar([]);
            end
        end

        % Value changed function: FilePropertiesEventGroupListBox
        function FilePropertiesEventGroupListBoxValueChanged(app, event)
            % ---------------------------------------------------------
            % RENDER
            app.RenderFilePropertiesEventGroupListBox();
        end

        % Button pushed function: FilePropertiesUndoButton
        function FilePropertiesUndoButtonPushed(app, event)
            try
                % ---------------------------------------------------------
                % Check Auth status
                if ~app.State.Auth.IsAuthorized
                    app.RenderErrorMessage('Please sign in first.')
                    return
                end
                % ---------------------------------------------------------
                % Some vars for shorthand
                FileId = app.FilePropertiesTab.UserData.id;
                idxFile = matches(app.State.Files.Id, FileId);
                SubId = app.State.Files.SubId{idxFile};
                idxSub = matches(app.State.Subjects.Id, SubId);
                FileIds = app.State.Subjects.FileIds{idxSub};
                % ---------------------------------------------------------
                % SET STATE
                % If the associated subject is also changed, then reset the entire subject
                % Otherwise only reset this file
                if strcmpi(app.State.Subjects.Status{idxSub}, 'changed')
                    app.ResetSubjectById(SubId);
                    cellfun(@(id) app.ResetFileById(id), FileIds);
                    % ---------------------------------------------------------
                    % RENDER
                    app.RenderFilesTreeSubjectNodeById(SubId);
                    cellfun(@(id) app.RenderFilesTreeFileNodeById(id), FileIds);
                    app.RenderPropertiesTabGroup('Files', FileId);
                    app.RenderFilePropertiesSaveButton();
                    app.RenderFilePropertiesUndoButton();
                    app.RenderFilePropertiesInspectButton();
                    app.RenderFilePropertiesImportEventsButton();
                    app.RenderFileAddProcessPanel();
                else
                    app.ResetFileById(FileId);
                    % ---------------------------------------------------------
                    % RENDER
                    app.RenderFilesTreeFileNodeById(FileId)
                    app.RenderPropertiesTabGroup('Files', FileId);
                    app.RenderFilePropertiesSaveButton();
                    app.RenderFilePropertiesUndoButton();
                    app.RenderFilePropertiesInspectButton();
                    app.RenderFilePropertiesImportEventsButton();
                    app.RenderFileAddProcessPanel();
                end
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
            end
        end

        % Button pushed function: FilePropertiesSaveButton
        function FilePropertiesSaveButtonPushed(app, event)
            try
                % ---------------------------------------------------------
                % Check Auth status
                if ~app.State.Auth.IsAuthorized
                    app.RenderErrorMessage('Please sign in first.')
                    return
                end
                % ---------------------------------------------------------
                % Disable button to prevent double clicking
                event.Source.Enable = 'off'; drawnow;
                % ---------------------------------------------------------
                % Open waitbar
                app.SetLoading(true, []);
                app.RenderWaitbar('Renaming file.', -1);
                % Some vars for shorthand
                FileId = app.FilePropertiesTab.UserData.id;
                % ---------------------------------------------------------
                % SET STATE
                app.SaveFile(FileId);
                % ---------------------------------------------------------
                % RENDER
                app.RenderFilesTreeFileNodeById(FileId);
                app.RenderPropertiesTabGroup('Files', FileId);
                app.RenderFilePropertiesSaveButton();
                app.RenderFilePropertiesUndoButton();
                app.RenderFilePropertiesInspectButton();
                app.RenderFilePropertiesImportEventsButton();
                app.RenderFileAddProcessPanel();
                % ---------------------------------------------------------
                % Close waitbar
                app.SetLoading(false, []);
                app.RenderWaitbar([]);
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
                app.SetLoading(false, event.Source);
                app.RenderWaitbar([]);
            end
        end

        % Button pushed function: FilePropertiesDeleteButton
        function FilePropertiesDeleteButtonPushed(app, event)
            try
                % ---------------------------------------------------------
                % Check Auth status
                if ~app.State.Auth.IsAuthorized
                    app.RenderErrorMessage('Please sign in first.')
                    return
                end
                % ---------------------------------------------------------
                % Disable button to prevent double clicking
                event.Source.Enable = 'off'; drawnow;
                % ---------------------------------------------------------
                % Some vars for shorthand
                FileId = app.FilePropertiesTab.UserData.id;
                idxFile = matches(app.State.Files.Id, FileId);
                SubId = app.State.Files.SubId{idxFile};
                idxSub = matches(app.State.Subjects.Id, SubId);
                Path = app.State.Files.Path{idxFile};
                [~, Filename] = fileparts(Path);
                % ---------------------------------------------------------
                % Ask the user to confirm
                sel = uiconfirm(app.UIFigure, {'Are you sure to permanently delete the file?', Filename}, 'Delete data?', ...
                    'Options', {'No, cancel', 'Yes, delete'},...
                    'DefaultOption', 'No, cancel', ...
                    'CancelOption', 'No, cancel', ...
                    'Icon', 'question');
                % ---------------------------------------------------------
                % If the user pressed 'Cancel' or 'Esc', return to stop the current process
                if strcmpi(sel, 'No, cancel')
                    event.Source.Enable = 'on';
                    return
                end
                % ---------------------------------------------------------
                % Open waitbar
                app.SetLoading(true, event.Source);
                app.RenderWaitbar('Deleting file.', -1);
                % ---------------------------------------------------------
                % SET STATE
                app.State.Files(idxFile, :) = DeleteFile(app, app.State.Files(idxFile, :));
                % if not in error, delete the file from the state
                if ~strcmpi(app.State.Files.Status{idxFile}, 'error')
                    app.RemoveFileById(SubId, FileId);
                end
                % ---------------------------------------------------------
                % RENDER
                app.RenderFilesTreeFileNodeById(FileId);
                app.RenderFilesTreeSubjectNodeById(SubId);
                app.RenderPropertiesTabGroup('', ''); % Show the Dataset tab
                app.RenderFileAddProcessPanel();
                % ---------------------------------------------------------
                % Close waitbar
                app.SetLoading(false, event.Source);
                app.RenderWaitbar([]);
                % Tell the user the subject is deleted
                sel = uiconfirm(app.UIFigure, sprintf('File ''%s'' has been deleted from the dataset.', Filename), 'Success', ...
                    'Options',{'Ok'},...
                    'DefaultOption', 'Ok', ...
                    'Icon', 'success'); 
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
                app.SetLoading(false, event.Source);
                app.RenderWaitbar([]);
            end
        end

        % Value changed function: FileAddProcessInput
        function FileAddProcessInputValueChanged(app, event)
            try
                % ---------------------------------------------------------
                % RENDER
                app.RenderAddProcessButton();
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
            end
        end

        % Button pushed function: FileAddProcessButton
        function FileAddProcessButtonPushed(app, event)
            try
                % ---------------------------------------------------------
                % Check Auth status
                if ~app.State.Auth.IsAuthorized
                    app.RenderErrorMessage('Please sign in first.')
                    return
                end
                % ---------------------------------------------------------
                % Disable button to prevent double clicking
                event.Source.Enable = 'off'; drawnow;
                % ---------------------------------------------------------
                % Check which tab is selected
                switch app.FilesTabGroup.SelectedTab.Title
                    case 'Raw'
                        tree = app.FilesRawTree;
                    case 'Derivatives'
                        tree = app.FilesDerivativesTree;
                    case 'First level'
                        tree = app.FilesFirstlvlOutputTree;
                    case 'Group level'
                        tree = app.FilesGouplvlOutputTree;
                    otherwise % Cannot add processes to output files
                        event.Source.Enable = 'on';
                        return
                end
                % ---------------------------------------------------------
                % Select all the selected files
                FileIds = cellfun(@(d) ifelse(strcmpi(d.Slice, 'Files'), d.id, ''), {tree.CheckedNodes.UserData}, 'UniformOutput', false);
                FileList = SelectFileById(app, FileIds);
                % ---------------------------------------------------------
                % Check what process to add
                switch app.FileAddProcessInput.Value
                    % =====================================================
                    case 'Select and Preprocess Data'
                        % ---------------------------------------------------------
                        % Call the GUI
                        app.GUIs.PreprocData = PreprocData(app, FileList);
                        if isvalid(app.GUIs.PreprocData)
                            uiwait(app.GUIs.PreprocData.UIFigure);
                        end
                        % ---------------------------------------------------------
                        % Check if user pressed cancel
                        if ~app.Import.Return
                            event.Source.Enable = 'on';
                            return
                        end
                        % ---------------------------------------------------------
                        % Define which function to call to generate the processes
                        AddProcFcn = @(file, settings) app.AddProcessPreprocData(file, settings);
                    % =====================================================
                    case 'Concatenate Data'
                        % ---------------------------------------------------------
                        % Call the GUI
                        app.GUIs.ConcatenateData = ConcatenateData(app, FileList);
                        if isvalid(app.GUIs.ConcatenateData)
                            uiwait(app.GUIs.ConcatenateData.UIFigure);
                        end
                        % ---------------------------------------------------------
                        % Check if user pressed cancel
                        if ~app.Import.Return
                            event.Source.Enable = 'on';
                            return
                        end
                        tmp = {};
                        SubIds = unique(FileList.SubId);
                        for i = 1:length(SubIds)
                            idx = strcmpi(FileList.SubId, SubIds{i});
                            tmp{i, 1} = FileList(idx, :); %#ok<AGROW>
                        end
                        FileList = tmp;
                        % ---------------------------------------------------------
                        % Define which function to call to generate the processes
                        AddProcFcn = @(file, settings) app.AddProcessConcatenateData(file, settings);
                    case 'Power Spectral Analysis'
                        % ---------------------------------------------------------
                        % Call the GUI
                        app.GUIs.PowerSpectralAnalysisSettings = PowerSpectralAnalysisSettings(app, FileList);
                        uiwait(app.GUIs.PowerSpectralAnalysisSettings.UIFigure);
                        % ---------------------------------------------------------
                        % Check if user pressed cancel
                        if ~app.Import.Return
                            event.Source.Enable = 'on';
                            return
                        end
                        % ---------------------------------------------------------
                        % Define which function to call to generate the processes
                        AddProcFcn = @(file, settings) app.AddProcessPowerSpectralAnalysis(file, settings);
                    case 'Time-Frequency Spectrogram'
                        % ---------------------------------------------------------
                        % Spectrogram settings
                        SpecSettings = struct();
                        SpecSettings.ChanSel = {[]};
                        SpecSettings.SpectrogramType = 'Multitaper';
                        SpecSettings.TimeStep = 0.05;
                        SpecSettings.FreqStep = 0.25;
                        SpecSettings.MaxFreq = 30;
                        SpecSettings.Cycles = 7;
                        % Check if this is an epoched dataset...
                        if all(cellfun(@(f) isfield(f, 'RecordingType'), FileList.JSON))
                            % ... if it is, then it has a baseline (potentially)
                            SpecSettings.HasBaseline = all(cellfun(@(f) strcmpi(f.RecordingType, 'epoch'), FileList.JSON));
                        else
                            % ... otherwise it has not
                            SpecSettings.HasBaseline = false;
                        end
                        % Do not perform baseline normalization by default
                        SpecSettings.DoBaselineNorm = false;
                        SpecSettings.BaselineMethod = 'absolute';
                        SpecSettings.BaselineInterval = [-1, 0]; % seconds
                        % ---------------------------------------------------------
                        % Get common chanlocs
                        AllChanlocs = cellfun(@(clocs, chans) clocs(strcmpi(chans.type, 'EEG'), :), FileList.chanlocs, FileList.channels, 'UniformOutput', false);
                        if length(FileList) == 1
                            Chanlocs = AllChanlocs{1};
                        else
                            [~, idx] = intersect(AllChanlocs{1}.name, AllChanlocs{2}.name, 'stable');
                            Chanlocs = FileList.chanlocs{1}(idx, :);
                            for i = 3:size(FileList, 1)
                                [~, idx] = intersect(Chanlocs.name, AllChanlocs{i}.name, 'stable');
                                Chanlocs = Chanlocs(idx, :);
                                if isempty(Chanlocs)
                                    app.RenderErrorMessage('There were no common channels in the files. Cannot perform spectrogram analysis without common channel names.');
                                    return;
                                end
                            end
                        end
                        Chanlocs.Properties.VariableNames = {'labels', 'X', 'Y', 'Z'};
                        Chanlocs = convertlocs(table2struct(Chanlocs), 'auto');
                        % ---------------------------------------------------------
                        % Call the GUI
                        app.GUIs.SpectrogramSettings = SpectrogramSettings(app, SpecSettings, Chanlocs);
                        uiwait(app.GUIs.SpectrogramSettings.UIFigure);
                        % ---------------------------------------------------------
                        % Check if user pressed cancel
                        if ~app.Import.Return
                            event.Source.Enable = 'on';
                            return
                        end
                        % ---------------------------------------------------------
                        % Define which function to call to generate the processes
                        AddProcFcn = @(file, settings) app.AddProcessSpectrogramSettings(file, settings);
                    case 'Save As 10-20 Dataset'
                        % We don't have to import any settings but we still need to set it
                        app.Import.Data = struct();
                        % ---------------------------------------------------------
                        % Define which function to call to generate the processes
                        AddProcFcn = @(file, settings) app.AddProcessSaveAsTenTwentyDataset(file, settings);
                    case 'Interpolate Power-Spectral Outliers (Single Subject)'
                        % ---------------------------------------------------------
                        % Call the GUI
                        app.GUIs.InterpolatePowerSpectralOutliers = InterpolatePowerSpectralOutliers(app, FileList);
                        uiwait(app.GUIs.InterpolatePowerSpectralOutliers.UIFigure);
                        % ---------------------------------------------------------
                        % Check if user pressed cancel
                        if ~app.Import.Return
                            event.Source.Enable = 'on';
                            return
                        end
                        % ---------------------------------------------------------
                        % Define which function to call to generate the processes
                        AddProcFcn = @(file, settings) app.AddProcessInterpolatePowerSpectralOutliers(file, settings);
                    case {...
                            'Correlation', ...
                            'Two-sample unpaired t-test', ...
                            'Two-sample paired t-test', ...
                            'One-way ANOVA', ...
                            'Two-way ANOVA', ...
                            'General Linear Model'}
                        % ---------------------------------------------------------
                        % Call the GUI
                        app.GUIs.GeneralLinearModel = GeneralLinearModel(app, FileList, app.FileAddProcessInput.Value);
                        if isvalid(app.GUIs.GeneralLinearModel)
                            uiwait(app.GUIs.GeneralLinearModel.UIFigure);
                        end
                        % ---------------------------------------------------------
                        % Check if user pressed cancel
                        if ~app.Import.Return
                            event.Source.Enable = 'on';
                            return
                        end
                        % Crop the FileList to one so we run the AddProcFnc only once, its just a trick
                        FileList = FileList(1, :);
                        % ---------------------------------------------------------
                        % Define which function to call to generate the processes
                        AddProcFcn = @(file, settings) app.AddProcessGeneralLinearModel(file, settings);
                    case 'Delete all checked files'
                        % ---------------------------------------------------------
                        % Confirm that this action is intended
                        sel = uiconfirm(app.UIFigure, {sprintf('Are you sure you want to permanently delete %i files?', size(FileList, 1)), 'This cannot be undone.'}, 'Warning', ...
                            'Options',{'Yes, delete', 'No, cancel'},...
                            'DefaultOption', 'No, cancel', ...
                            'Icon', 'warning');
                        if strcmpi(sel, 'No, cancel')
                            event.Source.Enable = 'on';
                            return
                        end
                        % Dummy 'settings'
                        app.Import.Data = '';
                        % ---------------------------------------------------------
                        % Define which function to call to generate the processes
                        AddProcFcn = @(file, settings) app.AddProcessDeleteAllCheckedFiles(file, settings);
                    otherwise
                        event.Source.Enable = 'on';
                        return
                end
                % ---------------------------------------------------------
                % Create Process for each checked file
                for i = 1:size(FileList, 1)
                    % ---------------------------------------------------------
                    % Add the authentication
                    app.Import.Data.Auth = app.State.Auth.Name;
                    % ---------------------------------------------------------
                    % SET STATE
                    Process = AddProcFcn(FileList(i, :), app.Import.Data);
                    % ---------------------------------------------------------
                    % Check if the process already exists
                    if any(strcmpi({app.State.Processes.Name}, Process.Name))
                        sel = uiconfirm(app.UIFigure, {'Cannot add the process. The process already exists:', Process.Name}, 'Warning', ...
                            'Options',{'OK'},...
                            'DefaultOption', 'OK', ...
                            'Icon', 'warning'); 
                        continue
                    end
                    % ---------------------------------------------------------
                    % Check if the output file already exists
                    if exist(Process.OutputPath) ~= 0 %#ok<EXIST> 
                        sel = uiconfirm(app.UIFigure, {'Cannot add the process. The output file already exists:', Process.OutputPath, '', 'Delete this file first and try again please.'}, 'Warning', ...
                            'Options',{'OK'},...
                            'DefaultOption', 'OK', ...
                            'Icon', 'Warning'); 
                        continue
                    end
                    % All checks completed, ready to go!
                    % ---------------------------------------------------------
                    % Add this process to the list
                    idx = length(app.State.Processes)+1;
                    app.State.Processes(idx) = Process;
                    % ---------------------------------------------------------
                    % RENDER
                    app.RenderProcessesPanel();
                    % If more than 3 processes, then show waitbar
                    if size(FileList, 1) > 3
                        app.RenderWaitbar(sprintf('Adding process %i of %i.', i, size(FileList, 1)), i/size(FileList, 1));
                    end
                end
                app.RenderWaitbar([]);
                event.Source.Enable = 'on';
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
                app.RenderWaitbar([]);
                event.Source.Enable = 'on';
            end
        end

        % Value changed function: ProcessesListBox
        function ProcessesListBoxValueChanged(app, event)
            try
                % ---------------------------------------------------------
                % RENDER
                app.RenderProcessesPanel();
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
            end
        end

        % Button pushed function: ProcessesMoveUpButton
        function ProcessesMoveUpButtonPushed(app, event)
            try
                % ---------------------------------------------------------
                % Check Auth status
                if ~app.State.Auth.IsAuthorized
                    app.RenderErrorMessage('Please sign in first.')
                    return
                end
                % ---------------------------------------------------------
                % SET STATE
                currentIdx = find(strcmpi(app.ProcessesListBox.Items, app.ProcessesListBox.Value));
                newIdx = currentIdx - 1;
                if newIdx < 1
                    return
                end
                app.State.Processes = [app.State.Processes(1:newIdx-1), app.State.Processes(currentIdx), app.State.Processes(newIdx), app.State.Processes(currentIdx+1:end)];
                % ---------------------------------------------------------
                % RENDER
                app.RenderProcessesPanel();
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
            end
        end

        % Button pushed function: ProcessesMoveDownButton
        function ProcessesMoveDownButtonPushed(app, event)
            try
                % ---------------------------------------------------------
                % Check Auth status
                if ~app.State.Auth.IsAuthorized
                    app.RenderErrorMessage('Please sign in first.')
                    return
                end
                % ---------------------------------------------------------
                % SET STATE
                currentIdx = find(strcmpi(app.ProcessesListBox.Items, app.ProcessesListBox.Value));
                newIdx = currentIdx + 1;
                if newIdx > length(app.State.Processes)
                    return
                end
                app.State.Processes = [app.State.Processes(1:currentIdx-1), app.State.Processes(newIdx), app.State.Processes(currentIdx), app.State.Processes(newIdx+1:end)];
                % ---------------------------------------------------------
                % RENDER
                app.RenderProcessesPanel();
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
            end
        end

        % Button pushed function: ProcessesDeleteButton
        function ProcessesDeleteButtonPushed(app, event)
            try
                % ---------------------------------------------------------
                % Check Auth status
                if ~app.State.Auth.IsAuthorized
                    app.RenderErrorMessage('Please sign in first.')
                    return
                end
                % ---------------------------------------------------------
                % SET STATE
                currentIdx = find(strcmpi(app.ProcessesListBox.Items, app.ProcessesListBox.Value));
                app.State.Processes(currentIdx) = [];
                % ---------------------------------------------------------
                % RENDER
                app.RenderProcessesPanel();
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
            end
        end

        % Button pushed function: ProcessesRunButton
        function ProcessesRunButtonPushed(app, event)
            try
                % ---------------------------------------------------------
                % Check Auth status
                if ~app.State.Auth.IsAuthorized
                    app.RenderErrorMessage('Please sign in first.')
                    return
                end
                % ---------------------------------------------------------
                % Disable button to prevent double clicking
                event.Source.Enable = 'off'; drawnow;
                % ---------------------------------------------------------
                % Open waitbar
                cnt = 0;
                tot = numel(app.State.Processes);
                % ---------------------------------------------------------
                % Run processes as long as there are some
                while ~isempty(app.State.Processes)
                    cnt = cnt+1;
                    try
                        % ---------------------------------------------------------
                        % Update the waitbar
                        app.RenderWaitbar(sprintf('Process %i of %i (started %s)', cnt, tot, datestr(now, 'HH:MM:SS')), cnt/tot);
                        % ---------------------------------------------------------
                        % SET STATE: Append the process name with 'started'
                        app.State.Processes(1).StartDateTime = datestr(now, 'yyyy-mm-ddTHH:MM:SS');
                        app.State.Processes(1).Name = [app.State.Processes(1).Name, ' (started ', datestr(now, 'HH:MM:SS'), ')'];
                        % ---------------------------------------------------------
                        % RENDER
                        app.RenderProcessesPanel();
                        drawnow;
                        % ---------------------------------------------------------
                        % Call the process function
                        [ ...
                            app.State.Results(cnt).ArgOut.Data, ...
                            app.State.Results(cnt).Next, ...
                            app.State.Results(cnt).Warnings] = ...
                            app.State.Processes(1).Function( ...
                            app.State.Processes(1).ArgIn{:});
                    catch ME
                        % An error occurred during this process, save for debugging later
                        printME(ME);
                        app.State.Results(cnt).Errors = ME;
                    end
                    % ---------------------------------------------------------
                    % Ok the process exited normally, store the process in the results slice
                    app.State.Results(cnt).Process = app.State.Processes(1);
                    % ---------------------------------------------------------
                    % Remove the process from the list
                    app.State.Processes(1) = [];
                    % ---------------------------------------------------------
                    % RENDER
                    app.RenderProcessesPanel();
                    drawnow;
                end
                % ---------------------------------------------------------
                % Check if we need to show any errors
                showErrors = false;
                for i = 1:length(app.State.Results)
                    if ~isempty(app.State.Results(i).Errors) || ~isempty(app.State.Results(i).Warnings)
                        showErrors = true;
                        break
                    end
                end
                if showErrors
                    ShowErrors(app, app.State.Results);
                end
                % ---------------------------------------------------------
                % Check what to do next
                for i = 1:length(app.State.Results)
                    if isempty(app.State.Results(i).Errors)
                        switch app.State.Results(i).Next
                            case 'AddFile'
                                for j = 1:length(app.State.Results(i).ArgOut.Data)
                                    % ---------------------------------------------------------
                                    % Check if there is any data at all
                                    if isfield(app.State.Results(i).ArgOut.Data(j), 'pnts')
                                        if app.State.Results(i).ArgOut.Data(j).pnts == 1
                                            continue
                                        end
                                    end
                                    % ---------------------------------------------------------
                                    % Extract subject and its id
                                    SubName = app.State.Results(i).ArgOut.Data(j).subject;
                                    [Subject, SubId, idxSub] = SelectSubjectByName(app, ['sub-', SubName]);
                                    % ---------------------------------------------------------
                                    % If subject was not found, throw error
                                    if isempty(SubId{1})
                                        warning('A new file was added to an unknown subject with the name ''%s''.', SubName)
                                        continue
                                    end
                                    % ---------------------------------------------------------
                                    % Create a structure containing info about the new file
                                    NewFile = dir([app.State.Results(i).ArgOut.Data(j).filepath, '/', app.State.Results(i).ArgOut.Data(j).filename]);
                                    % Check what file-type it is
                                    if regexpIdx(app.State.Results(i).ArgOut.Data(j).filepath, '\/rawdata\/')
                                        FileType = 'raw';
                                    elseif regexpIdx(app.State.Results(i).ArgOut.Data(j).filepath, '\/*-output-fstlvl\/')
                                        FileType = 'fstlvl';
                                    else
                                        FileType = 'derivative';
                                    end
                                    % Add the new file to the state
                                    [app.State.Files, thisSubjectFileIds] = AddFilesToState(app.State.Files, SubId{1}, NewFile, FileType, Subject.FileIds{1});
                                    % Add the new file's id to the subject's state
                                    app.State.Subjects.FileIds{idxSub} = thisSubjectFileIds;
                                end
                            case 'RemoveFile'
                                % ---------------------------------------------------------
                                File = app.State.Results(i).ArgOut.Data;
                                idxFile = matches(app.State.Files.Id, File.Id{1});
                                app.State.Files(idxFile, :) = File;
                                % if not in error, delete the file from the state
                                if ~strcmpi(app.State.Files.Status{idxFile}, 'error')
                                    app.RemoveFileById(app.State.Files.SubId{idxFile}, app.State.Files.Id{idxFile});
                                end
                        end
                    end
                end
                % ---------------------------------------------------------
                % Delete the processes and results
                app.State.Processes(:) = [];
                app.State.Results(:) = [];
                % ---------------------------------------------------------
                % RENDER
                % Set properties in the raw and derivatives tabs
                app.RenderFilesTree('raw');
                app.RenderFilesTree('derivative');
                app.RenderFilesTree('fstlvl');
                app.ToggleFilesTree(true);
                app.RenderFilesAddSubjectButton();
                app.RenderFilesExpandButton();
                app.RenderFilesSelectButton();
                app.RenderFilesFilterEditField();
                % Set the properties of the add process panel
                app.RenderFileAddProcessPanel();
                app.RenderProcessesPanel();
                % ---------------------------------------------------------
                % Close the waitbar
                app.RenderWaitbar([]);
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderProcessesPanel();
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
                app.RenderWaitbar([]);
            end
        end

        % Context menu opening function: FilesRawSubjectContextMenu
        function FilesRawSubjectContextMenuOpening(app, event)
            try
                % Set the header value to that of the current subject id.
                if isempty(app.UIFigure.CurrentObject)
                    return
                end
                SubName = app.UIFigure.CurrentObject.Text;
                app.FilesRawSubjectContextHeader.Text = SubName;
                app.RenderFilesRawSubjectContextImportEEGMenu(SubName);
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
            end
        end

        % Menu selected function: FilesRawSubjectContextImportEEGMenu
        function FilesRawSubjectContextImportEEGMenuSelected(app, event)
            try
                % ---------------------------------------------------------
                % Check Auth status
                if ~app.State.Auth.IsAuthorized
                    app.RenderErrorMessage('Please sign in first.')
                    return
                end
                % ---------------------------------------------------------
                % Render waitbar
                app.RenderWaitbar('Define import settings', 0);
                % ---------------------------------------------------------
                % Extract the subject name from the context menu header
                SubjName = strrep(app.FilesRawSubjectContextHeader.Text, 'sub-', '');
                % ---------------------------------------------------------
                % Call the add file GUI
                app.GUIs.AddFile = AddFile(app);
                uiwait(app.GUIs.AddFile.UIFigure);
                % ---------------------------------------------------------
                % Navigate back to the Protocol path
                cd(app.State.Protocol.Path);
                % ---------------------------------------------------------
                % Check if user pressed cancel
                if ~app.Import.Return
                    app.RenderWaitbar([]);
                    return
                end
                % ---------------------------------------------------------
                % Add the authentication and protocol path
                app.Import.Data.Auth = app.State.Auth.Name;
                app.Import.Data.ProtocolPath = app.State.Protocol.Path;
                % ---------------------------------------------------------
                % Specify the path
                app.Import.Data.TargetFile.KeyVals.sub = SubjName;
                if app.Import.Data.TargetFile.KeyVals.exclude.ses
                    Filepath = [app.State.Protocol.Path, '/rawdata/', ...
                        ['sub-', SubjName], '/', ...
                        lower(app.Import.Data.TargetFile.KeyVals.filetype)];
                else
                    Filepath = [app.State.Protocol.Path, '/rawdata/', ...
                        ['sub-', SubjName], '/', ...
                        ['ses-', app.Import.Data.TargetFile.KeyVals.ses], '/', ...
                        lower(app.Import.Data.TargetFile.KeyVals.filetype)];
                end
                % ---------------------------------------------------------
                % Specify the setname name
                switch app.Import.Data.TargetFileType
                    case 12
                        Filename = [struct2filename(app.Import.Data.TargetFile.KeyVals), '.edf'];
                    otherwise
                        Filename = [struct2filename(app.Import.Data.TargetFile.KeyVals), '.set'];
                end
                % ---------------------------------------------------------
                % Specify full filepath
                app.Import.Data.TargetFilePath = [Filepath, '/', Filename];
                % Clean up
                app.Import.Data = rmfield(app.Import.Data, 'SourceFile');
                app.Import.Data = rmfield(app.Import.Data, 'TargetFile');
                % ---------------------------------------------------------
                % Create the filepath if it does not exist yet
                if exist(Filepath, 'dir') == 0
                    try
                        CreateNewDirectory(Filepath)
                    catch ME % Check if the filepath is created correctly, if not show error message and return
                        app.RenderWaitbar([]);
                        sel = uiconfirm(app.UIFigure, 'Sorry, an unexpected error occurred when creating a new directory.', 'Error', ...
                            'Options',{'Ok'},...
                            'DefaultOption', 'Ok', ...
                            'Icon', 'error'); 
                        printME(ME);
                        return
                    end
                end
                % ---------------------------------------------------------
                % Check if output file already exists
                if ...
                        exist(strrep(app.Import.Data.TargetFilePath, '.set', '.edf'), 'file') ~= 0 || ...
                        exist(strrep(app.Import.Data.TargetFilePath, '.edf', '.set'), 'file') ~= 0
                    app.RenderWaitbar([]);
                    sel = uiconfirm(app.UIFigure, {'Cannot add the file. The output file already exists:', app.Import.Data.TargetFilePath}, 'Error', ...
                        'Options',{'OK'},...
                        'DefaultOption', 'OK', ...
                        'Icon', 'error');
                    return
                end
                % ---------------------------------------------------------
                % Check if the process already exists
                ProcessName = sprintf('Add file: %s', Filename);
                for i = 1:length(app.State.Processes)
                    if strcmpi(app.State.Processes(i).Name, ProcessName)
                        app.RenderWaitbar([]);
                        sel = uiconfirm(app.UIFigure, {'Cannot add the process. The process already exists:', ProcessName}, 'Error', ...
                            'Options',{'OK'},...
                            'DefaultOption', 'OK', ...
                            'Icon', 'error');
                        return
                    end
                end
                % All checks completed, ready to go!
                % ---------------------------------------------------------
                % Add this process to the list
                idx = length(app.State.Processes)+1;
                app.State.Processes(idx).Name = ProcessName;
                app.State.Processes(idx).Function = @ImportFile;
                app.State.Processes(idx).ArgIn = {...
                    app.Import.Data, ...
                    };
                % ---------------------------------------------------------
                % RENDER
                app.RenderProcessesPanel();
                app.RenderWaitbar([]);
            catch ME
                % ---------------------------------------------------------
                % Catch and print any errors
                printME(ME);
                app.RenderWaitbar([]);
                app.RenderErrorMessage('A critical error occurred. See the command window for more information.');
            end
        end

        % Menu selected function: CopyPathnameMenu
        function CopyPathnameMenuSelected(app, event)
            % ---------------------------------------------------------
            % Check which node is selected
            Data = app.UIFigure.CurrentObject.UserData;
            if isfield(Data, 'id')
                idxFile = matches(app.State.Files.Id, Data.id);
            else
                return
            end
            Path = app.State.Files.Path{idxFile};
            clipboard('copy', Path)
            sel = uiconfirm(app.UIFigure, 'Path copied to clipboard.', 'Info', ...
                'Options',{'OK'},...
                'DefaultOption', 'OK', ...
                'Icon', 'info'); %#ok<*NASGU> 
        end

        % Menu selected function: LoadEEGdataMenu
        function LoadEEGdataMenuSelected(app, event)
            % ---------------------------------------------------------
            % Check which node is selected
            Data = app.UIFigure.CurrentObject.UserData;
            if isfield(Data, 'id')
                idxFile = matches(app.State.Files.Id, Data.id);
            else
                return
            end
            Path = app.State.Files.Path{idxFile};
            assignin('base', 'EEG', LoadDataset(Path, 'all')); %#ok<ADMTHDINV>
            sel = uiconfirm(app.UIFigure, 'Loaded EEG data to the variable ''EEG'' in the base workspace.', 'Info', ...
                'Options',{'OK'},...
                'DefaultOption', 'OK', ...
                'Icon', 'info'); %#ok<*NASGU> 
        end

        % Menu selected function: LoadEEGheaderMenu
        function LoadEEGheaderMenuSelected(app, event)
            % ---------------------------------------------------------
            % Check which node is selected
            Data = app.UIFigure.CurrentObject.UserData;
            if isfield(Data, 'id')
                idxFile = matches(app.State.Files.Id, Data.id);
            else
                return
            end
            Path = app.State.Files.Path{idxFile};
            assignin('base', 'HDR', LoadDataset(Path, 'header')); %#ok<ADMTHDINV>
            sel = uiconfirm(app.UIFigure, 'Loaded EEG header to the variable ''HDR'' in the base workspace.', 'Info', ...
                'Options',{'OK'},...
                'DefaultOption', 'OK', ...
                'Icon', 'info'); %#ok<*NASGU> 
        end

        % Menu selected function: ReadTheDocsMenu
        function ReadTheDocsMenuSelected(app, event)
            try
                web('https://eeg-processor.readthedocs.io', '-browser')
            catch ME
                printME(ME)
            end
        end

        % Menu selected function: BIDSSpecificationMenu
        function BIDSSpecificationMenuSelected(app, event)
            try
                web('https://bids-specification.readthedocs.io', '-browser')
            catch ME
                printME(ME)
            end
        end

        % Menu selected function: DebugModeMenu
        function DebugModeMenuSelected(app, event)
            keyboard
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'EEG Processor';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.ReadTheDocsMenu = uimenu(app.HelpMenu);
            app.ReadTheDocsMenu.MenuSelectedFcn = createCallbackFcn(app, @ReadTheDocsMenuSelected, true);
            app.ReadTheDocsMenu.Text = 'Read the Docs';
            app.BIDSSpecificationMenu = uimenu(app.HelpMenu);
            app.BIDSSpecificationMenu.MenuSelectedFcn = createCallbackFcn(app, @BIDSSpecificationMenuSelected, true);
            app.BIDSSpecificationMenu.Text = 'BIDS Specification';
            app.DevelopMenu = uimenu(app.UIFigure);
            app.DevelopMenu.Text = 'Develop';
            app.DebugModeMenu = uimenu(app.DevelopMenu);
            app.DebugModeMenu.MenuSelectedFcn = createCallbackFcn(app, @DebugModeMenuSelected, true);
            app.DebugModeMenu.Text = 'Debug mode';
            app.UIFigureGridLayout = uigridlayout(app.UIFigure);
            app.LoadingImage = uiimage(app.UIFigureGridLayout);
            app.LoadingImage.Visible = 'off';
            app.MainPanel = uipanel(app.UIFigureGridLayout);
            app.MainGridLayout = uigridlayout(app.MainPanel);
            app.DatasetPanel = uipanel(app.MainGridLayout);
            app.DatasetGridLayout = uigridlayout(app.DatasetPanel);
            app.DatasetBrowseButton = uibutton(app.DatasetGridLayout, 'push');
            app.DatasetBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @DatasetBrowseButtonPushed, true);
            app.DatasetBrowseButton.Tooltip = {'browse'};
            app.DatasetBrowseButton.Text = '';
            app.DatasetPathEditField = uieditfield(app.DatasetGridLayout, 'text');
            app.DatasetPathEditField.Editable = 'off';
            app.DatasetPathEditField.Placeholder = 'Browse to select the root BIDS folder that contains the ''rawdata'' folder.';
            app.AuthButton = uibutton(app.DatasetGridLayout, 'push');
            app.AuthButton.ButtonPushedFcn = createCallbackFcn(app, @AuthButtonPushed, true);
            app.AuthButton.Tooltip = {''};
            app.AuthButton.Text = 'Sign in';
            app.AuthLabel = uilabel(app.DatasetGridLayout);
            app.AuthLabel.Visible = 'off';
            app.AuthLabel.Text = '';
            app.AuthEditField = uieditfield(app.DatasetGridLayout, 'text');
            app.AuthEditField.Placeholder = 'Enter your name';
            app.FilesPanel = uipanel(app.MainGridLayout);
            app.FilesGridLayout = uigridlayout(app.FilesPanel);
            app.FilesAddSubjectButton = uibutton(app.FilesGridLayout, 'push');
            app.FilesAddSubjectButton.ButtonPushedFcn = createCallbackFcn(app, @FilesAddSubjectButtonPushed, true);
            app.FilesAddSubjectButton.Enable = 'off';
            app.FilesAddSubjectButton.Tooltip = {'add subject'};
            app.FilesAddSubjectButton.Text = '';
            app.FilesExpandButton = uibutton(app.FilesGridLayout, 'push');
            app.FilesExpandButton.ButtonPushedFcn = createCallbackFcn(app, @FilesExpandButtonPushed, true);
            app.FilesExpandButton.Enable = 'off';
            app.FilesExpandButton.Tooltip = {'expand'};
            app.FilesExpandButton.Text = '';
            app.FilesSelectButton = uibutton(app.FilesGridLayout, 'push');
            app.FilesSelectButton.ButtonPushedFcn = createCallbackFcn(app, @FilesSelectButtonPushed, true);
            app.FilesSelectButton.Enable = 'off';
            app.FilesSelectButton.Tooltip = {'select all'};
            app.FilesSelectButton.Text = '';
            app.FilesFilterEditField = uieditfield(app.FilesGridLayout, 'text');
            app.FilesFilterEditField.ValueChangedFcn = createCallbackFcn(app, @FilesFilterEditFieldValueChanged, true);
            app.FilesFilterEditField.Enable = 'off';
            app.FilesFilterEditField.Placeholder = 'Filter';
            app.FilesTabGroup = uitabgroup(app.FilesGridLayout);
            app.FilesTabGroup.SelectionChangedFcn = createCallbackFcn(app, @FilesTabGroupSelectionChanged, true);
            app.FilesRawTab = uitab(app.FilesTabGroup);
            app.FilesRawTab.Title = 'Raw';
            app.FilesRawGridLayout = uigridlayout(app.FilesRawTab);
            app.FilesRawTree = uitree(app.FilesRawGridLayout, 'checkbox');
            app.FilesRawTree.SelectionChangedFcn = createCallbackFcn(app, @FilesTreeSelectionChanged, true);
            % Assign Checked Nodes
            app.FilesRawTree.CheckedNodesChangedFcn = createCallbackFcn(app, @FilesTreeCheckedNodesChanged, true);
            app.FilesDerivativesTab = uitab(app.FilesTabGroup);
            app.FilesDerivativesTab.Title = 'Derivatives';
            app.FilesDerivativesGridLayout = uigridlayout(app.FilesDerivativesTab);
            app.FilesDerivativesTree = uitree(app.FilesDerivativesGridLayout, 'checkbox');
            app.FilesDerivativesTree.SelectionChangedFcn = createCallbackFcn(app, @FilesTreeSelectionChanged, true);
            % Assign Checked Nodes
            app.FilesDerivativesTree.CheckedNodesChangedFcn = createCallbackFcn(app, @FilesTreeCheckedNodesChanged, true);
            app.FilesFirstlvlOutputTab = uitab(app.FilesTabGroup);
            app.FilesFirstlvlOutputTab.Title = 'First level';
            app.FilesFirstlvlOutputGridLayout = uigridlayout(app.FilesFirstlvlOutputTab);
            app.FilesFirstlvlOutputTree = uitree(app.FilesFirstlvlOutputGridLayout, 'checkbox');
            app.FilesFirstlvlOutputTree.SelectionChangedFcn = createCallbackFcn(app, @FilesTreeSelectionChanged, true);
            % Assign Checked Nodes
            app.FilesFirstlvlOutputTree.CheckedNodesChangedFcn = createCallbackFcn(app, @FilesTreeCheckedNodesChanged, true);
            app.FileAddProcessPanel = uipanel(app.FilesGridLayout);
            app.FileAddProcessPanel.Enable = 'off';
            app.FileAddProcessPanel.Title = 'Apply process to selected files';
            app.FileAddProcessGridLayout = uigridlayout(app.FileAddProcessPanel);
            app.FileAddProcessInput = uidropdown(app.FileAddProcessGridLayout);
            app.FileAddProcessInput.Items = {'Select...'};
            app.FileAddProcessInput.ValueChangedFcn = createCallbackFcn(app, @FileAddProcessInputValueChanged, true);
            app.FileAddProcessInput.Value = 'Select...';
            app.FileAddProcessButton = uibutton(app.FileAddProcessGridLayout, 'push');
            app.FileAddProcessButton.ButtonPushedFcn = createCallbackFcn(app, @FileAddProcessButtonPushed, true);
            app.FileAddProcessButton.Enable = 'off';
            app.FileAddProcessButton.Tooltip = {'add to processes'};
            app.FileAddProcessButton.Text = '';
            app.PropertiesTabGroup = uitabgroup(app.MainGridLayout);
            app.DatasetPropertiesTab = uitab(app.PropertiesTabGroup);
            app.DatasetPropertiesTab.Title = 'Dataset';
            app.DatasetPropertiesGridLayout = uigridlayout(app.DatasetPropertiesTab);
            app.NoDatasetLoadedLabel = uilabel(app.DatasetPropertiesGridLayout);
            app.NoDatasetLoadedLabel.Text = 'No dataset loaded.';
            app.DatasetNamePanel = uipanel(app.DatasetPropertiesGridLayout);
            app.DatasetNamePanel.Title = 'Dataset';
            app.DatasetNamePanel.Visible = 'off';
            app.DatasetNameGridLayout = uigridlayout(app.DatasetNamePanel);
            app.NameEditFieldLabel_6 = uilabel(app.DatasetNameGridLayout);
            app.NameEditFieldLabel_6.Text = 'Name';
            app.DatasetNameEditField = uieditfield(app.DatasetNameGridLayout, 'text');
            app.DatasetNameEditField.ValueChangedFcn = createCallbackFcn(app, @DatasetPropertiesChanged, true);
            app.DatasetNameEditField.Tag = 'Name';
            app.DOIEditFieldLabel = uilabel(app.DatasetNameGridLayout);
            app.DOIEditFieldLabel.Text = 'DOI';
            app.DatasetDOIEditField = uieditfield(app.DatasetNameGridLayout, 'text');
            app.DatasetDOIEditField.ValueChangedFcn = createCallbackFcn(app, @DatasetPropertiesChanged, true);
            app.DatasetDOIEditField.Tag = 'DatasetDOI';
            app.DatasetDOIEditField.Placeholder = '10.xxxx';
            app.LicenseDropDownLabel = uilabel(app.DatasetNameGridLayout);
            app.LicenseDropDownLabel.Text = 'License';
            app.DatasetLicenseDropDown = uidropdown(app.DatasetNameGridLayout);
            app.DatasetLicenseDropDown.Items = {'Public Domain', 'CC BY', 'CC BY-SA', 'CC BY-ND', 'CC BY-NC', 'CC BY-NC-SA', 'CC BY-NC-ND'};
            app.DatasetLicenseDropDown.ValueChangedFcn = createCallbackFcn(app, @DatasetPropertiesChanged, true);
            app.DatasetLicenseDropDown.Tag = 'License';
            app.DatasetLicenseDropDown.Tooltip = {'chooser-beta.creativecommons.org'};
            app.DatasetLicenseDropDown.Value = 'Public Domain';
            app.DatasetSessionsPanel = uipanel(app.DatasetPropertiesGridLayout);
            app.DatasetSessionsPanel.Title = 'Sessions';
            app.DatasetSessionsPanel.Visible = 'off';
            app.DatasetSessionsGridLayout = uigridlayout(app.DatasetSessionsPanel);
            app.DatasetSessionsAddButton = uibutton(app.DatasetSessionsGridLayout, 'push');
            app.DatasetSessionsAddButton.ButtonPushedFcn = createCallbackFcn(app, @DatasetSessionsAddButtonPushed, true);
            app.DatasetSessionsAddButton.Tooltip = {'add'};
            app.DatasetSessionsAddButton.Text = '';
            app.DatasetSessionsEditButton = uibutton(app.DatasetSessionsGridLayout, 'push');
            app.DatasetSessionsEditButton.ButtonPushedFcn = createCallbackFcn(app, @DatasetSessionsEditButtonPushed, true);
            app.DatasetSessionsEditButton.Enable = 'off';
            app.DatasetSessionsEditButton.Tooltip = {'edit'};
            app.DatasetSessionsEditButton.Text = '';
            app.DatasetSessionsListBox = uilistbox(app.DatasetSessionsGridLayout);
            app.DatasetSessionsListBox.Items = {};
            app.DatasetSessionsListBox.Value = {};
            app.DatasetTasksPanel = uipanel(app.DatasetPropertiesGridLayout);
            app.DatasetTasksPanel.Title = 'Tasks';
            app.DatasetTasksPanel.Visible = 'off';
            app.DatasetTasksGridLayout = uigridlayout(app.DatasetTasksPanel);
            app.DatasetTasksAddButton = uibutton(app.DatasetTasksGridLayout, 'push');
            app.DatasetTasksAddButton.ButtonPushedFcn = createCallbackFcn(app, @DatasetTasksAddButtonPushed, true);
            app.DatasetTasksAddButton.Tooltip = {'add'};
            app.DatasetTasksAddButton.Text = '';
            app.DatasetTasksEditButton = uibutton(app.DatasetTasksGridLayout, 'push');
            app.DatasetTasksEditButton.ButtonPushedFcn = createCallbackFcn(app, @DatasetTasksEditButtonPushed, true);
            app.DatasetTasksEditButton.Enable = 'off';
            app.DatasetTasksEditButton.Tooltip = {'edit'};
            app.DatasetTasksEditButton.Text = '';
            app.DatasetTasksListBox = uilistbox(app.DatasetTasksGridLayout);
            app.DatasetTasksListBox.Items = {};
            app.DatasetTasksListBox.Value = {};
            app.DatasetGeneratedByPanel = uipanel(app.DatasetPropertiesGridLayout);
            app.DatasetGeneratedByPanel.Title = 'Generated by';
            app.DatasetGeneratedByPanel.Visible = 'off';
            app.DatasetGeneratedByGridLayout = uigridlayout(app.DatasetGeneratedByPanel);
            app.NameEditFieldLabel_7 = uilabel(app.DatasetGeneratedByGridLayout);
            app.NameEditFieldLabel_7.Text = 'Name';
            app.DatasetGeneratedByNameEditField = uieditfield(app.DatasetGeneratedByGridLayout, 'text');
            app.DatasetGeneratedByNameEditField.ValueChangedFcn = createCallbackFcn(app, @DatasetPropertiesChanged, true);
            app.DatasetGeneratedByNameEditField.Tag = 'GeneratedByName';
            app.DescriptionTextAreaLabel = uilabel(app.DatasetGeneratedByGridLayout);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DatasetGeneratedByDescriptionTextArea = uitextarea(app.DatasetGeneratedByGridLayout);
            app.DatasetGeneratedByDescriptionTextArea.ValueChangedFcn = createCallbackFcn(app, @DatasetPropertiesChanged, true);
            app.DatasetGeneratedByDescriptionTextArea.Tag = 'GeneratedByDesc';
            app.DatasetAuthorsPanel = uipanel(app.DatasetPropertiesGridLayout);
            app.DatasetAuthorsPanel.Title = 'Authors';
            app.DatasetAuthorsPanel.Visible = 'off';
            app.DatasetAuthorsGridLayout = uigridlayout(app.DatasetAuthorsPanel);
            app.DatasetAuthorsTextArea = uitextarea(app.DatasetAuthorsGridLayout);
            app.DatasetAuthorsTextArea.ValueChangedFcn = createCallbackFcn(app, @DatasetPropertiesChanged, true);
            app.DatasetAuthorsTextArea.Tag = 'Authors';
            app.DatasetFundingPanel = uipanel(app.DatasetPropertiesGridLayout);
            app.DatasetFundingPanel.Title = 'Funding';
            app.DatasetFundingPanel.Visible = 'off';
            app.DatasetFundingGridLayout = uigridlayout(app.DatasetFundingPanel);
            app.DatasetFundingTextArea = uitextarea(app.DatasetFundingGridLayout);
            app.DatasetFundingTextArea.ValueChangedFcn = createCallbackFcn(app, @DatasetPropertiesChanged, true);
            app.DatasetFundingTextArea.Tag = 'Funding';
            app.DatasetEthicsPanel = uipanel(app.DatasetPropertiesGridLayout);
            app.DatasetEthicsPanel.Title = 'Ethics';
            app.DatasetEthicsPanel.Visible = 'off';
            app.DatasetEthicsGridLayout = uigridlayout(app.DatasetEthicsPanel);
            app.DatasetEthicsTextArea = uitextarea(app.DatasetEthicsGridLayout);
            app.DatasetEthicsTextArea.ValueChangedFcn = createCallbackFcn(app, @DatasetPropertiesChanged, true);
            app.DatasetEthicsTextArea.Tag = 'EthicsApprovals';
            app.DatasetAcknowledgePanel = uipanel(app.DatasetPropertiesGridLayout);
            app.DatasetAcknowledgePanel.Title = 'How to acknowledge';
            app.DatasetAcknowledgePanel.Visible = 'off';
            app.DatasetAcknowledgeGridLayout = uigridlayout(app.DatasetAcknowledgePanel);
            app.DatasetAcknowledgeTextArea = uitextarea(app.DatasetAcknowledgeGridLayout);
            app.DatasetAcknowledgeTextArea.ValueChangedFcn = createCallbackFcn(app, @DatasetPropertiesChanged, true);
            app.DatasetAcknowledgeTextArea.Tag = 'HowToAcknowledge';
            app.DatasetAcknowledgementsPanel = uipanel(app.DatasetPropertiesGridLayout);
            app.DatasetAcknowledgementsPanel.Title = 'Acknowledgements';
            app.DatasetAcknowledgementsPanel.Visible = 'off';
            app.DatasetAcknowledgementsGridLayout = uigridlayout(app.DatasetAcknowledgementsPanel);
            app.DatasetAcknowledgementsTextArea = uitextarea(app.DatasetAcknowledgementsGridLayout);
            app.DatasetAcknowledgementsTextArea.ValueChangedFcn = createCallbackFcn(app, @DatasetPropertiesChanged, true);
            app.DatasetAcknowledgementsTextArea.Tag = 'Acknowledgements';
            app.SubjectPropertiesTab = uitab(app.PropertiesTabGroup);
            app.SubjectPropertiesTab.Title = 'Subject';
            app.SubjectPropertiesGridLayout = uigridlayout(app.SubjectPropertiesTab);
            app.NoSubjectSelectedLabel = uilabel(app.SubjectPropertiesGridLayout);
            app.NoSubjectSelectedLabel.Text = 'Select a subject from the list.';
            app.SubjectPropertiesPanel = uipanel(app.SubjectPropertiesGridLayout);
            app.SubjectPropertiesPanel.Visible = 'off';
            app.SubjectPropertiesPanelGridLayout = uigridlayout(app.SubjectPropertiesPanel);
            app.SubjectPropertiesUndoButton = uibutton(app.SubjectPropertiesPanelGridLayout, 'push');
            app.SubjectPropertiesUndoButton.ButtonPushedFcn = createCallbackFcn(app, @SubjectPropertiesUndoButtonPushed, true);
            app.SubjectPropertiesUndoButton.Enable = 'off';
            app.SubjectPropertiesUndoButton.Tooltip = {'undo'};
            app.SubjectPropertiesUndoButton.Text = '';
            app.SubjectPropertiesSaveButton = uibutton(app.SubjectPropertiesPanelGridLayout, 'push');
            app.SubjectPropertiesSaveButton.ButtonPushedFcn = createCallbackFcn(app, @SubjectPropertiesSaveButtonPushed, true);
            app.SubjectPropertiesSaveButton.Enable = 'off';
            app.SubjectPropertiesSaveButton.Tooltip = {'save'};
            app.SubjectPropertiesSaveButton.Text = '';
            app.SubjectIDLabel = uilabel(app.SubjectPropertiesPanelGridLayout);
            app.SubjectIDLabel.Text = 'Subject ID';
            app.SubjectPropertiesIdEditField = uieditfield(app.SubjectPropertiesPanelGridLayout, 'text');
            app.SubjectPropertiesIdEditField.ValueChangedFcn = createCallbackFcn(app, @SubjectPropertiesIdEditFieldValueChanged, true);
            app.SubjectPropertiesDeletePanel = uipanel(app.SubjectPropertiesGridLayout);
            app.SubjectPropertiesDeletePanel.Visible = 'off';
            app.SubjectPropertiesDeleteGridLayout = uigridlayout(app.SubjectPropertiesDeletePanel);
            app.SubjectPropertiesDeleteLabel = uilabel(app.SubjectPropertiesDeleteGridLayout);
            app.SubjectPropertiesDeleteLabel.Text = 'Delete all files of this subject.';
            app.SubjectPropertiesDeleteButton = uibutton(app.SubjectPropertiesDeleteGridLayout, 'push');
            app.SubjectPropertiesDeleteButton.ButtonPushedFcn = createCallbackFcn(app, @SubjectPropertiesDeleteButtonPushed, true);
            app.SubjectPropertiesDeleteButton.Tooltip = {'delete subject and files'};
            app.SubjectPropertiesDeleteButton.Text = '';
            app.FilePropertiesTab = uitab(app.PropertiesTabGroup);
            app.FilePropertiesTab.Title = 'File';
            app.FilePropertiesGridLayout = uigridlayout(app.FilePropertiesTab);
            app.FilePropertiesDeletePanel = uipanel(app.FilePropertiesGridLayout);
            app.FilePropertiesDeletePanel.Visible = 'off';
            app.FilePropertiesDeleteGridLayout = uigridlayout(app.FilePropertiesDeletePanel);
            app.FilePropertiesDeleteLabel = uilabel(app.FilePropertiesDeleteGridLayout);
            app.FilePropertiesDeleteLabel.Text = 'Delete file.';
            app.FilePropertiesDeleteButton = uibutton(app.FilePropertiesDeleteGridLayout, 'push');
            app.FilePropertiesDeleteButton.ButtonPushedFcn = createCallbackFcn(app, @FilePropertiesDeleteButtonPushed, true);
            app.FilePropertiesDeleteButton.Tooltip = {'delete subject and files'};
            app.FilePropertiesDeleteButton.Text = '';
            app.FilePropertiesEventsPanel = uipanel(app.FilePropertiesGridLayout);
            app.FilePropertiesEventsPanel.Title = 'Events';
            app.FilePropertiesEventsPanel.Visible = 'off';
            app.FilePropertiesEventsGridLayout = uigridlayout(app.FilePropertiesEventsPanel);
            app.FilePropertiesEventGroupListBox = uilistbox(app.FilePropertiesEventsGridLayout);
            app.FilePropertiesEventGroupListBox.Items = {};
            app.FilePropertiesEventGroupListBox.ValueChangedFcn = createCallbackFcn(app, @FilePropertiesEventGroupListBoxValueChanged, true);
            app.FilePropertiesEventGroupListBox.Value = {};
            app.FilePropertiesEventInstanceListBox = uilistbox(app.FilePropertiesEventsGridLayout);
            app.FilePropertiesEventInstanceListBox.Items = {};
            app.FilePropertiesEventInstanceListBox.Value = {};
            app.FilePropertiesEventImportButton = uibutton(app.FilePropertiesEventsGridLayout, 'push');
            app.FilePropertiesEventImportButton.ButtonPushedFcn = createCallbackFcn(app, @FilePropertiesEventImportButtonPushed, true);
            app.FilePropertiesEventImportButton.Tooltip = {'import events'};
            app.FilePropertiesEventImportButton.Text = '';
            app.FilePropertiesHistoryPanel = uipanel(app.FilePropertiesGridLayout);
            app.FilePropertiesHistoryPanel.Title = 'History';
            app.FilePropertiesHistoryPanel.Visible = 'off';
            app.FilePropertiesHistoryGridLayout = uigridlayout(app.FilePropertiesHistoryPanel);
            app.FilePropertiesHistoryTree = uitree(app.FilePropertiesHistoryGridLayout);
            app.FilePropertiesPanel = uipanel(app.FilePropertiesGridLayout);
            app.FilePropertiesPanel.Visible = 'off';
            app.FilePropertiesPanelGridLayout = uigridlayout(app.FilePropertiesPanel);
            app.FilePropertiesInspectButton = uibutton(app.FilePropertiesPanelGridLayout, 'push');
            app.FilePropertiesInspectButton.ButtonPushedFcn = createCallbackFcn(app, @FilePropertiesInspectButtonPushed, true);
            app.FilePropertiesInspectButton.Tooltip = {'inspect'};
            app.FilePropertiesInspectButton.Text = '';
            app.FilePropertiesUndoButton = uibutton(app.FilePropertiesPanelGridLayout, 'push');
            app.FilePropertiesUndoButton.ButtonPushedFcn = createCallbackFcn(app, @FilePropertiesUndoButtonPushed, true);
            app.FilePropertiesUndoButton.Enable = 'off';
            app.FilePropertiesUndoButton.Tooltip = {'undo'};
            app.FilePropertiesUndoButton.Text = '';
            app.FilePropertiesSaveButton = uibutton(app.FilePropertiesPanelGridLayout, 'push');
            app.FilePropertiesSaveButton.ButtonPushedFcn = createCallbackFcn(app, @FilePropertiesSaveButtonPushed, true);
            app.FilePropertiesSaveButton.Enable = 'off';
            app.FilePropertiesSaveButton.Tooltip = {'save'};
            app.FilePropertiesSaveButton.Text = '';
            app.FilePropertiesDataLabel = uilabel(app.FilePropertiesPanelGridLayout);
            app.FilePropertiesDataLabel.Text = 'data';
            app.FilePropertiesDataValue = uilabel(app.FilePropertiesPanelGridLayout);
            app.FilePropertiesDataValue.Text = '';
            app.FilePropertiesChannelsLabel = uilabel(app.FilePropertiesPanelGridLayout);
            app.FilePropertiesChannelsLabel.Text = 'channels';
            app.FilePropertiesEEGChansValue = uilabel(app.FilePropertiesPanelGridLayout);
            app.FilePropertiesEEGChansValue.Text = '';
            app.FilePropertiesPNSChansValue = uilabel(app.FilePropertiesPanelGridLayout);
            app.FilePropertiesPNSChansValue.Text = '';
            app.FilePropertiesQualitiyLabel = uilabel(app.FilePropertiesPanelGridLayout);
            app.FilePropertiesQualitiyLabel.Text = 'quality';
            app.FilePropertiesQualitiyPanel = uipanel(app.FilePropertiesPanelGridLayout);
            app.FilePropertiesQualitiyGridLayout = uigridlayout(app.FilePropertiesQualitiyPanel);
            app.FilePropertiesQualitiyValue = uilabel(app.FilePropertiesQualitiyGridLayout);
            app.FilePropertiesQualitiyValue.Text = '';
            app.sessionLabel = uilabel(app.FilePropertiesPanelGridLayout);
            app.sessionLabel.Text = 'session';
            app.FilePropertiesSessionDropdown = uidropdown(app.FilePropertiesPanelGridLayout);
            app.FilePropertiesSessionDropdown.Items = {};
            app.FilePropertiesSessionDropdown.ValueChangedFcn = createCallbackFcn(app, @FilePropertiesValueChanged, true);
            app.FilePropertiesSessionDropdown.Tag = 'ses';
            app.FilePropertiesSessionDropdown.Value = {};
            app.FilePropertiesTaskDropdown = uidropdown(app.FilePropertiesPanelGridLayout);
            app.FilePropertiesTaskDropdown.Items = {};
            app.FilePropertiesTaskDropdown.ValueChangedFcn = createCallbackFcn(app, @FilePropertiesValueChanged, true);
            app.FilePropertiesTaskDropdown.Tag = 'task';
            app.FilePropertiesTaskDropdown.Value = {};
            app.taskLabel = uilabel(app.FilePropertiesPanelGridLayout);
            app.taskLabel.Text = 'task';
            app.FilePropertiesErrorPanel = uipanel(app.FilePropertiesGridLayout);
            app.FilePropertiesErrorPanel.Title = 'Error';
            app.FilePropertiesErrorPanel.Visible = 'off';
            app.FilePropertiesErrorGridLayout = uigridlayout(app.FilePropertiesErrorPanel);
            app.FilePropertiesErrorLabel = uilabel(app.FilePropertiesErrorGridLayout);
            app.FilePropertiesErrorLabel.Text = '';
            app.NoFileSelectedLabel = uilabel(app.FilePropertiesGridLayout);
            app.NoFileSelectedLabel.Text = 'Select a file from the list.';
            app.ProcessesPanel = uipanel(app.MainGridLayout);
            app.ProcessesPanel.Title = 'Processes';
            app.ProcessesGridLayout = uigridlayout(app.ProcessesPanel);
            app.ProcessesListBox = uilistbox(app.ProcessesGridLayout);
            app.ProcessesListBox.Items = {};
            app.ProcessesListBox.ValueChangedFcn = createCallbackFcn(app, @ProcessesListBoxValueChanged, true);
            app.ProcessesListBox.Value = {};
            app.ProcessesMoveUpButton = uibutton(app.ProcessesGridLayout, 'push');
            app.ProcessesMoveUpButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessesMoveUpButtonPushed, true);
            app.ProcessesMoveUpButton.Enable = 'off';
            app.ProcessesMoveUpButton.Tooltip = {'move up'};
            app.ProcessesMoveUpButton.Text = '';
            app.ProcessesMoveDownButton = uibutton(app.ProcessesGridLayout, 'push');
            app.ProcessesMoveDownButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessesMoveDownButtonPushed, true);
            app.ProcessesMoveDownButton.Enable = 'off';
            app.ProcessesMoveDownButton.Tooltip = {'move down'};
            app.ProcessesMoveDownButton.Text = '';
            app.ProcessesDeleteButton = uibutton(app.ProcessesGridLayout, 'push');
            app.ProcessesDeleteButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessesDeleteButtonPushed, true);
            app.ProcessesDeleteButton.Enable = 'off';
            app.ProcessesDeleteButton.Tooltip = {'delete process'};
            app.ProcessesDeleteButton.Text = '';
            app.ProcessesRunButton = uibutton(app.ProcessesGridLayout, 'push');
            app.ProcessesRunButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessesRunButtonPushed, true);
            app.ProcessesRunButton.Enable = 'off';
            app.ProcessesRunButton.Tooltip = {'run'};
            app.ProcessesRunButton.Text = '';
            app.TreeNodesPool = uitree(app.UIFigureGridLayout, 'checkbox');
            app.FilesRawSubjectContextMenu = uicontextmenu(app.UIFigure);
            app.FilesRawSubjectContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @FilesRawSubjectContextMenuOpening, true);
            app.FilesRawSubjectContextHeader = uimenu(app.FilesRawSubjectContextMenu);
            app.FilesRawSubjectContextHeader.Enable = 'off';
            app.FilesRawSubjectContextHeader.Text = 'Subject';
            app.FilesRawSubjectContextImportEEGMenu = uimenu(app.FilesRawSubjectContextMenu);
            app.FilesRawSubjectContextImportEEGMenu.MenuSelectedFcn = createCallbackFcn(app, @FilesRawSubjectContextImportEEGMenuSelected, true);
            app.FilesRawSubjectContextImportEEGMenu.Separator = 'on';
            app.FilesRawSubjectContextImportEEGMenu.Text = 'Import EEG';
            app.FilesContextMenu = uicontextmenu(app.UIFigure);
            app.CopyPathnameMenu = uimenu(app.FilesContextMenu);
            app.CopyPathnameMenu.MenuSelectedFcn = createCallbackFcn(app, @CopyPathnameMenuSelected, true);
            app.CopyPathnameMenu.Text = 'Copy pathname';
            app.LoadEEGdataMenu = uimenu(app.FilesContextMenu);
            app.LoadEEGdataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadEEGdataMenuSelected, true);
            app.LoadEEGdataMenu.Text = 'Load EEG data';
            app.LoadEEGheaderMenu = uimenu(app.FilesContextMenu);
            app.LoadEEGheaderMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadEEGheaderMenuSelected, true);
            app.LoadEEGheaderMenu.Text = 'Load EEG header';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEG_Processor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end