classdef EditChannelLocations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        UITable                      matlab.ui.control.Table
        SelectChanLocFilePanel       matlab.ui.container.Panel
        SelectChanLocFileGridLayout  matlab.ui.container.GridLayout
        ChanLocFileValue             matlab.ui.control.EditField
        BrowseButton                 matlab.ui.control.Button
        SaveCloseButton              matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        MainApp;
        InterruptDialog;
        Waitbar;
        % Structure that keeps the state
        State = struct();
        % Objects
        Light;
        Surfaces;
    end
    
    methods (Access = public)
        % =========================================================
        % FUNCTION: Initializes a new Empty State
        function InitializeState(app, FullFilePath)
            % ---------------------------------------------------------
            % Update the Waitbar
            app.Waitbar = uiprogressdlg(app.UIFigure);
            app.Waitbar.Title = 'Cogwheels are turning, please wait...';
            app.Waitbar.Message = 'Loading header information (may take up to 30 s)';
            app.Waitbar.Indeterminate = 'on';
            % ---------------------------------------------------------
            % Load file
            if exist(FullFilePath, 'file') == 2
                app.State.EEG = LoadDataset(FullFilePath, 'header');
            else
                error('File does not exist: ''%s''', FullFilePath)
            end
            app.State.Saved = true;
            % ---------------------------------------------------------
            % Make sure the filename and path are ok
            [Filepath, Filename] = fileparts(FullFilePath);
            app.State.EEG.setname = Filename;
            app.State.EEG.filename = [Filename, '.set'];
            app.State.EEG.datfile = [Filename, '.fdt'];
            app.State.EEG.filepath = Filepath;
            KeysValues = filename2struct(app.State.EEG.setname);
            app.State.EEG.subject = KeysValues.sub;
            % ---------------------------------------------------------
            % Save the reference channels
            EEGChanIdx = strcmpi({app.State.EEG.chanlocs.type}, 'EEG');
            app.State.Reference = {app.State.EEG.chanlocs(EEGChanIdx).ref};
            % ---------------------------------------------------------
            app.UIAxes.PlotBoxAspectRatio = [1, 1, 1];
            app.UIAxes.Color = [0, 0, 0];
            delete(app.Waitbar)
        end
        % =========================================================
        % MAIN FUNCTION
        % =========================================================
        % FUNCTION: Maps the initial State to the Properties of all Components
        function InitialMapStateToProps(app)
            % ---------------------------------------------------------
            % Set Chan Loc File location
            app.ChanLocFileValue.Value = app.State.EEG.chaninfo.filename;
            % ---------------------------------------------------------
            % Set the UITable
            app.UITable.Data = table;
            app.UITable.Data(:, 1) = {app.State.EEG.chanlocs.labels}';
            app.UITable.Data(:, 2) = {app.State.EEG.chanlocs.type}';
            app.UITable.Data(:, 3) = {app.State.EEG.chanlocs.X}';
            app.UITable.Data(:, 4) = {app.State.EEG.chanlocs.Y}';
            app.UITable.Data(:, 5) = {app.State.EEG.chanlocs.Z}';
            app.UITable.RowName = 1:length(app.State.EEG.chanlocs);
            % ---------------------------------------------------------
            % Set Lightning
            app.Light = light(app.UIAxes, 'Position', [max([app.State.EEG.chanlocs.X]), mean([app.State.EEG.chanlocs.Y]), max([app.State.EEG.chanlocs.Z])], 'Style', 'local');
            % ---------------------------------------------------------
            % Get the scaling factor to plot EEG channels
            scaling = (max([app.State.EEG.chanlocs.X]) - min([app.State.EEG.chanlocs.X]))/5;
            % ---------------------------------------------------------
            % Get a default sphere
            [X,Y,Z] = sphere(4);
            % ---------------------------------------------------------
            % Plot all Channels
            for i = 1:length(app.State.EEG.chanlocs)
                if ~strcmpi(app.State.EEG.chanlocs(i).type, 'EEG')
                    continue
                end
                app.Surfaces(i).Obj = surf(app.UIAxes, ...
                    X./scaling+app.State.EEG.chanlocs(i).X, ...
                    Y./scaling+app.State.EEG.chanlocs(i).Y, ...
                    Z./scaling+app.State.EEG.chanlocs(i).Z); %#ok<*AGROW>
                app.Surfaces(i).Obj.EdgeColor = 'none';
                app.Surfaces(i).Obj.LineStyle = 'none';
                app.Surfaces(i).Obj.AmbientStrength = 0.5;
                app.Surfaces(i).Obj.SpecularStrength = 1;
                app.Surfaces(i).Obj.SpecularExponent = 25;
                app.Surfaces(i).Obj.FaceLighting = 'gouraud';
                app.Surfaces(i).Obj.BackFaceLighting = 'unlit';
                app.Surfaces(i).Obj.FaceColor = [0.910, 0.761, 0.090];
                app.Surfaces(i).Obj.FaceAlpha = 1;
            end
            % ---------------------------------------------------------
            % Set axes limits
            app.UIAxes.XLim = [-1*(max(abs([app.State.EEG.chanlocs.X]))), 1*(max(abs([app.State.EEG.chanlocs.X])))];
            app.UIAxes.YLim = [-1*(max(abs([app.State.EEG.chanlocs.Y]))), 1*(max(abs([app.State.EEG.chanlocs.Y])))];
            app.UIAxes.ZLim = [-1*(max(abs([app.State.EEG.chanlocs.Z]))), 1*(max(abs([app.State.EEG.chanlocs.Z])))];
        end
        % =========================================================
        % FUNCTION: Maps the State to the Properties of all Components
        function MapStateToProps(app)
            % ---------------------------------------------------------
            % Set Chan Loc File location
            app.ChanLocFileValue.Value = app.State.EEG.chaninfo.filename;
            % ---------------------------------------------------------
            % Set the UITable
            app.UITable.Data(:, 1) = {app.State.EEG.chanlocs.labels}';
            app.UITable.Data(:, 2) = {app.State.EEG.chanlocs.type}';
            app.UITable.Data(:, 3) = {app.State.EEG.chanlocs.X}';
            app.UITable.Data(:, 4) = {app.State.EEG.chanlocs.Y}';
            app.UITable.Data(:, 5) = {app.State.EEG.chanlocs.Z}';
            % ---------------------------------------------------------
            % Set Lightning
            app.Light.Position = [max([app.State.EEG.chanlocs.X]), mean([app.State.EEG.chanlocs.Y]), max([app.State.EEG.chanlocs.Z])];
            % ---------------------------------------------------------
            % Get the scaling factor to plot EEG channels
            scaling = (max([app.State.EEG.chanlocs.X]) - min([app.State.EEG.chanlocs.X]))/5;
            % ---------------------------------------------------------
            % Get a default sphere
            [X,Y,Z] = sphere(4);
            % ---------------------------------------------------------
            % Plot all Channels
            for i = 1:length(app.State.EEG.chanlocs)
                if ~strcmpi(app.State.EEG.chanlocs(i).type, 'EEG')
                    continue
                end
                app.Surfaces(i).XData = X./scaling+app.State.EEG.chanlocs(i).X;
                app.Surfaces(i).YData = Y./scaling+app.State.EEG.chanlocs(i).Y;
                app.Surfaces(i).YData = Z./scaling+app.State.EEG.chanlocs(i).Z;
            end
            % ---------------------------------------------------------
            % Set axes limits
            app.UIAxes.XLim = [-1*(max(abs([app.State.EEG.chanlocs.X]))), 1*(max(abs([app.State.EEG.chanlocs.X])))];
            app.UIAxes.YLim = [-1*(max(abs([app.State.EEG.chanlocs.Y]))), 1*(max(abs([app.State.EEG.chanlocs.Y])))];
            app.UIAxes.ZLim = [-1*(max(abs([app.State.EEG.chanlocs.Z]))), 1*(max(abs([app.State.EEG.chanlocs.Z])))];
            % ---------------------------------------------------------
            app.SaveCloseButton.Enable = ifelse(app.State.Saved, 'off', 'on');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, FullFilePath)
            % ---------------------------------------------------------
            % Store main app object
            if exist('MainApp', 'var')
                app.MainApp = MainApp;
                % Position the new app relative to the main app
                app_positionGUI(app.MainApp, app.UIFigure)
                % Show waitbar to indicate another gui is open
                app.InterruptDialog = uiprogressdlg(app.MainApp.UIFigure);
            else
                app.MainApp = struct();
            end
            % Clear the import structure
            app.MainApp.Import = struct();
            app.MainApp.Import.Return = false;
            % ---------------------------------------------------------
            % Add icons to components
            app.BrowseButton.Icon = 'icon_folder-open.png';
            % ---------------------------------------------------------
            % Initialize State
            app.InitializeState(FullFilePath)
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.InitialMapStateToProps();
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.MainApp.Import.Return = false;
            % Delete the dialog box
            if isvalid(app)
                close(app.UIFigure)
            end
        end

        % Button pushed function: SaveCloseButton
        function SaveCloseButtonPushed(app, event)
            % ---------------------------------------------------------
            % Show waitbar
            app.Waitbar = uiprogressdlg(app.UIFigure);
            app.Waitbar.Title = 'Cogwheels are turning, please wait...';
            app.Waitbar.Message = 'Saving header file';
            app.Waitbar.Indeterminate = 'on';
            app.State.EEG = SaveDataset(app.State.EEG, 'header');
            % ---------------------------------------------------------
            % Set the import data
            app.MainApp.Import.Return = true;
            app.MainApp.Import.Data = [];
            % ---------------------------------------------------------
            close(app.Waitbar)
            app.Waitbar = [];
            % ---------------------------------------------------------
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            % ---------------------------------------------------------
            % Ask the user to select a file
            Filter = ifelse(ispc, { ...
                '*.txt', 'Geoscan file (*.txt)'; ...
                '*.sfp', 'Template file (*.sfp)'; ...
                }, '*.*');
            [Filename, Filepath] = uigetfile(Filter, 'Select a file to import');
            % ---------------------------------------------------------
            % If filename is zero, user pressed cancel
            if Filename == 0
                return
            end
            % ---------------------------------------------------------
            % Get extension
            [~, ~, Extension] = fileparts(Filename);
            % ---------------------------------------------------------
            % Get EEG channel indices
            EEGChanIdx = strcmpi({app.State.EEG.chanlocs.type}, 'EEG');
            % ---------------------------------------------------------
            % Load the Channels File
            switch Extension
                case '.txt'
                    % Assume Geoscan file
                    [chanlocs, ndchanlocs] = geoscan_to_chanlocs([Filepath, '/', Filename]);
                case '.sfp'
                    % Assume SFP template file
                    chanlocs = readlocs([Filepath, '/', Filename]);
                    ndchanlocs = [];
            end
            % ---------------------------------------------------------
            % Make sure the chanlocs are of the correct size
            if length(chanlocs) ~= sum(EEGChanIdx)
                sel = uiconfirm(app.UIFigure, sprintf('The selected channel locations file contains %i EEG channels, but the data contains %i EEG channels.', length(chanlocs), sum(EEGChanIdx)), 'Error', ...
                    'Options',{'Ok, cancel'},...
                    'DefaultOption', 'Ok, cancel', ...
                    'Icon', 'error'); %#ok<NASGU>
                return
            end % All is well, continue
            % ---------------------------------------------------------
            % Remove all chanlocs
            urpibchanlocs = app.State.EEG.chanlocs(~EEGChanIdx);
            app.State.EEG.chanlocs(:) = [];
            % ---------------------------------------------------------
            % Check that all channel labels are valid variable names
            for i = 1:length(chanlocs)
                chanlocs(i).labels = matlab.lang.makeValidName(chanlocs(i).labels);
            end
            % ---------------------------------------------------------
            % Set the chan type to EEG
            for i = 1:length(chanlocs)
                chanlocs(i).type = 'EEG';
            end
            % ---------------------------------------------------------
            % Save chanlocs to state
            fnames = fieldnames(chanlocs);
            for i = 1:length(chanlocs)
                k = length(app.State.EEG.chanlocs)+1;
                for j = 1:length(fnames)
                    app.State.EEG.chanlocs(k).(fnames{j}) = chanlocs(i).(fnames{j});
                end
            end
            app.State.EEG.chaninfo.filename = fullfile(Filepath, Filename);
            app.State.EEG.chaninfo.ndchanlocs = ndchanlocs;
            % ---------------------------------------------------------
            % Re-insert ref chans
            for i = 1:length(app.State.Reference)
                app.State.EEG.chanlocs(i).ref = app.State.Reference{i};
            end
            % ---------------------------------------------------------
            % Append the PNS chanlocs
            fnames = fieldnames(urpibchanlocs);
            for i = 1:length(urpibchanlocs)
                k = length(app.State.EEG.chanlocs)+1;
                for j = 1:length(fnames)
                    app.State.EEG.chanlocs(k).(fnames{j}) = urpibchanlocs(i).(fnames{j});
                end
            end
            % ---------------------------------------------------------
            % Set saved to false
            app.State.Saved = false;
            % ---------------------------------------------------------
            % Map state to props
            app.MapStateToProps();
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            % ---------------------------------------------------------
            % Save new label to state
            app.State.EEG.chanlocs(event.Indices(1)).labels = event.NewData;
            % ---------------------------------------------------------
            % Set saved to false
            app.State.Saved = false;
            app.SaveCloseButton.Enable = 'on';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % ---------------------------------------------------------
            % Ask the user to continue without saving
            if ~app.State.Saved
                sel = uiconfirm(app.UIFigure, 'Are you sure to close? All unsaved changes will be lost if you continue.', 'Close Window?', ...
                    'Options',{'No, cancel', 'Close without saving'},...
                    'DefaultOption', 'No, cancel', ...
                    'CancelOption', 'No, cancel', ...
                    'Icon', 'question');
                % ---------------------------------------------------------
                % If the user pressed 'Cancel' or 'Esc', return to stop the current process
                if strcmpi(sel, 'No, cancel')
                    return
                end
            end
            % ---------------------------------------------------------
            % Ok, we can delete the app.
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Edit Channel Locations';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ZTick = [];
            app.UIAxes.ZTickLabel = '';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SaveCloseButton = uibutton(app.GridLayout, 'push');
            app.SaveCloseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCloseButtonPushed, true);
            app.SaveCloseButton.Enable = 'off';
            app.SaveCloseButton.Text = 'Save and Close';
            app.SelectChanLocFilePanel = uipanel(app.GridLayout);
            app.SelectChanLocFilePanel.Title = 'Select EEG Channel Locations File';
            app.SelectChanLocFileGridLayout = uigridlayout(app.SelectChanLocFilePanel);
            app.BrowseButton = uibutton(app.SelectChanLocFileGridLayout, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Tooltip = {'browse'};
            app.BrowseButton.Text = '';
            app.ChanLocFileValue = uieditfield(app.SelectChanLocFileGridLayout, 'text');
            app.ChanLocFileValue.Editable = 'off';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Label'; 'Type'; 'X'; 'Y'; 'Z'};
            app.UITable.RowName = {'1'};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = [true false false false false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EditChannelLocations(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end