classdef DoubleDropdown < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        QuestionLabel1  matlab.ui.control.Label
        QuestionLabel2  matlab.ui.control.Label
        AnswerInput1    matlab.ui.control.DropDown
        AnswerInput2    matlab.ui.control.DropDown
        CancelButton    matlab.ui.control.Button
        OKButton        matlab.ui.control.Button
    end

    
    properties (Access = public)
        MainApp;
        InterruptDialog;
        % Structure that keeps the state
        State = struct();
    end
    
    methods (Access = public)
        % =========================================================
        % FUNCTION: Initializes a new Empty State
        function InitializeState(app, Question1, Items1, Answer1, Question2, Items2, Answer2)
            app.State = struct();
            app.State.Question1 = Question1;
            app.State.Items1 = Items1;
            app.State.Answer1 = Answer1;
            app.State.Question2 = Question2;
            app.State.Items2 = Items2;
            app.State.Answer2 = Answer2;
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            MapStateToProps(app)
        end
        % =========================================================
        % MAIN FUNCTION
        % =========================================================
        % FUNCTION: Map the State to the Properties of all Components
        function MapStateToProps(app)
            if isempty(app.State.Answer1) || isempty(app.State.Answer2)
                app.OKButton.Enable = 'off';
            else
                app.OKButton.Enable = 'on';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, Question1, Items1, Answer1, Question2, Items2, Answer2)
            % ---------------------------------------------------------
            % Store main app object
            if exist('MainApp', 'var')
                app.MainApp = MainApp;
                % Position the new app relative to the main app
                app_positionGUI(app.MainApp, app.UIFigure)
                % Show waitbar to indicate another gui is open
                app.InterruptDialog = uiprogressdlg(app.MainApp.UIFigure);
            else
                app.MainApp = struct();
            end
            % Clear the import structure
            app.MainApp.Import = struct();
            app.MainApp.Import.Return = false;
            % ---------------------------------------------------------
            % Initialize State
            InitializeState(app, Question1, Items1, Answer1, Question2, Items2, Answer2)
            app.QuestionLabel1.Text = app.State.Question1;
            app.AnswerInput1.Items = app.State.Items1;
            app.AnswerInput1.Value = app.State.Answer1;
            app.QuestionLabel2.Text = app.State.Question2;
            app.AnswerInput2.Items = app.State.Items2;
            app.AnswerInput2.Value = app.State.Answer2;
            app.UIFigure.Position(4) = min([300, 102+max([length(Items1), length(Items2)]).*24]);
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.MapStateToProps();
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.MainApp.Import.Return = false;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.MainApp.Import.Return = true;
            app.MainApp.Import.Data = app.State;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Value changed function: AnswerInput1
        function AnswerInput1ValueChanged(app, event)
            app.State.Answer1 = event.Value;
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.MapStateToProps();
        end

        % Value changed function: AnswerInput2
        function AnswerInput2ValueChanged(app, event)
            app.State.Answer2 = event.Value;
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.MapStateToProps();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Question';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Enable = 'off';
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.AnswerInput2 = uidropdown(app.GridLayout);
            app.AnswerInput2.Items = {};
            app.AnswerInput2.ValueChangedFcn = createCallbackFcn(app, @AnswerInput2ValueChanged, true);
            app.AnswerInput2.Value = {};
            app.AnswerInput1 = uidropdown(app.GridLayout);
            app.AnswerInput1.Items = {};
            app.AnswerInput1.ValueChangedFcn = createCallbackFcn(app, @AnswerInput1ValueChanged, true);
            app.AnswerInput1.Value = {};
            app.QuestionLabel2 = uilabel(app.GridLayout);
            app.QuestionLabel2.Text = '';
            app.QuestionLabel1 = uilabel(app.GridLayout);
            app.QuestionLabel1.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DoubleDropdown(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end