classdef SimpleDropdown < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        GridLayout     matlab.ui.container.GridLayout
        AnswerInput    matlab.ui.control.DropDown
        OKButton       matlab.ui.control.Button
        CancelButton   matlab.ui.control.Button
        QuestionLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        MainApp;
        InterruptDialog;
        % Structure that keeps the state
        State = struct();
    end
    
    methods (Access = public)
        % =========================================================
        % FUNCTION: Initializes a new Empty State
        function InitializeState(app, Question, Items, Answer)
            app.State = struct();
            app.State.Question = Question;
            app.State.Items = Items;
            app.State.Answer = Answer;
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            MapStateToProps(app)
        end
        % =========================================================
        % MAIN FUNCTION
        % =========================================================
        % FUNCTION: Map the State to the Properties of all Components
        function MapStateToProps(app)
            if isempty(app.State.Answer)
                app.OKButton.Enable = 'off';
            else
                app.OKButton.Enable = 'on';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, Question, Items, Answer)
            % ---------------------------------------------------------
            % Store main app object
            if exist('MainApp', 'var')
                app.MainApp = MainApp;
                % Position the new app relative to the main app
                app_positionGUI(app.MainApp, app.UIFigure)
                % Show waitbar to indicate another gui is open
                app.InterruptDialog = uiprogressdlg(app.MainApp.UIFigure);
            else
                app.MainApp = struct();
            end
            % Clear the import structure
            app.MainApp.Import = struct();
            app.MainApp.Import.Return = false;
            % ---------------------------------------------------------
            % Initialize State
            InitializeState(app, Question, Items, Answer)
            app.QuestionLabel.Text = app.State.Question;
            app.AnswerInput.Items = app.State.Items;
            app.AnswerInput.Value = app.State.Answer;
            app.UIFigure.Position(4) = min([300, 102+length(Items).*24]);
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.MapStateToProps();
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.MainApp.Import.Return = false;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.MainApp.Import.Return = true;
            app.MainApp.Import.Data = app.State;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Value changed function: AnswerInput
        function AnswerInputValueChanged(app, event)
            app.State.Answer = event.Value;
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.MapStateToProps();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Question';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.QuestionLabel = uilabel(app.GridLayout);
            app.QuestionLabel.Text = '';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Enable = 'off';
            app.OKButton.Text = 'OK';
            app.AnswerInput = uidropdown(app.GridLayout);
            app.AnswerInput.Items = {};
            app.AnswerInput.ValueChangedFcn = createCallbackFcn(app, @AnswerInputValueChanged, true);
            app.AnswerInput.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimpleDropdown(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end