classdef VisualInspection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        LoadDatasetMenu                 matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        SaveandCloseMenu                matlab.ui.container.Menu
        DebugMenu                       matlab.ui.container.Menu
        MapStateToPropsMenu             matlab.ui.container.Menu
        KeyboardMenu                    matlab.ui.container.Menu
        RealignPanelsMenu               matlab.ui.container.Menu
        PanelEditEvents                 matlab.ui.container.Panel
        GridLayoutPanelEditEvents       matlab.ui.container.GridLayout
        DeleteEventButton               matlab.ui.control.Button
        EditEventButton                 matlab.ui.control.Button
        GridLayout                      matlab.ui.container.GridLayout
        TopoTabGroup                    matlab.ui.container.TabGroup
        TopoChannelsTab                 matlab.ui.container.Tab
        GridLayoutTopoChannels          matlab.ui.container.GridLayout
        UIAxesTopoHFP                   matlab.ui.control.UIAxes
        UIAxesTopoLFP                   matlab.ui.control.UIAxes
        UIAxesTopoHurst                 matlab.ui.control.UIAxes
        UIAxesTopoCorr                  matlab.ui.control.UIAxes
        UIAxesTopoVar                   matlab.ui.control.UIAxes
        TopoComponentsTab               matlab.ui.container.Tab
        GridLayoutTopoComponents        matlab.ui.container.GridLayout
        RejectCompButton                matlab.ui.control.Button
        CompList                        matlab.ui.control.ListBox
        UIAxesTopoComp                  matlab.ui.control.UIAxes
        UIAxesCompLabel                 matlab.ui.control.UIAxes
        PanelStats                      matlab.ui.container.Panel
        GridLayoutPanelStats            matlab.ui.container.GridLayout
        UIAxesStats                     matlab.ui.control.UIAxes
        PanelPNS                        matlab.ui.container.Panel
        GridLayoutPanelPNS              matlab.ui.container.GridLayout
        UIAxesPNS                       matlab.ui.control.UIAxes
        PanelSpectro                    matlab.ui.container.Panel
        GridLayoutPanelSpectro          matlab.ui.container.GridLayout
        CalculateSpectrogramButton      matlab.ui.control.Button
        UIAxesSpectro                   matlab.ui.control.UIAxes
        PanelEEG                        matlab.ui.container.Panel
        GridLayoutPanelEEG              matlab.ui.container.GridLayout
        UIAxesEEG                       matlab.ui.control.UIAxes
        PanelICA                        matlab.ui.container.Panel
        GridLayoutPanelICA              matlab.ui.container.GridLayout
        UIAxesICA                       matlab.ui.control.UIAxes
        PanelTimeline                   matlab.ui.container.Panel
        GridLayoutPanelTimeline         matlab.ui.container.GridLayout
        UIAxesTimeline                  matlab.ui.control.UIAxes
        SettingsPanel                   matlab.ui.container.Panel
        GridLayoutSettingsPanel         matlab.ui.container.GridLayout
        PNSTimeseriesShowandAmplitudePanel  matlab.ui.container.Panel
        GridLayoutShowPNSSensorsPanel   matlab.ui.container.GridLayout
        PanelsShowandHeight             matlab.ui.container.Panel
        GridLayoutShowandHeight         matlab.ui.container.GridLayout
        HeightPanelStatsInput           matlab.ui.control.Spinner
        ShowPanelStatsInput             matlab.ui.control.CheckBox
        HeightPanelPNSInput             matlab.ui.control.Spinner
        ShowPanelPNSInput               matlab.ui.control.CheckBox
        HeightPanelSpectroInput         matlab.ui.control.Spinner
        ShowPanelSpectroInput           matlab.ui.control.CheckBox
        HeightPanelEEGInput             matlab.ui.control.Spinner
        ShowPanelEEGInput               matlab.ui.control.CheckBox
        HeightPanelICAInput             matlab.ui.control.Spinner
        ShowPanelICAInput               matlab.ui.control.CheckBox
        HeightPanelTimelineInput        matlab.ui.control.Spinner
        ShowPanelTimelineInput          matlab.ui.control.CheckBox
        GraphPropertiesPanel            matlab.ui.container.Panel
        GridLayoutGraphPropertiesPanel  matlab.ui.container.GridLayout
        MarkerSizeLabel                 matlab.ui.control.Label
        MarkerSizeInput                 matlab.ui.control.Spinner
        ptSpinnerLabel                  matlab.ui.control.Label
        LineWidthLabel                  matlab.ui.control.Label
        LineWidthInput                  matlab.ui.control.Spinner
        pxSpinnerLabel                  matlab.ui.control.Label
        EEGReferencePanel               matlab.ui.container.Panel
        GridLayoutEEGReferencePanel     matlab.ui.container.GridLayout
        EEGReferenceInput               matlab.ui.control.DropDown
        SortChannelsPanel               matlab.ui.container.Panel
        GridLayoutSortChannelsPanel     matlab.ui.container.GridLayout
        SortChanInput                   matlab.ui.control.DropDown
        ChanStepButtonGroup             matlab.ui.container.ButtonGroup
        ChanStepEditField               matlab.ui.control.NumericEditField
        ChanStep16Button                matlab.ui.control.ToggleButton
        ChanStep8Button                 matlab.ui.control.ToggleButton
        ChanStep4Button                 matlab.ui.control.ToggleButton
        ChanStep2Button                 matlab.ui.control.ToggleButton
        ChanStep1Button                 matlab.ui.control.ToggleButton
        NumChanButtonGroup              matlab.ui.container.ButtonGroup
        NumChanEditField                matlab.ui.control.NumericEditField
        NumChan128Button                matlab.ui.control.ToggleButton
        NumChan64Button                 matlab.ui.control.ToggleButton
        NumChan32Button                 matlab.ui.control.ToggleButton
        NumChan16Button                 matlab.ui.control.ToggleButton
        NumChan8Button                  matlab.ui.control.ToggleButton
        EventsPanel                     matlab.ui.container.Panel
        GridLayoutEventsPanel           matlab.ui.container.GridLayout
        EventGroupItemList              matlab.ui.control.ListBox
        EventGroupLabelList             matlab.ui.control.ListBox
        RejectEventGroupButton          matlab.ui.control.Button
        DeleteEventGroupButton          matlab.ui.control.Button
        EditEventGroupButton            matlab.ui.control.Button
        NewEventGroupButton             matlab.ui.control.Button
        DisplayFilterPanel              matlab.ui.container.Panel
        GridLayoutDisplayFilter         matlab.ui.container.GridLayout
        DisplayFilterHighInput          matlab.ui.control.Spinner
        HzLabel                         matlab.ui.control.Label
        DisplayFilterLowInput           matlab.ui.control.Spinner
        EEGAmplitudePanel               matlab.ui.container.Panel
        GridLayoutEEGAmplitudePanel     matlab.ui.container.GridLayout
        EEGAmplitudeInput               matlab.ui.control.Spinner
        uVSpinnerLabel                  matlab.ui.control.Label
        TimeWindowPanel                 matlab.ui.container.Panel
        GridLayoutTimeWindowPanel       matlab.ui.container.GridLayout
        TimeWindowLengthInput           matlab.ui.control.Spinner
        sSpinnerLabel                   matlab.ui.control.Label
        TimeWindowControlsPanel         matlab.ui.container.Panel
        GridLayoutTimeWindowControlsPanel  matlab.ui.container.GridLayout
        FastForwardTimeButton           matlab.ui.control.Button
        ForwardTimeButton               matlab.ui.control.Button
        StepForwardTimeButton           matlab.ui.control.Button
        StepBackwardTimeButton          matlab.ui.control.Button
        BackwardTimeButton              matlab.ui.control.Button
        FastBackwardTimeButton          matlab.ui.control.Button
        CloseSavePanel                  matlab.ui.container.Panel
        GridLayoutCloseSavePanel        matlab.ui.container.GridLayout
        SaveCloseButton                 matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        CloseButton                     matlab.ui.control.Button
    end


    properties (Access = public)
        MainApp;
        Waitbar;
        InterruptDialog;
        % Import structure
        Import;
        % GUIs
        gui_SimpleQuestion;
        % Objects
        PatchSeenTimeline; PatchCursorTimeline;
        TextCursorEEG; PatchCursorEEG; PatchCursorSpectro; PatchCursorPNS; PatchCursorStats;
        TextHypnoEEG;
        LineICA;
        LineEEG; LinePreselEEG;
        ImageSpectro;
        LinePNS;
        LineStatsVar; LineStatsCorr; LineStatsHurst; LineStatsLFP; LineStatsHFP;
        LineChansVar; LinePreselChansVar; LineRejChansVar;
        LineChansCorr; LinePreselChansCorr; LineRejChansCorr;
        LineChansHurst; LinePreselChansHurst; LineRejChansHurst;
        LineChansLFP; LinePreselChansLFP; LineRejChansLFP;
        LineChansHFP; LinePreselChansHFP; LineRejChansHFP;
        SurfTopoVar; SurfTopoCorr; SurfTopoHurst; SurfTopoLFP; SurfTopoHFP;
        CompLabelBar;
        % Constants
        Keys = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '0'; 'a'; 'b'; 'c'; 'd'; 'e'; 'f'; 'g'; 'h'; 'i'; 'j'; 'k'; 'l'; 'm'; 'n'; 'o'; 'p'; 'q'; 'r'; 's'; 't'; 'u'; 'v'; 'w'; 'x'; 'y'; 'z'; '-'; '='; '['; ']'; '\'; ';'; ''''; ','; '.'; '/'; '`'};
        % Structure that keeps the state
        State = struct();
        ComponentList = {};
    end

    methods (Access = public)

        % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
        % SUPPORT FUNCTIONS
        % =========================================================
        % FUNCTION: Initializes a new Empty State
        function InitializeState(app)
            % -----
            app.State = struct();
            app.State.IsLoading = false;
            app.State.Loaded = false;
            app.State.Saved = true;
            app.State.Time = 0;
            app.State.Times = 0:2;
            app.State.Chan = 1;
            app.State.Comp = 1;
            app.State.Cursor = [];
            app.State.IsSelectingEventWindow = false;
            app.State.NumEEGChans = 257;
            app.State.NumPNSChans = 1;
            app.State.EEGAmp = 150;
            app.State.PNSAmp = struct();
            app.State.TimeWindowLength = 30;
            app.State.DisplayFilter = [0.75, 45];
            app.State.FilterDesign = designfilt('bandpassfir', ...
                'StopbandFrequency1', max([0.1, app.State.DisplayFilter(1)-1]), ...
                'PassbandFrequency1', app.State.DisplayFilter(1), ...
                'PassbandFrequency2', app.State.DisplayFilter(2), ...
                'StopbandFrequency2', app.State.DisplayFilter(2)+1, ...
                'StopbandAttenuation1', 20, ...
                'StopbandAttenuation2', 20, ...
                'PassbandRipple',1, ...
                'DesignMethod', 'equiripple', ...
                'SampleRate', 500);
            app.State.ShowChans = 32;
            app.State.ChanStep = 1;
            app.State.ChanSort = 'Channel Label';
            app.State.ChanLocs = [];
            app.State.RejChanIdx = [];
            app.State.RejCompIdx = [];
            app.State.PreselChanIdx = [];
            app.State.SeenEpochIdx = [];
            app.State.RejEpochIdx = [];
            app.State.RejEventLabels = {'reject'};
            app.State.Reference = 'Cz';
            app.State.LineWidth = 0.25;
            app.State.MarkerSize = 7;
            app.State.Show.Timeline = true;
            app.State.Show.ICA = false;
            app.State.Show.EEG = true;
            app.State.Show.Spectro = false;
            app.State.Show.PNS = true;
            app.State.Show.Stats = true;
            app.State.Height.Timeline = 2;
            app.State.Height.ICA = 1;
            app.State.Height.EEG = 16;
            app.State.Height.Spectro = 2;
            app.State.Height.PNS = 1;
            app.State.Height.Stats = 2;
            app.State.Verbose = true;
        end
        % FUNCTION: LOADS DATA AND MAPS IT TO THE STATE
        function MapDataToState(app, Filename)
            if ~isempty(Filename)
                newState = true; % assume we need to have a new state
                % ---------------------------------------------------------
                % Update the Waitbar
                app.Waitbar = uiprogressdlg(app.UIFigure);
                app.Waitbar.Title = 'Cogwheels are turning, please wait...';
                app.Waitbar.Message = 'Loading Data';
                app.Waitbar.Indeterminate = 'on';
                if ~isempty(app.MainApp)
                    app.MainApp.Waitbar.Message = 'Close visual inspection app first to continue.';
                end
                drawnow;
                % ---------------------------------------------------------
                % Load the data and copy the state if available
                if exist(Filename, 'file') == 2
                    app.State.EEG = LoadDataset(Filename, 'all');
                else
                    error('File does not exist: ''%s''', Filename)
                end
                % check that view settings are valid
                if isfield(app.State.EEG.etc, 'viewsettings')
                    if sum(strcmpi({app.State.EEG.chanlocs.type}, 'EEG')) ~= app.State.EEG.etc.viewsettings.NumEEGChans
                        app.State.EEG.etc = rmfield(app.State.EEG.etc, 'viewsettings');
                    end
                end
                if isfield(app.State.EEG.etc, 'viewsettings')
                    newState = false;
                    FieldNames = fieldnames(app.State.EEG.etc.viewsettings);
                    for i = 1:length(FieldNames)
                        switch FieldNames{i}
                            case {'Events', 'FilterDesign', 'Times', 'MaxEventID', 'SelectedEventGroup'}
                                continue
                            otherwise
                                app.State.(FieldNames{i}) = app.State.EEG.etc.viewsettings.(FieldNames{i});
                        end
                    end
                end
                % ---------------------------------------------------------
                % Enforce accurate filepath, filename and subject name
                [app.State.EEG.filepath, app.State.EEG.setname] = fileparts(Filename);
                app.State.EEG.filename = [app.State.EEG.setname, '.set'];
                app.State.EEG.datfile = [app.State.EEG.setname, '.fdt'];
                KeysValues = filename2struct(app.State.EEG.setname);
                app.State.EEG.subject = KeysValues.sub;
                % ---------------------------------------------------------
                % Filter
                app.State.FilterDesign = designfilt('bandpassfir', ...
                    'StopbandFrequency1', max([0.1, app.State.DisplayFilter(1)-1]), ...
                    'PassbandFrequency1', app.State.DisplayFilter(1), ...
                    'PassbandFrequency2', app.State.DisplayFilter(2), ...
                    'StopbandFrequency2', app.State.DisplayFilter(2)+1, ...
                    'StopbandAttenuation1', 20, ...
                    'StopbandAttenuation2', 20, ...
                    'PassbandRipple',1, ...
                    'DesignMethod', 'equiripple', ...
                    'SampleRate', app.State.EEG.srate/floor(app.State.EEG.srate/120));
                % -------------------------------------------------------
                % If this recording contains hypnogram, set timeline height to 2x
                app.State.Height.Timeline = ifelse(isfield(app.State.EEG.etc, 'stages'), 3, 2);
                % ---------------------------------------------------------
                % If there are no events, create an empty structure
                if isempty(app.State.EEG.event)
                    app.State.EEG.event = struct('id', [], 'latency', [], 'duration', [], 'type', '', 'is_reject', []);
                end
                % ---------------------------------------------------------
                % Check that all channel labels are Matlab valid variable names
                for ci = 1:length(app.State.EEG.chanlocs)
                    app.State.EEG.chanlocs(ci).labels = matlab.lang.makeValidName(app.State.EEG.chanlocs(ci).labels);
                end
                % Check that all event labels are Matlab valid variable names
                for ei = 1:length({app.State.EEG.event.type})
                    app.State.EEG.event(ei).type = matlab.lang.makeValidName(app.State.EEG.event(ei).type);
                end
                % Set Timeseries
                app.State.Times = 0:1/app.State.EEG.srate:(app.State.EEG.pnts*app.State.EEG.trials)/app.State.EEG.srate-1/app.State.EEG.srate;
                % ---------------------------------------------------------
                % Update the state if not already known from past settings
                if newState
                    % ---------------------------------------------------------
                    % EEG Reference, check if we have common or mixed ref chans
                    RefChans = {};
                    for i = 1:length(app.State.EEG.chanlocs)
                        if ~strcmpi(app.State.EEG.chanlocs(i).type, 'EEG')
                            continue
                        end
                        if iscell(app.State.EEG.chanlocs(i).ref)
                            RefChans = [RefChans, app.State.EEG.chanlocs(i).ref]; %#ok<AGROW>
                        else
                            RefChans = [RefChans, {app.State.EEG.chanlocs(i).ref}]; %#ok<AGROW>
                        end
                    end
                    if numel(unique(RefChans)) > 1
                        app.State.Reference = 'mixed';
                    else
                        Ref = app.State.EEG.chanlocs(find(strcmpi({app.State.EEG.chanlocs.type}, 'EEG'), 1, 'first')).ref;
                        if regexpIdx(Ref, '\s') ||  regexpIdx(Ref, '\t')
                            app.State.Reference = 'average';
                        else
                            app.State.Reference = Ref;
                        end
                    end
                    % ---------------------------------------------------------
                    % PNS Timeseries show/hide
                    idx = find(~strcmpi({app.State.EEG.chanlocs.type}, 'EEG'));
                    for i = 1:length(idx)
                        app.State.Show.(app.State.EEG.chanlocs(idx(i)).labels) = ifelse(strcmpi(app.State.EEG.chanlocs(idx(i)).labels, 'vEOG'), true, false);
                    end
                    % ---------------------------------------------------------
                    % Number of Channels
                    app.State.NumEEGChans = sum(strcmpi({app.State.EEG.chanlocs.type}, 'EEG'));
                    app.State.NumPNSChans = sum(~strcmpi({app.State.EEG.chanlocs.type}, 'EEG'));
                    % ---------------------------------------------------------
                    % Time and Channel indices
                    app.State.TimeIdx = find(app.State.Times >= app.State.Time & app.State.Times < app.State.Time+app.State.TimeWindowLength);
                    app.State.TimeIdx = app.State.TimeIdx(1:floor(app.State.EEG.srate/120):end);
                    app.State.ChanIdx = app.State.Chan:app.State.ChanStep:app.State.Chan+(app.State.ShowChans*app.State.ChanStep)-1;
                    app.State.ChanIdx(app.State.ChanIdx < 1 | app.State.ChanIdx > app.State.NumEEGChans) = [];
                    app.State.ChanSortIdx = 1:app.State.NumEEGChans;
                end
                % ---------------------------------------------------------
                % Create unique event IDs
                if ~isfield(app.State.EEG.event, 'id')
                    for i = 1:length(app.State.EEG.event)
                        app.State.EEG.event(i).id = i;
                    end
                end
                app.State.MaxEventID = max([[app.State.EEG.event.id], 0]);
                app.State.SelectedEventGroup = '';
                % ---------------------------------------------------------
                % Add events to the state
                EventLabels = unique({'reject', app.State.EEG.event.type});
                EventLabels = [{'reject'}, EventLabels(cellfun(@(str) ~isempty(str), EventLabels) & ~strcmpi(EventLabels, 'reject'))];
                for i = 1:length(EventLabels)
                    idx = strcmpi({app.State.EEG.event.type}, EventLabels{i});
                    if any(idx)
                        app.State.Events.(EventLabels{i}).ID = [app.State.EEG.event(idx).id];
                        app.State.Events.(EventLabels{i}).Onset = [app.State.EEG.event(idx).latency] ./ app.State.EEG.srate;
                        app.State.Events.(EventLabels{i}).Duration = [app.State.EEG.event(idx).duration] ./ app.State.EEG.srate;
                    else
                        app.State.Events.(EventLabels{i}).ID = zeros(0);
                        app.State.Events.(EventLabels{i}).Onset = zeros(0);
                        app.State.Events.(EventLabels{i}).Duration = zeros(0);
                    end
                    if regexpIdx(lower(EventLabels{i}), 'reject')
                        app.State.Events.(EventLabels{i}).Color = [0.722, 0.110, 0.153];
                        app.State.Events.(EventLabels{i}).IsReject = true;
                    elseif isfield(app.State.EEG.event, 'is_reject')
                        app.State.Events.(EventLabels{i}).Color = ifelse(app.State.EEG.event(find(idx, 1, 'first')).is_reject, [0.722, 0.110, 0.153], hsv2rgb([(0.88-0.12).*rand(1, 1) + 0.12, 0.848, 0.722]));
                        app.State.Events.(EventLabels{i}).IsReject = ifelse(app.State.EEG.event(find(idx, 1, 'first')).is_reject, true, false);
                    else
                        app.State.Events.(EventLabels{i}).Color = hsv2rgb([(0.88-0.12).*rand(1, 1) + 0.12, 0.848, 0.722]);
                        app.State.Events.(EventLabels{i}).IsReject = false;
                    end
                end
                % ---------------------------------------------------------
                % If the events do not have the 'is_reject' field, create them now
                if ~isfield(app.State.EEG.event, 'is_reject')
                    for i = 1:length(app.State.EEG.event)
                        app.State.EEG.event(i).is_reject = app.State.Events.(app.State.EEG.event(i).type).IsReject;
                    end
                end
                % ---------------------------------------------------------
                % FASTER analysis
                if ~isfield(app.State.EEG.etc, 'faster')
                    app.Waitbar.Message = 'Calculating Channel and Epoch Statistics';
                    app.State.EEG.etc.faster = Proc_FasterStats(app.State.EEG, ifelse(strcmpi(app.State.Reference, 'average'), true, false));
                end
                % rerun faster if the number of channels changed
                if size(app.State.EEG.etc.faster.corr, 1) ~= size(app.State.EEG.data, 1)
                    app.Waitbar.Message = 'Calculating Channel and Epoch Statistics';
                    app.State.EEG.etc.faster = Proc_FasterStats(app.State.EEG, ifelse(strcmpi(app.State.Reference, 'average'), true, false));
                end
                fstPnst = size(app.State.EEG.etc.faster.corr, 2)*6*app.State.EEG.srate;
                pnts = app.State.EEG.pnts;
                if abs(fstPnst - pnts) > 6 * app.State.EEG.srate
                    app.Waitbar.Message = 'Calculating Channel and Epoch Statistics';
                    app.State.EEG.etc.faster = Proc_FasterStats(app.State.EEG, ifelse(strcmpi(app.State.Reference, 'average'), true, false));
                end
                % Component labels
                if ~isfield(app.State.EEG.etc, 'ic_classification') && ~isempty(app.State.EEG.icawinv)
                    % Automatic classification of components
                    app.State.EEG = pop_iclabel(app.State.EEG, 'default');
                    % Automatically flag components for rejection if their probability
                    % to be eye, heart, or muscle artefact is over 80%
                    app.State.EEG = pop_icflag(app.State.EEG, [...
                        NaN, NaN; ... % Brain
                        0.8, 1; ...   % Muscle
                        0.8, 1; ...   % Eye
                        0.8, 1; ...   % Heart
                        NaN, NaN; ... % Line noise
                        NaN, NaN; ... % Channel noise
                        NaN NaN ...   % Other
                        ]);
                    app.State.EEG.etc.rej_components = asrow(find(app.State.EEG.reject.gcompreject));
                end
                % Make sure 'rej_components' is a row vector
                if isfield(app.State.EEG.etc, 'rej_components')
                    app.State.EEG.etc.rej_components = asrow(app.State.EEG.etc.rej_components);
                end
                % -----
                % Update which epochs contain rejected events
                Events = app.State.EEG.event(ismember({app.State.EEG.event.type}, app.State.RejEventLabels));
                app.State.RejEpochIdx = eegevent2idx(Events, (0:app.State.EEG.etc.faster.epochlength:size(app.State.EEG.etc.faster.var, 2)*app.State.EEG.etc.faster.epochlength-1).*app.State.EEG.srate+1);
                % -----
                % Find the indices of the channels with a statistic higher than 3
                app.State.PreselChanIdx = asrow( ...
                    abs(nonparzscore(nanmean(app.State.EEG.etc.faster.var(:, ~app.State.RejEpochIdx), 2))) > 3 | ...
                    abs(nonparzscore(nanmean(app.State.EEG.etc.faster.corr(:, ~app.State.RejEpochIdx), 2))) > 3 | ...
                    abs(nonparzscore(nanmean(app.State.EEG.etc.faster.hurst(:, ~app.State.RejEpochIdx), 2))) > 3 | ...
                    abs(nonparzscore(nanmean(app.State.EEG.etc.faster.lfp(:, ~app.State.RejEpochIdx), 2))) > 3 | ...
                    abs(nonparzscore(nanmean(app.State.EEG.etc.faster.hfp(:, ~app.State.RejEpochIdx), 2))) > 3); %#ok<*NANMEAN>
                % ---------------------------------------------------------
                % Update which epochs have been seen
                app.SetSeenEpochIdx();
                % ---------------------------------------------------------
                % Find the indices of the rejected channels
                if isfield(app.State.EEG.etc, 'rej_channels')
                    app.State.RejChanIdx = ismember({app.State.EEG.chanlocs(strcmpi({app.State.EEG.chanlocs.type}, 'EEG')).labels}, app.State.EEG.etc.rej_channels);
                else
                    app.State.RejChanIdx = false(1, app.State.NumEEGChans);
                end
                % ---------------------------------------------------------
                % If spectrogram exists, do show the axes
                app.State.Show.Spectro = ifelse(~isempty(app.State.EEG.specdata), true, false);
                app.CalculateSpectrogramButton.Visible = 'off'; %ifelse(~isempty(app.State.EEG.specdata), 'off', 'on');
                % ---------------------------------------------------------
                % Calculate the plottable specdata
                if ~isempty(app.State.EEG.specdata)
                    switch app.State.EEG.specnormmethod
                        case 'none'
                            % Do not normalize
                            app.State.SpecData = app.State.EEG.specdata;
                        case 'zscore'
                            % Normalize by mean and standard deviation
                            app.State.SpecData = app.State.EEG.specnormfnc(app.State.EEG.specdata, app.State.EEG.specnormvals.mu, app.State.EEG.specnormvals.sd);
                        otherwise
                            % Normalize by mean only
                            app.State.SpecData = app.State.EEG.specnormfnc(app.State.EEG.specdata, app.State.EEG.specnormvals.mu);
                    end
                    % Average across channels
                    app.State.SpecData = reshape(squeeze(nanmean(app.State.SpecData, 1)), length(app.State.EEG.specfreqs), length(app.State.EEG.spectimes)*app.State.EEG.trials);
                    % Remove NaN's
                    app.State.SpecData(isnan(app.State.SpecData)) = 0;
                    % If no normalization was performed, transform to dB
                    if strcmpi(app.State.EEG.specnormmethod, 'none')
                        app.State.SpecData = pow2db(app.State.SpecData);
                    end
                    % Save spectrogram timeseries
                    app.State.SpecTimes = 0:mean(diff(app.State.EEG.spectimes)):(app.State.EEG.pnts*app.State.EEG.trials)/app.State.EEG.srate-mean(diff(app.State.EEG.spectimes));
                else
                    app.State.SpecData = [];
                    app.State.SpecTimes = [];
                end
                % ---------------------------------------------------------
                % Independent Components
                if isempty(app.State.EEG.icawinv)
                    % -----
                    % If there is no ICA, remove the tab
                    delete(app.TopoComponentsTab)
                else
                    % -----
                    % Enable the show and height inputs
                    app.State.Show.ICA = true;
                    % Create a new reject component vector if does not exist yet
                    if ~isfield(app.State.EEG.etc, 'rej_components')
                        app.State.EEG.etc.rej_components = [];
                        app.State.RejCompIdx = [];
                    else
                        app.State.RejCompIdx = app.State.EEG.etc.rej_components;
                    end
                end
                % ---------------------------------------------------------
                % Phys channel amplitude
                physchans = {app.State.EEG.chanlocs(~strcmpi({app.State.EEG.chanlocs.type}, 'EEG')).labels};
                for c = 1:length(physchans)
                    if ~isfield(app.State.PNSAmp, physchans{c})
                        app.State.PNSAmp.(physchans{c}) = 500;
                    end
                end
                % ---------------------------------------------------------
                % Change the state to loaded and delete waitbar
                app.State.Loaded = true;
                delete(app.Waitbar)
            end
        end
        % =========================================================
        % FUNCTION: Excutes the mapping of the initial state to the component properties
        function InitializeMapping(app)
            % ---------------------------------------------------------
            % Timeline Axes
            if app.State.Loaded
                % -----
                % Figure title
                app.UIFigure.Name = sprintf('Visual Inspection (%s)', app.State.EEG.setname);
                % -----
                % Set XLim
                app.UIAxesTimeline.XLim = [0, app.State.Times(end)./(24*60*60)];
                % -----
                % Set XTick
                if app.State.Times(end) < 10 % 0.25 s ticks
                    app.UIAxesTimeline.XTick = app.UIAxesTimeline.XLim(1):0.25/(24*60*60):app.UIAxesTimeline.XLim(2);
                    Format = 'SS.FFF';
                elseif app.State.Times(end) < 20 % 0.5 s ticks
                    app.UIAxesTimeline.XTick = app.UIAxesTimeline.XLim(1):0.5/(24*60*60):app.UIAxesTimeline.XLim(2);
                    Format = 'SS.FFF';
                elseif app.State.Times(end) < 40 % 1 s ticks
                    app.UIAxesTimeline.XTick = app.UIAxesTimeline.XLim(1):1/(24*60*60):app.UIAxesTimeline.XLim(2);
                    Format = 'SS';
                elseif app.State.Times(end) < 60 % 2 s ticks
                    app.UIAxesTimeline.XTick = app.UIAxesTimeline.XLim(1):2/(24*60*60):app.UIAxesTimeline.XLim(2);
                    Format = 'SS';
                elseif app.State.Times(end) < 3*60 % 5 s ticks
                    app.UIAxesTimeline.XTick = app.UIAxesTimeline.XLim(1):5/(24*60*60):app.UIAxesTimeline.XLim(2);
                    Format = 'MM:SS';
                elseif app.State.Times(end) < 6*60 % 10 s ticks
                    app.UIAxesTimeline.XTick = app.UIAxesTimeline.XLim(1):10/(24*60*60):app.UIAxesTimeline.XLim(2);
                    Format = 'MM:SS';
                elseif app.State.Times(end) < 10*60 % 15 s ticks
                    app.UIAxesTimeline.XTick = app.UIAxesTimeline.XLim(1):15/(24*60*60):app.UIAxesTimeline.XLim(2);
                    Format = 'MM:SS';
                elseif app.State.Times(end) < 20*60 % 30 s ticks
                    app.UIAxesTimeline.XTick = app.UIAxesTimeline.XLim(1):30/(24*60*60):app.UIAxesTimeline.XLim(2);
                    Format = 'MM:SS';
                elseif app.State.Times(end) < 40*60 % 1 m ticks
                    app.UIAxesTimeline.XTick = app.UIAxesTimeline.XLim(1):1/(24*60):app.UIAxesTimeline.XLim(2);
                    Format = 'MM';
                elseif app.State.Times(end) < 60*60 % 2 m ticks
                    app.UIAxesTimeline.XTick = app.UIAxesTimeline.XLim(1):2/(24*60):app.UIAxesTimeline.XLim(2);
                    Format = 'MM';
                elseif app.State.Times(end) < 3*60*60 % 5 m ticks
                    app.UIAxesTimeline.XTick = app.UIAxesTimeline.XLim(1):5/(24*60):app.UIAxesTimeline.XLim(2);
                    Format = 'HH:MM';
                elseif app.State.Times(end) < 6*60*60 % 10 m ticks
                    app.UIAxesTimeline.XTick = app.UIAxesTimeline.XLim(1):10/(24*60):app.UIAxesTimeline.XLim(2);
                    Format = 'HH:MM';
                elseif app.State.Times(end) < 10*60*60 % 15 m ticks
                    app.UIAxesTimeline.XTick = app.UIAxesTimeline.XLim(1):15/(24*60):app.UIAxesTimeline.XLim(2);
                    Format = 'HH:MM';
                elseif app.State.Times(end) < 20*60*60 % 30 m ticks
                    app.UIAxesTimeline.XTick = app.UIAxesTimeline.XLim(1):30/(24*60):app.UIAxesTimeline.XLim(2);
                    Format = 'HH:MM';
                else % 1 h ticks
                    app.UIAxesTimeline.XTick = app.UIAxesTimeline.XLim(1):1/24:app.UIAxesTimeline.XLim(2);
                    Format = 'HH:MM';
                end
                % -----
                % Set XTickLabels
                app.UIAxesTimeline.XTickLabel = datestr(app.UIAxesTimeline.XTick, Format);
                % -----
                % Set YTick and YTickLabels
                if isfield(app.State.EEG.etc, 'stages')
                    app.UIAxesTimeline.YLim = [0.5, 6.5];
                    app.UIAxesTimeline.YTick = 1:6;
                    app.UIAxesTimeline.YTickLabel = {'reject', 'W', 'R', 'N1', 'N2', 'N3'};
                    app.State.Height.Timeline = 2;
                    app.GridLayout.RowHeight{1} = '2x';
                else
                    app.UIAxesTimeline.YLim = [0.5, 1.5];
                    app.UIAxesTimeline.YTick = 1;
                    app.UIAxesTimeline.YTickLabel = 'reject';
                    app.State.Height.Timeline = 1;
                    app.GridLayout.RowHeight{1} = '1x';
                end
                % ---------------------------------------------------------
                % Delete all event patches
                RmTags = get(app.UIAxesTimeline.Children, 'Tag');
                if ~isempty(RmTags)
                    % -----
                    % Only keep tags that are events
                    RmTags(~regexpIdx(RmTags, 'Event_id-*')) = [];
                    % -----
                    % For each, delete them from the axes
                    for i = 1:length(RmTags)
                        delete(findobj(app.UIAxesTimeline.Children, 'Tag', RmTags{i}))
                    end
                end
                % -----
                % Set Patch X and YData
                app.PatchCursorTimeline.XData = [app.State.Time, app.State.Time+app.State.TimeWindowLength, app.State.Time+app.State.TimeWindowLength, app.State.Time]./(24*60*60);
                app.PatchCursorTimeline.YData = [0.5, 0.5, 6.5, 6.5];
            end
            % ---------------------------------------------------------
            % Cursors
            app.PatchCursorEEG.YData = [-1, -1, app.State.NumEEGChans+1, app.State.NumEEGChans+1];
            app.PatchCursorPNS.YData = [-1, -1, app.State.NumEEGChans+1, app.State.NumEEGChans+1];
            app.PatchCursorStats.YData = [-1, -1, app.State.NumEEGChans+1, app.State.NumEEGChans+1];
            % ---------------------------------------------------------
            % Time Window Length
            app.TimeWindowLengthInput.Value = app.State.TimeWindowLength;
            % ---------------------------------------------------------
            % EEG Amplitude
            app.EEGAmplitudeInput.Value = app.State.EEGAmp;
            % ---------------------------------------------------------
            % Display filter
            app.DisplayFilterLowInput.Value = app.State.DisplayFilter(1);
            app.DisplayFilterHighInput.Value = app.State.DisplayFilter(2);
            % ---------------------------------------------------------
            % Event Groups
            if app.State.Loaded
                app.EventGroupLabelList.Items = fieldnames(app.State.Events);
            end
            app.EventGroupItemList.Value = {}; % Deselect any selected item
            app.EventGroupLabelList.Value = {};
            % ---------------------------------------------------------
            % Number of Channels
            app.NumChanEditField.Value = app.State.ShowChans;
            idx = 1+find([128 64 32 16 8] == app.State.ShowChans);
            if ~isempty(idx)
                app.NumChanButtonGroup.SelectedObject = app.NumChanButtonGroup.Children(idx);
            end
            % ---------------------------------------------------------
            % Channel Step
            app.ChanStepEditField.Value = app.State.ChanStep;
            idx = 1+find([16 8 4 2 1] == app.State.ChanStep);
            if ~isempty(idx)
                app.ChanStepButtonGroup.SelectedObject = app.ChanStepButtonGroup.Children(idx);
            end
            % ---------------------------------------------------------
            % Sort Channels By
            app.SortChanInput.Value = app.State.ChanSort;
            % ---------------------------------------------------------
            % EEG Reference
            if app.State.Loaded
                app.EEGReferenceInput.Items = unique([{'average'}, {app.State.EEG.chanlocs(strcmpi({app.State.EEG.chanlocs.type}, 'EEG')).labels}, {app.State.EEG.chanlocs(strcmpi({app.State.EEG.chanlocs.type}, 'EEG')).ref}], 'stable');
            else
                app.EEGReferenceInput.Items = unique([{'average'}, {app.State.Reference}], 'stable');
            end
            if strcmpi(app.State.Reference, 'average') ||  strcmpi(app.State.Reference, 'mixed')
                app.EEGReferenceInput.Enable = 'off';
            else
                app.EEGReferenceInput.Enable = 'on';
                app.EEGReferenceInput.Value = app.State.Reference;
            end
            % ---------------------------------------------------------
            % Graph props
            app.MarkerSizeInput.Value = app.State.MarkerSize;
            app.LineWidthInput.Value = app.State.LineWidth;
            % ---------------------------------------------------------
            % Show and Height
            app.ShowPanelTimelineInput.Value = app.State.Show.Timeline;
            app.ShowPanelEEGInput.Value = app.State.Show.EEG;
            app.ShowPanelSpectroInput.Value = app.State.Show.Spectro;
            app.ShowPanelPNSInput.Value = app.State.Show.PNS;
            app.ShowPanelStatsInput.Value = app.State.Show.Stats;
            app.HeightPanelTimelineInput.Value = app.State.Height.Timeline;
            app.HeightPanelEEGInput.Value = app.State.Height.EEG;
            app.HeightPanelSpectroInput.Value = app.State.Height.Spectro;
            app.HeightPanelPNSInput.Value = app.State.Height.PNS;
            app.HeightPanelStatsInput.Value = app.State.Height.Stats;
            % ---------------------------------------------------------
            % Enable/Disable height input fields
            app.HeightPanelEEGInput.Enable = ifelse(app.State.Show.EEG, 'on', 'off');
            app.HeightPanelSpectroInput.Enable = ifelse(app.State.Show.Spectro, 'on', 'off');
            app.HeightPanelPNSInput.Enable = ifelse(app.State.Show.PNS, 'on', 'off');
            app.HeightPanelStatsInput.Enable = ifelse(app.State.Show.Stats, 'on', 'off');
            % ---------------------------------------------------------
            % PNS Timeseries Show
            if app.State.Loaded
                % ---------------------------------------------------------
                % Create Show check boxes and amplitude sensitivity for each PNS sensor
                idx = find(~strcmpi({app.State.EEG.chanlocs.type}, 'EEG'));
                app.GridLayoutShowPNSSensorsPanel.RowHeight = repmat({24}, 1, length(idx));
                for i = 1:length(idx)
                    h = uilabel(app.GridLayoutShowPNSSensorsPanel, 'Text', app.State.EEG.chanlocs(idx(i)).labels, 'FontSize', 10);
                    h.Layout.Row = i;
                    h.Layout.Column = 1;
                    h = uicheckbox(app.GridLayoutShowPNSSensorsPanel, 'Value', app.State.Show.(app.State.EEG.chanlocs(idx(i)).labels), 'Text', '');
                    h.Layout.Row = i;
                    h.Layout.Column = 2;
                    h.UserData.Label = app.State.EEG.chanlocs(idx(i)).labels;
                    h.ValueChangedFcn = @app.ShowPNSSenorValueChanged;
                    h = uispinner(app.GridLayoutShowPNSSensorsPanel, 'Value', 500, 'Limits', [0.001 Inf], 'Tag', 'NoWindowKeyPress', 'FontSize', 10);
                    h.Layout.Row = i;
                    h.Layout.Column = 3;
                    h.UserData.Label = app.State.EEG.chanlocs(idx(i)).labels;
                    h.ValueChangedFcn = @app.AmplitudePNSSensorValueChanged;
                end
                % ---------------------------------------------------------
                % Update the YLim
                app.UIAxesPNS.YLim = [0.33, length(idx)+0.67];
                app.UIAxesPNS.YTick = 1:length(idx);
                app.UIAxesPNS.YTickLabel = strrep({app.State.EEG.chanlocs(idx).labels}, '_', ' ');
            end
            % ---------------------------------------------------------
            % Plot Events in timeline
            if app.State.Loaded
                EventLabels = fieldnames(app.State.Events);
                for i = 1:length(EventLabels)
                    if ~ismember(EventLabels{i}, {'reject', 'wake', 'n1', 'n2', 'n3', 'nrem1', 'nrem2', 'nrem3', 'rem'}) && ~app.State.Events.(EventLabels{i}).IsReject
                        continue
                    end
                    for j = 1:length(app.State.Events.(EventLabels{i}).Onset)
                        % -----
                        % Creat patch in the timeline
                        % -----
                        % Get XData
                        XData = [ ...
                            app.State.Events.(EventLabels{i}).Onset(j), ...
                            app.State.Events.(EventLabels{i}).Onset(j) + app.State.Events.(EventLabels{i}).Duration(j), ...
                            app.State.Events.(EventLabels{i}).Onset(j) + app.State.Events.(EventLabels{i}).Duration(j), ...
                            app.State.Events.(EventLabels{i}).Onset(j), ...
                            ];
                        YData = max([1, find(strcmpi(EventLabels{i}, {'reject', 'wake', 'rem', 'n1', 'n2', 'n3', 'nrem1', 'nrem2', 'nrem3'}))]);
                        if YData > 6
                            YData = YData - 3; % To plot the NREM events on the right Y-value
                        end
                        YData = [YData-0.45, YData-0.45, YData+0.45, YData+0.45];
                        patch(app.UIAxesTimeline, ...
                            'XData', XData./(24*60*60), ...
                            'YData', YData, ...
                            'FaceColor', app.State.Events.(EventLabels{i}).Color, ...
                            'FaceAlpha', 0.9, ...
                            'LineStyle', 'none', ...
                            'Tag', ['Event_id-', num2str(app.State.Events.(EventLabels{i}).ID(j))], ...
                            'PickableParts', 'none');
                    end
                end
            end
            % ---------------------------------------------------------
            % Set Spectro Axes lims
            if app.State.Loaded
                if ~isempty(app.State.EEG.specdata)
                    app.UIAxesSpectro.YLim = [2, app.State.EEG.specfreqs(end)+mean(diff(app.State.EEG.specfreqs))/2];
                end
            end
            % ---------------------------------------------------------
            % Plot Statistics
            if app.State.Loaded
                % -----
                YData = abs(nonparzscore(nanmean(app.State.EEG.etc.faster.var, 1)));
                app.LineStatsVar.XData = 0:app.State.EEG.etc.faster.epochlength:size(app.State.EEG.etc.faster.var, 2)*app.State.EEG.etc.faster.epochlength;
                app.LineStatsVar.YData = [YData, YData(end)];
                % -----
                YData = abs(nonparzscore(nanmean(app.State.EEG.etc.faster.corr, 1)));
                app.LineStatsCorr.XData = 0:app.State.EEG.etc.faster.epochlength:size(app.State.EEG.etc.faster.corr, 2)*app.State.EEG.etc.faster.epochlength;
                app.LineStatsCorr.YData = [YData, YData(end)];
                % -----
                YData = abs(nonparzscore(nanmean(app.State.EEG.etc.faster.hurst, 1)));
                app.LineStatsHurst.XData = 0:app.State.EEG.etc.faster.epochlength:size(app.State.EEG.etc.faster.hurst, 2)*app.State.EEG.etc.faster.epochlength;
                app.LineStatsHurst.YData = [YData, YData(end)];
                % -----
                YData = abs(nonparzscore(nanmean(app.State.EEG.etc.faster.lfp, 1)));
                app.LineStatsLFP.XData = 0:app.State.EEG.etc.faster.epochlength:size(app.State.EEG.etc.faster.lfp, 2)*app.State.EEG.etc.faster.epochlength;
                app.LineStatsLFP.YData = [YData, YData(end)];
                % -----
                YData = abs(nonparzscore(nanmean(app.State.EEG.etc.faster.hfp, 1)));
                app.LineStatsHFP.XData = 0:app.State.EEG.etc.faster.epochlength:size(app.State.EEG.etc.faster.hfp, 2)*app.State.EEG.etc.faster.epochlength;
                app.LineStatsHFP.YData = [YData, YData(end)];
                % -----
                % Set Legend
                legend(app.UIAxesStats, 'Variance', 'Correlation', 'Hurst Exponent', 'LF Power', 'HF Power', 'Cursor', 'Location', 'southoutside', 'Orientation', 'horizontal')
            end
            % ---------------------------------------------------------
            % Plot Topoplots
            if app.State.Loaded && app.State.EEG.nbchan > 1 && ~any(isempty([app.State.EEG.chanlocs(strcmpi({app.State.EEG.chanlocs.type}, 'EEG')).X]))
                Stats = {'Var', 'Corr', 'Hurst', 'LFP', 'HFP'};
                for i = 1:length(Stats)
                    Fig = figure;
                    topoVals = abs(nonparzscore(nanmean(app.State.EEG.etc.faster.(lower(Stats{i}))(:, ~app.State.RejEpochIdx), 2)));
                    topoVals(isnan(topoVals)) = 0;
                    % When all values are zero, then 'topoplot' throws an
                    % error because the color limits are not incrementing,
                    % i.e. they are [0, 0], which is not allowed
                    if length(unique(topoVals)) == 1
                        if unique(topoVals) == 0
                            topoVals(topoVals == 0) = 0.0001;
                        end
                    end
                    topoplot(topoVals, app.State.EEG.chanlocs(strcmpi({app.State.EEG.chanlocs.type}, 'EEG')), ...
                        'style', 'map', ...
                        'electrodes', 'on', ...
                        'plotrad', 1, ...
                        'headrad', 0.6, ...
                        'intrad', 1, ...
                        'conv', 'on', ...
                        'numcontour', 0, ...
                        'emarker', {'.', 'w', app.State.MarkerSize, 1}, ...
                        'emarker2', {1, '.', [0.110, 0.769, 0.824], app.State.MarkerSize+3, 1}, ...
                        'hcolor', 'k', ...
                        'whitebk', 'on');
                    Ax = findobj(Fig.Children, 'Type', 'Axes');
                    eval(sprintf('copyobj(Ax.Children, app.UIAxesTopo%s)', Stats{i}));
                    for j = 1:length(Ax.Children)
                        eval(sprintf('app.UIAxesTopo%s.Children(j).PickableParts = ''none'';', Stats{i}))
                    end
                    close(Fig)
                end
                % -----
                % Extract the surface objects
                app.SurfTopoVar = findobj(app.UIAxesTopoVar.Children, 'Type', 'Surface');
                app.SurfTopoCorr = findobj(app.UIAxesTopoCorr.Children, 'Type', 'Surface');
                app.SurfTopoHurst = findobj(app.UIAxesTopoHurst.Children, 'Type', 'Surface');
                app.SurfTopoLFP = findobj(app.UIAxesTopoLFP.Children, 'Type', 'Surface');
                app.SurfTopoHFP = findobj(app.UIAxesTopoHFP.Children, 'Type', 'Surface');
                % -----
                % Set the Preselected Channels objects
                app.LinePreselChansVar = findobj(app.UIAxesTopoVar.Children, 'Marker', '.', 'MarkerSize', app.State.MarkerSize+3);
                app.LinePreselChansCorr = findobj(app.UIAxesTopoCorr.Children, 'Marker', '.', 'MarkerSize', app.State.MarkerSize+3);
                app.LinePreselChansHurst = findobj(app.UIAxesTopoHurst.Children, 'Marker', '.', 'MarkerSize', app.State.MarkerSize+3);
                app.LinePreselChansLFP = findobj(app.UIAxesTopoLFP.Children, 'Marker', '.', 'MarkerSize', app.State.MarkerSize+3);
                app.LinePreselChansHFP = findobj(app.UIAxesTopoHFP.Children, 'Marker', '.', 'MarkerSize', app.State.MarkerSize+3);
                % -----
                % Extract the channel location line object
                app.LineChansVar = findobj(app.UIAxesTopoVar.Children, 'MarkerSize', app.State.MarkerSize);
                app.LineChansVar.XData = [app.LinePreselChansVar.XData, app.LineChansVar.XData]; app.LineChansVar.YData = [app.LinePreselChansVar.YData, app.LineChansVar.YData]; app.LineChansVar.ZData = [app.LinePreselChansVar.ZData, app.LineChansVar.ZData];
                app.LineChansCorr = findobj(app.UIAxesTopoCorr.Children, 'MarkerSize', app.State.MarkerSize);
                app.LineChansCorr.XData = [app.LinePreselChansCorr.XData, app.LineChansCorr.XData]; app.LineChansCorr.YData = [app.LinePreselChansCorr.YData, app.LineChansCorr.YData]; app.LineChansCorr.ZData = [app.LinePreselChansCorr.ZData, app.LineChansCorr.ZData];
                app.LineChansHurst = findobj(app.UIAxesTopoHurst.Children, 'MarkerSize', app.State.MarkerSize);
                app.LineChansHurst.XData = [app.LinePreselChansHurst.XData, app.LineChansHurst.XData]; app.LineChansHurst.YData = [app.LinePreselChansHurst.YData, app.LineChansHurst.YData]; app.LineChansHurst.ZData = [app.LinePreselChansHurst.ZData, app.LineChansHurst.ZData];
                app.LineChansLFP = findobj(app.UIAxesTopoLFP.Children, 'MarkerSize', app.State.MarkerSize);
                app.LineChansLFP.XData = [app.LinePreselChansLFP.XData, app.LineChansLFP.XData]; app.LineChansLFP.YData = [app.LinePreselChansLFP.YData, app.LineChansLFP.YData]; app.LineChansLFP.ZData = [app.LinePreselChansLFP.ZData, app.LineChansLFP.ZData];
                app.LineChansHFP = findobj(app.UIAxesTopoHFP.Children, 'MarkerSize', app.State.MarkerSize);
                app.LineChansHFP.XData = [app.LinePreselChansHFP.XData, app.LineChansHFP.XData]; app.LineChansHFP.YData = [app.LinePreselChansHFP.YData, app.LineChansHFP.YData]; app.LineChansHFP.ZData = [app.LinePreselChansHFP.ZData, app.LineChansHFP.ZData];
                % -----
                % Copy the Preselected channels object to show the Rejected channels
                copyobj(app.LinePreselChansVar, app.UIAxesTopoVar); app.LineRejChansVar = app.UIAxesTopoVar.Children(1); app.LineRejChansVar.Color = [1, 0, 0];
                copyobj(app.LinePreselChansCorr, app.UIAxesTopoCorr); app.LineRejChansCorr = app.UIAxesTopoCorr.Children(1); app.LineRejChansCorr.Color = [1, 0, 0];
                copyobj(app.LinePreselChansHurst, app.UIAxesTopoHurst); app.LineRejChansHurst = app.UIAxesTopoHurst.Children(1); app.LineRejChansHurst.Color = [1, 0, 0];
                copyobj(app.LinePreselChansLFP, app.UIAxesTopoLFP); app.LineRejChansLFP = app.UIAxesTopoLFP.Children(1); app.LineRejChansLFP.Color = [1, 0, 0];
                copyobj(app.LinePreselChansHFP, app.UIAxesTopoHFP); app.LineRejChansHFP = app.UIAxesTopoHFP.Children(1); app.LineRejChansHFP.Color = [1, 0, 0];
            else
                delete(app.UIAxesTopoVar.Children)
                delete(app.UIAxesTopoCorr.Children)
                delete(app.UIAxesTopoHurst.Children)
                delete(app.UIAxesTopoLFP.Children)
                delete(app.UIAxesTopoHFP.Children)
                % -----
                % Extract the channel location line object
                app.LineChansVar.XData = []; app.LineChansVar.YData = []; app.LineChansVar.ZData = [];
                app.LineChansCorr.XData = []; app.LineChansCorr.YData = []; app.LineChansCorr.ZData = [];
                app.LineChansHurst.XData = []; app.LineChansHurst.YData = []; app.LineChansHurst.ZData = [];
                app.LineChansLFP.XData = []; app.LineChansLFP.YData = []; app.LineChansLFP.ZData = [];
                app.LineChansHFP.XData = []; app.LineChansHFP.YData = []; app.LineChansHFP.ZData = [];

            end
            % ---------------------------------------------------------
            % Independent Components
            if app.State.Show.ICA
                app.ShowPanelICAInput.Enable = 'on';
                app.ShowPanelICAInput.Value = true;
                app.HeightPanelICAInput.Enable = 'on';
                Fig = figure;
                topoplot(app.State.EEG.icawinv(:, 1), app.State.EEG.chanlocs(app.State.EEG.icachansind), ...
                    'style', 'map', ...
                    'electrodes', 'off', ...
                    'plotrad', 0.85, ...
                    'headrad', 0.6, ...
                    'intrad', 1, ...
                    'conv', 'on', ...
                    'numcontour', 0, ...
                    'hcolor', 'k', ...
                    'whitebk', 'on');
                copyobj(Fig.Children.Children, app.UIAxesTopoComp);
                close(Fig)
                % Plot label
                app.CompLabelBar = bar(app.UIAxesCompLabel, app.State.EEG.etc.ic_classification.ICLabel.classifications(1, :), 'k');
            end
        end
        % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
        % EVENT FUNCTIONS
        % =========================================================
        % FUNCTION: Opens the edit and remove event buttons
        function EventButtonDownFcn(app, Source, ~)
            app.PanelEditEvents.Visible = 'on';
            app.PanelEditEvents.Position(1:2) = app.UIFigure.CurrentPoint + [-15, 10];
            app.EditEventButton.UserData.Tag = Source.Tag;
            app.DeleteEventButton.UserData.Tag = Source.Tag;
            % Set the cursor
            id = str2double(strrep(Source.Tag, 'Event_id-', ''));
            idx = [app.State.EEG.event.id] == id;
            if ~any(idx)
                return
            end
            T = (app.State.EEG.event(idx).latency - 1) / app.State.EEG.srate;
            D = app.State.EEG.event(idx).duration / app.State.EEG.srate;
            app.State.Cursor = [T, T+D, T+D, T];
            app.PatchCursorEEG.XData = app.State.Cursor;
        end
        % =========================================================
        % FUNCTION: Converts a suggested reject event into a real one
        function AddRejectEventButtonDownFcn(app, Source, Event)
            % ---------------------------------------------------------
            % If user used right button click, move time, else add reject event
            if Event.Button == 3
                % -----
                % Update which epochs have been seen
                app.SetSeenEpochIdx();
                % -----
                % Update time
                CurrPoint = Event.IntersectionPoint;
                app.State.Time = floor(CurrPoint(1));
                % -----
                % Update the Time index
                app.State.TimeIdx = find(app.State.Times >= app.State.Time & app.State.Times < app.State.Time+app.State.TimeWindowLength);
                app.State.TimeIdx = app.State.TimeIdx(1:floor(app.State.EEG.srate/120):end);
                % -----
                app.ComponentList = [app.ComponentList, {'EEGChannelData', 'ICAData', 'SpectroData', 'PNSChannelData', 'EEGEvents'}];
            else
                % ---------------------------------------------------------
                % Add the reject event to the state
                EventLabel = 'reject';
                app.State.MaxEventID = app.State.MaxEventID+1;
                app.State.EEG.event(end+1).id = app.State.MaxEventID;
                app.State.Events.(EventLabel).ID = [app.State.Events.(EventLabel).ID, app.State.MaxEventID];
                % -----
                app.State.EEG.event(end).type = EventLabel;
                % -----
                app.State.EEG.event(end).latency = Source.XData(1) * app.State.EEG.srate;
                app.State.EEG.event(end).duration = app.State.EEG.etc.faster.epochlength * app.State.EEG.srate;
                app.State.Events.(EventLabel).Onset = [app.State.Events.(EventLabel).Onset, Source.XData(1)];
                app.State.Events.(EventLabel).Duration = [app.State.Events.(EventLabel).Duration, app.State.EEG.etc.faster.epochlength];
                % -----
                app.State.EEG.event(end).is_reject = true;
                % ---------------------------------------------------------
                % Recalculate the Faster channel statistics without the rejected periods
                Events = app.State.EEG.event(ismember({app.State.EEG.event.type}, app.State.RejEventLabels));
                app.State.RejEpochIdx = eegevent2idx(Events, (0:app.State.EEG.etc.faster.epochlength:size(app.State.EEG.etc.faster.var, 2)*app.State.EEG.etc.faster.epochlength-1).*app.State.EEG.srate+1);
                % -----
                % Find the indices of the channels with a statistic higher than 3
                PrevPreselChanIdx = app.State.PreselChanIdx;
                app.State.PreselChanIdx = asrow( ...
                    abs(nonparzscore(nanmean(app.State.EEG.etc.faster.var(:, ~app.State.RejEpochIdx), 2))) > 3 | ...
                    abs(nonparzscore(nanmean(app.State.EEG.etc.faster.corr(:, ~app.State.RejEpochIdx), 2))) > 3 | ...
                    abs(nonparzscore(nanmean(app.State.EEG.etc.faster.hurst(:, ~app.State.RejEpochIdx), 2))) > 3 | ...
                    abs(nonparzscore(nanmean(app.State.EEG.etc.faster.lfp(:, ~app.State.RejEpochIdx), 2))) > 3 | ...
                    abs(nonparzscore(nanmean(app.State.EEG.etc.faster.hfp(:, ~app.State.RejEpochIdx), 2))) > 3);
                if any(PrevPreselChanIdx ~= app.State.PreselChanIdx)
                    app.ComponentList = [app.ComponentList, {'PreselChannelIndices', 'Toposurface'}];
                end
                % -----
                % Set sorted channel indices based on clean data segments
                app.SetChanSortIdx();
                % ---------------------------------------------------------
                % Map the State to the Properties of all Components
                app.ComponentList = [app.ComponentList, {'EventList', 'EEGEvents', 'TimelineEvents', 'EEGChannelData'}];
            end
            app.MapStateToProps();
        end
        % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
        % SET STATE FUNCTIONS
        % =========================================================
        % FUNCTION: Updates the state to show or hide PNS timeseries
        function ShowPNSSenorValueChanged(app, Source, Event)
            % ---------------------------------------------------------
            app.State.Show.(Source.UserData.Label) = Event.Value;
            app.ComponentList = [app.ComponentList, {'PNSChannelData', 'PNSChannelIndices', 'AlignAxes'}];
            app.MapStateToProps();
        end
        % =========================================================
        % FUNCTION: Updates the state to change the amplitude of PNS timeseries
        function AmplitudePNSSensorValueChanged(app, Source, Event)
            % ---------------------------------------------------------
            app.State.PNSAmp.(Source.UserData.Label) = Event.Value;
            app.ComponentList = [app.ComponentList, {'PNSChannelData'}];
            app.MapStateToProps();
        end
        % =========================================================
        function SetSeenEpochIdx(app)
            % Updates the state
            Event.latency = app.State.Time;
            Event.duration = app.State.TimeWindowLength;
            if isempty(app.State.SeenEpochIdx)
                app.State.SeenEpochIdx = eegevent2idx(Event, 0:app.State.EEG.etc.faster.epochlength:size(app.State.EEG.etc.faster.var, 2)*app.State.EEG.etc.faster.epochlength-1);
            else
                app.State.SeenEpochIdx = (app.State.SeenEpochIdx + eegevent2idx(Event, 0:app.State.EEG.etc.faster.epochlength:size(app.State.EEG.etc.faster.var, 2)*app.State.EEG.etc.faster.epochlength-1)) > 0;
            end
            onset = find(diff([0, app.State.SeenEpochIdx]) == 1) - 1;
            offset = find(diff([app.State.SeenEpochIdx, 0]) == -1);
            XData = (sort([reshape([onset; onset], 1, numel([onset; onset])), reshape([offset; offset], 1, numel([offset; offset]))]).*app.State.EEG.etc.faster.epochlength)./(24*60*60);
            YData = repmat([0, 7, 7, 0], 1, length(XData)/4);
            app.PatchSeenTimeline.XData = XData;
            app.PatchSeenTimeline.YData = YData;
        end
        function SetChanSortIdx(app)
            % ---------------------------------------------------------
            app.State.ChanSort = app.SortChanInput.Value;
            % -----
            switch app.State.ChanSort
                case 'Channel Label'
                    app.State.ChanSortIdx = 1:app.State.NumEEGChans;
                case 'Combined Rank'
                    % -----
                    % Calculate channel rank based on all statistics
                    [~, ChanRank] = sort(mean([...
                        abs(nonparzscore(nanmean(app.State.EEG.etc.faster.var(:, ~app.State.RejEpochIdx), 2))), ...
                        abs(nonparzscore(nanmean(app.State.EEG.etc.faster.corr(:, ~app.State.RejEpochIdx), 2))), ...
                        abs(nonparzscore(nanmean(app.State.EEG.etc.faster.hurst(:, ~app.State.RejEpochIdx), 2))), ...
                        abs(nonparzscore(nanmean(app.State.EEG.etc.faster.lfp(:, ~app.State.RejEpochIdx), 2))), ...
                        abs(nonparzscore(nanmean(app.State.EEG.etc.faster.hfp(:, ~app.State.RejEpochIdx), 2)))...
                        ], 2), 'descend');
                    app.State.ChanSortIdx = ChanRank;
                    app.State.ChanStep = 1;
                    app.State.Chan = 1;
                case 'Variance'
                    [~, app.State.ChanSortIdx] = sort(abs(nonparzscore(nanmean(app.State.EEG.etc.faster.var(:, ~app.State.RejEpochIdx), 2))), 'descend');
                    app.State.ChanStep = 1;
                    app.State.Chan = 1;
                case 'Correlation'
                    [~, app.State.ChanSortIdx] = sort(abs(nonparzscore(nanmean(app.State.EEG.etc.faster.corr(:, ~app.State.RejEpochIdx), 2))), 'descend');
                    app.State.ChanStep = 1;
                    app.State.Chan = 1;
                case 'Hurst Exponent'
                    [~, app.State.ChanSortIdx] = sort(abs(nonparzscore(nanmean(app.State.EEG.etc.faster.hurst(:, ~app.State.RejEpochIdx), 2))), 'descend');
                    app.State.ChanStep = 1;
                    app.State.Chan = 1;
                case 'LF Power'
                    [~, app.State.ChanSortIdx] = sort(abs(nonparzscore(nanmean(app.State.EEG.etc.faster.lfp(:, ~app.State.RejEpochIdx), 2))), 'descend');
                    app.State.ChanStep = 1;
                    app.State.Chan = 1;
                case 'HF Power'
                    [~, app.State.ChanSortIdx] = sort(abs(nonparzscore(nanmean(app.State.EEG.etc.faster.hfp(:, ~app.State.RejEpochIdx), 2))), 'descend');
                    app.State.ChanStep = 1;
                    app.State.Chan = 1;
            end
            % ---------------------------------------------------------
            % Update Channel Index
            if strcmpi(app.State.ShowChans, 'all')
                app.State.ChanIdx = 1:app.State.ChanStep:app.State.NumEEGChans;
            else
                app.State.ChanIdx = app.State.Chan:app.State.ChanStep:app.State.Chan+(app.State.ShowChans*app.State.ChanStep)-1;
            end
            app.State.ChanIdx(app.State.ChanIdx < 1 | app.State.ChanIdx > app.State.NumEEGChans) = [];
        end
        % =========================================================
        % FUNCTION: Train the Support Vector Machine
        function TrainSVM(app)
            if sum(app.State.RejEpochIdx) >= 3 && sum(app.State.SeenEpochIdx) >= 6
                % Find good and rejected epochs that we've seen
                SeenRejEpochs = find(app.State.RejEpochIdx(app.State.SeenEpochIdx));
                SeenGoodEpochs = find(~app.State.RejEpochIdx(app.State.SeenEpochIdx));
                % Keep only a few epochs as not to overload the model
                RejEpochs = randsample(SeenRejEpochs, min([length(SeenRejEpochs), 8]));
                GoodEpochs = randsample(SeenGoodEpochs, min([length(SeenGoodEpochs), 16]));
                MixEpochs = randsample([SeenRejEpochs, SeenGoodEpochs], min([length([SeenRejEpochs, SeenGoodEpochs]), 1024]));
                SelectIdx = unique([RejEpochs, GoodEpochs, MixEpochs]);
                XData = [...
                    ascolumn(abs(nonparzscore(prctile(app.State.EEG.etc.faster.var(~app.State.RejChanIdx, :), 50)))), ...
                    ascolumn(abs(nonparzscore(prctile(app.State.EEG.etc.faster.corr(~app.State.RejChanIdx, :), 50)))), ...
                    ascolumn(abs(nonparzscore(prctile(app.State.EEG.etc.faster.hurst(~app.State.RejChanIdx, :), 50)))), ...
                    ascolumn(abs(nonparzscore(prctile(app.State.EEG.etc.faster.lfp(~app.State.RejChanIdx, :), 50)))), ...
                    ascolumn(abs(nonparzscore(prctile(app.State.EEG.etc.faster.hfp(~app.State.RejChanIdx, :), 50)))), ...
                    ];
                XData = XData(SelectIdx, :);
                % Remove any absurd datapoints because this will take a long time to compute
                SelectIdx(any(XData > 15, 2)) = [];
                XData(any(XData > 15, 2), :) = [];
                if isempty(XData)
                    return
                end
                % Traing the SVM
                XData(isnan(XData)) = 0;
                app.State.SVMModel = fitcsvm(XData, round(app.State.RejEpochIdx(SelectIdx)-0.5), ...
                    'KernelFunction', 'RBF', ...
                    'KernelScale', 'auto', ...
                    'ClassNames', [-1, 1]);
            end
            % TODO: Move this elsewhere
            app.LineStatsVar.YData = abs(nonparzscore(prctile(app.State.EEG.etc.faster.var(~app.State.RejChanIdx, :), 50))); app.LineStatsVar.YData(end+1) = app.LineStatsVar.YData(end);
            app.LineStatsCorr.YData = abs(nonparzscore(prctile(app.State.EEG.etc.faster.corr(~app.State.RejChanIdx, :), 50))); app.LineStatsCorr.YData(end+1) = app.LineStatsCorr.YData(end);
            app.LineStatsHurst.YData = abs(nonparzscore(prctile(app.State.EEG.etc.faster.hurst(~app.State.RejChanIdx, :), 50))); app.LineStatsHurst.YData(end+1) = app.LineStatsHurst.YData(end);
            app.LineStatsLFP.YData = abs(nonparzscore(prctile(app.State.EEG.etc.faster.lfp(~app.State.RejChanIdx, :), 50))); app.LineStatsLFP.YData(end+1) = app.LineStatsLFP.YData(end);
            app.LineStatsHFP.YData = abs(nonparzscore(prctile(app.State.EEG.etc.faster.hfp(~app.State.RejChanIdx, :), 50))); app.LineStatsHFP.YData(end+1) = app.LineStatsHFP.YData(end);
        end
        % =========================================================
        % FUNCTION: Apply the trained SVM model to predict bad epochs
        function SuggestRejEpochIdx = PredictSVM(app)
            SuggestRejEpochIdx = [];
            if isfield(app.State, 'SVMModel')
                % Get what epochs we're looking at
                Event.latency = app.State.Time;
                Event.duration = app.State.TimeWindowLength;
                CurrEpochIdx = eegevent2idx(Event, 0:app.State.EEG.etc.faster.epochlength:size(app.State.EEG.etc.faster.var, 2)*app.State.EEG.etc.faster.epochlength-1);
                % Get XData
                XData = [...
                    ascolumn(abs(nonparzscore(prctile(app.State.EEG.etc.faster.var(~app.State.RejChanIdx, :), 50)))), ...
                    ascolumn(abs(nonparzscore(prctile(app.State.EEG.etc.faster.corr(~app.State.RejChanIdx, :), 50)))), ...
                    ascolumn(abs(nonparzscore(prctile(app.State.EEG.etc.faster.hurst(~app.State.RejChanIdx, :), 50)))), ...
                    ascolumn(abs(nonparzscore(prctile(app.State.EEG.etc.faster.lfp(~app.State.RejChanIdx, :), 50)))), ...
                    ascolumn(abs(nonparzscore(prctile(app.State.EEG.etc.faster.hfp(~app.State.RejChanIdx, :), 50)))), ...
                    ];
                XData = XData(CurrEpochIdx, :);
                [Label, Score] = predict(app.State.SVMModel, XData); %#ok<ASGLU>
                AlreadyRejEpochIdx = app.State.RejEpochIdx(CurrEpochIdx);
                Label(AlreadyRejEpochIdx) = -1;
                SuggestRejEpochIdx = find(CurrEpochIdx);
                SuggestRejEpochIdx = SuggestRejEpochIdx(Label == 1);
            end
        end
        % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
        % SET PROPERTIES FUNCTIONS
        % =========================================================
        % FUNCTION: Removes cursor from state and view
        function RemoveCursor(app)
            if isempty(app.State.Cursor)
                return
            end
            app.State.Cursor = [];
            app.TextCursorEEG.Position(1) = -10;
            app.TextCursorEEG.String = '';
            app.PatchCursorEEG.XData = [-20, -10, -10, -20];
            app.PatchCursorPNS.XData = [-20, -10, -10, -20];
            app.PatchCursorStats.XData = [-20, -10, -10, -20];
        end
        % =========================================================
        function SetEventList(app)
            % -----
            % Get all possible event labels
            EventLabels = asrow(fieldnames(app.State.Events));
            % -----
            % Add a key to each of these event groups
            if length(EventLabels) > length(app.Keys)
                EventLabels = EventLabels(1:length(app.Keys));
            end
            % -----
            % Set the Event group list
            app.EventGroupLabelList.Items = cellfun(@(label, key) [label, ' (', key, ')'], ascolumn(EventLabels), app.Keys(1:length(EventLabels)), 'UniformOutput', false);
            app.EventGroupLabelList.UserData = app.Keys(1:length(EventLabels));
            if ~isempty(app.State.SelectedEventGroup)
                % ---------------------------------------------------------
                % Set the Selected Item
                app.EventGroupLabelList.Value = app.State.SelectedEventGroup;
                % ---------------------------------------------------------
                % Extract the onset and end times for each of these events
                EventLabel = app.State.SelectedEventGroup(1:end-4);
                idxEvent = strcmpi({app.State.EEG.event.type}, EventLabel);
                Onset = [app.State.EEG.event(idxEvent).latency] ./ app.State.EEG.srate;
                Duration = [app.State.EEG.event(idxEvent).duration] ./ app.State.EEG.srate;
                [Onset, idxSort] = sort(Onset);
                Duration = Duration(idxSort);
                app.EventGroupItemList.Items = cellstr([datestr(Onset/(60*60*24), 'HH:MM:SS'), repmat(' - ', length(Onset), 1), datestr((Onset+Duration)/(60*60*24), 'HH:MM:SS')]);
                app.EventGroupItemList.UserData = struct();
                app.EventGroupItemList.UserData.Onset = Onset;
                app.EventGroupItemList.UserData.Duration = Duration;
                % ---------------------------------------------------------
                % Enable edit and delete button
                app.EditEventGroupButton.Enable = ifelse(strcmpi(EventLabel, 'reject'), 'off', 'on');
                app.DeleteEventGroupButton.Enable = ifelse(strcmpi(EventLabel, 'reject'), 'off', 'on');
                app.RejectEventGroupButton.Enable = ifelse(strcmpi(EventLabel, 'reject'), 'off', 'on');
                UpdateProperty(app, app.RejectEventGroupButton, 'Icon', ifelse(app.State.Events.(EventLabel).IsReject, 'icon_tick.png', 'icon_ban.png'));
                app.RejectEventGroupButton.BackgroundColor = ifelse(app.State.Events.(EventLabel).IsReject, [0.16, 0.65, 0.27], [0.86, 0.21 ,0.27]);
            else
                % ---------------------------------------------------------
                % Set the Selected Item to null
                app.EventGroupLabelList.Value = {};
                app.EventGroupItemList.Items = {};
                % ---------------------------------------------------------
                % Disable edit and delete button
                UpdateProperty(app, app.EditEventGroupButton, 'Enable', 'off');
                UpdateProperty(app, app.DeleteEventGroupButton, 'Enable', 'off');
                UpdateProperty(app, app.RejectEventGroupButton, 'Enable', 'off');
                UpdateProperty(app, app.RejectEventGroupButton, 'Icon', 'icon_ban.png');
                UpdateProperty(app, app.RejectEventGroupButton, 'BackgroundColor', [0.86, 0.21 ,0.27]);
            end
        end
        % =========================================================
        function SetTimelineEvents(app)
            % ---------------------------------------------------------
            % Get all the Tags of the current children
            Tags = get(app.UIAxesTimeline.Children, 'Tag');
            % -----
            % Get the events that should be there
            Events = app.State.EEG.event(ismember({app.State.EEG.event.type}, [app.State.RejEventLabels, {'wake', 'n1', 'n2', 'n3', 'nrem1', 'nrem2', 'nrem3', 'rem'}]));
            if ~isempty(Tags)
                % -----
                % Only keep tags that are events
                Tags(~regexpIdx(Tags, 'Event_id-*')) = [];
                % -----
                % Find the Tags that should no longer be in view
                if isempty(Events)
                    RmTags = Tags;
                else
                    IDs = cellfun(@(id) ['Event_id-', num2str(id)], ascolumn(num2cell([Events.id])), 'UniformOutput', false);
                    RmTags = setdiff(Tags, IDs);
                end
                % -----
                % For each, delete them from the axes
                for i = 1:length(RmTags)
                    delete(findobj(app.UIAxesTimeline.Children, 'Tag', RmTags{i}))
                end
            end
            % -----
            % If there were no events that should be in view, we're done here
            if isempty(Events)
                return
            end
            % ---------------------------------------------------------
            % Get all the Tags of the current children
            Tags = get(app.UIAxesTimeline.Children, 'Tag');
            if ~isempty(Tags)
                Tags(~regexpIdx(Tags, 'Event_id-*')) = [];
            end
            % -----
            % Find the Tags that that should be added
            IDs = cellfun(@(id) ['Event_id-', num2str(id)], ascolumn(num2cell([Events.id])), 'UniformOutput', false);
            AddTags = setdiff(IDs, Tags);
            for i = 1:length(AddTags)
                % -----
                % Get the ID, Onset, Duration and Index
                ID = strsplit(AddTags{i}, '-');
                ID = str2double(ID{2});
                EventLabel = app.State.EEG.event([app.State.EEG.event.id] == ID).type;
                if ~app.State.Events.(EventLabel).IsReject
                    continue
                end
                Onset = app.State.EEG.event([app.State.EEG.event.id] == ID).latency ./ app.State.EEG.srate;
                Duration = app.State.EEG.event([app.State.EEG.event.id] == ID).duration ./ app.State.EEG.srate;
                % -----
                % Get XData
                XData = [ ...
                    Onset, ...
                    Onset + Duration, ...
                    Onset + Duration, ...
                    Onset, ...
                    ];
                % -----
                % Get YData
                YData = [0.55, 0.55, 1.45, 1.45];
                % -----
                % Create event plot in the timeline
                patch(app.UIAxesTimeline, ...
                    'XData', XData./(24*60*60), ...
                    'YData', YData, ...
                    'FaceColor', [0.722, 0.110, 0.153], ...
                    'FaceAlpha', 0.9, ...
                    'LineStyle', 'none', ...
                    'Tag', ['Event_id-', num2str(ID)], ...
                    'PickableParts', 'none');
            end
        end
        % =========================================================
        function SetEEGEvents(app)
            % ID #0016
            % Init
            BoundaryLineWidth = 1;
            EventLineWidth = 2;
            EventMarkerSize = 6;
            % ---------------------------------------------------------
            % Get all the Tags of the current children
            Tags = get(app.UIAxesEEG.Children, 'Tag');
            % -----
            % Get the events that should be there
            Events = selectEventsByTime(app.State.EEG.event, app.State.Time*app.State.EEG.srate+1, (app.State.Time+app.State.TimeWindowLength)*app.State.EEG.srate);
            % Sort Events by latency
            [~, IdxEvents] = sort([Events.latency]);
            Events = Events(IdxEvents);
            % Sort events by type
            IdxEvents = ismember({Events.type}, {'wake', 'n1', 'n2', 'n3', 'nrem1', 'nrem2', 'nrem3', 'rem', 'reject', 'boundary'});
            Events = [Events(IdxEvents), Events(~IdxEvents)];
            % -----
            % Calculate how tall a track is
            TrackHeight = range(app.UIAxesEEG.YLim) / ((app.PanelEEG.Position(4)-30) / 10);
            % -----
            % Create a track for each event
            Tracks = zeros(length(Events), app.State.TimeWindowLength);
            for i = 1:length(Events)
                Events(i).track = 0;
%                 if Events(i).duration <= (app.State.EEG.srate / 4)
%                     continue
%                 end
                Onset = max([1, floor(Events(i).latency/app.State.EEG.srate - app.State.Time)+1]);
                Offset = min([app.State.TimeWindowLength, ceil((Events(i).latency+Events(i).duration)/app.State.EEG.srate - app.State.Time)]);
                TrackMarked = false;
                TrackNum = 1;
                while ~TrackMarked
                    if ismember(Events(i).type, {'wake', 'n1', 'n2', 'n3', 'nrem1', 'nrem2', 'nrem3', 'rem', 'reject', 'boundary'})
                        Tracks(TrackNum, Onset) = 1;
                        Events(i).track = TrackNum-1;
                        TrackMarked = true;
                    elseif any(Tracks(TrackNum, max([1, Onset-1]):min([app.State.TimeWindowLength, Offset+1])) == 1)
                        TrackNum = TrackNum+1;
                    else
                        Tracks(TrackNum, Onset:Offset) = 1;
                        Events(i).track = TrackNum-1;
                        TrackMarked = true;
                    end
                end
            end
            % -----
            % Check which objects should be removed
            if ~isempty(Tags)
                % -----
                % Only keep tags that are events
                Tags(~regexpIdx(Tags, 'Event_id-*')) = [];
                % -----
                % Find the Tags that should no longer be in view
                if isempty(Events)
                    RmTags = Tags;
                else
                    IDs = cellfun(@(id) ['Event_id-', num2str(id)], ascolumn(num2cell([Events.id])), 'UniformOutput', false);
                    RmTags = setdiff(Tags, IDs);
                end
                % -----
                % For each, delete them from the axes
                for i = 1:length(RmTags)
                    delete(findobj(app.UIAxesEEG.Children, 'Tag', RmTags{i}))
                end
            end
            % -----
            % If there were no events that should be in view, we're done here
            if isempty(Events)
                return
            end
            % ---------------------------------------------------------
            % Get all the Tags of the current children
            Tags = get(app.UIAxesEEG.Children, 'Tag');
            if ~isempty(Tags)
                Tags(~regexpIdx(Tags, 'Event_id-*')) = [];
            end
            % -----
            % Find the Tags that that should be added
            IDs = cellfun(@(id) ['Event_id-', num2str(id)], ascolumn(num2cell([Events.id])), 'UniformOutput', false);
            AddTags = setdiff(IDs, Tags);
            EditTags = intersect(IDs, Tags);
            % -----
            % Fixed ID #0008
            SlpEvents = Events(ismember({Events.type}, {'wake', 'n1', 'n2', 'n3', 'nrem1', 'nrem2', 'nrem3', 'rem'}));
            if ~isempty(SlpEvents)
                [~, idx] = min(abs((([SlpEvents.latency]+[SlpEvents.duration]/2)/app.State.EEG.srate) - (app.State.Time+app.State.TimeWindowLength/2)));
                Onset = SlpEvents(idx).latency / app.State.EEG.srate;
                Duration = SlpEvents(idx).duration / app.State.EEG.srate;
                app.TextHypnoEEG.String = upper(SlpEvents(idx).type);
                app.TextHypnoEEG.Position(1:3) = [Onset + Duration/2, length(app.State.ChanIdx)/2, -1];
            else
                app.TextHypnoEEG.String = '';
            end
            % -----
            % Update all the event objects that are currently plotted
            for i = 1:length(EditTags)
                % -----
                % Get the ID, Onset, Duration and Index
                ID = strsplit(EditTags{i}, '-');
                ID = str2double(ID{2});
                Onset = Events([Events.id] == ID).latency / app.State.EEG.srate;
                Duration = Events([Events.id] == ID).duration / app.State.EEG.srate;
                EventLabel = Events([Events.id] == ID).type;
                switch EventLabel
                    case {'wake', 'n1', 'n2', 'n3', 'nrem1', 'nrem2', 'nrem3', 'rem', 'reject', 'boundary'}
                        % Do nothing
                    otherwise
                        if ~app.State.Events.(EventLabel).IsReject
                            TextObj = findobj(app.UIAxesEEG, 'Type', 'line', 'Tag', EditTags{i});
                            TextObj.YData = [app.UIAxesEEG.YLim(1)+Events([Events.id] == ID).track*TrackHeight, app.UIAxesEEG.YLim(1)+Events([Events.id] == ID).track*TrackHeight];
                        end
                end
                % -----
                % Update the text object
                TextObj = findobj(app.UIAxesEEG, 'Type', 'text', 'Tag', EditTags{i});
                YPos = ifelse(Events([Events.id] == ID).track == 0, app.UIAxesEEG.YLim(1), app.UIAxesEEG.YLim(1)+Events([Events.id] == ID).track*TrackHeight);
                HorAlign = ifelse(Events([Events.id] == ID).track == 0, 'center', 'right');
                VerAlign = ifelse(Events([Events.id] == ID).track == 0, 'bottom', 'middle');
                TextObj.Position(1) = max([Onset, app.State.Time]);
                TextObj.Position(2) = YPos;
                TextObj.String = ifelse(Events([Events.id] == ID).track == 0, EventLabel, [EventLabel, '  ']);
                TextObj.HorizontalAlignment = ifelse(app.State.Events.(EventLabel).IsReject, 'left', HorAlign);
                TextObj.VerticalAlignment = ifelse(app.State.Events.(EventLabel).IsReject, 'top', VerAlign);
            end
            % -----
            % Create all the event objects that not plotted yet
            for i = 1:length(AddTags)
                % -----
                % Get the ID, Onset, Duration and Index
                ID = strsplit(AddTags{i}, '-');
                ID = str2double(ID{2});
                Onset = Events([Events.id] == ID).latency / app.State.EEG.srate;
                Duration = Events([Events.id] == ID).duration / app.State.EEG.srate;
                EventLabel = Events([Events.id] == ID).type;
                % -----
                % Get XData
                XData = [ ...
                    Onset, ...
                    Onset + Duration, ...
                    Onset + Duration, ...
                    Onset, ...
                    ];
                % -----
                % Create event plot in the EEG axes
                YData = [app.UIAxesEEG.YLim(1)-0.01, app.UIAxesEEG.YLim(1)-0.01, app.State.NumEEGChans+0.01, app.State.NumEEGChans+0.01];
                switch EventLabel
                    case {'wake', 'n1', 'n2', 'n3', 'nrem1', 'nrem2', 'nrem3', 'rem'}
                        plot(app.UIAxesEEG, [XData(1), XData(1)], app.UIAxesEEG.YLim + [0, +1], ...
                            'Color', app.State.Events.(EventLabel).Color, ...
                            'MarkerEdgeColor', [1, 1, 1], ...
                            'MarkerFaceColor', app.State.Events.(EventLabel).Color, ...
                            'LineStyle', '--', ...
                            'LineWidth', BoundaryLineWidth, ...
                            'Marker', 'o', ...
                            'MarkerSize', EventMarkerSize, ...
                            'Tag', ['Event_id-', num2str(ID)], ...
                            'PickableParts', 'none');
                    case 'reject'
                        patch(app.UIAxesEEG, ...
                            'XData', XData, ...
                            'YData', YData, ...
                            'FaceColor', app.State.Events.(EventLabel).Color, ...
                            'FaceAlpha', 0.1, ...
                            'LineStyle', 'none', ...
                            'Tag', ['Event_id-', num2str(ID)], ...
                            'PickableParts', 'none');
                    case 'boundary'
                        plot(app.UIAxesEEG, XData([1, 4]), app.UIAxesEEG.YLim + [0, +1], ...
                            'Color', app.State.Events.(EventLabel).Color, ...
                            'LineStyle', '--', ...
                            'LineWidth', BoundaryLineWidth, ...
                            'Tag', ['Event_id-', num2str(ID)], ...
                            'PickableParts', 'none');
                    otherwise
                        if app.State.Events.(EventLabel).IsReject
                            patch(app.UIAxesEEG, ...
                                'XData', XData, ...
                                'YData', YData, ...
                                'FaceColor', [0.722, 0.110, 0.153], ...
                                'FaceAlpha', 0.1, ...
                                'LineStyle', 'none', ...
                                'Tag', ['Event_id-', num2str(ID)], ...
                                'PickableParts', 'none');
                        elseif Duration > -1
                            YData = [app.UIAxesEEG.YLim(1)+Events([Events.id] == ID).track*TrackHeight, app.UIAxesEEG.YLim(1)+Events([Events.id] == ID).track*TrackHeight];
                            plot(app.UIAxesEEG, XData(1:2), YData, ...
                                'Color', app.State.Events.(EventLabel).Color, ...
                                'MarkerEdgeColor', [1, 1, 1], ...
                                'MarkerFaceColor', app.State.Events.(EventLabel).Color, ...
                                'LineStyle', '-', ...
                                'LineWidth', EventLineWidth, ...
                                'Marker', 'o', ...
                                'MarkerSize', EventMarkerSize, ...
                                'Tag', ['Event_id-', num2str(ID)], ...
                                'PickableParts', 'none');
                        else
                            plot(app.UIAxesEEG, XData(1), app.UIAxesEEG.YLim(1), ...
                                'Color', app.State.Events.(EventLabel).Color, ...
                                'MarkerEdgeColor', [1, 1, 1], ...
                                'MarkerFaceColor', app.State.Events.(EventLabel).Color, ...
                                'LineStyle', '-', ...
                                'LineWidth', EventLineWidth, ...
                                'Marker', 'o', ...
                                'MarkerSize', EventMarkerSize, ...
                                'Tag', ['Event_id-', num2str(ID)], ...
                                'PickableParts', 'none');
                        end
                end
                YPos = ifelse(Events([Events.id] == ID).track == 0, app.UIAxesEEG.YLim(1), app.UIAxesEEG.YLim(1)+Events([Events.id] == ID).track*TrackHeight);
                HorAlign = ifelse(Events([Events.id] == ID).track == 0, 'center', 'right');
                VerAlign = ifelse(Events([Events.id] == ID).track == 0, 'bottom', 'middle');
                text(app.UIAxesEEG, max([Onset, app.State.Time]), YPos, ifelse(Events([Events.id] == ID).track == 0, EventLabel, [EventLabel, '  ']), ...
                    'Color', app.State.Events.(EventLabel).Color.^3, ...
                    'FontSize', 10, ...
                    'HorizontalAlignment', ifelse(app.State.Events.(EventLabel).IsReject, 'left', HorAlign), ...
                    'VerticalAlignment', ifelse(app.State.Events.(EventLabel).IsReject, 'top', VerAlign), ...
                    'BackgroundColor', ifelse(app.State.Events.(EventLabel).IsReject, [0.993, 0.957, 0.963], 'none'), ...
                    'Interpreter', 'none', ...
                    'Tag', ['Event_id-', num2str(ID)], ...
                    'ButtonDownFcn', @app.EventButtonDownFcn);
            end
            % -----
            % Finally, add the suggested bad epochs
            T = now;
            app.TrainSVM();
            if app.State.Verbose; fprintf('>> BIDS: Training SVM took %i ms\n', round((now-T)*24*60*60*1000)); end
            T = now;
            SuggestedRejEpochIdx = app.PredictSVM();
            if app.State.Verbose; fprintf('>> BIDS: Predicting bad epochs took %i ms\n', round((now-T)*24*60*60*1000)); end
            delete(findobj(app.UIAxesEEG.Children, 'Tag', 'SuggestedRejEpoch'))
            for i = 1:length(SuggestedRejEpochIdx)
                patch(app.UIAxesEEG, ...
                    'XData', [ ...
                    SuggestedRejEpochIdx(i)*app.State.EEG.etc.faster.epochlength-app.State.EEG.etc.faster.epochlength, ...
                    SuggestedRejEpochIdx(i)*app.State.EEG.etc.faster.epochlength, ...
                    SuggestedRejEpochIdx(i)*app.State.EEG.etc.faster.epochlength, ...
                    SuggestedRejEpochIdx(i)*app.State.EEG.etc.faster.epochlength-app.State.EEG.etc.faster.epochlength, ...
                    ], ...
                    'YData', [-1, -1, app.State.NumEEGChans+1, app.State.NumEEGChans+1], ...
                    'FaceColor', [0 1 1], ...
                    'FaceAlpha', 0.1, ...
                    'LineStyle', 'none', ...
                    'Tag', 'SuggestedRejEpoch', ...
                    'PickableParts', 'none');
                % Edit: no longer automatically add a reject event when
                % clicking on the suggested epoch. It interferes too much
                % with the UI. Rather, the user can just drag the cursor
                % and add a reject event that way.
                % Removed line is: 'ButtonDownFcn', @app.AddRejectEventButtonDownFcn
            end
        end
        % =========================================================
        function SetLineWidth(app)
            app.PatchCursorEEG.LineWidth = app.State.LineWidth + 0.5;
            app.PatchCursorSpectro.LineWidth = app.State.LineWidth + 0.5;
            app.PatchCursorPNS.LineWidth = app.State.LineWidth + 0.5;
            app.PatchCursorStats.LineWidth = app.State.LineWidth + 0.5;
            app.LineICA.LineWidth = app.State.LineWidth;
            app.LineEEG.LineWidth = app.State.LineWidth;
            app.LinePreselEEG.LineWidth = app.State.LineWidth;
            app.LinePNS.LineWidth = app.State.LineWidth;
            app.LineStatsVar.LineWidth = max([app.State.LineWidth, 1]);
            app.LineStatsCorr.LineWidth = max([app.State.LineWidth, 1]);
            app.LineStatsHurst.LineWidth = max([app.State.LineWidth, 1]);
            app.LineStatsLFP.LineWidth = max([app.State.LineWidth, 1]);
            app.LineStatsHFP.LineWidth = max([app.State.LineWidth, 1]);
        end
        % =========================================================
        function SetMarkerSize(app)
            app.LineChansVar.MarkerSize = app.State.MarkerSize;
            app.LinePreselChansVar.MarkerSize = app.State.MarkerSize + 3;
            app.LineRejChansVar.MarkerSize = app.State.MarkerSize + 3;
            app.LineChansCorr.MarkerSize = app.State.MarkerSize;
            app.LinePreselChansCorr.MarkerSize = app.State.MarkerSize + 3;
            app.LineRejChansCorr.MarkerSize = app.State.MarkerSize + 3;
            app.LineChansHurst.MarkerSize = app.State.MarkerSize;
            app.LinePreselChansHurst.MarkerSize = app.State.MarkerSize + 3;
            app.LineRejChansHurst.MarkerSize = app.State.MarkerSize + 3;
            app.LineChansLFP.MarkerSize = app.State.MarkerSize;
            app.LinePreselChansLFP.MarkerSize = app.State.MarkerSize + 3;
            app.LineRejChansLFP.MarkerSize = app.State.MarkerSize + 3;
            app.LineChansHFP.MarkerSize = app.State.MarkerSize;
            app.LinePreselChansHFP.MarkerSize = app.State.MarkerSize + 3;
            app.LineRejChansHFP.MarkerSize = app.State.MarkerSize + 3;
        end
        % =========================================================
        function SetToposurface(app)
            if isempty(app.State.ChanLocs)
                return
            end
            if app.State.EEG.nbchan == 1
                return
            end
            % ---------------------------------------------------------
            % Create Topoplots
            Stats = {'Var', 'Corr', 'Hurst', 'LFP', 'HFP'};
            for i = 1:length(Stats)
                topoVals = abs(nonparzscore(nanmean(app.State.EEG.etc.faster.(lower(Stats{i}))(:, ~app.State.RejEpochIdx), 2)));
                topoVals(isnan(topoVals)) = 0;
                [~, CData] = topoplot(topoVals, app.State.EEG.chanlocs(strcmpi({app.State.EEG.chanlocs.type}, 'EEG')), ...
                    'style', 'map', ...
                    'electrodes', 'off', ...
                    'plotrad', 0.85, ...
                    'headrad', 0.6, ...
                    'intrad', 1, ...
                    'conv', 'on', ...
                    'noplot', 'on'); %#ok<ASGLU>
                eval(sprintf('app.SurfTopo%s.CData = CData;', Stats{i}))
                close(gcf);
            end
        end

        % =========================================================
        function SetChannelIndices(app, type)
            % ---------------------------------------------------------
            switch type
                case 'NewChannels'
                    % -----
                    % Update Channel Step
                    idx = 1+find([16 8 4 2 1] == app.State.ChanStep);
                    if ~isempty(idx)
                        app.ChanStepButtonGroup.SelectedObject = app.ChanStepButtonGroup.Children(idx);
                    end
                    switch app.State.ChanSort
                        case 'Channel Label'
                            set(app.ChanStepButtonGroup.Children, 'Enable', 'on')
                        otherwise
                            set(app.ChanStepButtonGroup.Children, 'Enable', 'off')
                    end
                    % -----
                    % Update EEG Axes YLim, YTick and YTickLabels
                    app.UIAxesEEG.YLim = [0.5, length(app.State.ChanIdx)+0.5];
                    app.UIAxesEEG.YLim(1) = 0.5-range(app.UIAxesEEG.YLim)/64;
                    app.UIAxesEEG.YTick = 1:length(app.State.ChanIdx);
                    app.UIAxesEEG.YTickLabel = {app.State.EEG.chanlocs(app.State.ChanSortIdx(app.State.ChanIdx)).labels};
                    % -----
                    % Update Topoplots
                    if isempty(app.State.ChanLocs)
                        return
                    end
                    if app.State.EEG.nbchan == 1
                        return
                    end
                    app.LineChansVar.XData = app.State.ChanLocs(1, app.State.ChanSortIdx(app.State.ChanIdx));
                    app.LineChansVar.YData = app.State.ChanLocs(2, app.State.ChanSortIdx(app.State.ChanIdx));
                    app.LineChansVar.ZData = app.State.ChanLocs(3, app.State.ChanSortIdx(app.State.ChanIdx));
                    app.LineChansVar.UserData.Labels = {app.State.EEG.chanlocs(app.State.ChanSortIdx(app.State.ChanIdx)).labels};
                    app.LineChansCorr.XData = app.State.ChanLocs(1, app.State.ChanSortIdx(app.State.ChanIdx));
                    app.LineChansCorr.YData = app.State.ChanLocs(2, app.State.ChanSortIdx(app.State.ChanIdx));
                    app.LineChansCorr.ZData = app.State.ChanLocs(3, app.State.ChanSortIdx(app.State.ChanIdx));
                    app.LineChansCorr.UserData.Labels = {app.State.EEG.chanlocs(app.State.ChanSortIdx(app.State.ChanIdx)).labels};
                    app.LineChansHurst.XData = app.State.ChanLocs(1, app.State.ChanSortIdx(app.State.ChanIdx));
                    app.LineChansHurst.YData = app.State.ChanLocs(2, app.State.ChanSortIdx(app.State.ChanIdx));
                    app.LineChansHurst.ZData = app.State.ChanLocs(3, app.State.ChanSortIdx(app.State.ChanIdx));
                    app.LineChansHurst.UserData.Labels = {app.State.EEG.chanlocs(app.State.ChanSortIdx(app.State.ChanIdx)).labels};
                    app.LineChansLFP.XData = app.State.ChanLocs(1, app.State.ChanSortIdx(app.State.ChanIdx));
                    app.LineChansLFP.YData = app.State.ChanLocs(2, app.State.ChanSortIdx(app.State.ChanIdx));
                    app.LineChansLFP.ZData = app.State.ChanLocs(3, app.State.ChanSortIdx(app.State.ChanIdx));
                    app.LineChansLFP.UserData.Labels = {app.State.EEG.chanlocs(app.State.ChanSortIdx(app.State.ChanIdx)).labels};
                    app.LineChansHFP.XData = app.State.ChanLocs(1, app.State.ChanSortIdx(app.State.ChanIdx));
                    app.LineChansHFP.YData = app.State.ChanLocs(2, app.State.ChanSortIdx(app.State.ChanIdx));
                    app.LineChansHFP.ZData = app.State.ChanLocs(3, app.State.ChanSortIdx(app.State.ChanIdx));
                    app.LineChansHFP.UserData.Labels = {app.State.EEG.chanlocs(app.State.ChanSortIdx(app.State.ChanIdx)).labels};
                    % ---------------------------------------------------------
                case 'PreselectChannels'
                    % -----
                    % Update Topoplots if vector has changed
                    if isempty(app.State.ChanLocs)
                        return
                    end
                    if app.State.EEG.nbchan == 1
                        return
                    end
                    if numel(app.LinePreselChansVar.XData) == sum(app.State.PreselChanIdx)
                        if all(app.LinePreselChansVar.XData == app.State.ChanLocs(1, app.State.PreselChanIdx)) && all(app.LinePreselChansVar.YData == app.State.ChanLocs(2, app.State.PreselChanIdx))
                            return
                        end
                    end
                    app.LinePreselChansVar.XData = app.State.ChanLocs(1, app.State.PreselChanIdx);
                    app.LinePreselChansVar.YData = app.State.ChanLocs(2, app.State.PreselChanIdx);
                    app.LinePreselChansVar.ZData = app.State.ChanLocs(3, app.State.PreselChanIdx);
                    app.LinePreselChansCorr.XData = app.State.ChanLocs(1, app.State.PreselChanIdx);
                    app.LinePreselChansCorr.YData = app.State.ChanLocs(2, app.State.PreselChanIdx);
                    app.LinePreselChansCorr.ZData = app.State.ChanLocs(3, app.State.PreselChanIdx);
                    app.LinePreselChansHurst.XData = app.State.ChanLocs(1, app.State.PreselChanIdx);
                    app.LinePreselChansHurst.YData = app.State.ChanLocs(2, app.State.PreselChanIdx);
                    app.LinePreselChansHurst.ZData = app.State.ChanLocs(3, app.State.PreselChanIdx);
                    app.LinePreselChansLFP.XData = app.State.ChanLocs(1, app.State.PreselChanIdx);
                    app.LinePreselChansLFP.YData = app.State.ChanLocs(2, app.State.PreselChanIdx);
                    app.LinePreselChansLFP.ZData = app.State.ChanLocs(3, app.State.PreselChanIdx);
                    app.LinePreselChansHFP.XData = app.State.ChanLocs(1, app.State.PreselChanIdx);
                    app.LinePreselChansHFP.YData = app.State.ChanLocs(2, app.State.PreselChanIdx);
                    app.LinePreselChansHFP.ZData = app.State.ChanLocs(3, app.State.PreselChanIdx);
                    % ---------------------------------------------------------
                case 'RejectChannels'
                    % -----
                    % Update Topoplots
                    if isempty(app.State.ChanLocs)
                        return
                    end
                    if app.State.EEG.nbchan == 1
                        return
                    end
                    app.LineRejChansVar.XData = app.State.ChanLocs(1, app.State.RejChanIdx);
                    app.LineRejChansVar.YData = app.State.ChanLocs(2, app.State.RejChanIdx);
                    app.LineRejChansVar.ZData = app.State.ChanLocs(3, app.State.RejChanIdx);
                    app.LineRejChansCorr.XData = app.State.ChanLocs(1, app.State.RejChanIdx);
                    app.LineRejChansCorr.YData = app.State.ChanLocs(2, app.State.RejChanIdx);
                    app.LineRejChansCorr.ZData = app.State.ChanLocs(3, app.State.RejChanIdx);
                    app.LineRejChansHurst.XData = app.State.ChanLocs(1, app.State.RejChanIdx);
                    app.LineRejChansHurst.YData = app.State.ChanLocs(2, app.State.RejChanIdx);
                    app.LineRejChansHurst.ZData = app.State.ChanLocs(3, app.State.RejChanIdx);
                    app.LineRejChansLFP.XData = app.State.ChanLocs(1, app.State.RejChanIdx);
                    app.LineRejChansLFP.YData = app.State.ChanLocs(2, app.State.RejChanIdx);
                    app.LineRejChansLFP.ZData = app.State.ChanLocs(3, app.State.RejChanIdx);
                    app.LineRejChansHFP.XData = app.State.ChanLocs(1, app.State.RejChanIdx);
                    app.LineRejChansHFP.YData = app.State.ChanLocs(2, app.State.RejChanIdx);
                    app.LineRejChansHFP.ZData = app.State.ChanLocs(3, app.State.RejChanIdx);
                    % ---------------------------------------------------------
            end
        end
        % =========================================================
        function SetTime(app)
            % ---------------------------------------------------------
            % Time Window Input
            app.TimeWindowLengthInput.Value = app.State.TimeWindowLength;
            % ---------------------------------------------------------
            % Timeline patch XData
            if app.State.Show.Timeline
                app.PatchCursorTimeline.XData = [app.State.Time, app.State.Time+app.State.TimeWindowLength, app.State.Time+app.State.TimeWindowLength, app.State.Time]./(24*60*60);
            end
            % ---------------------------------------------------------
            % All Axes XLim
            if app.State.Show.ICA
                app.UIAxesICA.XLim = [app.State.Time, app.State.Time+app.State.TimeWindowLength];
                app.UIAxesICA.XTick = app.State.Time:app.State.Time+app.State.TimeWindowLength;
            end
            if app.State.Show.EEG
                app.UIAxesEEG.XLim = [app.State.Time, app.State.Time+app.State.TimeWindowLength];
                app.UIAxesEEG.XTick = app.State.Time:app.State.Time+app.State.TimeWindowLength;
                app.UIAxesEEG.XTickLabel = [{datestr(app.State.Time/(24*60*60), 'HH:MM:SS')}, cell(1,app.State.TimeWindowLength)];
            end
            if app.State.Show.Spectro
                app.UIAxesSpectro.XLim = [app.State.Time, app.State.Time+app.State.TimeWindowLength];
                app.UIAxesSpectro.XTick = app.State.Time:app.State.Time+app.State.TimeWindowLength;
            end
            if app.State.Show.PNS
                app.UIAxesPNS.XLim = [app.State.Time, app.State.Time+app.State.TimeWindowLength];
                app.UIAxesPNS.XTick = app.State.Time:app.State.Time+app.State.TimeWindowLength;
            end
            if app.State.Show.Stats
                app.UIAxesStats.XLim = [app.State.Time, app.State.Time+app.State.TimeWindowLength];
                app.UIAxesStats.XTick = app.State.Time:app.State.Time+app.State.TimeWindowLength;
            end
        end
        % =========================================================
        function [XData, YData] = GetEEGData(app, SelectBool)
            % ---------------------------------------------------------
            % Remove any components if necessary
            if ~isempty(app.State.RejCompIdx)
                YData = pop_subcomp(app.State.EEG, app.State.RejCompIdx, 0);
            else
                YData = app.State.EEG;
            end
            % ---------------------------------------------------------
            % Extract all EEG data of this time window
            if YData.trials == 1
                try
                    YData = YData.data(strcmpi({app.State.EEG.chanlocs.type}, 'EEG'), app.State.TimeIdx);
                catch ME
                    app.FastBackwardTimeButtonPushed([]);
                    YData = YData.data(strcmpi({app.State.EEG.chanlocs.type}, 'EEG'), app.State.TimeIdx);
                end
            else
                EEGChanIdx = strcmpi({app.State.EEG.chanlocs.type}, 'EEG');
                YData = reshape(YData.data(EEGChanIdx, :, :), sum(EEGChanIdx), YData.trials*YData.pnts);
                YData = YData(:, app.State.TimeIdx);
            end
            % -----
            % Rereference the data
            if strcmpi(app.State.Reference, 'average')
                YData = YData - repmat(mean(YData(~app.State.RejChanIdx, :), 1), size(YData, 1), 1);
            elseif ~strcmpi(app.State.Reference, 'mixed') && any(strcmpi({app.State.EEG.chanlocs.labels}, app.State.Reference))
                YData = YData - repmat(YData(strcmpi({app.State.EEG.chanlocs.labels}, app.State.Reference), :), size(YData, 1), 1);
            end
            % -----
            % Blank out any channel that is not part of the Select Boolean
            YData(~SelectBool, :) = NaN;
            % -----
            % Select only the sorted channels that are part of the Channel Index
            YData = YData(app.State.ChanSortIdx(app.State.ChanIdx), :);
            % -----
            % Create Add vector to line up the channels along the Y-axis
            Add = ascolumn(1:size(YData, 1));
            % -----
            % Remove any rows in YData and Add
            Add(isnan(YData(:, 1)), :) = [];
            YData(isnan(YData(:, 1)), :) = [];
            % -----
            % Filter
            YData = filter(app.State.FilterDesign, YData')';
            gd = round(mean(grpdelay(app.State.FilterDesign)));
            YData = [YData(:, gd:end), NaN(size(YData, 1), gd-1)];
            % -----
            % Remove median
            YData = YData - repmat(median(YData, 2, 'omitnan'), 1, length(app.State.TimeIdx));
            % -----
            % Scale the EEG data and assign each channel to a row according to 'Add'
            YData = YData ./ app.State.EEGAmp + repmat(Add, 1, length(app.State.TimeIdx));
            % -----
            % Construct the XData
            XData = 0:1/app.State.EEG.srate:(app.State.EEG.pnts*app.State.EEG.trials)/app.State.EEG.srate-1/app.State.EEG.srate;
            XData = repmat([XData(app.State.TimeIdx), NaN], 1, size(YData, 1));
            % -----
            % Reshape the matrix into a row-vector delimited by a 'NaN'
            YData = reshape([YData, nan(size(YData, 1), 1)]', 1, size(YData, 1)*(size(YData, 2)+1));
        end
        % =========================================================
        function [XData, YData] = GetPNSData(app, ChanIdx)
            % ---------------------------------------------------------
            % Extract all PNS data of this time window
            if app.State.EEG.trials > 1
                YData = reshape(app.State.EEG.data, app.State.EEG.nbchan, app.State.EEG.pnts*app.State.EEG.trials);
                YData = YData(~strcmpi({app.State.EEG.chanlocs.type}, 'EEG'), app.State.TimeIdx);
            else
                YData = app.State.EEG.data(~strcmpi({app.State.EEG.chanlocs.type}, 'EEG'), app.State.TimeIdx);
            end
            % -----
            % Select only the PNS sensors that need shown
            YData = YData(ChanIdx, :) .* -1; % times -1 because the YDir is reversed
            AmpFactor = structfun(@(f) f, app.State.PNSAmp);
            AmpFactor = AmpFactor(ChanIdx);
            % -----
            % Scale the PNS data and assign each channel to a row
            YData = YData - repmat(median(YData, 2, 'omitnan'), 1, length(app.State.TimeIdx));
            YData = YData ./ repmat(AmpFactor, 1, length(app.State.TimeIdx)) + repmat((1:size(YData, 1))', 1, length(app.State.TimeIdx));
            % -----
            % Construct the XData
            XData = 0:1/app.State.EEG.srate:(app.State.EEG.pnts*app.State.EEG.trials)/app.State.EEG.srate-1/app.State.EEG.srate;
            XData = repmat([XData(app.State.TimeIdx), NaN], 1, size(YData, 1));
            % -----
            % Reshape the matrix into a row-vector delimited by a 'NaN'
            YData = reshape([YData, nan(size(YData, 1), 1)]', 1, size(YData, 1)*(length(app.State.TimeIdx)+1));
        end
        % =========================================================
        function SetICATopo(app)
            if app.State.EEG.nbchan == 1
                return
            end
            delete(app.UIAxesTopoComp.Children);
            Fig = figure;
            topoplot(app.State.EEG.icawinv(:, app.State.Comp), app.State.EEG.chanlocs(app.State.EEG.icachansind), ...
                'style', 'map', ...
                'electrodes', 'off', ...
                'plotrad', 0.85, ...
                'headrad', 0.6, ...
                'intrad', 1, ...
                'conv', 'on', ...
                'numcontour', 0, ...
                'hcolor', 'k', ...
                'whitebk', 'on');
            copyobj(Fig.Children.Children, app.UIAxesTopoComp);
            close(Fig)
            app.CompLabelBar.YData = app.State.EEG.etc.ic_classification.ICLabel.classifications(app.State.Comp, :);
            % TODO: Check this out
            app.UIAxesCompLabel.YTick = 0:0.1:1;
        end
        % =========================================================
        function SetICAList(app)
            % -----
            % Set independent-component item list
            app.CompList.Items = cellfun(@(ComponentIdx, RejectStr) sprintf('#%i%s', ComponentIdx, RejectStr), ...
                num2cell(1:size(app.State.EEG.icawinv, 2)), ...
                cellfun(@(n) ifelse(ismember(n, app.State.RejCompIdx), ' (reject)', ''), num2cell(1:size(app.State.EEG.icawinv, 2)), 'UniformOutput', false), ...
                'UniformOutput', false);
            app.CompList.Value = app.CompList.Items{app.State.Comp};
            app.RejectCompButton.Icon = ifelse(ismember(app.State.Comp, app.State.RejCompIdx), 'icon_tick.png', 'icon_ban.png');
            app.RejectCompButton.BackgroundColor = ifelse(ismember(app.State.Comp, app.State.RejCompIdx), [0.16, 0.65, 0.27], [0.86, 0.21 ,0.27]);
        end
        % =========================================================
        function SetChannelData(app, type)
            % ---------------------------------------------------------
            switch type
                case 'EEG'
                    % Set the YData of the EEG and Preselect EEG objects
                    try
                    [app.LineEEG.XData, app.LineEEG.YData] = app.GetEEGData(~app.State.RejChanIdx & ~app.State.PreselChanIdx);
                    catch ME
                        keyboard
                    end
                    [app.LinePreselEEG.XData, app.LinePreselEEG.YData] = app.GetEEGData(~app.State.RejChanIdx & app.State.PreselChanIdx);
                    app.UIAxesICA.YLim = [-app.State.EEGAmp, app.State.EEGAmp]./2;
                    % ---------------------------------------------------------
                case 'Spectro'
                    % Set the CData of the Spectro image object
                    if ~isempty(app.State.EEG.specdata)
                        % Spec times indices
                        TimeIdx = app.State.SpecTimes >= app.State.Time & app.State.SpecTimes < app.State.Time+app.State.TimeWindowLength;
                        app.ImageSpectro.XData = app.State.SpecTimes(TimeIdx);
                        app.ImageSpectro.YData = app.State.EEG.specfreqs;
                        app.ImageSpectro.ZData = zeros(size(app.State.SpecData(:, TimeIdx)));
                        app.ImageSpectro.CData = app.State.SpecData(:, TimeIdx);
                    end
                    % ---------------------------------------------------------
                case 'PNS'
                    % Set the YLim, YTick and YTickLabels
                    ChanIdx = find(~strcmpi({app.State.EEG.chanlocs.type}, 'EEG'));
                    if isempty(ChanIdx)
                        return
                    end
                    doShow = structfun(@(f) f, app.State.Show);
                    doShow(1:6) = []; % Remove the show-value for axes fields
                    ChanIdx = ChanIdx(doShow);
                    app.UIAxesPNS.YLim = ifelse(isempty(ChanIdx), [0.5, 1.5], [0.5, length(ChanIdx)+0.5]);
                    app.UIAxesPNS.YTick = ifelse(isempty(ChanIdx), 1, 1:length(ChanIdx));
                    app.UIAxesPNS.YTickLabel = ifelse(isempty(ChanIdx), '', strrep({app.State.EEG.chanlocs(ChanIdx).labels}, '_', ' '));
                    % -----
                    % Set the sensor data (YData)
                    [XData, YData] = app.GetPNSData(doShow);
                    if numel(app.LinePNS.YData) ~= numel(YData)
                        Update = true;
                    elseif nansum(abs(app.LinePNS.YData - YData)) ~= 0 %#ok<NANSUM>
                        Update = true;
                    else
                        Update = false;
                    end
                    if Update
                        app.LinePNS.XData = XData;
                        app.LinePNS.YData = YData;
                    end
                    % ---------------------------------------------------------
                case 'ICA'
                    % Extract all EEG data of this time window
                    if app.State.EEG.trials == 1
                        EEGData = app.State.EEG.data(strcmpi({app.State.EEG.chanlocs.type}, 'EEG'), app.State.TimeIdx);
                    else
                        EEGChanIdx = strcmpi({app.State.EEG.chanlocs.type}, 'EEG');
                        EEGData = reshape(app.State.EEG.data(EEGChanIdx, :, :), sum(EEGChanIdx), app.State.EEG.trials*app.State.EEG.pnts);
                        EEGData = EEGData(:, app.State.TimeIdx);
                    end
                    YData = (app.State.EEG.icaweights(app.State.Comp, :)*app.State.EEG.icasphere)*EEGData(app.State.EEG.icachansind, :);
                    % -----
                    % Filter
                    YData = filter(app.State.FilterDesign, YData')';
                    gd = round(mean(grpdelay(app.State.FilterDesign)));
                    YData = [YData(:, gd:end), NaN(size(YData, 1), gd-1)];
                    % -----
                    app.LineICA.XData = app.State.Times(app.State.TimeIdx);
                    app.LineICA.YData = YData;
                    app.UIAxesICA.YLim = [-app.State.EEGAmp, app.State.EEGAmp]./2;
                    app.UIAxesICA.YTickLabel = sprintf('IC#%i', app.State.Comp);
                otherwise
                    return
            end
        end
        % =========================================================
        function AlignAxes(app)
            % ---------------------------------------------------------
            MaxPos = 0;
            MaxPos = ifelse(app.State.Show.EEG, max([MaxPos, app.UIAxesEEG.InnerPosition(1) - app.UIAxesEEG.Parent.ColumnWidth{1}]), MaxPos);
            MaxPos = ifelse(app.State.Show.ICA, max([MaxPos, app.UIAxesICA.InnerPosition(1) - app.UIAxesICA.Parent.ColumnWidth{1}]), MaxPos);
            MaxPos = ifelse(app.State.Show.Spectro, max([MaxPos, app.UIAxesSpectro.InnerPosition(1) - app.UIAxesSpectro.Parent.ColumnWidth{1}]), MaxPos);
            MaxPos = ifelse(app.State.Show.PNS, max([MaxPos, app.UIAxesPNS.InnerPosition(1) - app.UIAxesPNS.Parent.ColumnWidth{1}]), MaxPos);
            MaxPos = ifelse(app.State.Show.Stats, max([MaxPos, app.UIAxesStats.InnerPosition(1) - app.UIAxesStats.Parent.ColumnWidth{1}]), MaxPos);
            MaxPos = round(MaxPos);
            if app.State.Show.EEG
                app.UIAxesEEG.Parent.ColumnWidth{1} = max([1, app.UIAxesEEG.Parent.ColumnWidth{1} + (MaxPos - round(app.UIAxesEEG.InnerPosition(1)))]);
            end
            if app.State.Show.ICA
                app.UIAxesICA.Parent.ColumnWidth{1} = max([1, app.UIAxesICA.Parent.ColumnWidth{1} + (MaxPos - round(app.UIAxesICA.InnerPosition(1)))]);
            end
            if app.State.Show.Spectro
                app.UIAxesSpectro.Parent.ColumnWidth{1} = max([1, app.UIAxesSpectro.Parent.ColumnWidth{1} + (MaxPos - round(app.UIAxesSpectro.InnerPosition(1)))]);
            end
            if app.State.Show.PNS
                app.UIAxesPNS.Parent.ColumnWidth{1} = max([1, app.UIAxesPNS.Parent.ColumnWidth{1} + (MaxPos - round(app.UIAxesPNS.InnerPosition(1)))]);
            end
            if app.State.Show.Stats
                app.UIAxesStats.Parent.ColumnWidth{1} = max([1, app.UIAxesStats.Parent.ColumnWidth{1} + (MaxPos - round(app.UIAxesStats.InnerPosition(1)))]);
            end
        end
        % \\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\\
        % MAIN FUNCTION
        % =========================================================
        % FUNCTION: Map the State to the Properties of all Components
        function MapStateToProps(app)
            % ---------------------------------------------------------
            app.UIFigure.Pointer = 'watch'; drawnow;
            if ~isvalid(app)
                return
            end
            app.State.IsLoading = true;
            % ---------------------------------------------------------
            app.SaveButton.Enable = 'on';
            % ---------------------------------------------------------
            % Remove any chan labels from the topoplots
            delete(findall(app.GridLayoutTopoChannels, 'Tag', 'TopoChanSel'))
            app.PanelEditEvents.Visible = 'off';
            % ---------------------------------------------------------
            % Set saved to false
            app.State.Saved = false;
            if app.State.Verbose; fprintf('>> ---------------------------------------------------------\n'); end
            % ---------------------------------------------------------
            % Height input fields
            if ismember('PanelShow', app.ComponentList)
                T = now;
                app.HeightPanelTimelineInput.Enable = ifelse(app.State.Show.Timeline, 'on', 'off');
                app.HeightPanelICAInput.Enable = ifelse(app.State.Show.ICA, 'on', 'off');
                app.HeightPanelEEGInput.Enable = ifelse(app.State.Show.EEG, 'on', 'off');
                app.HeightPanelSpectroInput.Enable = ifelse(app.State.Show.Spectro, 'on', 'off');
                app.HeightPanelPNSInput.Enable = ifelse(app.State.Show.PNS, 'on', 'off');
                app.HeightPanelStatsInput.Enable = ifelse(app.State.Show.Stats, 'on', 'off');
                if app.State.Verbose; fprintf('>> BIDS: Enable/disable height input fields took %i ms\n', round((now-T)*24*60*60*1000)); end
            end
            % ---------------------------------------------------------
            % Panel heights
            if ismember('PanelHeight', app.ComponentList)
                T = now;
                app.GridLayout.RowHeight{1} = ifelse(app.State.Show.Timeline, [num2str(app.State.Height.Timeline), 'x'], 1);
                app.GridLayout.RowHeight{2} = ifelse(app.State.Show.ICA, [num2str(app.State.Height.ICA), 'x'], 1);
                app.GridLayout.RowHeight{3} = ifelse(app.State.Show.EEG, [num2str(app.State.Height.EEG), 'x'], 1);
                app.GridLayout.RowHeight{4} = ifelse(app.State.Show.Spectro, [num2str(app.State.Height.Spectro), 'x'], 1);
                app.GridLayout.RowHeight{5} = ifelse(app.State.Show.PNS, [num2str(app.State.Height.PNS), 'x'], 1);
                app.GridLayout.RowHeight{6} = ifelse(app.State.Show.Stats, [num2str(app.State.Height.Stats), 'x'], 1);
                if app.State.Verbose; fprintf('>> BIDS: Setting pannel heights took %i ms\n', round((now-T)*24*60*60*1000)); end
            end
            % ---------------------------------------------------------
            % Anything below here depends on the fact the EEG is loaded
            if ~app.State.Loaded
                return
            end
            % ---------------------------------------------------------
            % Set the channel data vectors (YData) of all EEG plot objects
            if ismember('EEGChannelData', app.ComponentList) && app.State.Show.EEG
                T = now;
                app.SetChannelData('EEG');
                if app.State.Verbose; fprintf('>> BIDS: Setting EEG channel data took %i ms\n', round((now-T)*24*60*60*1000)); end
            end
            % ---------------------------------------------------------
            % Set the channel data vectors (YData) and axes properties (YLim, YTick and YTickLabel) of all PNS plot objects
            if ismember('PNSChannelData', app.ComponentList) && app.State.Show.PNS
                T = now;
                app.SetChannelData('PNS');
                if app.State.Verbose; fprintf('>> BIDS: Setting PNS channel data took %i ms\n', round((now-T)*24*60*60*1000)); end
            end
            % ---------------------------------------------------------
            % Set the spectral data image (CData) of all Spectro plot objects
            if ismember('SpectroData', app.ComponentList) && app.State.Show.Spectro
                T = now;
                app.SetChannelData('Spectro');
                if app.State.Verbose; fprintf('>> BIDS: Setting spectrodata took %i ms\n', round((now-T)*24*60*60*1000)); end
            end
            % ---------------------------------------------------------
            % Set the ICA activation vectors (YData)
            if ismember('ICAList', app.ComponentList) && app.State.Show.ICA
                T = now;
                app.SetICAList();
                if app.State.Verbose; fprintf('>> BIDS: Listing all ICA components took %i ms\n', round((now-T)*24*60*60*1000)); end
            end
            % ---------------------------------------------------------
            % Set the ICA activation vectors (YData_
            if ismember('ICAData', app.ComponentList) && app.State.Show.ICA
                T = now;
                app.SetChannelData('ICA');
                if app.State.Verbose; fprintf('>> BIDS: Setting ICA data took %i ms\n', round((now-T)*24*60*60*1000)); end
            end
            % ---------------------------------------------------------
            % Set the topoplot of the ICA component
            if ismember('ICATopo', app.ComponentList) && app.State.Show.ICA
                T = now;
                app.SetICATopo();
                if app.State.Verbose; fprintf('>> BIDS: Setting ICA topoplot took %i ms\n', round((now-T)*24*60*60*1000)); end
            end
            % ---------------------------------------------------------
            % Set the event list
            if ismember('EventList', app.ComponentList)
                T = now;
                app.SetEventList();
                if app.State.Verbose; fprintf('>> BIDS: Setting event list took %i ms\n', round((now-T)*24*60*60*1000)); end
            end
            % ---------------------------------------------------------
            % Set the channel indices and sorting
            if ismember('NewChannelIndices', app.ComponentList)
                T = now;
                app.SetChannelIndices('NewChannels');
                if app.State.Verbose; fprintf('>> BIDS: setting new channel indices took %i ms\n', round((now-T)*24*60*60*1000)); end
            end
            % ---------------------------------------------------------
            % Update the preselected channels in the topoplots
            if ismember('PreselChannelIndices', app.ComponentList)
                T = now;
                app.SetChannelIndices('PreselectChannels');
                if app.State.Verbose; fprintf('>> BIDS: Setting preselected channels took %i ms\n', round((now-T)*24*60*60*1000)); end
            end
            % ---------------------------------------------------------
            % Update Rejected Channels in the topoplots
            if ismember('RejChannelIndices', app.ComponentList)
                T = now;
                app.SetChannelIndices('RejectChannels');
                if app.State.Verbose; fprintf('>> BIDS: Setting rejected channels took %i ms\n', round((now-T)*24*60*60*1000)); end
            end
            % ---------------------------------------------------------
            % Set the XLim and XTick of all plot objects
            if ismember('Time', app.ComponentList)
                T = now;
                app.SetTime();
                if app.State.Verbose; fprintf('>> BIDS: Changing time took %i ms\n', round((now-T)*24*60*60*1000)); end
            end
            % ---------------------------------------------------------
            % Update the surface in the topoplots
            if ismember('Toposurface', app.ComponentList)
                T = now;
                app.SetToposurface();
                if app.State.Verbose; fprintf('>> BIDS: Updating topoplots took %i ms\n', round((now-T)*24*60*60*1000)); end
            end
            % ---------------------------------------------------------
            % Make sure the axes have the same horizontal position
            if ismember('AlignAxes', app.ComponentList)
                T = now;
                app.AlignAxes();
                if app.State.Verbose; fprintf('>> BIDS: Aligning axes took %i ms\n', round((now-T)*24*60*60*1000)); end
            end
            % ---------------------------------------------------------
            % Set linewidth
            if ismember('LineWidth', app.ComponentList)
                T = now;
                app.SetLineWidth();
                if app.State.Verbose; fprintf('>> BIDS: Setting linewidth took %i ms\n', round((now-T)*24*60*60*1000)); end
            end
            % ---------------------------------------------------------
            % Set markersize
            if ismember('MarkerSize', app.ComponentList)
                T = now;
                app.SetMarkerSize();
                if app.State.Verbose; fprintf('>> BIDS: Setting markersize took %i ms\n', round((now-T)*24*60*60*1000)); end
            end
            % ---------------------------------------------------------
            % Set the EEG events
            if ismember('EEGEvents', app.ComponentList) && app.State.Show.EEG
                T = now;
                app.SetEEGEvents();
                if app.State.Verbose; fprintf('>> BIDS: Setting EEG events took %i ms\n', round((now-T)*24*60*60*1000)); end
            end
            % ---------------------------------------------------------
            % Set the event timeline
            if ismember('TimelineEvents', app.ComponentList) && app.State.Show.Timeline
                T = now;
                app.SetTimelineEvents();
                if app.State.Verbose; fprintf('>> BIDS: Setting timeline events took %i ms\n', round((now-T)*24*60*60*1000)); end
            end
            % ---------------------------------------------------------
            % Set Title
            Title = sprintf('Completed inspection of %s (%.0f%%) – %i/%i rejected Channels (%.0f%%) – %s rejected time (%.0f%%).', ...
                duration2str((sum(app.State.SeenEpochIdx)*app.State.EEG.etc.faster.epochlength)/(24*60*60)), ...
                100*(sum(app.State.SeenEpochIdx)*app.State.EEG.etc.faster.epochlength/app.State.Times(end)), ...
                sum(app.State.RejChanIdx), ...
                app.State.NumEEGChans, ...
                100*(sum(app.State.RejChanIdx)/app.State.NumEEGChans), ...
                duration2str(sum([app.State.EEG.event([app.State.EEG.event.is_reject] == 1).duration])/(24*60*60*app.State.EEG.srate)), ...
                100*(sum([app.State.EEG.event([app.State.EEG.event.is_reject] == 1).duration])/(app.State.EEG.pnts*app.State.EEG.trials)) ...
                );
            if app.State.Show.Timeline
                app.PanelTimeline.Title = Title;
                app.PanelEEG.Title = '';
                app.PanelSpectro.Title = '';
                app.PanelPNS.Title = '';
                app.PanelStats.Title = '';
            elseif app.State.Show.EEG
                app.PanelTimeline.Title = '';
                app.PanelEEG.Title = Title;
                app.PanelSpectro.Title = '';
                app.PanelPNS.Title = '';
                app.PanelStats.Title = '';
            elseif app.State.Show.Spectro
                app.PanelTimeline.Title = '';
                app.PanelEEG.Title = '';
                app.PanelSpectro.Title = Title;
                app.PanelPNS.Title = '';
                app.PanelStats.Title = '';
            elseif app.State.Show.PNS
                app.PanelTimeline.Title = '';
                app.PanelEEG.Title = '';
                app.PanelSpectro.Title = '';
                app.PanelPNS.Title = Title;
                app.PanelStats.Title = '';
            elseif app.State.Show.Stats
                app.PanelTimeline.Title = '';
                app.PanelEEG.Title = '';
                app.PanelSpectro.Title = '';
                app.PanelPNS.Title = '';
                app.PanelStats.Title = Title;
            end
            % ---------------------------------------------------------
            % Clear component list
            app.ComponentList = {};
            if app.State.Verbose; fprintf('>> BIDS: done\n'); end
            % ---------------------------------------------------------
            app.State.IsLoading = false;
            app.UIFigure.Pointer = 'arrow';
        end
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, Filename)


            % ---------------------------------------------------------
            % Initialize State
            InitializeState(app)
            % ---------------------------------------------------------
            % Position this app
            ScreenSize = get(0, 'ScreenSize');
            % -----
            app.UIFigure.Position(1) = 25;
            app.UIFigure.Position(2) = 80;
            app.UIFigure.Position(3) = ScreenSize(3) - 50;
            app.UIFigure.Position(4) = ScreenSize(4) - 160;
            % ---------------------------------------------------------
            % Check input file if exists
            if nargin >= 3
                if exist(Filename, 'file') == 0
                    sel = uiconfirm(app.UIFigure, {'File not found:', Filename}, 'Error', ...
                        'Options',{'Close menu'},...
                        'DefaultOption', 'Close menu', ...
                        'Icon', 'error'); %#ok<NASGU>
                    close(app.UIFigure)
                    return
                end
            else
                Filename = '';
            end
            % ---------------------------------------------------------
            % Store main app object
            if exist('MainApp', 'var')
                app.MainApp = MainApp;
            else
                app.MainApp = struct();
            end
            % ---------------------------------------------------------
            % Clear the import structure
            app.MainApp.Import = struct();
            app.MainApp.Import.Return = false;
            % ---------------------------------------------------------
            % Load the colormap
            if exist('colormap_batlow.mat', 'file') == 2
                app.State.CMap = load('colormap_batlow.mat');
                app.State.CMap = app.State.CMap.batlow;
                app.UIFigure.Colormap = app.State.CMap;
            else
                app.State.CMap = parula();
                app.UIFigure.Colormap = app.State.CMap;
            end
            % ---------------------------------------------------------
            % Load Icons
            if exist('icon_plus.png', 'file') == 2
                app.NewEventGroupButton.Icon = 'icon_plus.png';
                app.EditEventGroupButton.Icon = 'icon_edit.png';
                app.DeleteEventGroupButton.Icon = 'icon_trash.png';
                app.RejectEventGroupButton.Icon = 'icon_ban.png';
                app.RejectCompButton.Icon = 'icon_ban.png';
                app.EditEventButton.Icon = 'icon_edit.png';
                app.DeleteEventButton.Icon = 'icon_trash.png';
            end
            % ---------------------------------------------------------
            % Remove the toolbars from the axes
            app.UIAxesTimeline.Tag = 'Timeline';
            app.UIAxesTimeline.Toolbar = [];
            app.UIAxesTimeline.Interactions = [];
            % -----
            app.UIAxesICA.Tag = 'ICA';
            app.UIAxesICA.Toolbar = [];
            app.UIAxesICA.Interactions = [];
            % -----
            app.UIAxesEEG.Tag = 'EEG';
            app.UIAxesEEG.Toolbar = [];
            app.UIAxesEEG.Interactions = [];
            % -----
            app.UIAxesSpectro.Tag = 'Spectro';
            app.UIAxesSpectro.Toolbar = [];
            app.UIAxesSpectro.Interactions = [];
            % -----
            app.UIAxesPNS.Tag = 'PNS';
            app.UIAxesPNS.Toolbar = [];
            app.UIAxesPNS.Interactions = [];
            % -----
            app.UIAxesStats.Tag = 'Stats';
            app.UIAxesStats.Toolbar = [];
            app.UIAxesStats.Interactions = [];
            app.UIAxesStats.YTickLabel = {'', '-3', '-4', '-5', '-6'};
            % -----
            app.UIAxesTopoVar.Toolbar = [];
            app.UIAxesTopoVar.Interactions = [];
            % -----
            app.UIAxesTopoCorr.Toolbar = [];
            app.UIAxesTopoCorr.Interactions = [];
            % -----
            app.UIAxesTopoHurst.Toolbar = [];
            app.UIAxesTopoHurst.Interactions = [];
            % -----
            app.UIAxesTopoLFP.Toolbar = [];
            app.UIAxesTopoLFP.Interactions = [];
            % -----
            app.UIAxesTopoHFP.Toolbar = [];
            app.UIAxesTopoHFP.Interactions = [];
            % -----
            app.UIAxesCompLabel.Toolbar = [];
            app.UIAxesCompLabel.Interactions = [];
            % -----
            app.UIAxesTopoComp.Toolbar = [];
            app.UIAxesTopoComp.Interactions = [];
            % ---------------------------------------------------------
            % Plot Empty Graphics
            % -----
            % Timeline
            app.PatchSeenTimeline = patch(app.UIAxesTimeline, ...
                'XData', [0, 0, 0, 0], ...
                'YData', [0, 0, 0, 0], ...
                'FaceColor', [0.278, 0.561, 0.118], ...
                'EdgeColor', [0.278, 0.561, 0.118], ...
                'FaceAlpha', 0.15, ...
                'LineStyle', '-', ...
                'LineWidth', 0.25, ...
                'PickableParts', 'none');
            app.PatchCursorTimeline = patch(app.UIAxesTimeline, ...
                'XData', [0, 0, 0, 0], ...
                'YData', [0, 0, 0, 0], ...
                'FaceColor', [0.110, 0.769, 0.824], ...
                'FaceAlpha', 0.1, ...
                'LineStyle', '-', ...
                'EdgeColor', [0.110, 0.769, 0.824], ...
                'PickableParts', 'none');
            % -----
            % ICA
            app.LineICA = line(app.UIAxesICA, 0, 0, 'Color', [0, 0, 0], 'Tag', 'ICA', 'LineWidth', app.State.LineWidth, 'LineStyle', '-', 'Marker', 'none', 'PickableParts', 'none');
            % -----
            % EEG
            app.TextHypnoEEG = text(app.UIAxesEEG, 0, 0, '', 'Color', [0.9, 0.9, 0.9], 'FontSize', 72, 'FontWeight', 'bold', 'VerticalAlignment', 'middle', 'HorizontalAlignment', 'center', 'PickableParts', 'none');
            app.LineEEG = line(app.UIAxesEEG, 0, 0, 'Color', [0, 0, 0], 'Tag', 'EEG', 'LineWidth', app.State.LineWidth, 'LineStyle', '-', 'Marker', 'none', 'PickableParts', 'none');
            app.LinePreselEEG = line(app.UIAxesEEG, 0, 0, 'Color', [0.012, 0.591, 0.679], 'Tag', 'PreselEEG', 'LineWidth', app.State.LineWidth, 'LineStyle', '-', 'Marker', 'none', 'PickableParts', 'none');
            app.PatchCursorEEG = patch(app.UIAxesEEG, 'XData', [-999, -998, -998, -999], 'YData', [0, 0, 1, 1], 'FaceColor', [0.188, 0.310, 0.792], 'FaceAlpha', 0.1, 'LineWidth', app.State.LineWidth+0.5, 'LineStyle', '-', 'EdgeColor', [0.188, 0.310, 0.792], 'Tag', 'Cursor', 'PickableParts', 'none');
            app.TextCursorEEG = text(app.UIAxesEEG, -998, 0.5, '', 'Color', [0.188, 0.310, 0.792], 'BackgroundColor', 'none', 'FontSize', 10, 'FontWeight', 'bold', 'HorizontalAlignment', 'right', 'VerticalAlignment', 'bottom', 'Margin', 1, 'PickableParts', 'none');
            % -----
            % Spectrogram
            app.ImageSpectro = pcolor(app.UIAxesSpectro, -2:-1, -2:-1, [0, 0; 0, 0]);
            app.ImageSpectro.EdgeColor = 'none';
            app.UIAxesSpectro.YScale = 'log';
            % -----
            % PNS
            app.LinePNS = line(app.UIAxesPNS, 0, 0, 'Color', [0, 0, 0], 'Tag', 'PNS', 'LineWidth', app.State.LineWidth, 'LineStyle', '-', 'Marker', 'none', 'PickableParts', 'none');
            % -----
            % Stats
            app.LineStatsVar = stairs(app.UIAxesStats, 0, 0, 'Color', [1.000, 0.318, 0.663], 'Tag', 'StatsVar', 'LineWidth', max([app.State.LineWidth, 1]), 'LineStyle', '-', 'Marker', 'none', 'PickableParts', 'none');
            app.LineStatsCorr = stairs(app.UIAxesStats, 0, 0, 'Color', [0.553, 0.239, 0.612], 'Tag', 'StatsCorr', 'LineWidth', max([app.State.LineWidth, 1]), 'LineStyle', '-', 'Marker', 'none', 'PickableParts', 'none');
            app.LineStatsHurst = stairs(app.UIAxesStats, 0, 0, 'Color', [0.910, 0.761, 0.090], 'Tag', 'StatsHurst', 'LineWidth', max([app.State.LineWidth, 1]), 'LineStyle', '-', 'Marker', 'none', 'PickableParts', 'none');
            app.LineStatsLFP = stairs(app.UIAxesStats, 0, 0, 'Color', [0.850, 0.325, 0.098], 'Tag', 'StatsLFP', 'LineWidth', max([app.State.LineWidth, 1]), 'LineStyle', '-', 'Marker', 'none', 'PickableParts', 'none');
            app.LineStatsHFP = stairs(app.UIAxesStats, 0, 0, 'Color', [0.722, 0.110, 0.153], 'Tag', 'StatsHFP', 'LineWidth', max([app.State.LineWidth, 1]), 'LineStyle', '-', 'Marker', 'none', 'PickableParts', 'none');
            % ---------------------------------------------------------
            % If a Filename is Specfied, load the data and update the state
            app.MapDataToState(Filename);
            % ---------------------------------------------------------
            % Setup the inputs and create all the plot objects
            app.InitializeMapping();
            % ---------------------------------------------------------
            % Extract the channel locations from the topoplot
            if app.State.Loaded
                app.State.ChanLocs = [app.LineChansVar.XData; app.LineChansVar.YData; app.LineChansVar.ZData];
            end
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'PanelShow', 'PanelHeight', 'EventList', 'TimelineEvents', 'EEGEvents', 'LineWidth', 'MarkerSize', 'NewChannelIndices', 'PreselChannelIndices', 'RejChannelIndices', 'Time', 'ICAData', 'ICAList', 'ICATopo', 'EEGChannelData', 'SpectroData' 'PNSChannelData', 'Toposurface', 'AlignAxes'}];
            app.MapStateToProps();
        end

        % Button pushed function: FastBackwardTimeButton
        function FastBackwardTimeButtonPushed(app, event)
            % ---------------------------------------------------------
            if ~app.State.Loaded
                return
            end
            % ---------------------------------------------------------
            % Update which epochs have been seen
            app.SetSeenEpochIdx();
            % ---------------------------------------------------------
            app.State.Time = 0;
            app.State.TimeIdx = find(app.State.Times >= app.State.Time & app.State.Times < app.State.Time+app.State.TimeWindowLength);
            app.State.TimeIdx = app.State.TimeIdx(1:floor(app.State.EEG.srate/120):end);
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'Time', 'ICAData', 'EEGChannelData', 'SpectroData', 'PNSChannelData', 'EEGEvents'}];
            app.MapStateToProps();
        end

        % Button pushed function: BackwardTimeButton
        function BackwardTimeButtonPushed(app, event)
            % ---------------------------------------------------------
            if ~app.State.Loaded
                return
            end
            % ---------------------------------------------------------
            % Update which epochs have been seen
            app.SetSeenEpochIdx();
            % ---------------------------------------------------------
            NewTime = app.State.Time - app.State.TimeWindowLength;
            if app.State.Time <= 0
                return
            elseif NewTime <= 0
                app.State.Time = 0;
                app.State.TimeIdx = find(app.State.Times >= app.State.Time & app.State.Times < app.State.Time+app.State.TimeWindowLength);
                app.State.TimeIdx = app.State.TimeIdx(1:floor(app.State.EEG.srate/120):end);
            else
                app.State.Time = NewTime;
                app.State.TimeIdx = find(app.State.Times >= app.State.Time & app.State.Times < app.State.Time+app.State.TimeWindowLength);
                app.State.TimeIdx = app.State.TimeIdx(1:floor(app.State.EEG.srate/120):end);
            end
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'Time', 'ICAData', 'EEGChannelData', 'SpectroData', 'PNSChannelData', 'EEGEvents'}];
            app.MapStateToProps();
        end

        % Button pushed function: StepBackwardTimeButton
        function StepBackwardTimeButtonPushed(app, event)
            % ---------------------------------------------------------
            if ~app.State.Loaded
                return
            end
            % ---------------------------------------------------------
            % Update which epochs have been seen
            app.SetSeenEpochIdx();
            % ---------------------------------------------------------
            app.State.Time = app.State.Time - 1;
            if app.State.Time < 0
                app.State.Time = 0;
            end
            app.State.TimeIdx = find(app.State.Times >= app.State.Time & app.State.Times < app.State.Time+app.State.TimeWindowLength);
            app.State.TimeIdx = app.State.TimeIdx(1:floor(app.State.EEG.srate/120):end);
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'Time', 'ICAData', 'EEGChannelData', 'SpectroData', 'PNSChannelData', 'EEGEvents'}];
            app.MapStateToProps();
        end

        % Button pushed function: StepForwardTimeButton
        function StepForwardTimeButtonPushed(app, event)
            % ---------------------------------------------------------
            if ~app.State.Loaded
                return
            end
            % ---------------------------------------------------------
            % Update which epochs have been seen
            app.SetSeenEpochIdx();
            % ---------------------------------------------------------
            app.State.Time = app.State.Time + 1;
            if app.State.Time > ceil(app.State.Times(end) - app.State.TimeWindowLength)
                app.State.Time = ceil(app.State.Times(end) - app.State.TimeWindowLength);
            end
            app.State.TimeIdx = find(app.State.Times >= app.State.Time & app.State.Times < app.State.Time+app.State.TimeWindowLength);
            app.State.TimeIdx = app.State.TimeIdx(1:floor(app.State.EEG.srate/120):end);
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'Time', 'ICAData', 'EEGChannelData', 'SpectroData', 'PNSChannelData', 'EEGEvents'}];
            app.MapStateToProps();
        end

        % Button pushed function: ForwardTimeButton
        function ForwardTimeButtonPushed(app, event)
            % ---------------------------------------------------------
            if ~app.State.Loaded
                return
            end
            % ---------------------------------------------------------
            % Update which epochs have been seen
            app.SetSeenEpochIdx();
            % ---------------------------------------------------------
            NewTime = app.State.Time + app.State.TimeWindowLength;
            if app.State.Time >= ceil(app.State.Times(end) - app.State.TimeWindowLength)
                return
            elseif NewTime >= ceil(app.State.Times(end) - app.State.TimeWindowLength)
                app.State.Time = ceil(app.State.Times(end) - app.State.TimeWindowLength);
                app.State.TimeIdx = find(app.State.Times >= app.State.Time & app.State.Times < app.State.Time+app.State.TimeWindowLength);
                app.State.TimeIdx = app.State.TimeIdx(1:floor(app.State.EEG.srate/120):end);
            else
                app.State.Time = NewTime;
                app.State.TimeIdx = find(app.State.Times >= app.State.Time & app.State.Times < app.State.Time+app.State.TimeWindowLength);
                app.State.TimeIdx = app.State.TimeIdx(1:floor(app.State.EEG.srate/120):end);
            end
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'Time', 'ICAData', 'EEGChannelData', 'SpectroData', 'PNSChannelData', 'EEGEvents'}];
            app.MapStateToProps();
        end

        % Button pushed function: FastForwardTimeButton
        function FastForwardTimeButtonPushed(app, event)
            % ---------------------------------------------------------
            if ~app.State.Loaded
                return
            end
            % ---------------------------------------------------------
            % Update which epochs have been seen
            app.SetSeenEpochIdx();
            % ---------------------------------------------------------
            app.State.Time = ceil(app.State.Times(end) - app.State.TimeWindowLength);
            app.State.TimeIdx = find(app.State.Times >= app.State.Time & app.State.Times < app.State.Time+app.State.TimeWindowLength);
            app.State.TimeIdx = app.State.TimeIdx(1:floor(app.State.EEG.srate/120):end);
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'Time', 'ICAData', 'EEGChannelData', 'SpectroData', 'PNSChannelData', 'EEGEvents'}];
            app.MapStateToProps();
        end

        % Value changed function: TimeWindowLengthInput
        function TimeWindowLengthInputValueChanged(app, event)
            % ---------------------------------------------------------
            if ~app.State.Loaded
                return
            end
            % ---------------------------------------------------------
            % Update which epochs have been seen
            app.SetSeenEpochIdx();
            % ---------------------------------------------------------
            app.State.TimeWindowLength = app.TimeWindowLengthInput.Value;
            if ceil(app.State.Times(end)) < (app.State.Time + app.State.TimeWindowLength)
                app.State.Time = ceil(app.State.Times(end)) - app.State.TimeWindowLength;
                if app.State.Time < 0
                    app.State.TimeWindowLength = ceil(app.State.Times(end));
                    app.TimeWindowLengthInput.Value = ceil(app.State.Times(end));
                    app.State.Time = 0;
                end
            end
            app.State.TimeIdx = find(app.State.Times >= app.State.Time & app.State.Times < app.State.Time+app.State.TimeWindowLength);
            app.State.TimeIdx = app.State.TimeIdx(1:floor(app.State.EEG.srate/120):end);
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'Time', 'ICAData', 'EEGChannelData', 'SpectroData', 'PNSChannelData', 'EEGEvents'}];
            app.MapStateToProps();
        end

        % Value changed function: EEGAmplitudeInput
        function EEGAmplitudeInputValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.EEGAmp = app.EEGAmplitudeInput.Value;
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'EEGChannelData'}];
            app.MapStateToProps();
        end

        % Value changed function: DisplayFilterLowInput
        function DisplayFilterLowInputValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.DisplayFilter(1) = app.DisplayFilterLowInput.Value;
            app.State.FilterDesign = designfilt('bandpassfir', ...
                'StopbandFrequency1', max([0.1, app.State.DisplayFilter(1)-1]), ...
                'PassbandFrequency1', max([0.2, app.State.DisplayFilter(1)]), ...
                'PassbandFrequency2', app.State.DisplayFilter(2), ...
                'StopbandFrequency2', app.State.DisplayFilter(2)+1, ...
                'StopbandAttenuation1', 20, ...
                'StopbandAttenuation2', 20, ...
                'PassbandRipple',1, ...
                'DesignMethod', 'equiripple', ...
                'SampleRate', app.State.EEG.srate/floor(app.State.EEG.srate/120));
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'EEGChannelData', 'ICAData'}];
            app.MapStateToProps();
        end

        % Value changed function: DisplayFilterHighInput
        function DisplayFilterHighInputValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.DisplayFilter(2) = app.DisplayFilterHighInput.Value;
            app.State.FilterDesign = designfilt('bandpassfir', ...
                'StopbandFrequency1', max([0.1, app.State.DisplayFilter(1)-1]), ...
                'PassbandFrequency1', app.State.DisplayFilter(1), ...
                'PassbandFrequency2', app.State.DisplayFilter(2), ...
                'StopbandFrequency2', app.State.DisplayFilter(2)+1, ...
                'StopbandAttenuation1', 20, ...
                'StopbandAttenuation2', 20, ...
                'PassbandRipple',1, ...
                'DesignMethod', 'equiripple', ...
                'SampleRate', app.State.EEG.srate/floor(app.State.EEG.srate/120));
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'EEGChannelData', 'ICAData'}];
            app.MapStateToProps();
        end

        % Button pushed function: NewEventGroupButton
        function NewEventGroupButtonPushed(app, event)
            % ---------------------------------------------------------
            % Call the GUI
            app.gui_SimpleQuestion = SimpleQuestion(app, 'Event label', '');
            uiwait(app.gui_SimpleQuestion.UIFigure);
            % ---------------------------------------------------------
            % Check if user pressed cancel
            if ~app.Import.Return
                return
            end
            % ---------------------------------------------------------
            % Make the name a valid Matlab variable name and remove
            % non-alphanumeric characters
            app.Import.Data.Answer(~isstrprop(app.Import.Data.Answer, 'alphanum')) = '';
            app.Import.Data.Answer = lower(matlab.lang.makeValidName(app.Import.Data.Answer));
            % ---------------------------------------------------------
            % Check if event does not exist already
            if any(ismember(app.Import.Data.Answer, lower(fieldnames(app.State.Events))))
                sel = uiconfirm(app.UIFigure, ['Cannot add the event group ''', app.Import.Data.Answer, ''', it already exists.'], 'Error', ...
                    'Options',{'Ok'},...
                    'DefaultOption', 'Ok', ...
                    'Icon', 'error'); %#ok<NASGU>
                return
            end
            % ---------------------------------------------------------
            % Save to state
            app.State.Events.(app.Import.Data.Answer).ID = zeros(0);
            app.State.Events.(app.Import.Data.Answer).Onset = zeros(0);
            app.State.Events.(app.Import.Data.Answer).Duration = zeros(0);
            if regexpIdx(app.Import.Data.Answer, 'reject')
                app.State.Events.(app.Import.Data.Answer).Color = [0.722, 0.110, 0.153];
                app.State.Events.(app.Import.Data.Answer).IsReject = true;
                app.State.RejEventLabels = unique([{'reject'}, app.State.RejEventLabels]);
            else
                app.State.Events.(app.Import.Data.Answer).Color = hsv2rgb([(0.88-0.12).*rand(1, 1) + 0.12, 0.848, 0.722]);
                app.State.Events.(app.Import.Data.Answer).IsReject = false;
            end
            app.State.SelectedEventGroup = '';
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'EventList'}];
            app.MapStateToProps();
        end

        % Button pushed function: EditEventGroupButton
        function EditEventGroupButtonPushed(app, event)
            % ---------------------------------------------------------
            % Call the GUI
            EventLabel = app.State.SelectedEventGroup(1:end-4);
            Key = app.State.SelectedEventGroup(end-3:end);
            app.gui_SimpleQuestion = SimpleQuestion(app, 'Edit event label', EventLabel);
            uiwait(app.gui_SimpleQuestion.UIFigure);
            % ---------------------------------------------------------
            % Check if user pressed cancel
            if ~app.Import.Return
                return
            end
            % ---------------------------------------------------------
            % Make the name a valid Matlab variable name
            app.Import.Data.Answer(~isstrprop(app.Import.Data.Answer, 'alphanum')) = '';
            app.Import.Data.Answer = lower(matlab.lang.makeValidName(app.Import.Data.Answer));
            % ---------------------------------------------------------
            % Check if event does not exist already
            if any(ismember(lower(app.Import.Data.Answer), lower(fieldnames(app.State.Events))))
                sel = uiconfirm(app.UIFigure, ['Cannot change the event group label to ''', app.Import.Data.Answer, ''', it already exists.'], 'Error', ...
                    'Options',{'Ok'},...
                    'DefaultOption', 'Ok', ...
                    'Icon', 'error'); %#ok<NASGU>
                return
            end
            % ---------------------------------------------------------
            % Rename the field
            app.State.Events = renameField(app.State.Events, EventLabel, app.Import.Data.Answer);
            % ---------------------------------------------------------
            % Change event ids and labels in the EEG structure
            for i = 1:length(app.State.EEG.event)
                if strcmpi(app.State.EEG.event(i).type, EventLabel)
                    app.State.EEG.event(i).id = app.State.MaxEventID+1;
                    app.State.EEG.event(i).type = app.Import.Data.Answer;
                    app.State.MaxEventID = app.State.MaxEventID+1;
                end
            end
            % ---------------------------------------------------------
            % Change the ids, onset and latency in the state
            idx = strcmpi({app.State.EEG.event.type}, app.Import.Data.Answer);
            app.State.Events.(app.Import.Data.Answer).ID = [app.State.EEG.event(idx).id];
            % ---------------------------------------------------------
            % Update the selected event group
            app.State.SelectedEventGroup = [app.Import.Data.Answer, Key];
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'EventList', 'EEGEvents'}];
            app.MapStateToProps();
        end

        % Button pushed function: DeleteEventGroupButton
        function DeleteEventGroupButtonPushed(app, event)
            % ---------------------------------------------------------
            % Check if the user is sure
            EventLabel = app.State.SelectedEventGroup(1:end-4);
            sel = uiconfirm(app.UIFigure, ['Are you sure to delete all events with the label ''', EventLabel ,'''?'], 'Delete event group?', ...
                'Options',{'No, cancel', 'Yes, delete'},...
                'DefaultOption', 'No, cancel', ...
                'CancelOption', 'No, cancel', ...
                'Icon', 'question');
            % ---------------------------------------------------------
            % If the user pressed 'Cancel' or 'Esc', return to stop the current process
            if strcmpi(sel, 'No, cancel')
                return
            end
            % ---------------------------------------------------------
            % User is ok, so delete the events
            app.State.Events = rmfield(app.State.Events, EventLabel);
            app.State.RejEventLabels(strcmpi(app.State.RejEventLabels, EventLabel)) = [];
            % Delete the event from the EEG structure
            for i = length(app.State.EEG.event):-1:1
                if strcmpi(app.State.EEG.event(i).type, EventLabel)
                    app.State.EEG.event(i) = [];
                end
            end
            app.State.SelectedEventGroup = '';
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'EventList', 'EEGEvents'}];
            app.MapStateToProps();
        end

        % Button pushed function: RejectEventGroupButton
        function RejectEventGroupButtonPushed(app, event)
            % ---------------------------------------------------------
            % Check if the user is sure
            EventLabel = app.State.SelectedEventGroup(1:end-4);
            if app.State.Events.(EventLabel).IsReject
                Question = ['Are you sure to accept all time segments containing events with the label ''', EventLabel ,'''?'];
                Title = 'accept';
            else
                Question = ['Are you sure to reject all time segments containing events with the label ''', EventLabel ,'''?'];
                Title = 'reject';
            end
            sel = uiconfirm(app.UIFigure, Question, [Title, ' event group?'], ...
                'Options',{'No, cancel', ['Yes, ', Title]},...
                'DefaultOption', 'No, cancel', ...
                'CancelOption', 'No, cancel', ...
                'Icon', 'question');
            % ---------------------------------------------------------
            % If the user pressed 'Cancel' or 'Esc', return to stop the current process
            if strcmpi(sel, 'No, cancel')
                return
            end
            % ---------------------------------------------------------
            % Toggle the is-reject boolean
            app.State.Events.(EventLabel).IsReject = ~app.State.Events.(EventLabel).IsReject;
            % Update the reject event lables
            if app.State.Events.(EventLabel).IsReject
                app.State.RejEventLabels = unique([{EventLabel}, app.State.RejEventLabels]);
            else
                app.State.RejEventLabels(strcmpi(app.State.RejEventLabels, EventLabel)) = [];
            end
            % ---------------------------------------------------------
            % Change event ids and is_reject in the EEG structure
            for i = 1:length(app.State.EEG.event)
                if strcmpi(app.State.EEG.event(i).type, EventLabel)
                    app.State.EEG.event(i).id = app.State.MaxEventID+1;
                    app.State.EEG.event(i).is_reject = app.State.Events.(EventLabel).IsReject;
                    app.State.MaxEventID = app.State.MaxEventID+1;
                end
            end
            % ---------------------------------------------------------
            % Change the ids in the state
            idx = strcmpi({app.State.EEG.event.type}, EventLabel);
            app.State.Events.(EventLabel).ID = [app.State.EEG.event(idx).id];
            % ---------------------------------------------------------
            % Update which epochs contain rejected events
            Events = app.State.EEG.event(ismember({app.State.EEG.event.type}, app.State.RejEventLabels));
            app.State.RejEpochIdx = eegevent2idx(Events, (0:app.State.EEG.etc.faster.epochlength:size(app.State.EEG.etc.faster.var, 2)*app.State.EEG.etc.faster.epochlength-1).*app.State.EEG.srate+1);
            % -----
            % Find the indices of the channels with a statistic higher than 3
            PrevPreselChanIdx = app.State.PreselChanIdx;
            app.State.PreselChanIdx = asrow( ...
                abs(nonparzscore(nanmean(app.State.EEG.etc.faster.var(:, ~app.State.RejEpochIdx), 2))) > 3 | ...
                abs(nonparzscore(nanmean(app.State.EEG.etc.faster.corr(:, ~app.State.RejEpochIdx), 2))) > 3 | ...
                abs(nonparzscore(nanmean(app.State.EEG.etc.faster.hurst(:, ~app.State.RejEpochIdx), 2))) > 3 | ...
                abs(nonparzscore(nanmean(app.State.EEG.etc.faster.lfp(:, ~app.State.RejEpochIdx), 2))) > 3 | ...
                abs(nonparzscore(nanmean(app.State.EEG.etc.faster.hfp(:, ~app.State.RejEpochIdx), 2))) > 3);
            if any(PrevPreselChanIdx ~= app.State.PreselChanIdx)
                app.ComponentList = [app.ComponentList, {'PreselChannelIndices', 'Toposurface'}];
            end
            % -----
            % Set sorted channel indices based on clean data segments
            app.SetChanSortIdx();
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'EventList', 'EEGEvents', 'TimelineEvents', 'EEGChannelData'}];
            app.MapStateToProps();
        end

        % Value changed function: EventGroupLabelList
        function EventGroupLabelListValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.SelectedEventGroup = app.EventGroupLabelList.Value;
            app.EventGroupItemList.Value = {};
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'EventList'}];
            app.MapStateToProps();
        end

        % Value changed function: EventGroupItemList
        function EventGroupItemListValueChanged(app, event)
            % ---------------------------------------------------------
            if ~app.State.Loaded
                return
            end
            % ---------------------------------------------------------
            % Get the onset of the selected event and set that to time
            idx = find(strcmpi(app.EventGroupItemList.Items, app.EventGroupItemList.Value), 1, 'first');
            if isempty(app.EventGroupItemList.UserData)
                return
            end
            % Change the time, but only if not currently in view
            T = app.EventGroupItemList.UserData.Onset(idx);
            D = app.EventGroupItemList.UserData.Duration(idx);
            W = app.State.TimeWindowLength;
            if T < app.State.Time || (T + D) > (app.State.Time + W)
                app.State.Time = min([T, floor(T - (W-D) / 12)]);
            end
            % Set the cursor
            app.State.Cursor = [T, T+D, T+D, T];
            app.PatchCursorEEG.XData = app.State.Cursor;
            % ---------------------------------------------------------
            % Calculate the new time index
            app.State.TimeIdx = find(app.State.Times >= app.State.Time & app.State.Times < app.State.Time+app.State.TimeWindowLength);
            app.State.TimeIdx = app.State.TimeIdx(1:floor(app.State.EEG.srate/120):end);
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'Time', 'ICAData', 'EEGChannelData', 'SpectroData', 'PNSChannelData', 'EEGEvents'}];
            app.MapStateToProps();
        end

        % Callback function
        function EditEventButtonPushed(app, event)
            % ---------------------------------------------------------
            % Get ID of the event
            ID = strsplit(event.Source.CurrentObject.UserData.Tag, '-');
            ID = str2double(ID{2});
            % ---------------------------------------------------------
            % Get Event label and index
            EventLabel = app.State.EEG.event([app.State.EEG.event.id] == ID).type;
            idx = app.State.Events.(EventLabel).ID == ID;
            % ---------------------------------------------------------
            % Call the GUI
            app.gui_SimpleQuestion = SimpleDropdown(app, 'Change event label', fieldnames(app.State.Events), EventLabel);
            uiwait(app.gui_SimpleQuestion.UIFigure);
            % ---------------------------------------------------------
            % Check if user pressed cancel
            if ~app.Import.Return
                return
            end
            % Get new event label
            NewEventLabel = app.Import.Data.Answer;
            % Delete the event object
            delete(findall(app.UIAxesEEG, 'Tag', event.Source.CurrentObject.UserData.Tag))
            % ---------------------------------------------------------
            % Edit the reject event in the state
            app.State.Events.(NewEventLabel).ID = [app.State.Events.(NewEventLabel).ID, app.State.Events.(EventLabel).ID(idx)];
            app.State.Events.(NewEventLabel).Onset = [app.State.Events.(NewEventLabel).Onset, app.State.Events.(EventLabel).Onset(idx)];
            app.State.Events.(NewEventLabel).Duration = [app.State.Events.(NewEventLabel).Duration, app.State.Events.(EventLabel).Duration(idx)];
            app.State.Events.(EventLabel).ID(idx) = [];
            app.State.Events.(EventLabel).Onset(idx) = [];
            app.State.Events.(EventLabel).Duration(idx) = [];
            % ---------------------------------------------------------
            % Change the event label in the EEG structure
            app.State.EEG.event([app.State.EEG.event.id] == ID).type = NewEventLabel;
            app.State.EEG.event([app.State.EEG.event.id] == ID).is_reject = app.State.Events.(NewEventLabel).IsReject;
            % -----
            % Update which epochs contain rejected events
            if app.State.Events.(EventLabel).IsReject
                Events = app.State.EEG.event(ismember({app.State.EEG.event.type}, app.State.RejEventLabels));
                app.State.RejEpochIdx = eegevent2idx(Events, (0:app.State.EEG.etc.faster.epochlength:size(app.State.EEG.etc.faster.var, 2)*app.State.EEG.etc.faster.epochlength-1).*app.State.EEG.srate+1);
                % -----
                % Find the indices of the channels with a statistic higher than 3
                PrevPreselChanIdx = app.State.PreselChanIdx;
                app.State.PreselChanIdx = asrow( ...
                    abs(nonparzscore(nanmean(app.State.EEG.etc.faster.var(:, ~app.State.RejEpochIdx), 2))) > 3 | ...
                    abs(nonparzscore(nanmean(app.State.EEG.etc.faster.corr(:, ~app.State.RejEpochIdx), 2))) > 3 | ...
                    abs(nonparzscore(nanmean(app.State.EEG.etc.faster.hurst(:, ~app.State.RejEpochIdx), 2))) > 3 | ...
                    abs(nonparzscore(nanmean(app.State.EEG.etc.faster.lfp(:, ~app.State.RejEpochIdx), 2))) > 3 | ...
                    abs(nonparzscore(nanmean(app.State.EEG.etc.faster.hfp(:, ~app.State.RejEpochIdx), 2))) > 3);
                if any(PrevPreselChanIdx ~= app.State.PreselChanIdx)
                    app.ComponentList = [app.ComponentList, {'PreselChannelIndices', 'Toposurface'}];
                end
                % -----
                % Call sort channel function to re-evaluate bad channels
                app.SetChanSortIdx();
                app.ComponentList = [app.ComponentList, {'TimelineEvents', 'EEGChannelData'}];
            end
            % ---------------------------------------------------------
            % Set component list
            app.ComponentList = [app.ComponentList, {'EventList', 'EEGEvents'}];
            app.MapStateToProps();
        end

        % Callback function
        function DeleteEventButtonPushed(app, event)
            % ---------------------------------------------------------
            % Get ID of the to-be-deleted event
            ID = strsplit(event.Source.CurrentObject.UserData.Tag, '-');
            ID = str2double(ID{2});
            % ---------------------------------------------------------
            % Get Event label and index
            EventLabel = app.State.EEG.event([app.State.EEG.event.id] == ID).type;
            idx = app.State.Events.(EventLabel).ID == ID;
            % ---------------------------------------------------------
            % If an event, other than 'reject' is selected, set the time equal to its onset
            if ~strcmpi(EventLabel, 'reject')
                % -----
                % Ask the user to confirm
                sel = uiconfirm(app.UIFigure, ['Are you sure to permanently delete the event ''', EventLabel, '''?'], 'Delete event?', ...
                    'Options',{'No, cancel', 'Yes, delete'},...
                    'DefaultOption', 'No, cancel', ...
                    'CancelOption', 'No, cancel', ...
                    'Icon', 'question');
                % ---------------------------------------------------------
                % If the user pressed 'Cancel' or 'Esc', return to stop the current process
                if strcmpi(sel, 'No, cancel')
                    return
                end
            end
            % ---------------------------------------------------------
            % Delete the reject event from the state
            app.State.Events.(EventLabel).ID(idx) = [];
            app.State.Events.(EventLabel).Onset(idx) = [];
            app.State.Events.(EventLabel).Duration(idx) = [];
            % -----
            idx = [app.State.EEG.event.id] == ID;
            app.State.EEG.event(idx) = [];
            % -----
            % Update which epochs contain rejected events
            if app.State.Events.(EventLabel).IsReject
                Events = app.State.EEG.event(ismember({app.State.EEG.event.type}, app.State.RejEventLabels));
                app.State.RejEpochIdx = eegevent2idx(Events, (0:app.State.EEG.etc.faster.epochlength:size(app.State.EEG.etc.faster.var, 2)*app.State.EEG.etc.faster.epochlength-1).*app.State.EEG.srate+1);
                % -----
                % Find the indices of the channels with a statistic higher than 3
                PrevPreselChanIdx = app.State.PreselChanIdx;
                app.State.PreselChanIdx = asrow( ...
                    abs(nonparzscore(nanmean(app.State.EEG.etc.faster.var(:, ~app.State.RejEpochIdx), 2))) > 3 | ...
                    abs(nonparzscore(nanmean(app.State.EEG.etc.faster.corr(:, ~app.State.RejEpochIdx), 2))) > 3 | ...
                    abs(nonparzscore(nanmean(app.State.EEG.etc.faster.hurst(:, ~app.State.RejEpochIdx), 2))) > 3 | ...
                    abs(nonparzscore(nanmean(app.State.EEG.etc.faster.lfp(:, ~app.State.RejEpochIdx), 2))) > 3 | ...
                    abs(nonparzscore(nanmean(app.State.EEG.etc.faster.hfp(:, ~app.State.RejEpochIdx), 2))) > 3);
                if any(PrevPreselChanIdx ~= app.State.PreselChanIdx)
                    app.ComponentList = [app.ComponentList, {'PreselChannelIndices', 'Toposurface'}];
                end
                % -----
                % Call sort channel function to re-evaluate bad channels
                app.SetChanSortIdx();
                app.ComponentList = [app.ComponentList, {'TimelineEvents', 'EEGChannelData'}];
            end
            % ---------------------------------------------------------
            % Set component list
            app.ComponentList = [app.ComponentList, {'EventList', 'EEGEvents'}];
            app.MapStateToProps();
        end

        % Selection changed function: NumChanButtonGroup
        function NumChanButtonGroupSelectionChanged(app, event)
            % ---------------------------------------------------------
            % Update Show Channels
            app.State.ShowChans = str2double(app.NumChanButtonGroup.SelectedObject.Text);
            app.NumChanEditField.Value = app.State.ShowChans;
            % ---------------------------------------------------------
            % Update Channel Index
            app.State.ChanIdx = app.State.Chan:app.State.ChanStep:app.State.Chan+(app.State.ShowChans*app.State.ChanStep)-1;
            app.State.ChanIdx(app.State.ChanIdx < 1 | app.State.ChanIdx > app.State.NumEEGChans) = [];
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'NewChannelIndices', 'EEGChannelData', 'AlignAxes'}];
            app.MapStateToProps();
        end

        % Value changed function: NumChanEditField
        function NumChanEditFieldValueChanged(app, event)
            % ---------------------------------------------------------
            % Update Show Channels
            app.State.ShowChans = event.Source.Value;
            % ---------------------------------------------------------
            % Correct value if required
            if app.State.ShowChans > app.State.NumEEGChans
                app.State.ShowChans = app.State.NumEEGChans;
                event.Source.Value = app.State.ShowChans;
            end
            % ---------------------------------------------------------
            % Set appropriate group selection
            idx = 1+find([128 64 32 16 8] == app.State.ShowChans);
            if ~isempty(idx)
                app.NumChanButtonGroup.SelectedObject = app.NumChanButtonGroup.Children(idx);
            end
            % ---------------------------------------------------------
            % Update Channel Index
            app.State.ChanIdx = app.State.Chan:app.State.ChanStep:app.State.Chan+(app.State.ShowChans*app.State.ChanStep)-1;
            app.State.ChanIdx(app.State.ChanIdx < 1 | app.State.ChanIdx > app.State.NumEEGChans) = [];
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'NewChannelIndices', 'EEGChannelData', 'AlignAxes'}];
            app.MapStateToProps();
        end

        % Selection changed function: ChanStepButtonGroup
        function ChanStepButtonGroupSelectionChanged(app, event)
            % ---------------------------------------------------------
            app.State.ChanStep = str2double(app.ChanStepButtonGroup.SelectedObject.Text);
            if app.State.Chan > app.State.ChanStep
                app.State.Chan = 1;
            end
            app.ChanStepEditField.Value = app.State.ChanStep;
            % ---------------------------------------------------------
            % Update Channel Index
            app.State.ChanIdx = app.State.Chan:app.State.ChanStep:app.State.Chan+(app.State.ShowChans*app.State.ChanStep)-1;
            app.State.ChanIdx(app.State.ChanIdx < 1 | app.State.ChanIdx > app.State.NumEEGChans) = [];
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'NewChannelIndices', 'EEGChannelData', 'AlignAxes'}];
            app.MapStateToProps();
        end

        % Value changed function: ChanStepEditField
        function ChanStepEditFieldValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.ChanStep = event.Source.Value;
            if app.State.Chan > app.State.ChanStep
                app.State.Chan = 1;
            end
            % ---------------------------------------------------------
            % Set appropriate group selection
            idx = 1+find([16 8 4 2 1] == app.State.ChanStep);
            if ~isempty(idx)
                app.ChanStepButtonGroup.SelectedObject = app.ChanStepButtonGroup.Children(idx);
            end
            % ---------------------------------------------------------
            % Update Channel Index
            app.State.ChanIdx = app.State.Chan:app.State.ChanStep:app.State.Chan+(app.State.ShowChans*app.State.ChanStep)-1;
            app.State.ChanIdx(app.State.ChanIdx < 1 | app.State.ChanIdx > app.State.NumEEGChans) = [];
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'NewChannelIndices', 'EEGChannelData', 'AlignAxes'}];
            app.MapStateToProps();
        end

        % Value changed function: SortChanInput
        function SortChanInputValueChanged(app, event)
            % ---------------------------------------------------------
            app.SetChanSortIdx();
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'NewChannelIndices', 'EEGChannelData', 'AlignAxes'}];
            app.MapStateToProps();
        end

        % Value changed function: EEGReferenceInput
        function EEGReferenceInputValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.Reference = app.EEGReferenceInput.Value;
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'EEGChannelData'}];
            app.MapStateToProps();
        end

        % Value changed function: LineWidthInput
        function LineWidthInputValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.LineWidth = app.LineWidthInput.Value;
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'LineWidth'}];
            app.MapStateToProps();
        end

        % Value changed function: MarkerSizeInput
        function MarkerSizeInputValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.MarkerSize = app.MarkerSizeInput.Value;
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'MarkerSize'}];
            app.MapStateToProps();
        end

        % Value changed function: ShowPanelTimelineInput
        function ShowPanelTimelineInputValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.Show.Timeline = app.ShowPanelTimelineInput.Value;
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'PanelShow', 'PanelHeight', 'Time', 'AlignAxes'}];
            app.MapStateToProps();
        end

        % Value changed function: HeightPanelTimelineInput
        function HeightPanelTimelineInputValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.Height.Timeline = app.HeightPanelTimelineInput.Value;
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'PanelHeight'}];
            app.MapStateToProps();
        end

        % Value changed function: ShowPanelICAInput
        function ShowPanelICAInputValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.Show.ICA = app.ShowPanelICAInput.Value;
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'PanelShow', 'PanelHeight', 'Time', 'AlignAxes'}];
            app.MapStateToProps();
        end

        % Value changed function: HeightPanelICAInput
        function HeightPanelICAInputValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.Height.ICA = app.HeightPanelICAInput.Value;
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'PanelHeight'}];
            app.MapStateToProps();
        end

        % Value changed function: ShowPanelEEGInput
        function ShowPanelEEGInputValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.Show.EEG = app.ShowPanelEEGInput.Value;
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'PanelShow', 'PanelHeight', 'Time', 'EEGChannelData', 'AlignAxes'}];
            app.MapStateToProps();
        end

        % Value changed function: HeightPanelEEGInput
        function HeightPanelEEGInputValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.Height.EEG = app.HeightPanelEEGInput.Value;
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'PanelHeight'}];
            app.MapStateToProps();
        end

        % Value changed function: ShowPanelSpectroInput
        function ShowPanelSpectroInputValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.Show.Spectro = app.ShowPanelSpectroInput.Value;
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'PanelShow', 'ICAData', 'PanelHeight', 'Time', 'SpectroData', 'AlignAxes'}];
            app.MapStateToProps();
        end

        % Value changed function: HeightPanelSpectroInput
        function HeightPanelSpectroInputValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.Height.Spectro = app.HeightPanelSpectroInput.Value;
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'PanelHeight'}];
            app.MapStateToProps();
        end

        % Value changed function: ShowPanelPNSInput
        function ShowPanelPNSInputValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.Show.PNS = app.ShowPanelPNSInput.Value;
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'PanelShow', 'PanelHeight', 'Time', 'PNSChannelData', 'AlignAxes'}];
            app.MapStateToProps();
        end

        % Value changed function: HeightPanelPNSInput
        function HeightPanelPNSInputValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.Height.PNS = app.HeightPanelPNSInput.Value;
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'PanelHeight'}];
            app.MapStateToProps();
        end

        % Value changed function: ShowPanelStatsInput
        function ShowPanelStatsInputValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.Show.Stats = app.ShowPanelStatsInput.Value;
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'PanelShow', 'PanelHeight', 'Time', 'AlignAxes'}];
            app.MapStateToProps();
        end

        % Value changed function: HeightPanelStatsInput
        function HeightPanelStatsInputValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.Height.Stats = app.HeightPanelStatsInput.Value;
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'PanelHeight'}];
            app.MapStateToProps();
        end

        % Button pushed function: CalculateSpectrogramButton
        function CalculateSpectrogramButtonPushed(app, event)
            % ---------------------------------------------------------
            app.Waitbar = uiprogressdlg(app.UIFigure);
            app.Waitbar.Title = 'Cogwheels are turning, please wait...';
            app.Waitbar.Message = 'Calculating multi-taper spectrogram';
            app.Waitbar.Indeterminate = 'on';
            % Get Rejected Channels
            if isfield(app.State.EEG.etc, 'rej_channels')
                RejChans = app.State.EEG.etc.rej_channels; %#ok<NASGU>
            else
                RejChans = []; %#ok<NASGU>
            end
            % ---------------------------------------------------------
            % <INSERT FUNCTION HERE>
            delete(app.Waitbar)
            % ---------------------------------------------------------
            % If there was some issue, do nothing
            if ~isempty(Warnings)
                return
            end % Ok, all is well we can continue
            % ---------------------------------------------------------
            % Calculate plotable specdata
            switch app.State.EEG.specnormmethod
                case 'none'
                    % Do not normalize
                    app.State.SpecData = app.State.EEG.specdata;
                case 'zscore'
                    % normalize by mean and standard deviation
                    app.State.SpecData = app.State.EEG.specnormfnc(app.State.EEG.specdata, app.State.EEG.specnormvals.mu, app.State.EEG.specnormvals.sd);
                otherwise
                    % normalize by mean only
                    app.State.SpecData = app.State.EEG.specnormfnc(app.State.EEG.specdata, app.State.EEG.specnormvals.mu);
            end
            % Average across channels
            app.State.SpecData = reshape(squeeze(nanmean(app.State.SpecData, 1)), length(app.State.EEG.specfreqs), length(app.State.EEG.spectimes)*app.State.EEG.trials);
            % Remove NaN's
            app.State.SpecData(isnan(app.State.SpecData)) = 0;
            % If no normalization was performed, transform to dB
            if strcmpi(app.State.EEG.specnormmethod, 'none')
                app.State.SpecData = pow2db(app.State.SpecData);
            end
            % Save spectrogram timeseries
            app.State.SpecTimes = 0:mean(diff(app.State.EEG.spectimes)):(app.State.EEG.pnts*app.State.EEG.trials)/app.State.EEG.srate-mean(diff(app.State.EEG.spectimes));
            % ---------------------------------------------------------
            % Remove the button from view and set axes YLim
            app.CalculateSpectrogramButton.Visible = ifelse(~isempty(app.State.EEG.specdata), 'off', 'on');
            app.UIAxesSpectro.YLim = [2, app.State.EEG.specfreqs(end)+mean(diff(app.State.EEG.specfreqs))/2];
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'SpectroData'}];
            app.MapStateToProps();
        end

        % Value changed function: CompList
        function CompListValueChanged(app, event)
            % ---------------------------------------------------------
            app.State.Comp = find(strcmpi(app.CompList.Value, app.CompList.Items));
            app.State.Show.ICA = true;
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'ICAData', 'ICATopo', 'ICAList', 'AlignAxes'}];
            app.MapStateToProps();
        end

        % Button pushed function: RejectCompButton
        function RejectCompButtonPushed(app, event)
            % ---------------------------------------------------------
            app.State.Comp = find(strcmpi(app.CompList.Value, app.CompList.Items));
            app.State.Show.ICA = true;
            % Check if component needs to be added or rejected
            if ismember(app.State.Comp, app.State.RejCompIdx)
                app.State.RejCompIdx = setdiff(app.State.RejCompIdx, app.State.Comp);
            else
                app.State.RejCompIdx = [app.State.RejCompIdx, app.State.Comp];
            end
            app.State.EEG.etc.rej_components = app.State.RejCompIdx;
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'EEGChannelData', 'ICAList'}];
            app.MapStateToProps();
        end

        % Menu selected function: MapStateToPropsMenu
        function MapStateToPropsMenuSelected(app, event)
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'PanelShow', 'PanelHeight', 'EventList', 'EEGEvents', 'TimelineEvents', 'LineWidth', 'MarkerSize', 'NewChannelIndices', 'PreselChannelIndices', 'RejChannelIndices', 'Time', 'ICAData', 'EEGChannelData', 'PNSChannelData', 'SpectroData', 'Toposurface', 'AlignAxes'}];
            app.MapStateToProps();
        end

        % Menu selected function: KeyboardMenu
        function KeyboardMenuSelected(app, event)
            keyboard
        end

        % Menu selected function: RealignPanelsMenu
        function RealignPanelsMenuSelected(app, event)

        end

        % Menu selected function: LoadDatasetMenu
        function LoadDatasetMenuSelected(app, event)
            % ---------------------------------------------------------
            % Ask the user to select a file
            % -----
            % But there are some issues
            % (1) If PC, then MFFs are regarded as directories, not files
            % (2) If Mac, we cannot use filters
            % -----
            % Set the filter, but only if we're on a PC
            Filter = ifelse(ispc, {'*.set', 'EEG, EEGLAB (*.set)'}, '*.*');
            % -----
            [Filename, Filepath] = uigetfile(Filter, 'Select a file to import');
            Filepath = strrep(Filepath, '\', '/');
            % ---------------------------------------------------------
            % If filename is zero, user pressed cancel
            if Filename == 0
                return
            end
            app.InitializeState();
            app.MapDataToState([Filepath, '/', Filename]);
            % ---------------------------------------------------------
            % Setup the inputs and create all the plot objects
            app.InitializeMapping();
            % ---------------------------------------------------------
            % Extract the channel locations from the topoplot
            if app.State.Loaded
                app.State.ChanLocs = [app.LineChansVar.XData; app.LineChansVar.YData; app.LineChansVar.ZData];
            end
            % ---------------------------------------------------------
            % Map the State to the Properties of all Components
            app.ComponentList = [app.ComponentList, {'PanelShow', 'PanelHeight', 'EventList', 'TimelineEvents', 'EEGEvents', 'LineWidth', 'MarkerSize', 'NewChannelIndices', 'PreselChannelIndices', 'RejChannelIndices', 'Time', 'ICAData', 'ICAList', 'ICATopo', 'EEGChannelData', 'SpectroData' 'PNSChannelData', 'Toposurface', 'AlignAxes'}];
            app.MapStateToProps();
        end

        % Callback function: SaveButton, SaveMenu
        function SaveButtonPushed(app, event)
            % ---------------------------------------------------------
            if ~app.State.Loaded
                return
            end
            % ---------------------------------------------------------
            app.CloseButton.Enable = 'off';
            app.SaveButton.Enable = 'off';
            app.SaveCloseButton.Enable = 'off';
            drawnow;
            % ---------------------------------------------------------
            % Copy view settings
            app.State.EEG.etc.viewsettings = rmfield(app.State, 'EEG');
            % Extract Keys and values
            KeyVals = filename2struct(app.State.EEG.setname);
            KeyVals.desc = 'inspect';
            NewSetname = struct2filename(KeyVals);
            % ---------------------------------------------------------
            % If this is a raw file, update filepath and filename,
            % otherwise, keep the same file
            if regexpIdx(app.State.EEG.filepath, '\/rawdata\/')
                if isfield(KeyVals, 'ses')
                    NewFilepath = [app.MainApp.State.Protocol.Path, '/derivatives/EEG-inspect/sub-', KeyVals.sub, '/ses-', KeyVals.ses];
                else
                    NewFilepath = [app.MainApp.State.Protocol.Path, '/derivatives/EEG-inspect/sub-', KeyVals.sub];
                end
                NewFilename = [NewSetname, '.set'];
                NewDatfile = [NewSetname, '.fdt'];
                % ---------------------------------------------------------
                % Add file to state if it does not exist yet
                if exist([NewFilepath, '/', NewFilename], 'file') == 0
                    % Save the entire file
                    SaveFile = 'all';
                    % Set new name and paths
                    app.State.EEG.etc.JSON.Sources = fullpath2bidsuri(app.MainApp.State.Protocol.Path, fullfile(app.State.EEG.filepath, app.State.EEG.filename));
                    app.State.EEG.filepath = NewFilepath;
                    app.State.EEG.setname = NewSetname;
                    app.State.EEG.filename = NewFilename;
                    app.State.EEG.datfile = NewDatfile;
                    app.MainApp.Import.Data.EEG = rmfield(app.State.EEG, 'data');
                    app.MainApp.Import.Return = true;
                    app.MainApp.Import.Data.Next = 'AddFile';
                else
                    % Save only the header info
                    SaveFile = 'header';
                    % ---------------------------------------------------------
                    % Filename already exists, ask to overwrite
                    sel = uiconfirm(app.UIFigure, {'The file ', [app.State.EEG.filepath, '/', app.State.EEG.filename], 'already exists. Do you want to overwrite this file?'}, 'Question', ...
                        'Options',{'No, cancel', 'Yes, overwrite'},...
                        'DefaultOption', 'Yes, overwrite', ...
                        'CancelOption', 'No, cancel', ...
                        'Icon', 'question');
                    switch sel
                        case 'Yes, overwrite'
                            app.State.EEG.filepath = NewFilepath;
                            app.State.EEG.setname = NewSetname;
                            app.State.EEG.filename = NewFilename;
                            app.State.EEG.datfile = NewDatfile;
                            app.MainApp.Import.Data.EEG = rmfield(app.State.EEG, 'data');
                            app.MainApp.Import.Return = true;
                            app.MainApp.Import.Data.Next = 'UpdateFile';
                            % ---------------------------------------------------------
                        case 'No, cancel'
                            app.CloseButton.Enable = 'on';
                            app.SaveButton.Enable = 'on';
                            app.SaveCloseButton.Enable = 'on';
                            return
                            % ---------------------------------------------------------
                    end
                end
            else % File already exists, and we only have to save the header
                app.MainApp.Import.Data.EEG = rmfield(app.State.EEG, 'data');
                app.MainApp.Import.Return = true;
                app.MainApp.Import.Data.Next = 'UpdateFile';
                SaveFile = 'header';
            end
            % ---------------------------------------------------------
            % Save file to derivatives directory
            app.Waitbar = uiprogressdlg(app.UIFigure);
            app.Waitbar.Title = 'Cogwheels are turning, please wait...';
            app.Waitbar.Message = 'Saving Data';
            app.Waitbar.Indeterminate = 'on';
            % ---------------------------------------------------------
            % Save Dataset
            app.State.EEG = SaveDataset(app.State.EEG, SaveFile);
            % ---------------------------------------------------------
            delete(app.Waitbar)
            % ---------------------------------------------------------
            % Set saved to true
            app.State.Saved = true;
            app.CloseButton.Enable = 'on';
            app.SaveButton.Enable = 'on';
            app.SaveCloseButton.Enable = 'on';
        end

        % Callback function: SaveCloseButton, SaveandCloseMenu
        function SaveCloseButtonPushed(app, event)
            % ---------------------------------------------------------
            if ~app.State.Loaded
                return
            end
            % ---------------------------------------------------------
            % Save the file
            app.SaveButtonPushed(event);
            % ---------------------------------------------------------
            % Call the close request function
            if isvalid(app)
                close(app.UIFigure)
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            % ---------------------------------------------------------
            app.CloseButton.Enable = 'off';
            app.SaveButton.Enable = 'off';
            app.SaveCloseButton.Enable = 'off';
            drawnow;
            % Nothing to return to the main app
            app.MainApp.Import.Return = false;
            % ---------------------------------------------------------
            % Call the close request function
            if isvalid(app)
                close(app.UIFigure)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % ---------------------------------------------------------
            % Ask the user to continue without saving
            if ~app.State.Saved
                sel = uiconfirm(app.UIFigure, 'Are you sure to close? All unsaved changes will be lost if you continue.', 'Close Window?', ...
                    'Options',{'No, cancel', 'Close without saving'},...
                    'DefaultOption', 'No, cancel', ...
                    'CancelOption', 'No, cancel', ...
                    'Icon', 'question');
                % ---------------------------------------------------------
                % If the user pressed 'Cancel' or 'Esc', return to stop the current process
                if strcmpi(sel, 'No, cancel')
                    app.CloseButton.Enable = 'on';
                    app.SaveButton.Enable = 'on';
                    app.SaveCloseButton.Enable = 'on';
                    return
                end
            end
            % ---------------------------------------------------------
            % Ok, we can delete the app.
            delete(app)
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            % ---------------------------------------------------------
            if ~app.State.Loaded
                return
            end
            % ---------------------------------------------------------
            if app.State.IsLoading
                return
            end
            % ---------------------------------------------------------
            % Check which axes was clicked
            switch event.Source.CurrentObject.Tag
                % ---------------------------------------------------------
                case 'EditEventButton'
                    % ---------------------------------------------------------
                    app.EditEventButtonPushed(event);
                case 'DeleteEventButton'
                    % ---------------------------------------------------------
                    app.DeleteEventButtonPushed(event);
                case 'Timeline'
                    % ---------------------------------------------------------
                    CurrPoint = event.Source.CurrentObject.CurrentPoint(1, 1:2);
                    if CurrPoint(1) <= 0 || CurrPoint(1) > app.State.Times(end)
                        return
                    end
                    % -----
                    % Update which epochs have been seen
                    app.SetSeenEpochIdx();
                    % -----
                    % Update time
                    app.State.Time = floor(round(CurrPoint(1)*24*60*60)/app.State.TimeWindowLength)*app.State.TimeWindowLength;
                    % -----
                    % Update the Time index
                    app.State.TimeIdx = find(app.State.Times >= app.State.Time & app.State.Times < app.State.Time+app.State.TimeWindowLength);
                    app.State.TimeIdx = app.State.TimeIdx(1:floor(app.State.EEG.srate/120):end);
                    % -----
                    app.ComponentList = [app.ComponentList, {'Time', 'ICAData', 'EEGChannelData', 'SpectroData', 'PNSChannelData', 'EEGEvents'}];
                    % ---------------------------------------------------------
                case 'EEG'
                    % ---------------------------------------------------------
                    CurrPoint = event.Source.CurrentObject.CurrentPoint(1, 1:2);
                    % -----
                    % User selected a channel if X value is less than zero
                    if CurrPoint(1) < app.State.Time
                        % -----
                        % Get what channel is selected
                        ChanSel = app.UIAxesEEG.YTickLabel{round(CurrPoint(2))};
                        % -----
                        % If there are no rejected channels yet, create one
                        if ~isfield(app.State.EEG.etc, 'rej_channels')
                            app.State.EEG.etc.rej_channels = {ChanSel};
                            % -----
                            % Otherwise, add or remove it from the list
                        else
                            if ismember(ChanSel, app.State.EEG.etc.rej_channels) % Remove chan from list
                                app.State.EEG.etc.rej_channels(strcmp(app.State.EEG.etc.rej_channels, ChanSel)) = [];
                            else % Add chan to list
                                app.State.EEG.etc.rej_channels = unique([app.State.EEG.etc.rej_channels, {ChanSel}]);
                            end
                        end
                        % -----
                        % Update the state
                        app.State.RejChanIdx = ismember({app.State.EEG.chanlocs(strcmpi({app.State.EEG.chanlocs.type}, 'EEG')).labels}, app.State.EEG.etc.rej_channels);
                        app.ComponentList = [app.ComponentList, {'RejChannelIndices', 'EEGChannelData'}];
                        % ---------------------------------------------------------
                        % Otherwise, user selected a time period
                    else
                        if strcmpi(event.Source.SelectionType, 'normal')
                            % -----
                            % Indicate that the user is dragging a new event
                            app.State.IsSelectingEventWindow = true;
                            % Save the cursor index
                            app.State.Cursor = CurrPoint(1);
                            app.PatchCursorEEG.XData = [app.State.Cursor, app.State.Cursor, app.State.Cursor, app.State.Cursor];
                            % Make sure the cursor is on top of the axes objects
                            app.UIAxesEEG.Children = [findobj(app.UIAxesEEG.Children, 'Tag', 'Cursor'); findobj(app.UIAxesEEG.Children, '-not', 'Tag', 'Cursor')];
                            return
                        elseif strcmpi(event.Source.SelectionType, 'alt')
                            % -----
                            % Update which epochs have been seen
                            app.SetSeenEpochIdx();
                            % -----
                            % Update time
                            app.State.Time = floor(CurrPoint(1));
                            % -----
                            % Update the Time index
                            app.State.TimeIdx = find(app.State.Times >= app.State.Time & app.State.Times < app.State.Time+app.State.TimeWindowLength);
                            app.State.TimeIdx = app.State.TimeIdx(1:floor(app.State.EEG.srate/120):end);
                            % -----
                            app.ComponentList = [app.ComponentList, {'Time', 'ICAData', 'EEGChannelData', 'SpectroData', 'PNSChannelData', 'EEGEvents'}];
                        end
                    end
                    % ---------------------------------------------------------
                case 'Topo'
                    if isempty(app.State.ChanLocs)
                        return
                    end
                    CurrPoint = event.Source.CurrentObject.CurrentPoint(1, :);
                    delete(findall(app.GridLayoutTopoChannels, 'Tag', 'TopoChanSel'))
                    ShownChans = unique([asrow(app.State.ChanSortIdx(app.State.ChanIdx)), find(app.State.RejChanIdx), find(app.State.PreselChanIdx)]);
                    [~, SelChan] = min((CurrPoint(1)-app.State.ChanLocs(1, ShownChans)).^2 + (CurrPoint(2)-app.State.ChanLocs(2, ShownChans)).^2);
                    plot(event.Source.CurrentObject, app.State.ChanLocs(1, ShownChans(SelChan)), app.State.ChanLocs(2, ShownChans(SelChan)), 'o', ...
                        'Tag', 'TopoChanSel', ...
                        'Color', [0.188, 0.310, 0.792], ...
                        'MarkerFaceColor', [0.188, 0.310, 0.792], ...
                        'MarkerEdgeColor', [0.188, 0.310, 0.792], ...
                        'MarkerSize', app.State.MarkerSize*1.1, ...
                        'PickableParts', 'none');
                    text(event.Source.CurrentObject, -0.53, 0.43, app.State.EEG.chanlocs(ShownChans(SelChan)).labels, ...
                        'BackgroundColor', [0.188, 0.310, 0.792], ...
                        'Tag', 'TopoChanSel', ...
                        'Color', 'w',...
                        'FontWeight', 'bold', ...
                        'VerticalAlignment', 'top', ...
                        'HorizontalAlignment', 'left', ...
                        'FontSize', 11, ...
                        'Margin', 3, ...
                        'PickableParts', 'none');
                    return
            end
            app.MapStateToProps();
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            % ---------------------------------------------------------
            % if the user is currently dragging in the eeg axes, update the event window
            if app.State.IsSelectingEventWindow
                if app.UIAxesEEG.CurrentPoint(1) < app.State.Times(1)
                    app.State.Cursor(2) = app.State.Times(1);
                elseif app.UIAxesEEG.CurrentPoint(1) > app.State.Times(end)
                    app.State.Cursor(2) = app.State.Times(end);
                else
                    app.State.Cursor(2) = app.UIAxesEEG.CurrentPoint(1);
                end
                if app.State.EEG.trials > 1
                    triallength = (app.State.EEG.xmax - app.State.EEG.xmin) + 1/app.State.EEG.srate;
                    app.State.Cursor(1) = floor(app.State.Cursor(1)/triallength) * triallength + 1/app.State.EEG.srate;
                    app.State.Cursor(2) = ceil(app.State.Cursor(2)/triallength) * triallength - 1/app.State.EEG.srate;
                end
                app.PatchCursorEEG.XData = [app.State.Cursor(1), app.State.Cursor(2), app.State.Cursor(2), app.State.Cursor(1)];
                if app.State.EEG.trials == 1
                    app.TextCursorEEG.Position = app.UIAxesEEG.CurrentPoint(1, :);
                    app.TextCursorEEG.String = [duration2str(abs(diff(app.State.Cursor))/(24*60*60)), ' '];
                end
                drawnow;
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            % ---------------------------------------------------------
            % Set the boolean to false indicating we're not dragging anymore
            app.State.IsSelectingEventWindow = false;
            app.State.Cursor = sort(app.State.Cursor);
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            % ---------------------------------------------------------
            if ~app.State.Loaded
                return
            end
            % ---------------------------------------------------------
            if app.State.IsLoading
                return
            end
            % ---------------------------------------------------------
            if ~isempty(event.Source.CurrentObject)
                if strcmpi(event.Source.CurrentObject.Tag, 'NoWindowKeyPress')
                    return
                end
            end
            % Delete any temporary objects
            delete(findall(app.GridLayoutTopoChannels, 'Tag', 'TopoChanSel'));
            % ---------------------------------------------------------
            switch event.Key
                % ---------------------------------------------------------
                case 'leftarrow'
                    app.BackwardTimeButtonPushed(event);
                    % ---------------------------------------------------------
                case 'rightarrow'
                    app.ForwardTimeButtonPushed(event);
                    % ---------------------------------------------------------
                case 'uparrow'
                    if app.State.ChanStep == 1
                        app.State.Chan = app.State.Chan - app.State.ShowChans;
                        if app.State.Chan < 1
                            app.State.Chan = 1;
                        end
                    else
                        app.State.Chan = app.State.Chan - 1;
                        if app.State.Chan < 1
                            app.State.Chan = app.State.ChanStep;
                        end
                    end
                    % -----
                    % Update Channel Index
                    if strcmpi(app.State.ShowChans, 'all')
                        app.State.ChanIdx = 1:app.State.ChanStep:app.State.NumEEGChans;
                    else
                        app.State.ChanIdx = app.State.Chan:app.State.ChanStep:app.State.Chan+(app.State.ShowChans*app.State.ChanStep)-1;
                    end
                    app.State.ChanIdx(app.State.ChanIdx < 1 | app.State.ChanIdx > app.State.NumEEGChans) = [];
                    % -----
                    % Map the State to the Properties of all Components
                    app.ComponentList = [app.ComponentList, {'NewChannelIndices', 'EEGChannelData', 'AlignAxes'}];
                    app.MapStateToProps();
                    % ---------------------------------------------------------
                case 'downarrow'
                    if app.State.ChanStep == 1
                        app.State.Chan = app.State.Chan + app.State.ShowChans;
                        if app.State.Chan > (app.State.NumEEGChans - app.State.ShowChans) + 1
                            app.State.Chan = (app.State.NumEEGChans - app.State.ShowChans) + 1;
                        end
                    else
                        app.State.Chan = app.State.Chan + 1;
                        if app.State.Chan > app.State.ChanStep
                            app.State.Chan = 1;
                        end
                    end
                    % -----
                    % Update Channel Index
                    if strcmpi(app.State.ShowChans, 'all')
                        app.State.ChanIdx = 1:app.State.ChanStep:app.State.NumEEGChans;
                    else
                        app.State.ChanIdx = app.State.Chan:app.State.ChanStep:app.State.Chan+(app.State.ShowChans*app.State.ChanStep)-1;
                    end
                    app.State.ChanIdx(app.State.ChanIdx < 1 | app.State.ChanIdx > app.State.NumEEGChans) = [];
                    % -----
                    % Map the State to the Properties of all Components
                    app.ComponentList = [app.ComponentList, {'NewChannelIndices', 'EEGChannelData', 'AlignAxes'}];
                    app.MapStateToProps();
                    % ---------------------------------------------------------
                case {'escape', 'backspace'}
                    app.RemoveCursor();
                    % ---------------------------------------------------------
                otherwise
                    % Make sure there is a cursor and the user is not currently selecting an event window
                    if isempty(app.State.Cursor) || app.State.IsSelectingEventWindow
                        return
                    end
                    % ---------------------------------------------------------
                    % Get the index of the selected event
                    switch event.Key
                        case 'backquote'
                            Key = '`';
                        case 'hyphen'
                            Key = '-';
                        case 'equal'
                            Key = '=';
                        case 'leftbracket'
                            Key = '[';
                        case 'rightbracket'
                            Key = ']';
                        case 'backslash'
                            Key = '\';
                        case 'semicolon'
                            Key = ';';
                        case 'quote'
                            Key = '''';
                        case 'comma'
                            Key = ',';
                        case 'period'
                            Key = '.';
                        case 'slash'
                            Key = '/';
                        otherwise
                            Key = event.Key;
                    end
                    EventIdx = strcmpi(app.EventGroupLabelList.UserData, Key);
                    if ~any(EventIdx)
                        return
                    end
                    % Get the selected event label and remove the key identifier, e.g. ' (1)'
                    EventLabel = app.EventGroupLabelList.Items{EventIdx};
                    EventLabel = EventLabel(1:end-4);
                    % ---------------------------------------------------------
                    % Add the event to the state
                    app.State.MaxEventID = app.State.MaxEventID+1;
                    app.State.EEG.event(end+1).id = app.State.MaxEventID;
                    app.State.Events.(EventLabel).ID = [app.State.Events.(EventLabel).ID, app.State.MaxEventID];
                    % -----
                    app.State.EEG.event(end).type = EventLabel;
                    % -----
                    diffLatency = 0;
                    minTime = 0.5;
                    maxTime = app.State.EEG.pnts * app.State.EEG.trials + 1;
                    latency = app.State.Cursor(1) * app.State.EEG.srate;
                    % Latency must not be < 0.5 or > maxTime
                    if latency < minTime
                        diffLatency = latency - minTime;
                        latency = minTime;
                    elseif latency > maxTime
                        diffLatency = latency - maxTime;
                        latency = maxTime;
                    end
                    app.State.EEG.event(end).latency = latency;
                    app.State.Events.(EventLabel).Onset = [app.State.Events.(EventLabel).Onset, latency/app.State.EEG.srate];
                    % -----
                    if length(app.State.Cursor) > 1
                        duration = (app.State.Cursor(2) - app.State.Cursor(1)) * app.State.EEG.srate;
                        duration = duration + diffLatency;
                        app.State.EEG.event(end).duration = duration;
                        app.State.Events.(EventLabel).Duration = [app.State.Events.(EventLabel).Duration, duration/app.State.EEG.srate];
                    else
                        app.State.EEG.event(end).duration = 0;
                        app.State.Events.(EventLabel).Duration = [app.State.Events.(EventLabel).Duration, 0];
                    end
                    % -----
                    app.State.EEG.event(end).is_reject = app.State.Events.(EventLabel).IsReject;
                    % ---------------------------------------------------------
                    % Empty the cursor and remove cursor from view
                    app.RemoveCursor();
                    % ---------------------------------------------------------
                    % Recalculate the Faster channel statistics without the rejected periods
                    if app.State.Events.(EventLabel).IsReject
                        % -----
                        % Update which epochs contain rejected events
                        Events = app.State.EEG.event(ismember({app.State.EEG.event.type}, app.State.RejEventLabels));
                        app.State.RejEpochIdx = eegevent2idx(Events, (0:app.State.EEG.etc.faster.epochlength:size(app.State.EEG.etc.faster.var, 2)*app.State.EEG.etc.faster.epochlength-1).*app.State.EEG.srate+1);
                        % -----
                        % Find the indices of the channels with a statistic higher than 3
                        PrevPreselChanIdx = app.State.PreselChanIdx;
                        app.State.PreselChanIdx = asrow( ...
                            abs(nonparzscore(nanmean(app.State.EEG.etc.faster.var(:, ~app.State.RejEpochIdx), 2))) > 3 | ...
                            abs(nonparzscore(nanmean(app.State.EEG.etc.faster.corr(:, ~app.State.RejEpochIdx), 2))) > 3 | ...
                            abs(nonparzscore(nanmean(app.State.EEG.etc.faster.hurst(:, ~app.State.RejEpochIdx), 2))) > 3 | ...
                            abs(nonparzscore(nanmean(app.State.EEG.etc.faster.lfp(:, ~app.State.RejEpochIdx), 2))) > 3 | ...
                            abs(nonparzscore(nanmean(app.State.EEG.etc.faster.hfp(:, ~app.State.RejEpochIdx), 2))) > 3);
                        if any(PrevPreselChanIdx ~= app.State.PreselChanIdx)
                            app.ComponentList = [app.ComponentList, {'PreselChannelIndices', 'Toposurface'}];
                        end
                        % -----
                        % Set sorted channel indices based on clean data segments
                        app.SetChanSortIdx();
                        % -----
                        % Set componentlist
                        app.ComponentList = [app.ComponentList, {'NewChannelIndices', 'EEGChannelData'}];
                    end
                    % ---------------------------------------------------------
                    % Map the State to the Properties of all Components
                    app.ComponentList = [app.ComponentList, {'EventList', 'EEGEvents', 'TimelineEvents'}];
                    app.MapStateToProps();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Visual Inspection';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.LoadDatasetMenu = uimenu(app.Menu);
            app.LoadDatasetMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDatasetMenuSelected, true);
            app.LoadDatasetMenu.Accelerator = 'o';
            app.LoadDatasetMenu.Text = 'Load Dataset';
            app.SaveMenu = uimenu(app.Menu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveMenu.Separator = 'on';
            app.SaveMenu.Accelerator = 'S';
            app.SaveMenu.Text = 'Save';
            app.SaveandCloseMenu = uimenu(app.Menu);
            app.SaveandCloseMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCloseButtonPushed, true);
            app.SaveandCloseMenu.Text = 'Save and Close';
            app.DebugMenu = uimenu(app.UIFigure);
            app.DebugMenu.Text = 'Debug';
            app.MapStateToPropsMenu = uimenu(app.DebugMenu);
            app.MapStateToPropsMenu.MenuSelectedFcn = createCallbackFcn(app, @MapStateToPropsMenuSelected, true);
            app.MapStateToPropsMenu.Text = 'MapStateToProps';
            app.KeyboardMenu = uimenu(app.DebugMenu);
            app.KeyboardMenu.MenuSelectedFcn = createCallbackFcn(app, @KeyboardMenuSelected, true);
            app.KeyboardMenu.Text = 'Keyboard';
            app.RealignPanelsMenu = uimenu(app.DebugMenu);
            app.RealignPanelsMenu.MenuSelectedFcn = createCallbackFcn(app, @RealignPanelsMenuSelected, true);
            app.RealignPanelsMenu.Text = 'Re-align Panels';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.CloseSavePanel = uipanel(app.GridLayout);
            app.GridLayoutCloseSavePanel = uigridlayout(app.CloseSavePanel);
            app.CloseButton = uibutton(app.GridLayoutCloseSavePanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.SaveButton = uibutton(app.GridLayoutCloseSavePanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SaveCloseButton = uibutton(app.GridLayoutCloseSavePanel, 'push');
            app.SaveCloseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCloseButtonPushed, true);
            app.SaveCloseButton.Text = 'Save and Close';
            app.SettingsPanel = uipanel(app.GridLayout);
            app.GridLayoutSettingsPanel = uigridlayout(app.SettingsPanel);
            app.TimeWindowControlsPanel = uipanel(app.GridLayoutSettingsPanel);
            app.TimeWindowControlsPanel.Title = 'Time Window Controls';
            app.GridLayoutTimeWindowControlsPanel = uigridlayout(app.TimeWindowControlsPanel);
            app.FastBackwardTimeButton = uibutton(app.GridLayoutTimeWindowControlsPanel, 'push');
            app.FastBackwardTimeButton.ButtonPushedFcn = createCallbackFcn(app, @FastBackwardTimeButtonPushed, true);
            app.FastBackwardTimeButton.Text = '|<<';
            app.BackwardTimeButton = uibutton(app.GridLayoutTimeWindowControlsPanel, 'push');
            app.BackwardTimeButton.ButtonPushedFcn = createCallbackFcn(app, @BackwardTimeButtonPushed, true);
            app.BackwardTimeButton.Text = '<<';
            app.StepBackwardTimeButton = uibutton(app.GridLayoutTimeWindowControlsPanel, 'push');
            app.StepBackwardTimeButton.ButtonPushedFcn = createCallbackFcn(app, @StepBackwardTimeButtonPushed, true);
            app.StepBackwardTimeButton.Text = '<';
            app.StepForwardTimeButton = uibutton(app.GridLayoutTimeWindowControlsPanel, 'push');
            app.StepForwardTimeButton.ButtonPushedFcn = createCallbackFcn(app, @StepForwardTimeButtonPushed, true);
            app.StepForwardTimeButton.Text = '>';
            app.ForwardTimeButton = uibutton(app.GridLayoutTimeWindowControlsPanel, 'push');
            app.ForwardTimeButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardTimeButtonPushed, true);
            app.ForwardTimeButton.Text = '>>';
            app.FastForwardTimeButton = uibutton(app.GridLayoutTimeWindowControlsPanel, 'push');
            app.FastForwardTimeButton.ButtonPushedFcn = createCallbackFcn(app, @FastForwardTimeButtonPushed, true);
            app.FastForwardTimeButton.Text = '>>|';
            app.TimeWindowPanel = uipanel(app.GridLayoutSettingsPanel);
            app.TimeWindowPanel.Title = 'Time Window';
            app.GridLayoutTimeWindowPanel = uigridlayout(app.TimeWindowPanel);
            app.sSpinnerLabel = uilabel(app.GridLayoutTimeWindowPanel);
            app.sSpinnerLabel.Text = 's';
            app.TimeWindowLengthInput = uispinner(app.GridLayoutTimeWindowPanel);
            app.TimeWindowLengthInput.Limits = [1 Inf];
            app.TimeWindowLengthInput.ValueChangedFcn = createCallbackFcn(app, @TimeWindowLengthInputValueChanged, true);
            app.TimeWindowLengthInput.Tag = 'NoWindowKeyPress';
            app.TimeWindowLengthInput.Value = 30;
            app.EEGAmplitudePanel = uipanel(app.GridLayoutSettingsPanel);
            app.EEGAmplitudePanel.Title = 'EEG Amplitude';
            app.GridLayoutEEGAmplitudePanel = uigridlayout(app.EEGAmplitudePanel);
            app.uVSpinnerLabel = uilabel(app.GridLayoutEEGAmplitudePanel);
            app.uVSpinnerLabel.Text = 'uV';
            app.EEGAmplitudeInput = uispinner(app.GridLayoutEEGAmplitudePanel);
            app.EEGAmplitudeInput.Limits = [1 Inf];
            app.EEGAmplitudeInput.ValueChangedFcn = createCallbackFcn(app, @EEGAmplitudeInputValueChanged, true);
            app.EEGAmplitudeInput.Tag = 'NoWindowKeyPress';
            app.EEGAmplitudeInput.Value = 150;
            app.DisplayFilterPanel = uipanel(app.GridLayoutSettingsPanel);
            app.DisplayFilterPanel.Title = 'Display Filter';
            app.GridLayoutDisplayFilter = uigridlayout(app.DisplayFilterPanel);
            app.DisplayFilterLowInput = uispinner(app.GridLayoutDisplayFilter);
            app.DisplayFilterLowInput.Step = 0.25;
            app.DisplayFilterLowInput.Limits = [0 Inf];
            app.DisplayFilterLowInput.ValueChangedFcn = createCallbackFcn(app, @DisplayFilterLowInputValueChanged, true);
            app.DisplayFilterLowInput.Tag = 'NoWindowKeyPress';
            app.DisplayFilterLowInput.Value = 0.75;
            app.HzLabel = uilabel(app.GridLayoutDisplayFilter);
            app.HzLabel.Text = 'Hz';
            app.DisplayFilterHighInput = uispinner(app.GridLayoutDisplayFilter);
            app.DisplayFilterHighInput.Limits = [1 Inf];
            app.DisplayFilterHighInput.ValueChangedFcn = createCallbackFcn(app, @DisplayFilterHighInputValueChanged, true);
            app.DisplayFilterHighInput.Tag = 'NoWindowKeyPress';
            app.DisplayFilterHighInput.Value = 45;
            app.EventsPanel = uipanel(app.GridLayoutSettingsPanel);
            app.EventsPanel.Title = 'Events';
            app.GridLayoutEventsPanel = uigridlayout(app.EventsPanel);
            app.NewEventGroupButton = uibutton(app.GridLayoutEventsPanel, 'push');
            app.NewEventGroupButton.ButtonPushedFcn = createCallbackFcn(app, @NewEventGroupButtonPushed, true);
            app.NewEventGroupButton.Text = '';
            app.EditEventGroupButton = uibutton(app.GridLayoutEventsPanel, 'push');
            app.EditEventGroupButton.ButtonPushedFcn = createCallbackFcn(app, @EditEventGroupButtonPushed, true);
            app.EditEventGroupButton.Enable = 'off';
            app.EditEventGroupButton.Text = '';
            app.DeleteEventGroupButton = uibutton(app.GridLayoutEventsPanel, 'push');
            app.DeleteEventGroupButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteEventGroupButtonPushed, true);
            app.DeleteEventGroupButton.Enable = 'off';
            app.DeleteEventGroupButton.Text = '';
            app.RejectEventGroupButton = uibutton(app.GridLayoutEventsPanel, 'push');
            app.RejectEventGroupButton.ButtonPushedFcn = createCallbackFcn(app, @RejectEventGroupButtonPushed, true);
            app.RejectEventGroupButton.Enable = 'off';
            app.RejectEventGroupButton.Text = '';
            app.EventGroupLabelList = uilistbox(app.GridLayoutEventsPanel);
            app.EventGroupLabelList.Items = {};
            app.EventGroupLabelList.ValueChangedFcn = createCallbackFcn(app, @EventGroupLabelListValueChanged, true);
            app.EventGroupLabelList.Value = {};
            app.EventGroupItemList = uilistbox(app.GridLayoutEventsPanel);
            app.EventGroupItemList.Items = {};
            app.EventGroupItemList.ValueChangedFcn = createCallbackFcn(app, @EventGroupItemListValueChanged, true);
            app.EventGroupItemList.Value = {};
            app.NumChanButtonGroup = uibuttongroup(app.GridLayoutSettingsPanel);
            app.NumChanButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NumChanButtonGroupSelectionChanged, true);
            app.NumChanButtonGroup.Title = 'Show Number of Channels';
            app.NumChan8Button = uitogglebutton(app.NumChanButtonGroup);
            app.NumChan8Button.Text = '8';
            app.NumChan16Button = uitogglebutton(app.NumChanButtonGroup);
            app.NumChan16Button.Text = '16';
            app.NumChan32Button = uitogglebutton(app.NumChanButtonGroup);
            app.NumChan32Button.Text = '32';
            app.NumChan32Button.Value = true;
            app.NumChan64Button = uitogglebutton(app.NumChanButtonGroup);
            app.NumChan64Button.Text = '64';
            app.NumChan128Button = uitogglebutton(app.NumChanButtonGroup);
            app.NumChan128Button.Text = '128';
            app.NumChanEditField = uieditfield(app.NumChanButtonGroup, 'numeric');
            app.NumChanEditField.Limits = [1 Inf];
            app.NumChanEditField.ValueChangedFcn = createCallbackFcn(app, @NumChanEditFieldValueChanged, true);
            app.NumChanEditField.Value = 32;
            app.ChanStepButtonGroup = uibuttongroup(app.GridLayoutSettingsPanel);
            app.ChanStepButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChanStepButtonGroupSelectionChanged, true);
            app.ChanStepButtonGroup.Title = 'Channel Step';
            app.ChanStep1Button = uitogglebutton(app.ChanStepButtonGroup);
            app.ChanStep1Button.Text = '1';
            app.ChanStep1Button.Value = true;
            app.ChanStep2Button = uitogglebutton(app.ChanStepButtonGroup);
            app.ChanStep2Button.Text = '2';
            app.ChanStep4Button = uitogglebutton(app.ChanStepButtonGroup);
            app.ChanStep4Button.Text = '4';
            app.ChanStep8Button = uitogglebutton(app.ChanStepButtonGroup);
            app.ChanStep8Button.Text = '8';
            app.ChanStep16Button = uitogglebutton(app.ChanStepButtonGroup);
            app.ChanStep16Button.Text = '16';
            app.ChanStepEditField = uieditfield(app.ChanStepButtonGroup, 'numeric');
            app.ChanStepEditField.Limits = [1 Inf];
            app.ChanStepEditField.ValueChangedFcn = createCallbackFcn(app, @ChanStepEditFieldValueChanged, true);
            app.ChanStepEditField.Value = 1;
            app.SortChannelsPanel = uipanel(app.GridLayoutSettingsPanel);
            app.SortChannelsPanel.Title = 'Sort Channels';
            app.GridLayoutSortChannelsPanel = uigridlayout(app.SortChannelsPanel);
            app.SortChanInput = uidropdown(app.GridLayoutSortChannelsPanel);
            app.SortChanInput.Items = {'Channel Label', 'Combined Rank', 'Variance', 'Correlation', 'Hurst Exponent', 'LF Power', 'HF Power'};
            app.SortChanInput.ValueChangedFcn = createCallbackFcn(app, @SortChanInputValueChanged, true);
            app.SortChanInput.Value = 'Channel Label';
            app.EEGReferencePanel = uipanel(app.GridLayoutSettingsPanel);
            app.EEGReferencePanel.Title = 'EEG Reference';
            app.GridLayoutEEGReferencePanel = uigridlayout(app.EEGReferencePanel);
            app.EEGReferenceInput = uidropdown(app.GridLayoutEEGReferencePanel);
            app.EEGReferenceInput.Items = {'average'};
            app.EEGReferenceInput.ValueChangedFcn = createCallbackFcn(app, @EEGReferenceInputValueChanged, true);
            app.EEGReferenceInput.Value = 'average';
            app.GraphPropertiesPanel = uipanel(app.GridLayoutSettingsPanel);
            app.GraphPropertiesPanel.Title = 'Graph Properties';
            app.GridLayoutGraphPropertiesPanel = uigridlayout(app.GraphPropertiesPanel);
            app.pxSpinnerLabel = uilabel(app.GridLayoutGraphPropertiesPanel);
            app.pxSpinnerLabel.Text = 'px';
            app.LineWidthInput = uispinner(app.GridLayoutGraphPropertiesPanel);
            app.LineWidthInput.Step = 0.25;
            app.LineWidthInput.Limits = [0.25 Inf];
            app.LineWidthInput.ValueChangedFcn = createCallbackFcn(app, @LineWidthInputValueChanged, true);
            app.LineWidthInput.Tag = 'NoWindowKeyPress';
            app.LineWidthInput.Value = 0.25;
            app.LineWidthLabel = uilabel(app.GridLayoutGraphPropertiesPanel);
            app.LineWidthLabel.Text = 'Trace Line Width';
            app.ptSpinnerLabel = uilabel(app.GridLayoutGraphPropertiesPanel);
            app.ptSpinnerLabel.Text = 'pt';
            app.MarkerSizeInput = uispinner(app.GridLayoutGraphPropertiesPanel);
            app.MarkerSizeInput.Limits = [1 Inf];
            app.MarkerSizeInput.ValueChangedFcn = createCallbackFcn(app, @MarkerSizeInputValueChanged, true);
            app.MarkerSizeInput.Tag = 'NoWindowKeyPress';
            app.MarkerSizeInput.Value = 7;
            app.MarkerSizeLabel = uilabel(app.GridLayoutGraphPropertiesPanel);
            app.MarkerSizeLabel.Text = 'Channel Marker Size';
            app.PanelsShowandHeight = uipanel(app.GridLayoutSettingsPanel);
            app.PanelsShowandHeight.Title = 'Panels: Show and Height';
            app.GridLayoutShowandHeight = uigridlayout(app.PanelsShowandHeight);
            app.ShowPanelTimelineInput = uicheckbox(app.GridLayoutShowandHeight);
            app.ShowPanelTimelineInput.ValueChangedFcn = createCallbackFcn(app, @ShowPanelTimelineInputValueChanged, true);
            app.ShowPanelTimelineInput.Text = 'Timeline';
            app.ShowPanelTimelineInput.Value = true;
            app.HeightPanelTimelineInput = uispinner(app.GridLayoutShowandHeight);
            app.HeightPanelTimelineInput.Limits = [1 Inf];
            app.HeightPanelTimelineInput.ValueChangedFcn = createCallbackFcn(app, @HeightPanelTimelineInputValueChanged, true);
            app.HeightPanelTimelineInput.Tag = 'NoWindowKeyPress';
            app.HeightPanelTimelineInput.Value = 2;
            app.ShowPanelICAInput = uicheckbox(app.GridLayoutShowandHeight);
            app.ShowPanelICAInput.ValueChangedFcn = createCallbackFcn(app, @ShowPanelICAInputValueChanged, true);
            app.ShowPanelICAInput.Enable = 'off';
            app.ShowPanelICAInput.Text = 'ICA';
            app.HeightPanelICAInput = uispinner(app.GridLayoutShowandHeight);
            app.HeightPanelICAInput.Limits = [1 Inf];
            app.HeightPanelICAInput.ValueChangedFcn = createCallbackFcn(app, @HeightPanelICAInputValueChanged, true);
            app.HeightPanelICAInput.Tag = 'NoWindowKeyPress';
            app.HeightPanelICAInput.Enable = 'off';
            app.HeightPanelICAInput.Value = 1;
            app.ShowPanelEEGInput = uicheckbox(app.GridLayoutShowandHeight);
            app.ShowPanelEEGInput.ValueChangedFcn = createCallbackFcn(app, @ShowPanelEEGInputValueChanged, true);
            app.ShowPanelEEGInput.Text = 'EEG';
            app.ShowPanelEEGInput.Value = true;
            app.HeightPanelEEGInput = uispinner(app.GridLayoutShowandHeight);
            app.HeightPanelEEGInput.Limits = [1 Inf];
            app.HeightPanelEEGInput.ValueChangedFcn = createCallbackFcn(app, @HeightPanelEEGInputValueChanged, true);
            app.HeightPanelEEGInput.Tag = 'NoWindowKeyPress';
            app.HeightPanelEEGInput.Value = 16;
            app.ShowPanelSpectroInput = uicheckbox(app.GridLayoutShowandHeight);
            app.ShowPanelSpectroInput.ValueChangedFcn = createCallbackFcn(app, @ShowPanelSpectroInputValueChanged, true);
            app.ShowPanelSpectroInput.Text = 'Spectro';
            app.HeightPanelSpectroInput = uispinner(app.GridLayoutShowandHeight);
            app.HeightPanelSpectroInput.Limits = [1 Inf];
            app.HeightPanelSpectroInput.ValueChangedFcn = createCallbackFcn(app, @HeightPanelSpectroInputValueChanged, true);
            app.HeightPanelSpectroInput.Tag = 'NoWindowKeyPress';
            app.HeightPanelSpectroInput.Enable = 'off';
            app.HeightPanelSpectroInput.Value = 2;
            app.ShowPanelPNSInput = uicheckbox(app.GridLayoutShowandHeight);
            app.ShowPanelPNSInput.ValueChangedFcn = createCallbackFcn(app, @ShowPanelPNSInputValueChanged, true);
            app.ShowPanelPNSInput.Text = 'PNS';
            app.ShowPanelPNSInput.Value = true;
            app.HeightPanelPNSInput = uispinner(app.GridLayoutShowandHeight);
            app.HeightPanelPNSInput.Limits = [1 Inf];
            app.HeightPanelPNSInput.ValueChangedFcn = createCallbackFcn(app, @HeightPanelPNSInputValueChanged, true);
            app.HeightPanelPNSInput.Tag = 'NoWindowKeyPress';
            app.HeightPanelPNSInput.Value = 1;
            app.ShowPanelStatsInput = uicheckbox(app.GridLayoutShowandHeight);
            app.ShowPanelStatsInput.ValueChangedFcn = createCallbackFcn(app, @ShowPanelStatsInputValueChanged, true);
            app.ShowPanelStatsInput.Text = 'Statistics';
            app.ShowPanelStatsInput.Value = true;
            app.HeightPanelStatsInput = uispinner(app.GridLayoutShowandHeight);
            app.HeightPanelStatsInput.Limits = [1 Inf];
            app.HeightPanelStatsInput.ValueChangedFcn = createCallbackFcn(app, @HeightPanelStatsInputValueChanged, true);
            app.HeightPanelStatsInput.Tag = 'NoWindowKeyPress';
            app.HeightPanelStatsInput.Value = 2;
            app.PNSTimeseriesShowandAmplitudePanel = uipanel(app.GridLayoutSettingsPanel);
            app.PNSTimeseriesShowandAmplitudePanel.Title = 'PNS Timeseries: Show and Amplitude';
            app.GridLayoutShowPNSSensorsPanel = uigridlayout(app.PNSTimeseriesShowandAmplitudePanel);
            app.PanelTimeline = uipanel(app.GridLayout);
            app.GridLayoutPanelTimeline = uigridlayout(app.PanelTimeline);
            app.UIAxesTimeline = uiaxes(app.GridLayoutPanelTimeline);
            app.UIAxesTimeline.XLim = [0 2];
            app.UIAxesTimeline.YLim = [0 1];
            app.UIAxesTimeline.ZLim = [0 1];
            app.UIAxesTimeline.YDir = 'reverse';
            app.UIAxesTimeline.XTick = [0 1 2];
            app.UIAxesTimeline.XTickLabel = {'0'; '1'; '2'};
            app.UIAxesTimeline.YTick = [0 1];
            app.UIAxesTimeline.YTickLabel = {'0'; '1'};
            app.UIAxesTimeline.ZTick = [0 1];
            app.UIAxesTimeline.ZTickLabel = '';
            app.UIAxesTimeline.Tag = 'Timeline';
            app.PanelICA = uipanel(app.GridLayout);
            app.GridLayoutPanelICA = uigridlayout(app.PanelICA);
            app.UIAxesICA = uiaxes(app.GridLayoutPanelICA);
            app.UIAxesICA.XLim = [0 2];
            app.UIAxesICA.YLim = [0 1];
            app.UIAxesICA.ZLim = [0 1];
            app.UIAxesICA.XTick = [0 1 2];
            app.UIAxesICA.XTickLabel = '';
            app.UIAxesICA.YTick = 0.5;
            app.UIAxesICA.YTickLabel = '';
            app.UIAxesICA.ZTick = [0 1];
            app.UIAxesICA.ZTickLabel = '';
            app.UIAxesICA.Tag = 'ICA';
            app.PanelEEG = uipanel(app.GridLayout);
            app.GridLayoutPanelEEG = uigridlayout(app.PanelEEG);
            app.UIAxesEEG = uiaxes(app.GridLayoutPanelEEG);
            title(app.UIAxesEEG, ' ')
            app.UIAxesEEG.XLim = [0 2];
            app.UIAxesEEG.YLim = [0 1];
            app.UIAxesEEG.ZLim = [0 1];
            app.UIAxesEEG.YDir = 'reverse';
            app.UIAxesEEG.XTick = [0 1 2];
            app.UIAxesEEG.XTickLabel = '';
            app.UIAxesEEG.YTick = [0 1];
            app.UIAxesEEG.YTickLabel = {'0'; '1'};
            app.UIAxesEEG.ZTick = [0 1];
            app.UIAxesEEG.ZTickLabel = '';
            app.UIAxesEEG.Tag = 'EEG';
            app.PanelSpectro = uipanel(app.GridLayout);
            app.GridLayoutPanelSpectro = uigridlayout(app.PanelSpectro);
            app.UIAxesSpectro = uiaxes(app.GridLayoutPanelSpectro);
            app.UIAxesSpectro.XLim = [0 2];
            app.UIAxesSpectro.YLim = [0 45];
            app.UIAxesSpectro.ZLim = [0 1];
            app.UIAxesSpectro.XTick = [0 1 2];
            app.UIAxesSpectro.XTickLabel = '';
            app.UIAxesSpectro.YTick = [4 8 12 16 30];
            app.UIAxesSpectro.YTickLabel = {'4'; '8'; '12'; '16'; '30'};
            app.UIAxesSpectro.ZTick = [0 1];
            app.UIAxesSpectro.ZTickLabel = '';
            app.UIAxesSpectro.Tag = 'Spectro';
            app.CalculateSpectrogramButton = uibutton(app.GridLayoutPanelSpectro, 'push');
            app.CalculateSpectrogramButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSpectrogramButtonPushed, true);
            app.CalculateSpectrogramButton.Visible = 'off';
            app.CalculateSpectrogramButton.Text = 'Calculate Spectrogram';
            app.PanelPNS = uipanel(app.GridLayout);
            app.GridLayoutPanelPNS = uigridlayout(app.PanelPNS);
            app.UIAxesPNS = uiaxes(app.GridLayoutPanelPNS);
            app.UIAxesPNS.XLim = [0 2];
            app.UIAxesPNS.YLim = [0 1];
            app.UIAxesPNS.ZLim = [0 1];
            app.UIAxesPNS.YDir = 'reverse';
            app.UIAxesPNS.XTick = [0 1 2];
            app.UIAxesPNS.XTickLabel = '';
            app.UIAxesPNS.YTick = [0 1];
            app.UIAxesPNS.YTickLabel = {'0'; '1'};
            app.UIAxesPNS.ZTick = [0 1];
            app.UIAxesPNS.ZTickLabel = '';
            app.UIAxesPNS.Tag = 'PNS';
            app.PanelStats = uipanel(app.GridLayout);
            app.GridLayoutPanelStats = uigridlayout(app.PanelStats);
            app.UIAxesStats = uiaxes(app.GridLayoutPanelStats);
            app.UIAxesStats.XLim = [0 2];
            app.UIAxesStats.YLim = [0 4.753];
            app.UIAxesStats.ZLim = [0 1];
            app.UIAxesStats.XTick = [0 1 2];
            app.UIAxesStats.XTickLabel = '';
            app.UIAxesStats.YTick = [0 3.0902 3.719 4.264 4.753];
            app.UIAxesStats.YTickLabel = '';
            app.UIAxesStats.ZTick = [0 1];
            app.UIAxesStats.ZTickLabel = '';
            app.UIAxesStats.Tag = 'Stats';
            app.TopoTabGroup = uitabgroup(app.GridLayout);
            app.TopoChannelsTab = uitab(app.TopoTabGroup);
            app.TopoChannelsTab.Title = 'Channels';
            app.GridLayoutTopoChannels = uigridlayout(app.TopoChannelsTab);
            app.UIAxesTopoVar = uiaxes(app.GridLayoutTopoChannels);
            title(app.UIAxesTopoVar, 'Variance')
            app.UIAxesTopoVar.XLim = [-0.55 0.55];
            app.UIAxesTopoVar.YLim = [-0.45 0.45];
            app.UIAxesTopoVar.ZLim = [0 1];
            app.UIAxesTopoVar.CLim = [0 3];
            app.UIAxesTopoVar.XTick = [];
            app.UIAxesTopoVar.XTickLabel = {};
            app.UIAxesTopoVar.YTick = [];
            app.UIAxesTopoVar.YTickLabel = {};
            app.UIAxesTopoVar.ZTick = [];
            app.UIAxesTopoVar.ZTickLabel = '';
            app.UIAxesTopoVar.Tag = 'Topo';
            app.UIAxesTopoCorr = uiaxes(app.GridLayoutTopoChannels);
            title(app.UIAxesTopoCorr, 'Correlation')
            app.UIAxesTopoCorr.XLim = [-0.55 0.55];
            app.UIAxesTopoCorr.YLim = [-0.45 0.45];
            app.UIAxesTopoCorr.ZLim = [0 1];
            app.UIAxesTopoCorr.CLim = [0 3];
            app.UIAxesTopoCorr.XTick = [];
            app.UIAxesTopoCorr.XTickLabel = '';
            app.UIAxesTopoCorr.YTick = [];
            app.UIAxesTopoCorr.YTickLabel = '';
            app.UIAxesTopoCorr.ZTick = [];
            app.UIAxesTopoCorr.ZTickLabel = '';
            app.UIAxesTopoCorr.Tag = 'Topo';
            app.UIAxesTopoHurst = uiaxes(app.GridLayoutTopoChannels);
            title(app.UIAxesTopoHurst, 'Hurst Exponent')
            app.UIAxesTopoHurst.XLim = [-0.55 0.55];
            app.UIAxesTopoHurst.YLim = [-0.45 0.45];
            app.UIAxesTopoHurst.ZLim = [0 1];
            app.UIAxesTopoHurst.CLim = [0 3];
            app.UIAxesTopoHurst.XTick = [];
            app.UIAxesTopoHurst.XTickLabel = '';
            app.UIAxesTopoHurst.YTick = [];
            app.UIAxesTopoHurst.YTickLabel = '';
            app.UIAxesTopoHurst.ZTick = [];
            app.UIAxesTopoHurst.ZTickLabel = '';
            app.UIAxesTopoHurst.Tag = 'Topo';
            app.UIAxesTopoLFP = uiaxes(app.GridLayoutTopoChannels);
            title(app.UIAxesTopoLFP, 'LF Power (0.3 - 4 Hz)')
            app.UIAxesTopoLFP.XLim = [-0.55 0.55];
            app.UIAxesTopoLFP.YLim = [-0.45 0.45];
            app.UIAxesTopoLFP.ZLim = [0 1];
            app.UIAxesTopoLFP.CLim = [0 3];
            app.UIAxesTopoLFP.XTick = [];
            app.UIAxesTopoLFP.XTickLabel = '';
            app.UIAxesTopoLFP.YTick = [];
            app.UIAxesTopoLFP.YTickLabel = '';
            app.UIAxesTopoLFP.ZTick = [];
            app.UIAxesTopoLFP.ZTickLabel = '';
            app.UIAxesTopoLFP.Tag = 'Topo';
            app.UIAxesTopoHFP = uiaxes(app.GridLayoutTopoChannels);
            title(app.UIAxesTopoHFP, 'HF Power (25 - 60 Hz)')
            app.UIAxesTopoHFP.XLim = [-0.55 0.55];
            app.UIAxesTopoHFP.YLim = [-0.45 0.45];
            app.UIAxesTopoHFP.ZLim = [0 1];
            app.UIAxesTopoHFP.CLim = [0 3];
            app.UIAxesTopoHFP.XTick = [];
            app.UIAxesTopoHFP.XTickLabel = '';
            app.UIAxesTopoHFP.YTick = [];
            app.UIAxesTopoHFP.YTickLabel = '';
            app.UIAxesTopoHFP.ZTick = [];
            app.UIAxesTopoHFP.ZTickLabel = '';
            app.UIAxesTopoHFP.Tag = 'Topo';
            app.TopoComponentsTab = uitab(app.TopoTabGroup);
            app.TopoComponentsTab.Title = 'Components';
            app.GridLayoutTopoComponents = uigridlayout(app.TopoComponentsTab);
            app.UIAxesCompLabel = uiaxes(app.GridLayoutTopoComponents);
            ylabel(app.UIAxesCompLabel, 'p')
            app.UIAxesCompLabel.XLim = [0.5 7.5];
            app.UIAxesCompLabel.YLim = [0 1];
            app.UIAxesCompLabel.XTick = [1 2 3 4 5 6 7];
            app.UIAxesCompLabel.XTickLabel = {'Brain'; 'Muscle'; 'Eye'; 'Heart'; 'Line'; 'Noise'; 'Other'};
            app.UIAxesCompLabel.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxesTopoComp = uiaxes(app.GridLayoutTopoComponents);
            app.UIAxesTopoComp.XLim = [-0.55 0.55];
            app.UIAxesTopoComp.YLim = [-0.45 0.45];
            app.UIAxesTopoComp.ZLim = [0 1];
            app.UIAxesTopoComp.XTick = [];
            app.UIAxesTopoComp.XTickLabel = '';
            app.UIAxesTopoComp.YTick = [];
            app.UIAxesTopoComp.YTickLabel = '';
            app.UIAxesTopoComp.ZTick = [];
            app.UIAxesTopoComp.ZTickLabel = '';
            app.CompList = uilistbox(app.GridLayoutTopoComponents);
            app.CompList.Items = {'1'};
            app.CompList.ValueChangedFcn = createCallbackFcn(app, @CompListValueChanged, true);
            app.CompList.Value = '1';
            app.RejectCompButton = uibutton(app.GridLayoutTopoComponents, 'push');
            app.RejectCompButton.ButtonPushedFcn = createCallbackFcn(app, @RejectCompButtonPushed, true);
            app.RejectCompButton.Tooltip = {''};
            app.RejectCompButton.Text = '';
            app.PanelEditEvents = uipanel(app.UIFigure);
            app.PanelEditEvents.Visible = 'off';
            app.GridLayoutPanelEditEvents = uigridlayout(app.PanelEditEvents);
            app.EditEventButton = uibutton(app.GridLayoutPanelEditEvents, 'push');
            app.EditEventButton.Tag = 'EditEventButton';
            app.EditEventButton.Text = '';
            app.DeleteEventButton = uibutton(app.GridLayoutPanelEditEvents, 'push');
            app.DeleteEventButton.Tag = 'DeleteEventButton';
            app.DeleteEventButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VisualInspection(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end